classdef aArmsOptim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GlobalContainer                 matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        SidePanelContainer              matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        OptimizationOptionsPanel        matlab.ui.container.Panel
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        Panel_5                         matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        Panel_9                         matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        TargetsTab                      matlab.ui.container.Tab
        TargetDropDown                  matlab.ui.control.DropDown
        Panel_3                         matlab.ui.container.Panel
        TargetsTabGroup                 matlab.ui.container.TabGroup
        StaticTab                       matlab.ui.container.Tab
        StaticKPIWeight                 matlab.ui.control.Spinner
        StaticKPITol                    matlab.ui.control.Spinner
        StaticKPIEditField              matlab.ui.control.NumericEditField
        StaticCasterWeight              matlab.ui.control.Spinner
        StaticAntiDiveWeight            matlab.ui.control.Spinner
        StaticScrubWeight               matlab.ui.control.Spinner
        StaticCasterTol                 matlab.ui.control.Spinner
        StaticAntiDiveTol               matlab.ui.control.Spinner
        StaticScrubTol                  matlab.ui.control.Spinner
        StaticCasterEditField           matlab.ui.control.NumericEditField
        StaticAntiDiveEditField         matlab.ui.control.NumericEditField
        StaticScrubEditField            matlab.ui.control.NumericEditField
        StaticKPILabel                  matlab.ui.control.Label
        StaticCasteLabel                matlab.ui.control.Label
        StaticAntiDiveLabel             matlab.ui.control.Label
        StaticScrubLabel                matlab.ui.control.Label
        StaticTrailWeight               matlab.ui.control.Spinner
        StaticRCWeight                  matlab.ui.control.Spinner
        StaticTrailTol                  matlab.ui.control.Spinner
        StaticRCTol                     matlab.ui.control.Spinner
        StaticTrailEditField            matlab.ui.control.NumericEditField
        StaticRCEditField               matlab.ui.control.NumericEditField
        StaticTrailLabel                matlab.ui.control.Label
        StaticRCLabel                   matlab.ui.control.Label
        StaticKPICheckBox               matlab.ui.control.CheckBox
        StaticCasterCheckBox            matlab.ui.control.CheckBox
        StaticAntiDiveCheckBox          matlab.ui.control.CheckBox
        StaticScrubCheckBox             matlab.ui.control.CheckBox
        StaticTrailCheckBox             matlab.ui.control.CheckBox
        StaticRCCheckBox                matlab.ui.control.CheckBox
        StaticWeightLabel               matlab.ui.control.Label
        StaticValueMaxLabel             matlab.ui.control.Label
        StaticValueLabel                matlab.ui.control.Label
        HeaveTab                        matlab.ui.container.Tab
        HeaveAntiDiveWeight             matlab.ui.control.Spinner
        HeaveScrubWeight                matlab.ui.control.Spinner
        HeaveTrailWeight                matlab.ui.control.Spinner
        HeaveRCWeight                   matlab.ui.control.Spinner
        HeaveAntiDiveTol                matlab.ui.control.Spinner
        HeaveScrubTol                   matlab.ui.control.Spinner
        HeaveTrailTol                   matlab.ui.control.Spinner
        HeaveRCTol                      matlab.ui.control.Spinner
        HeaveBumpSteerWeight            matlab.ui.control.Spinner
        HeaveBumpSteerTol               matlab.ui.control.Spinner
        HeaveCamberGainWeight           matlab.ui.control.Spinner
        HeaveWeightLabel                matlab.ui.control.Label
        HeaveCamberGainTol              matlab.ui.control.Spinner
        HeaveTolLabel                   matlab.ui.control.Label
        HeaveCamberGainEditField        matlab.ui.control.NumericEditField
        HeaveValueLabel                 matlab.ui.control.Label
        HeaveAntiDiveLabel              matlab.ui.control.Label
        HeaveScrubLabel                 matlab.ui.control.Label
        HeaveTrailLabel                 matlab.ui.control.Label
        HeaveRCLabel                    matlab.ui.control.Label
        HeaveBumpSteerLabel             matlab.ui.control.Label
        HeaveCamberGainLabel            matlab.ui.control.Label
        HeaveAntiDiveCheckBox           matlab.ui.control.CheckBox
        HeaveScrubCheckBox              matlab.ui.control.CheckBox
        HeaveTrailCheckBox              matlab.ui.control.CheckBox
        HeaveRCCheckBox                 matlab.ui.control.CheckBox
        HeaveBumpSteerCheckBox          matlab.ui.control.CheckBox
        HeaveCamberGainCheckBox         matlab.ui.control.CheckBox
        RollTab                         matlab.ui.container.Tab
        RollRCMigrationWeight           matlab.ui.control.Spinner
        RollRCHeightWeight              matlab.ui.control.Spinner
        RollRCMigrationTol              matlab.ui.control.Spinner
        RollRCHeightTol                 matlab.ui.control.Spinner
        RollCamberInWeight              matlab.ui.control.Spinner
        RollCamberInTol                 matlab.ui.control.Spinner
        RollCamberInEditField           matlab.ui.control.NumericEditField
        RollCamberOutWeight             matlab.ui.control.Spinner
        RollCamberOutTol                matlab.ui.control.Spinner
        RollCamberOutEditField          matlab.ui.control.NumericEditField
        RollWeightLabel                 matlab.ui.control.Label
        RollValueLabel_2                matlab.ui.control.Label
        RollValueLabel                  matlab.ui.control.Label
        RollRCMigrationLabel            matlab.ui.control.Label
        RollRCHeightLabel               matlab.ui.control.Label
        RollCamberInLabel               matlab.ui.control.Label
        RollCamberOutLabel              matlab.ui.control.Label
        RollRCMigrationCheckBox         matlab.ui.control.CheckBox
        RollRCHeightCheckBox            matlab.ui.control.CheckBox
        RollCamberInCheckBox            matlab.ui.control.CheckBox
        RollCamberOutCheckBox           matlab.ui.control.CheckBox
        SteeringTab                     matlab.ui.container.Tab
        SteeringRangeWeight             matlab.ui.control.Spinner
        SteeringAckermanProgressivenessWeight  matlab.ui.control.Spinner
        SteeringRangeTol                matlab.ui.control.Spinner
        SteeringAckermanLinearityWeight  matlab.ui.control.Spinner
        SteeringAckermanVariationWeight  matlab.ui.control.Spinner
        SteeringRangeEditField          matlab.ui.control.NumericEditField
        SteeringAckermanWeight          matlab.ui.control.Spinner
        SteeringAckermanTol             matlab.ui.control.Spinner
        SteeringAckermanEditField       matlab.ui.control.NumericEditField
        SteeringScrubWeight             matlab.ui.control.Spinner
        SteeringScrubTol                matlab.ui.control.Spinner
        SteeringAckermanProgressivenessLabel  matlab.ui.control.Label
        SteeringRangeLabel              matlab.ui.control.Label
        SteeringAckermanLinearityLabel  matlab.ui.control.Label
        SteeringAckermanVariationLabel  matlab.ui.control.Label
        SteeringAckermanLabel           matlab.ui.control.Label
        SteeringScrubLabel              matlab.ui.control.Label
        SteeringAckermanProgressivenessCheckBox  matlab.ui.control.CheckBox
        SteeringAckermanLinearityCheckBox  matlab.ui.control.CheckBox
        SteeringAckermanVariationCheckBox  matlab.ui.control.CheckBox
        SteeringRangeCheckBox           matlab.ui.control.CheckBox
        SteeringAckermanCheckbox        matlab.ui.control.CheckBox
        SteeringScrubCheckBox           matlab.ui.control.CheckBox
        SteeringTrailWeight             matlab.ui.control.Spinner
        SteeringTrailTol                matlab.ui.control.Spinner
        SteeringTrailLabel              matlab.ui.control.Label
        SteeringTrailCheckBox           matlab.ui.control.CheckBox
        SteeringCamberGainInWeight      matlab.ui.control.Spinner
        SteeringCamberGainInTol         matlab.ui.control.Spinner
        SteeringCamberInEditField       matlab.ui.control.NumericEditField
        SteeringCamberGainInLabel       matlab.ui.control.Label
        SteeringCamberGainInCheckBox    matlab.ui.control.CheckBox
        SteeringCamberGainOutWeight     matlab.ui.control.Spinner
        SteeringCamberGainOutTol        matlab.ui.control.Spinner
        SteeringCamberOutEditField      matlab.ui.control.NumericEditField
        SteeringCamberGainOutLabel      matlab.ui.control.Label
        SteeringCamberGainOutCheckBox   matlab.ui.control.CheckBox
        SteeringWeightLabel             matlab.ui.control.Label
        SteeringValueLabel_2            matlab.ui.control.Label
        SteeringValueLabel              matlab.ui.control.Label
        Tab                             matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        SelectTargetsPanel              matlab.ui.container.Panel
        SteeringCheckBox                matlab.ui.control.CheckBox
        RollCheckBox                    matlab.ui.control.CheckBox
        HeaveCheckBox                   matlab.ui.control.CheckBox
        StaticCheckBox                  matlab.ui.control.CheckBox
        BoundsTab                       matlab.ui.container.Tab
        SelectBoundsPanel               matlab.ui.container.Panel
        SteeringBoundsCheckBox          matlab.ui.control.CheckBox
        LowerAarmCheckBox               matlab.ui.control.CheckBox
        UpperAarmCheckBox               matlab.ui.control.CheckBox
        BoundsDropDown                  matlab.ui.control.DropDown
        Panel_7                         matlab.ui.container.Panel
        BoundsTabGroup                  matlab.ui.container.TabGroup
        UpperAarmTab                    matlab.ui.container.Tab
        UCAforePanel                    matlab.ui.container.Panel
        lowerLabel                      matlab.ui.control.Label
        upperLabel                      matlab.ui.control.Label
        UCAforeXlower                   matlab.ui.control.Spinner
        UCAforeXupper                   matlab.ui.control.Spinner
        UCAforeYlower                   matlab.ui.control.Spinner
        UCAforeYupper                   matlab.ui.control.Spinner
        UCAforeZlower                   matlab.ui.control.Spinner
        UCAforeZupper                   matlab.ui.control.Spinner
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        UCAaftPanel                     matlab.ui.container.Panel
        lowerLabel_2                    matlab.ui.control.Label
        upperLabel_2                    matlab.ui.control.Label
        UCAaftXlower                    matlab.ui.control.Spinner
        UCAaftXupper                    matlab.ui.control.Spinner
        UCAaftYlower                    matlab.ui.control.Spinner
        UCAaftYupper                    matlab.ui.control.Spinner
        UCAaftZlower                    matlab.ui.control.Spinner
        UCAaftZupper                    matlab.ui.control.Spinner
        XLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        ZLabel_2                        matlab.ui.control.Label
        UCAuprightPanel                 matlab.ui.container.Panel
        lowerLabel_3                    matlab.ui.control.Label
        upperLabel_3                    matlab.ui.control.Label
        UCAuprightXlower                matlab.ui.control.Spinner
        UCAuprightXupper                matlab.ui.control.Spinner
        UCAuprightYlower                matlab.ui.control.Spinner
        UCAuprightYupper                matlab.ui.control.Spinner
        UCAuprightZlower                matlab.ui.control.Spinner
        UCAuprightZupper                matlab.ui.control.Spinner
        XLabel_3                        matlab.ui.control.Label
        YLabel_3                        matlab.ui.control.Label
        ZLabel_3                        matlab.ui.control.Label
        UCAforeCheckBox                 matlab.ui.control.CheckBox
        UCAaftCheckBox                  matlab.ui.control.CheckBox
        UCAuprightCheckBox              matlab.ui.control.CheckBox
        LowerAarmTab                    matlab.ui.container.Tab
        LCAforePanel                    matlab.ui.container.Panel
        lowerLabel_9                    matlab.ui.control.Label
        upperLabel_9                    matlab.ui.control.Label
        LCAforeXlower                   matlab.ui.control.Spinner
        LCAforeXupper                   matlab.ui.control.Spinner
        LCAforeYlower                   matlab.ui.control.Spinner
        LCAforeYupper                   matlab.ui.control.Spinner
        LCAforeZlower                   matlab.ui.control.Spinner
        LCAforeZupper                   matlab.ui.control.Spinner
        XLabel_9                        matlab.ui.control.Label
        YLabel_9                        matlab.ui.control.Label
        ZLabel_9                        matlab.ui.control.Label
        LCAaftPanel                     matlab.ui.container.Panel
        lowerLabel_10                   matlab.ui.control.Label
        upperLabel_10                   matlab.ui.control.Label
        LCAaftXlower                    matlab.ui.control.Spinner
        LCAaftXupper                    matlab.ui.control.Spinner
        LCAaftYlower                    matlab.ui.control.Spinner
        LCAaftYupper                    matlab.ui.control.Spinner
        LCAaftZlower                    matlab.ui.control.Spinner
        LCAaftZupper                    matlab.ui.control.Spinner
        XLabel_10                       matlab.ui.control.Label
        YLabel_10                       matlab.ui.control.Label
        ZLabel_10                       matlab.ui.control.Label
        LCAuprightPanel                 matlab.ui.container.Panel
        lowerLabel_11                   matlab.ui.control.Label
        upperLabel_11                   matlab.ui.control.Label
        LCAuprightXlower                matlab.ui.control.Spinner
        LCAuprightXupper                matlab.ui.control.Spinner
        LCAuprightYlower                matlab.ui.control.Spinner
        LCAuprightYupper                matlab.ui.control.Spinner
        LCAuprightZlower                matlab.ui.control.Spinner
        LCAuprightZupper                matlab.ui.control.Spinner
        XLabel_11                       matlab.ui.control.Label
        YLabel_11                       matlab.ui.control.Label
        ZLabel_11                       matlab.ui.control.Label
        LCAforeCheckBox                 matlab.ui.control.CheckBox
        LCAaftCheckBox                  matlab.ui.control.CheckBox
        LCAuprightCheckBox              matlab.ui.control.CheckBox
        SteeringTab_2                   matlab.ui.container.Tab
        SteeringRackPanel               matlab.ui.container.Panel
        lowerLabel_7                    matlab.ui.control.Label
        upperLabel_7                    matlab.ui.control.Label
        SteeringRackXlower              matlab.ui.control.Spinner
        SteeringRackXupper              matlab.ui.control.Spinner
        SteeringRackYlower              matlab.ui.control.Spinner
        SteeringRackYupper              matlab.ui.control.Spinner
        SteeringRackZlower              matlab.ui.control.Spinner
        SteeringRackZupper              matlab.ui.control.Spinner
        XLabel_7                        matlab.ui.control.Label
        YLabel_7                        matlab.ui.control.Label
        ZLabel_7                        matlab.ui.control.Label
        SteeringUprightPanel            matlab.ui.container.Panel
        lowerLabel_8                    matlab.ui.control.Label
        upperLabel_8                    matlab.ui.control.Label
        SteeringUprightXlower           matlab.ui.control.Spinner
        SteeringUprightXupper           matlab.ui.control.Spinner
        SteeringUprightYlower           matlab.ui.control.Spinner
        SteeringUprightYupper           matlab.ui.control.Spinner
        SteeringUprightZlower           matlab.ui.control.Spinner
        SteeringUprightZupper           matlab.ui.control.Spinner
        XLabel_8                        matlab.ui.control.Label
        YLabel_8                        matlab.ui.control.Label
        ZLabel_8                        matlab.ui.control.Label
        SteeringRackCheckBox            matlab.ui.control.CheckBox
        SteeringUprightCheckBox         matlab.ui.control.CheckBox
        Tab_2                           matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        SettingsTab                     matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        RollLimit                       matlab.ui.control.NumericEditField
        LimitsLabel                     matlab.ui.control.Label
        SteeringUpperLimit              matlab.ui.control.NumericEditField
        SteeringLowerLimit              matlab.ui.control.NumericEditField
        HeaveUpperLimit                 matlab.ui.control.NumericEditField
        HeaveLowerLimit                 matlab.ui.control.NumericEditField
        SteeringStep                    matlab.ui.control.NumericEditField
        RollStep                        matlab.ui.control.NumericEditField
        HeaveStep                       matlab.ui.control.NumericEditField
        StepLabel                       matlab.ui.control.Label
        SteeringLabel                   matlab.ui.control.Label
        RollLabel                       matlab.ui.control.Label
        HeaveLabel                      matlab.ui.control.Label
        ToleranceLabel                  matlab.ui.control.Label
        RollTol                         matlab.ui.control.NumericEditField
        InterpolationPanel              matlab.ui.container.Panel
        SolverToleranceEditFieldLabel   matlab.ui.control.Label
        SolverToleranceEditField        matlab.ui.control.NumericEditField
        LinButton                       matlab.ui.control.StateButton
        ExpButton                       matlab.ui.control.StateButton
        ObjectiveFunctionLabel          matlab.ui.control.Label
        PlotOptimProgressCheckBox       matlab.ui.control.CheckBox
        PlotoptimizationprogressLabel   matlab.ui.control.Label
        LoadSessionButton               matlab.ui.control.Button
        SaveSessionButton               matlab.ui.control.Button
        Panel_10                        matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        ResultsListHeadingPanel         matlab.ui.container.Panel
        IDLabel                         matlab.ui.control.Label
        ResultsList                     matlab.ui.container.Panel
        Panel_11                        matlab.ui.container.Panel
        AnalysisSelectDropDown          matlab.ui.control.DropDown
        ParameterSelectDropDown         matlab.ui.control.DropDown
        ChangeParameterUpButton         matlab.ui.control.Button
        ChangeParameterDownButton       matlab.ui.control.Button
        ResultAxes                      matlab.ui.control.UIAxes
        SaveResultsPanel                matlab.ui.container.Panel
        SaveResultsListBox              matlab.ui.control.ListBox
        SaveSelectionButton             matlab.ui.control.Button
        SaveCancelButton                matlab.ui.control.Button
        SaveSelectionAsButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp
        Dataset
        SessionData
        Configuration
        OptimAnalysisList
        AnalysisList
        ParametersList
        BoundsList
        Analysis
    end
    
    methods (Access = private)
        
        
        function loadConfig(app)
            % Targets
            %% Enable
            app.StaticCheckBox.Value = app.Configuration.targets.enable(1);
            app.HeaveCheckBox.Value = app.Configuration.targets.enable(2);
            app.RollCheckBox.Value = app.Configuration.targets.enable(3);
            app.SteeringCheckBox.Value = app.Configuration.targets.enable(4);
            %% Analysis type
            %%% Static
            %%%% enable
            app.StaticRCCheckBox.Value = app.Configuration.targets.analysis{1}.enable(1);
            app.StaticTrailCheckBox.Value = app.Configuration.targets.analysis{1}.enable(2);
            app.StaticScrubCheckBox.Value = app.Configuration.targets.analysis{1}.enable(3);
            app.StaticAntiDiveCheckBox.Value = app.Configuration.targets.analysis{1}.enable(4);
            app.StaticCasterCheckBox.Value = app.Configuration.targets.analysis{1}.enable(5);
            app.StaticKPICheckBox.Value = app.Configuration.targets.analysis{1}.enable(6);
            %%%% value
            app.StaticRCEditField.Value = app.Configuration.targets.analysis{1}.value(1);
            app.StaticTrailEditField.Value = app.Configuration.targets.analysis{1}.value(2);
            app.StaticScrubEditField.Value = app.Configuration.targets.analysis{1}.value(3);
            app.StaticAntiDiveEditField.Value = app.Configuration.targets.analysis{1}.value(4);
            app.StaticCasterEditField.Value = app.Configuration.targets.analysis{1}.value(5);
            app.StaticKPIEditField.Value = app.Configuration.targets.analysis{1}.value(6);
            %%%% tolerance
            app.StaticRCTol.Value = app.Configuration.targets.analysis{1}.tol(1);
            app.StaticTrailTol.Value = app.Configuration.targets.analysis{1}.tol(2);
            app.StaticScrubTol.Value = app.Configuration.targets.analysis{1}.tol(3);
            app.StaticAntiDiveTol.Value = app.Configuration.targets.analysis{1}.tol(4);
            app.StaticCasterTol.Value = app.Configuration.targets.analysis{1}.tol(5);
            app.StaticKPITol.Value = app.Configuration.targets.analysis{1}.tol(6);
            %%%% weight
            app.StaticRCWeight.Value = app.Configuration.targets.analysis{1}.weight(1);
            app.StaticTrailWeight.Value = app.Configuration.targets.analysis{1}.weight(2);
            app.StaticScrubWeight.Value = app.Configuration.targets.analysis{1}.weight(3);
            app.StaticAntiDiveWeight.Value = app.Configuration.targets.analysis{1}.weight(4);
            app.StaticCasterWeight.Value = app.Configuration.targets.analysis{1}.weight(5);
            app.StaticKPIWeight.Value = app.Configuration.targets.analysis{1}.weight(6);
            %%% Heave
            %%%% enable
            app.HeaveCamberGainCheckBox.Value = app.Configuration.targets.analysis{2}.enable(1);
            app.HeaveBumpSteerCheckBox.Value = app.Configuration.targets.analysis{2}.enable(2);
            app.HeaveRCCheckBox.Value = app.Configuration.targets.analysis{2}.enable(3);
            app.HeaveTrailCheckBox.Value = app.Configuration.targets.analysis{2}.enable(4);
            app.HeaveScrubCheckBox.Value = app.Configuration.targets.analysis{2}.enable(5);
            app.HeaveAntiDiveCheckBox.Value = app.Configuration.targets.analysis{2}.enable(6);
            %%%% value
            app.HeaveCamberGainEditField.Value = app.Configuration.targets.analysis{2}.value(1);
            %%%% tolerance
            app.HeaveCamberGainTol.Value = app.Configuration.targets.analysis{2}.tol(1);
            app.HeaveBumpSteerTol.Value = app.Configuration.targets.analysis{2}.tol(2);
            app.HeaveRCTol.Value = app.Configuration.targets.analysis{2}.tol(3);
            app.HeaveTrailTol.Value = app.Configuration.targets.analysis{2}.tol(4);
            app.HeaveScrubTol.Value = app.Configuration.targets.analysis{2}.tol(5);
            app.HeaveAntiDiveTol.Value = app.Configuration.targets.analysis{2}.tol(6);
            %%%% weight
            app.HeaveCamberGainWeight.Value = app.Configuration.targets.analysis{2}.weight(1);
            app.HeaveBumpSteerWeight.Value = app.Configuration.targets.analysis{2}.weight(2);
            app.HeaveRCWeight.Value = app.Configuration.targets.analysis{2}.weight(3);
            app.HeaveTrailWeight.Value = app.Configuration.targets.analysis{2}.weight(4);
            app.HeaveScrubWeight.Value = app.Configuration.targets.analysis{2}.weight(5);
            app.HeaveAntiDiveWeight.Value = app.Configuration.targets.analysis{2}.weight(6);
            %%% Roll
            %%%% enable
            app.RollCamberOutCheckBox.Value = app.Configuration.targets.analysis{3}.enable(1);
            app.RollCamberInCheckBox.Value = app.Configuration.targets.analysis{3}.enable(2);
            app.RollRCHeightCheckBox.Value = app.Configuration.targets.analysis{3}.enable(3);
            app.RollRCMigrationCheckBox.Value = app.Configuration.targets.analysis{3}.enable(4);
            %%%% value
            app.RollCamberOutEditField.Value = app.Configuration.targets.analysis{3}.value(1);
            app.RollCamberInEditField.Value = app.Configuration.targets.analysis{3}.value(2);
            %%%% tolerance
            app.RollCamberOutTol.Value = app.Configuration.targets.analysis{3}.tol(1);
            app.RollCamberInTol.Value = app.Configuration.targets.analysis{3}.tol(2);
            app.RollRCHeightTol.Value = app.Configuration.targets.analysis{3}.tol(3);
            app.RollRCMigrationTol.Value = app.Configuration.targets.analysis{3}.tol(4);
            %%%% weight
            app.RollCamberOutWeight.Value = app.Configuration.targets.analysis{3}.weight(1);
            app.RollCamberInWeight.Value = app.Configuration.targets.analysis{3}.weight(2);
            app.RollRCHeightWeight.Value = app.Configuration.targets.analysis{3}.weight(3);
            app.RollRCMigrationWeight.Value = app.Configuration.targets.analysis{3}.weight(4);
            %%% Steering
            %%%% enable
            app.SteeringCamberGainOutCheckBox.Value = app.Configuration.targets.analysis{4}.enable(1);
            app.SteeringCamberGainInCheckBox.Value = app.Configuration.targets.analysis{4}.enable(2);
            app.SteeringTrailCheckBox.Value = app.Configuration.targets.analysis{4}.enable(3);
            app.SteeringScrubCheckBox.Value = app.Configuration.targets.analysis{4}.enable(4);
            app.SteeringAckermanCheckbox.Value = app.Configuration.targets.analysis{4}.enable(5);
            app.SteeringAckermanVariationCheckBox.Value = app.Configuration.targets.analysis{4}.enable(6);
            app.SteeringAckermanLinearityCheckBox.Value = app.Configuration.targets.analysis{4}.enable(7);
            app.SteeringAckermanProgressivenessCheckBox.Value = app.Configuration.targets.analysis{4}.enable(8);
            app.SteeringRangeCheckBox.Value = app.Configuration.targets.analysis{4}.enable(9);
            %%%% value
            app.SteeringCamberOutEditField.Value = app.Configuration.targets.analysis{4}.value(1);
            app.SteeringCamberInEditField.Value = app.Configuration.targets.analysis{4}.value(2);
            app.SteeringAckermanEditField.Value = app.Configuration.targets.analysis{4}.value(3);
            app.SteeringRangeEditField.Value = app.Configuration.targets.analysis{4}.value(4);
            %%%% tolerance
            app.SteeringCamberGainOutTol.Value = app.Configuration.targets.analysis{4}.tol(1);
            app.SteeringCamberGainInTol.Value = app.Configuration.targets.analysis{4}.tol(2);
            app.SteeringTrailTol.Value = app.Configuration.targets.analysis{4}.tol(3);
            app.SteeringScrubTol.Value = app.Configuration.targets.analysis{4}.tol(4);
            app.SteeringAckermanTol.Value = app.Configuration.targets.analysis{4}.tol(5);
            app.SteeringRangeTol.Value = app.Configuration.targets.analysis{4}.tol(6);
            %%%% weight
            app.SteeringCamberGainOutWeight.Value = app.Configuration.targets.analysis{4}.weight(1);
            app.SteeringCamberGainInWeight.Value = app.Configuration.targets.analysis{4}.weight(2);
            app.SteeringTrailWeight.Value = app.Configuration.targets.analysis{4}.weight(3);
            app.SteeringScrubWeight.Value = app.Configuration.targets.analysis{4}.weight(4);
            app.SteeringAckermanWeight.Value = app.Configuration.targets.analysis{4}.weight(5);
            app.SteeringAckermanVariationWeight.Value = app.Configuration.targets.analysis{4}.weight(6);
            app.SteeringAckermanLinearityWeight.Value = app.Configuration.targets.analysis{4}.weight(7);
            app.SteeringAckermanProgressivenessWeight.Value = app.Configuration.targets.analysis{4}.weight(8);
            app.SteeringRangeWeight.Value = app.Configuration.targets.analysis{4}.weight(9);
            % Bounds
            %% Enable
            app.UpperAarmCheckBox.Value = app.Configuration.bounds.enable{1}(1);
            app.LowerAarmCheckBox.Value = app.Configuration.bounds.enable{1}(2);
            app.SteeringBoundsCheckBox.Value = app.Configuration.bounds.enable{1}(3);
            app.UCAforeCheckBox.Value = app.Configuration.bounds.enable{2}(1);
            app.UCAaftCheckBox.Value = app.Configuration.bounds.enable{2}(2);
            app.UCAuprightCheckBox.Value = app.Configuration.bounds.enable{2}(3);
            app.LCAforeCheckBox.Value = app.Configuration.bounds.enable{2}(4);
            app.LCAaftCheckBox.Value = app.Configuration.bounds.enable{2}(5);
            app.LCAuprightCheckBox.Value = app.Configuration.bounds.enable{2}(6);
            app.SteeringRackCheckBox.Value = app.Configuration.bounds.enable{2}(7);
            app.SteeringUprightCheckBox.Value = app.Configuration.bounds.enable{2}(8);
            %% Lower bounds
            app.UCAforeXlower.Value = app.Configuration.bounds.lb(1);
            app.UCAforeYlower.Value = app.Configuration.bounds.lb(2);
            app.UCAforeZlower.Value = app.Configuration.bounds.lb(3);
            app.UCAaftXlower.Value = app.Configuration.bounds.lb(4);
            app.UCAaftYlower.Value = app.Configuration.bounds.lb(5);
            app.UCAaftZlower.Value = app.Configuration.bounds.lb(6);
            app.UCAuprightXlower.Value = app.Configuration.bounds.lb(7);
            app.UCAuprightYlower.Value = app.Configuration.bounds.lb(8);
            app.UCAuprightZlower.Value = app.Configuration.bounds.lb(9);
            app.LCAforeXlower.Value = app.Configuration.bounds.lb(10);
            app.LCAforeYlower.Value = app.Configuration.bounds.lb(11);
            app.LCAforeZlower.Value = app.Configuration.bounds.lb(12);
            app.LCAaftXlower.Value = app.Configuration.bounds.lb(13);
            app.LCAaftYlower.Value = app.Configuration.bounds.lb(14);
            app.LCAaftZlower.Value = app.Configuration.bounds.lb(15);
            app.LCAuprightXlower.Value = app.Configuration.bounds.lb(16);
            app.LCAuprightYlower.Value = app.Configuration.bounds.lb(17);
            app.LCAuprightZlower.Value = app.Configuration.bounds.lb(18);
            app.SteeringRackXlower.Value = app.Configuration.bounds.lb(19);
            app.SteeringRackYlower.Value = app.Configuration.bounds.lb(20);
            app.SteeringRackZlower.Value = app.Configuration.bounds.lb(21);
            app.SteeringUprightXlower.Value = app.Configuration.bounds.lb(22);
            app.SteeringUprightYlower.Value = app.Configuration.bounds.lb(23);
            app.SteeringUprightZlower.Value = app.Configuration.bounds.lb(24);
            %% Upper bounds
            app.UCAforeXupper.Value = app.Configuration.bounds.ub(1);
            app.UCAforeYupper.Value = app.Configuration.bounds.ub(2);
            app.UCAforeZupper.Value = app.Configuration.bounds.ub(3);
            app.UCAaftXupper.Value = app.Configuration.bounds.ub(4);
            app.UCAaftYupper.Value = app.Configuration.bounds.ub(5);
            app.UCAaftZupper.Value = app.Configuration.bounds.ub(6);
            app.UCAuprightXupper.Value = app.Configuration.bounds.ub(7);
            app.UCAuprightYupper.Value = app.Configuration.bounds.ub(8);
            app.UCAuprightZupper.Value = app.Configuration.bounds.ub(9);
            app.LCAforeXupper.Value = app.Configuration.bounds.ub(10);
            app.LCAforeYupper.Value = app.Configuration.bounds.ub(11);
            app.LCAforeZupper.Value = app.Configuration.bounds.ub(12);
            app.LCAaftXupper.Value = app.Configuration.bounds.ub(13);
            app.LCAaftYupper.Value = app.Configuration.bounds.ub(14);
            app.LCAaftZupper.Value = app.Configuration.bounds.ub(15);
            app.LCAuprightXupper.Value = app.Configuration.bounds.ub(16);
            app.LCAuprightYupper.Value = app.Configuration.bounds.ub(17);
            app.LCAuprightZupper.Value = app.Configuration.bounds.ub(18);
            app.SteeringRackXupper.Value = app.Configuration.bounds.ub(19);
            app.SteeringRackYupper.Value = app.Configuration.bounds.ub(20);
            app.SteeringRackZupper.Value = app.Configuration.bounds.ub(21);
            app.SteeringUprightXupper.Value = app.Configuration.bounds.ub(22);
            app.SteeringUprightYupper.Value = app.Configuration.bounds.ub(23);
            app.SteeringUprightZupper.Value = app.Configuration.bounds.ub(24);
            % Settings
            %% Heave
            app.HeaveStep.Value = app.Configuration.settings{1}.step;
            app.HeaveLowerLimit.Value = app.Configuration.settings{1}.limits(1);
            app.HeaveUpperLimit.Value = app.Configuration.settings{1}.limits(2);
            %% Roll
            app.RollStep.Value = app.Configuration.settings{2}.step;
            app.RollLimit.Value = app.Configuration.settings{2}.limits;
            app.RollTol.Value = app.Configuration.settings{2}.tol;
            %% Steering
            app.SteeringStep.Value = app.Configuration.settings{3}.step;
            app.SteeringLowerLimit.Value = app.Configuration.settings{3}.limits(1);
            app.SteeringUpperLimit.Value = app.Configuration.settings{3}.limits(2);
            %% Solver
            app.SolverToleranceEditField.Value = app.Configuration.settings{4}.tol;
            app.LinButton.Value = app.Configuration.settings{5}.lin;
            app.ExpButton.Value = app.Configuration.settings{5}.exp;
            app.PlotOptimProgressCheckBox.Value = app.Configuration.settings{6}.check;
        end
        
        
        function saveConfig(app,path)
            % Targets
            %% Enable
            app.Configuration.targets.enable(1) = app.StaticCheckBox.Value;
            app.Configuration.targets.enable(2) = app.HeaveCheckBox.Value;
            app.Configuration.targets.enable(3) = app.RollCheckBox.Value;
            app.Configuration.targets.enable(4) = app.SteeringCheckBox.Value;
            %% Analysis type
            %%% Static
            %%%% enable
            app.Configuration.targets.analysis{1}.enable(1) = app.StaticRCCheckBox.Value;
            app.Configuration.targets.analysis{1}.enable(2) = app.StaticTrailCheckBox.Value;
            app.Configuration.targets.analysis{1}.enable(3) = app.StaticScrubCheckBox.Value;
            app.Configuration.targets.analysis{1}.enable(4) = app.StaticAntiDiveCheckBox.Value;
            app.Configuration.targets.analysis{1}.enable(5) = app.StaticCasterCheckBox.Value;
            app.Configuration.targets.analysis{1}.enable(6) = app.StaticKPICheckBox.Value;
            %%%% value
            app.Configuration.targets.analysis{1}.value(1) = app.StaticRCEditField.Value;
            app.Configuration.targets.analysis{1}.value(2) = app.StaticTrailEditField.Value;
            app.Configuration.targets.analysis{1}.value(3) = app.StaticScrubEditField.Value;
            app.Configuration.targets.analysis{1}.value(4) = app.StaticAntiDiveEditField.Value;
            app.Configuration.targets.analysis{1}.value(5) = app.StaticCasterEditField.Value;
            app.Configuration.targets.analysis{1}.value(6) = app.StaticKPIEditField.Value;
            %%%% tolerance
            app.Configuration.targets.analysis{1}.tol(1) = app.StaticRCTol.Value;
            app.Configuration.targets.analysis{1}.tol(2) = app.StaticTrailTol.Value;
            app.Configuration.targets.analysis{1}.tol(3) = app.StaticScrubTol.Value;
            app.Configuration.targets.analysis{1}.tol(4) = app.StaticAntiDiveTol.Value;
            app.Configuration.targets.analysis{1}.tol(5) = app.StaticCasterTol.Value;
            app.Configuration.targets.analysis{1}.tol(6) = app.StaticKPITol.Value;
            %%%% weight
            app.Configuration.targets.analysis{1}.weight(1) = app.StaticRCWeight.Value;
            app.Configuration.targets.analysis{1}.weight(2) = app.StaticTrailWeight.Value;
            app.Configuration.targets.analysis{1}.weight(3) = app.StaticScrubWeight.Value;
            app.Configuration.targets.analysis{1}.weight(4) = app.StaticAntiDiveWeight.Value;
            app.Configuration.targets.analysis{1}.weight(5) = app.StaticCasterWeight.Value;
            app.Configuration.targets.analysis{1}.weight(6) = app.StaticKPIWeight.Value;
            %%% Heave
            %%%% enable
            app.Configuration.targets.analysis{2}.enable(1) = app.HeaveCamberGainCheckBox.Value;
            app.Configuration.targets.analysis{2}.enable(2) = app.HeaveBumpSteerCheckBox.Value;
            app.Configuration.targets.analysis{2}.enable(3) = app.HeaveRCCheckBox.Value;
            app.Configuration.targets.analysis{2}.enable(4) = app.HeaveTrailCheckBox.Value;
            app.Configuration.targets.analysis{2}.enable(5) = app.HeaveScrubCheckBox.Value;
            app.Configuration.targets.analysis{2}.enable(6) = app.HeaveAntiDiveCheckBox.Value;
            %%%% value
            app.Configuration.targets.analysis{2}.value(1) = app.HeaveCamberGainEditField.Value;
            %%%% tolerance
            app.Configuration.targets.analysis{2}.tol(1) = app.HeaveCamberGainTol.Value;
            app.Configuration.targets.analysis{2}.tol(2) = app.HeaveBumpSteerTol.Value;
            app.Configuration.targets.analysis{2}.tol(3) = app.HeaveRCTol.Value;
            app.Configuration.targets.analysis{2}.tol(4) = app.HeaveTrailTol.Value;
            app.Configuration.targets.analysis{2}.tol(5) = app.HeaveScrubTol.Value;
            app.Configuration.targets.analysis{2}.tol(6) = app.HeaveAntiDiveTol.Value;
            %%%% weight
            app.Configuration.targets.analysis{2}.weight(1) = app.HeaveCamberGainWeight.Value;
            app.Configuration.targets.analysis{2}.weight(2) = app.HeaveBumpSteerWeight.Value;
            app.Configuration.targets.analysis{2}.weight(3) = app.HeaveRCWeight.Value;
            app.Configuration.targets.analysis{2}.weight(4) = app.HeaveTrailWeight.Value;
            app.Configuration.targets.analysis{2}.weight(5) = app.HeaveScrubWeight.Value;
            app.Configuration.targets.analysis{2}.weight(6) = app.HeaveAntiDiveWeight.Value;
            %%% Roll
            %%%% enable
            app.Configuration.targets.analysis{3}.enable(1) = app.RollCamberOutCheckBox.Value;
            app.Configuration.targets.analysis{3}.enable(2) = app.RollCamberInCheckBox.Value;
            app.Configuration.targets.analysis{3}.enable(3) = app.RollRCHeightCheckBox.Value;
            app.Configuration.targets.analysis{3}.enable(4) = app.RollRCMigrationCheckBox.Value;
            %%%% value
            app.Configuration.targets.analysis{3}.value(1) = app.RollCamberOutEditField.Value;
            app.Configuration.targets.analysis{3}.value(2) = app.RollCamberInEditField.Value;
            %%%% tolerance
            app.Configuration.targets.analysis{3}.tol(1) = app.RollCamberOutTol.Value;
            app.Configuration.targets.analysis{3}.tol(2) = app.RollCamberInTol.Value;
            app.Configuration.targets.analysis{3}.tol(3) = app.RollRCHeightTol.Value;
            app.Configuration.targets.analysis{3}.tol(4) = app.RollRCMigrationTol.Value;
            %%%% weight
            app.Configuration.targets.analysis{3}.weight(1) = app.RollCamberOutWeight.Value;
            app.Configuration.targets.analysis{3}.weight(2) = app.RollCamberInWeight.Value;
            app.Configuration.targets.analysis{3}.weight(3) = app.RollRCHeightWeight.Value;
            app.Configuration.targets.analysis{3}.weight(4) = app.RollRCMigrationWeight.Value;
            %%% Steering
            %%%% enable
            app.Configuration.targets.analysis{4}.enable(1) = app.SteeringCamberGainOutCheckBox.Value;
            app.Configuration.targets.analysis{4}.enable(2) = app.SteeringCamberGainInCheckBox.Value;
            app.Configuration.targets.analysis{4}.enable(3) = app.SteeringTrailCheckBox.Value;
            app.Configuration.targets.analysis{4}.enable(4) = app.SteeringScrubCheckBox.Value;
            app.Configuration.targets.analysis{4}.enable(5) = app.SteeringAckermanCheckbox.Value;
            app.Configuration.targets.analysis{4}.enable(6) = app.SteeringAckermanVariationCheckBox.Value;
            app.Configuration.targets.analysis{4}.enable(7) = app.SteeringAckermanLinearityCheckBox.Value;
            app.Configuration.targets.analysis{4}.enable(8) = app.SteeringAckermanProgressivenessCheckBox.Value;
            app.Configuration.targets.analysis{4}.enable(9) = app.SteeringRangeCheckBox.Value;
            %%%% value
            app.Configuration.targets.analysis{4}.value(1) = app.SteeringCamberOutEditField.Value;
            app.Configuration.targets.analysis{4}.value(2) = app.SteeringCamberInEditField.Value;
            app.Configuration.targets.analysis{4}.value(3) = app.SteeringAckermanEditField.Value;
            app.Configuration.targets.analysis{4}.value(4) = app.SteeringRangeEditField.Value;
            %%%% tolerance
            app.Configuration.targets.analysis{4}.tol(1) = app.SteeringCamberGainOutTol.Value;
            app.Configuration.targets.analysis{4}.tol(2) = app.SteeringCamberGainInTol.Value;
            app.Configuration.targets.analysis{4}.tol(3) = app.SteeringTrailTol.Value;
            app.Configuration.targets.analysis{4}.tol(4) = app.SteeringScrubTol.Value;
            app.Configuration.targets.analysis{4}.tol(5) = app.SteeringAckermanTol.Value;
            app.Configuration.targets.analysis{4}.tol(6) = app.SteeringRangeTol.Value;
            %%%% weight
            app.Configuration.targets.analysis{4}.weight(1) = app.SteeringCamberGainOutWeight.Value;
            app.Configuration.targets.analysis{4}.weight(2) = app.SteeringCamberGainInWeight.Value;
            app.Configuration.targets.analysis{4}.weight(3) = app.SteeringTrailWeight.Value;
            app.Configuration.targets.analysis{4}.weight(4) = app.SteeringScrubWeight.Value;
            app.Configuration.targets.analysis{4}.weight(5) = app.SteeringAckermanWeight.Value;
            app.Configuration.targets.analysis{4}.weight(6) = app.SteeringAckermanVariationWeight.Value;
            app.Configuration.targets.analysis{4}.weight(7) = app.SteeringAckermanLinearityWeight.Value;
            app.Configuration.targets.analysis{4}.weight(8) = app.SteeringAckermanProgressivenessWeight.Value;
            app.Configuration.targets.analysis{4}.weight(9) = app.SteeringRangeWeight.Value;
            % Bounds
            %% Enable
            app.Configuration.bounds.enable{1}(1) = app.UpperAarmCheckBox.Value;
            app.Configuration.bounds.enable{1}(2) = app.LowerAarmCheckBox.Value;
            app.Configuration.bounds.enable{1}(3) = app.SteeringBoundsCheckBox.Value;
            app.Configuration.bounds.enable{2}(1) = app.UCAforeCheckBox.Value;
            app.Configuration.bounds.enable{2}(2) = app.UCAaftCheckBox.Value;
            app.Configuration.bounds.enable{2}(3) = app.UCAuprightCheckBox.Value;
            app.Configuration.bounds.enable{2}(4) = app.LCAforeCheckBox.Value;
            app.Configuration.bounds.enable{2}(5) = app.LCAaftCheckBox.Value;
            app.Configuration.bounds.enable{2}(6) = app.LCAuprightCheckBox.Value;
            app.Configuration.bounds.enable{2}(7) = app.SteeringRackCheckBox.Value;
            app.Configuration.bounds.enable{2}(8) = app.SteeringUprightCheckBox.Value;
            %% Lower bounds
            app.Configuration.bounds.lb(1) = app.UCAforeXlower.Value;
            app.Configuration.bounds.lb(2) = app.UCAforeYlower.Value;
            app.Configuration.bounds.lb(3) = app.UCAforeZlower.Value;
            app.Configuration.bounds.lb(4) = app.UCAaftXlower.Value;
            app.Configuration.bounds.lb(5) = app.UCAaftYlower.Value;
            app.Configuration.bounds.lb(6) = app.UCAaftZlower.Value;
            app.Configuration.bounds.lb(7) = app.UCAuprightXlower.Value;
            app.Configuration.bounds.lb(8) = app.UCAuprightYlower.Value;
            app.Configuration.bounds.lb(9) = app.UCAuprightZlower.Value;
            app.Configuration.bounds.lb(10) = app.LCAforeXlower.Value;
            app.Configuration.bounds.lb(11) = app.LCAforeYlower.Value;
            app.Configuration.bounds.lb(12) = app.LCAforeZlower.Value;
            app.Configuration.bounds.lb(13) = app.LCAaftXlower.Value;
            app.Configuration.bounds.lb(14) = app.LCAaftYlower.Value;
            app.Configuration.bounds.lb(15) = app.LCAaftZlower.Value;
            app.Configuration.bounds.lb(16) = app.LCAuprightXlower.Value;
            app.Configuration.bounds.lb(17) = app.LCAuprightYlower.Value;
            app.Configuration.bounds.lb(18) = app.LCAuprightZlower.Value;
            app.Configuration.bounds.lb(19) = app.SteeringRackXlower.Value;
            app.Configuration.bounds.lb(20) = app.SteeringRackYlower.Value;
            app.Configuration.bounds.lb(21) = app.SteeringRackZlower.Value;
            app.Configuration.bounds.lb(22) = app.SteeringUprightXlower.Value;
            app.Configuration.bounds.lb(23) = app.SteeringUprightYlower.Value;
            app.Configuration.bounds.lb(24) = app.SteeringUprightZlower.Value;
            %% Upper bounds
            app.Configuration.bounds.ub(1) = app.UCAforeXupper.Value;
            app.Configuration.bounds.ub(2) = app.UCAforeYupper.Value;
            app.Configuration.bounds.ub(3) = app.UCAforeZupper.Value;
            app.Configuration.bounds.ub(4) = app.UCAaftXupper.Value;
            app.Configuration.bounds.ub(5) = app.UCAaftYupper.Value;
            app.Configuration.bounds.ub(6) = app.UCAaftZupper.Value;
            app.Configuration.bounds.ub(7) = app.UCAuprightXupper.Value;
            app.Configuration.bounds.ub(8) = app.UCAuprightYupper.Value;
            app.Configuration.bounds.ub(9) = app.UCAuprightZupper.Value;
            app.Configuration.bounds.ub(10) = app.LCAforeXupper.Value;
            app.Configuration.bounds.ub(11) = app.LCAforeYupper.Value;
            app.Configuration.bounds.ub(12) = app.LCAforeZupper.Value;
            app.Configuration.bounds.ub(13) = app.LCAaftXupper.Value;
            app.Configuration.bounds.ub(14) = app.LCAaftYupper.Value;
            app.Configuration.bounds.ub(15) = app.LCAaftZupper.Value;
            app.Configuration.bounds.ub(16) = app.LCAuprightXupper.Value;
            app.Configuration.bounds.ub(17) = app.LCAuprightYupper.Value;
            app.Configuration.bounds.ub(18) = app.LCAuprightZupper.Value;
            app.Configuration.bounds.ub(19) = app.SteeringRackXupper.Value;
            app.Configuration.bounds.ub(20) = app.SteeringRackYupper.Value;
            app.Configuration.bounds.ub(21) = app.SteeringRackZupper.Value;
            app.Configuration.bounds.ub(22) = app.SteeringUprightXupper.Value;
            app.Configuration.bounds.ub(23) = app.SteeringUprightYupper.Value;
            app.Configuration.bounds.ub(24) = app.SteeringUprightZupper.Value;
            % Settings
            %% Heave
            app.Configuration.settings{1}.step = app.HeaveStep.Value;
            app.Configuration.settings{1}.limits(1) = app.HeaveLowerLimit.Value;
            app.Configuration.settings{1}.limits(2) = app.HeaveUpperLimit.Value;
            %% Roll
            app.Configuration.settings{2}.step = app.RollStep.Value;
            app.Configuration.settings{2}.limits = app.RollLimit.Value;
            app.Configuration.settings{2}.tol = app.RollTol.Value;
            %% Steering
            app.Configuration.settings{3}.step = app.SteeringStep.Value;
            app.Configuration.settings{3}.limits(1) = app.SteeringLowerLimit.Value;
            app.Configuration.settings{3}.limits(2) = app.SteeringUpperLimit.Value;
            %% Solver
            app.Configuration.settings{4}.tol = app.SolverToleranceEditField.Value;
            app.Configuration.settings{5}.lin = app.LinButton.Value;
            app.Configuration.settings{5}.exp = app.ExpButton.Value;
            app.Configuration.settings{6}.check = app.PlotOptimProgressCheckBox.Value;
            
            OptimConfig = app.Configuration;
            if exist('path','var')
                save(path,'OptimConfig');
            else
                save(fullfile('AppData','AarmsOptimConfig.mat'),'OptimConfig');
            end
        end
        
        function loadParams(app)
            for i=1:4
                if ~isempty(app.Analysis{i}.Xaxis.Value)
                    switch i
                        case 1 % Parallel
                            % Static values:
                            staticIndex = find(abs(app.Analysis{i}.Xaxis.Value) == min(abs(app.Analysis{i}.Xaxis.Value)),1);
                            app.StaticRCEditField.Value = app.Analysis{i}.Parameter{7}.Value(staticIndex);
                            app.StaticTrailEditField.Value = app.Analysis{i}.Parameter{5}.Value(staticIndex);
                            app.StaticScrubEditField.Value = app.Analysis{i}.Parameter{6}.Value(staticIndex);
                            app.StaticAntiDiveEditField.Value = app.Analysis{i}.Parameter{9}.Value(staticIndex);
                            app.StaticCasterEditField.Value = app.Analysis{i}.Parameter{4}.Value(staticIndex);
                            app.StaticKPIEditField.Value = app.Analysis{i}.Parameter{3}.Value(staticIndex);
                            % Heave values:
                            app.HeaveCamberGainEditField.Value = max(app.Analysis{i}.Parameter{1}.Value) - min(app.Analysis{i}.Parameter{1}.Value);
                            %% Tolerances
                            app.HeaveBumpSteerTol.Value = (max(app.Analysis{i}.Parameter{2}.Value) - min(app.Analysis{i}.Parameter{2}.Value))/2;
                            app.HeaveRCTol.Value = (max(app.Analysis{i}.Parameter{7}.Value) - min(app.Analysis{i}.Parameter{7}.Value))/2;
                            app.HeaveTrailTol.Value = (max(app.Analysis{i}.Parameter{5}.Value) - min(app.Analysis{i}.Parameter{5}.Value))/2;
                            app.HeaveScrubTol.Value = (max(app.Analysis{i}.Parameter{6}.Value) - min(app.Analysis{i}.Parameter{6}.Value))/2;
                            app.HeaveAntiDiveTol.Value = (max(app.Analysis{i}.Parameter{9}.Value) - min(app.Analysis{i}.Parameter{9}.Value))/2;
                        case 3 % Roll
                            % Roll values
                            staticIndex = find(abs(app.Analysis{i}.Xaxis.Value) == min(abs(app.Analysis{i}.Xaxis.Value)),1);
                            app.RollCamberOutEditField.Value = app.Analysis{i}.Parameter{1}.Value(1) - app.Analysis{i}.Parameter{1}.Value(staticIndex);
                            app.RollCamberInEditField.Value = app.Analysis{i}.Parameter{1}.Value(staticIndex) - app.Analysis{i}.Parameter{1}.Value(end);
                            %% Tolerances
                            app.RollRCHeightTol.Value = (max(app.Analysis{i}.Parameter{7}.Value) - min(app.Analysis{i}.Parameter{7}.Value))/2;
                            app.RollRCMigrationTol.Value = (max(app.Analysis{i}.Parameter{8}.Value) - min(app.Analysis{i}.Parameter{8}.Value))/2;
                        case 4 % Steering
                            % Steering values
                            staticIndex = find(abs(app.Analysis{i}.Xaxis.Value) == min(abs(app.Analysis{i}.Xaxis.Value)),1);
                            app.SteeringCamberOutEditField.Value = app.Analysis{i}.Parameter{1}.Value(staticIndex) - app.Analysis{i}.Parameter{1}.Value(1);
                            app.SteeringCamberInEditField.Value = app.Analysis{i}.Parameter{1}.Value(staticIndex) - app.Analysis{i}.Parameter{1}.Value(end);
                            app.SteeringAckermanEditField.Value = mean(app.Analysis{i}.Parameter{10}.Value(~isnan(app.Analysis{i}.Parameter{10}.Value)));
                            app.SteeringRangeEditField.Value = max(app.Analysis{i}.Parameter{2}.Value) - min(app.Analysis{i}.Parameter{2}.Value);
                            %% Tolerances
                            app.SteeringTrailTol.Value = (max(app.Analysis{i}.Parameter{5}.Value) - min(app.Analysis{i}.Parameter{5}.Value))/2;
                            app.SteeringScrubTol.Value = (max(app.Analysis{i}.Parameter{6}.Value) - min(app.Analysis{i}.Parameter{6}.Value))/2;
                    end
                else
                    switch i
                        case 1 % Parallel
                            % Static values:
                            app.StaticRCEditField.Value = 0;
                            app.StaticTrailEditField.Value = 0;
                            app.StaticScrubEditField.Value = 0;
                            app.StaticAntiDiveEditField.Value = 0;
                            app.StaticCasterEditField.Value = 0;
                            app.StaticKPIEditField.Value = 0;
                            % Heave values:
                            app.HeaveCamberGainEditField.Value = 0;
                            %% Tolerances
                            app.HeaveBumpSteerTol.Value = 0.1;
                            app.HeaveRCTol.Value = 1;
                            app.HeaveTrailTol.Value = 1;
                            app.HeaveScrubTol.Value = 1;
                            app.HeaveAntiDiveTol.Value = 1;
                        case 3 % Roll
                            % Roll values
                            app.RollCamberOutEditField.Value = 0;
                            app.RollCamberInEditField.Value = 0;
                            %% Tolerances
                            app.RollRCHeightTol.Value = 1;
                            app.RollRCMigrationTol.Value = 10;
                        case 4 % Steering
                            % Steering values
                            app.SteeringCamberOutEditField.Value = 0;
                            app.SteeringCamberInEditField.Value = 0;
                            app.SteeringAckermanEditField.Value = 0;
                            app.SteeringRangeEditField.Value = 0;
                            %% Tolerances
                            app.SteeringTrailTol.Value = 1;
                            app.SteeringScrubTol.Value = 1;
                    end
                end
            end
        end
        
        function OptimData = performAnalysis(app,OptimData)
            
            %Perform analysis
            try
                OptimData.Analysis = {};
                for k = 1:length(app.AnalysisList.ID)
                    OptimData.Analysis{k}.X = [];
                    OptimData.Analysis{k}.X_R = [];
                    OptimData.Analysis{k}.Xaxis.Value = [];
                    for p = 1:length(app.ParametersList)
                        OptimData.Analysis{k}.Parameter{p}.Value = [];
                    end
                    switch k
                        case 1 % Heave
                            % Suspension coordinates
                            [OptimData.Analysis{k}.X, OptimData.Analysis{k}.Xaxis.Value] = parallel(OptimData.X, app.Configuration.settings{1}.step, app.Configuration.settings{1}.limits);
                            OptimData.Analysis{k}.X_R = symSuspensionPoints(OptimData.Analysis{k}.X);
                        case 2 % Roll
                            % Suspension coordinates
                            [OptimData.Analysis{k}.X, OptimData.Analysis{k}.X_R, OptimData.Analysis{k}.Xaxis.Value] = roll(OptimData.X, app.Dataset.TireRadius, app.Configuration.settings{2}.step, app.Configuration.settings{2}.limits, app.Configuration.settings{2}.tol);
                        case 3 % Steering
                            % Suspension coordinates
                            [OptimData.Analysis{k}.X, OptimData.Analysis{k}.X_R, OptimData.Analysis{k}.Xaxis.Value] = steering(OptimData.X, app.Configuration.settings{3}.step, app.Configuration.settings{3}.limits);
                            % Ackerman
                            OptimData.Analysis{k}.Parameter{10}.Value = ackerman(OptimData.Analysis{k}.X, OptimData.Analysis{k}.X_R, app.Dataset.VehicleParams.WheelBase, app.Dataset.VehicleParams.Track, OptimData.Analysis{k}.Xaxis.Value);
                    end
                    % Parameters that are common to every analysis are calculated here
                    OptimData.Analysis{k}.Parameter{1}.Value = camber(OptimData.Analysis{k}.X, OptimData.Analysis{k}.Xaxis.Value);      %Camber
                    OptimData.Analysis{k}.Parameter{2}.Value = toe(OptimData.Analysis{k}.X, OptimData.Analysis{k}.Xaxis.Value);            %Toe
                    OptimData.Analysis{k}.Parameter{3}.Value = kpi(OptimData.Analysis{k}.X, OptimData.Analysis{k}.Xaxis.Value);            %KPI
                    OptimData.Analysis{k}.Parameter{4}.Value = caster(OptimData.Analysis{k}.X, OptimData.Analysis{k}.Xaxis.Value);      %Caster
                    OptimData.Analysis{k}.Parameter{5}.Value = trail(OptimData.Analysis{k}.X, app.Dataset.TireRadius, OptimData.Analysis{k}.Xaxis.Value);        %Trail
                    OptimData.Analysis{k}.Parameter{6}.Value = scrub(OptimData.Analysis{k}.X, app.Dataset.TireRadius, OptimData.Analysis{k}.Xaxis.Value);        %Scrub
                    RC = rollCenter(OptimData.Analysis{k}.X, OptimData.Analysis{k}.X_R, app.Dataset.TireRadius, OptimData.Analysis{k}.Xaxis.Value); %RC
                    height = squeeze(RC(3,:,:)); %RC Height
                    if isrow(height)
                        OptimData.Analysis{k}.Parameter{7}.Value(:,:) = height';
                    else
                        OptimData.Analysis{k}.Parameter{7}.Value(:,:) = height;
                    end
                    mig = squeeze(RC(2,:,:)); %RC Migration
                    if isrow(height)
                        OptimData.Analysis{k}.Parameter{8}.Value(:,:) = mig';
                    else
                        OptimData.Analysis{k}.Parameter{8}.Value(:,:) = mig;
                    end
                    OptimData.Analysis{k}.Parameter{9}.Value = antiDive(OptimData.Analysis{k}.X, app.Dataset.TireRadius, [app.Dataset.VehicleParams.CGX 0 app.Dataset.VehicleParams.CGZ], OptimData.Analysis{k}.Xaxis.Value);%Anti-dive
                 end
            catch err
                uialert(app.UIFigure,['performAnalysis error. ' err.message],'Error');
                %Clear Xaxis/Yaxis value to know that analysis was not performed correctly
                for k = 1:length(app.AnalysisList.ID)
                    OptimData.Analysis{k}.Xaxis.Value = [];
                    OptimData.Analysis{k}.Yaxis.Value = [];
                end
            end
        end
        
        function addResult(app,OptimData)
            try
                s = length(app.SessionData)+1;
                if s > 1
                    if isfield(OptimData,'ID') && ~isempty(OptimData.ID)
                        OptimData.ID = ['Test ' num2str(s-1) ' - ' OptimData.ID];
                    else
                        OptimData.ID = ['Test ' num2str(s-1)];
                    end
                end
                OptimData = performAnalysis(app,OptimData);
                app.SessionData{s} = OptimData;
                
                % Add data to results list
                children = app.ResultsList.Children;
                checked = false(1,s);
                checked(end) = true;
                j = 1;
                for i=1:length(children)
                    if strcmp(children(i).Type,'uicheckbox')
                        if children(i).Value
                            checked(end-j) = true;
                        end
                        j = j+1;
                    end
                    delete(children(i));
                end
                for i=1:s
                    if s ==1
                        zPos = 125;
                    elseif s < app.ResultsList.Position(4)/20
                        zPos = app.ResultsList.Position(4)-25-(i-1)*20;
                    else
                        zPos = 20*(s-i)+5;
                    end
                    uicheckbox(app.ResultsList, ...
                        'Text',app.SessionData{i}.ID, ...
                        'Value',checked(i), ...
                        'Position', [10 zPos 400 20], ...
                        'ValueChangedFcn', @app.resultCheckBoxCB);
                end
            catch err
                uialert(app.UIFigure,['addResult error. Data could not be stored. ' err.message],'Error');
                app.SessionData(s) = [];
            end
        end
        
        function resultCheckBoxCB(app,~,~)
            updateResultsPlot(app)
        end
        
        function updateResultsPlot(app)
            index = false(1,length(app.ResultsList.Children));
            for i=1:length(app.ResultsList.Children)
                if strcmp(app.ResultsList.Children(i).Type,'uicheckbox')
                    index(i) = true;
                end
            end
            children = app.ResultsList.Children(index);
            children = flip(children);
            ax = app.ResultAxes;
            cla(ax);
            hold(ax,'on');
            legend(ax);
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            p = strcmp(app.ParametersList,app.ParameterSelectDropDown.Value);
            j = 1;
            for i=1:length(children)
                if children(i).Value
                    % Plot stuff
                    val = app.SessionData{i}.Analysis{a}.Parameter{p}.Value;
                    if ~isempty(val)
                        plot(ax, app.SessionData{i}.Analysis{a}.Xaxis.Value,val);
                    end
                    ax.Legend.String{j} = app.SessionData{i}.ID;
                    j = j+1;
                end
            end
            ax.XLabel.String = app.AnalysisList.Xaxis{a};
            ax.YLabel.String = app.ParametersList{p};
        end
        
        function lb = optimLB(app)
            bEn = optimBoundsEnable(app);
            lb = app.Configuration.bounds.lb(logical(bEn));
        end
        
        function ub = optimUB(app)
            bEn = optimBoundsEnable(app);
            ub = app.Configuration.bounds.ub(logical(bEn));
        end
        
        function bEn = optimBoundsEnable(app)
            bEn = [app.Configuration.bounds.enable{1}(1)*app.Configuration.bounds.enable{2}(1)*true(1,3) ...
                app.Configuration.bounds.enable{1}(1)*app.Configuration.bounds.enable{2}(2)*true(1,3) ...
                app.Configuration.bounds.enable{1}(2)*app.Configuration.bounds.enable{2}(4)*true(1,3) ...
                app.Configuration.bounds.enable{1}(2)*app.Configuration.bounds.enable{2}(5)*true(1,3) ...
                app.Configuration.bounds.enable{1}(1)*app.Configuration.bounds.enable{2}(3)*true(1,3) ...
                app.Configuration.bounds.enable{1}(2)*app.Configuration.bounds.enable{2}(6)*true(1,3) ...
                app.Configuration.bounds.enable{1}(3)*app.Configuration.bounds.enable{2}(7)*true(1,3) ...
                app.Configuration.bounds.enable{1}(3)*app.Configuration.bounds.enable{2}(8)*true(1,3)];
            for i=1:24
                if ~app.Configuration.bounds.lb(i) && ~app.Configuration.bounds.ub(i)
                    bEn(i) = false;
                end
            end
        end
        
        function runOptim(app)
            try
                optimProgressUI = uiprogressdlg(app.UIFigure,'Title','Optimization in progress','Indeterminate','on');
                saveConfig(app)
                % Optimization parameters
                %% Suspension Data
                data = app.Dataset;
                %% Bounds for optimization variable
                lb = optimLB(app);
                ub = optimUB(app);
                %% Number of variables to optimize
                bEn = optimBoundsEnable(app);
                nvars = sum(bEn);
                %% Equality/Inequality constraints
                A = [];
                b = [];
                Aeq = [];
                beq = [];
                % Targets
                % Weights of objective function. Weight of each parameter used to define objective function.
                targets = app.Configuration.targets;
                % Objective function handle
                fun = @(X)app.X_ofun(X, data, bEn, targets, app.Configuration);
                if app.Configuration.settings{6}.check
                    options = optimoptions('ga','FunctionTolerance',app.Configuration.settings{4}.tol,'PlotFcn',@gaplotbestf);
                else
                    options = optimoptions('ga','FunctionTolerance',app.Configuration.settings{4}.tol);
                end
                % RUN OPTIMIZATION %
                tic;
                X_ = ga(fun,nvars,A,b,Aeq,beq,lb,ub,[],options);
                elapsedTime = toc;
                %% Calculate actual coordinates from optimzation results
                X = zeros(1,30);
                j = 1;
                for i=1:length(bEn)
                    if bEn(i)
                        X(i) = data.X(i) + X_(j);
                        j=j+1;
                    else
                        X(i) = data.X(i);
                    end
                end
                X(25:30) = data.X(25:30);
                if length(data.X) == 45
                    X(31:45) = data.X(31:45);
                end
                X = X(:);
                % ---------------- %
                close(optimProgressUI);
                selection = uiconfirm(app.UIFigure, ...
                    {'Optimization completed', ['Elapsed time: ' num2str(elapsedTime)]},...
                    'Add result', ...
                    'Options',{'Add' 'Dismiss'}, ...
                    'CancelOption',2, ...
                    'DefaultOption','Add');
                switch selection
                    case 'Dismiss'
                        return;
                    case 'Add'
                        optimData.X = X;
                        optimData.ID = app.Dataset.ID;
                        optimData.Targets = targets;
                        optimData.Bounds.lb = lb;
                        optimData.Bounds.ub = ub;
                        optimData.Bounds.bEn = bEn;
                        addResult(app,optimData)
                        updateResultsPlot(app)
                end
            catch err
                close(optimProgressUI);
                uialert(app.UIFigure,['runOptim error. Optimization failed. ' err.message], 'Error');
            end
        end
        
        function val = X_ofun(~, X_, dataset, bEn, targets, config)
            j = 1;
            X = zeros(1,30);
            for i=1:length(bEn)
                if bEn(i)
                    X(i) = dataset.X(i) + X_(j);
                    j=j+1;
                else
                    X(i) = dataset.X(i);
                end
            end
            X(25:30) = dataset.X(25:30);
            X = X';
            % Symmetric points
            X_R = X.*[1 -1 1 1 -1 1 1 -1 1 1 -1 1 1 -1 1 1 -1 1 1 -1 1 1 -1 1 1 -1 1 1 -1 1]';
            CG = [dataset.VehicleParams.CGX 0 dataset.VehicleParams.CGZ];
            if targets.enable(1) % Static targets
                if targets.analysis{1}.enable(1) % RC height
                    param = rollCenter(X, X_R, dataset.TireRadius);
                    if not(all(param(3) < targets.analysis{1}.value(1)+targets.analysis{1}.tol(1))*all(param(3) > targets.analysis{1}.value(1)-targets.analysis{1}.tol(1)))
                        if config.settings{5}.lin
                            staticRC = abs((param(3) - targets.analysis{1}.value(1))/(1+abs(targets.analysis{1}.value(1))))*targets.analysis{1}.weight(1);
                        elseif config.settings{5}.exp
                            staticRC = (config.settings{5}.expBase*targets.analysis{1}.weight(1)).^(abs((param(3)-targets.analysis{1}.value(1))/targets.analysis{1}.value(1)));
                        end
                    else
                        staticRC = 0;
                    end
                else
                    staticRC = 0;
                end
                if targets.analysis{1}.enable(2) % Trail
                    param = trail(X, dataset.TireRadius);
                    if not(all(param < targets.analysis{1}.value(2)+targets.analysis{1}.tol(2))*all(param > targets.analysis{1}.value(2)-targets.analysis{1}.tol(2)))
                        if config.settings{5}.lin
                            staticTrail = abs((param - targets.analysis{1}.value(2))/(1+abs(targets.analysis{1}.value(2))))*targets.analysis{1}.weight(2);
                        elseif config.settings{5}.exp
                            staticTrail = (config.settings{5}.expBase*targets.analysis{1}.weight(2)).^(abs((param - targets.analysis{1}.value(2))/(1+abs(targets.analysis{1}.value(2)))));
                        end
                    else
                        staticTrail = 0;
                    end
                else
                    staticTrail = 0;
                end
                if targets.analysis{1}.enable(3) % Scrub
                    param = scrub(X, dataset.TireRadius);
                    if not(all(param < targets.analysis{1}.value(3)+targets.analysis{1}.tol(3))*all(param > targets.analysis{1}.value(3)-targets.analysis{1}.tol(3)))
                        if config.settings{5}.lin
                            staticScrub = abs((param - targets.analysis{1}.value(3))/(1+abs(targets.analysis{1}.value(3))))*targets.analysis{1}.weight(3);
                        elseif config.settings{5}.exp
                            staticScrub = (config.settings{5}.expBase*targets.analysis{1}.weight(3)).^(abs((param - targets.analysis{1}.value(3))/(1+abs(targets.analysis{1}.value(3)))));
                        end 
                    else
                        staticScrub = 0;
                    end
                else
                    staticScrub = 0;
                end
                if targets.analysis{1}.enable(4) %Anti-Dive
                    param = antiDive(X, dataset.TireRadius, CG);
                    if not(all(param < targets.analysis{1}.value(4)+targets.analysis{1}.tol(4))*all(param > targets.analysis{1}.value(4)-targets.analysis{1}.tol(4)))
                        if config.settings{5}.lin
                            staticAntiDive = abs((param - targets.analysis{1}.value(4))/(1+abs(targets.analysis{1}.value(4))))*targets.analysis{1}.weight(4);
                        elseif config.settings{5}.exp
                            staticAntiDive = (config.settings{5}.expBase*targets.analysis{1}.weight(4)).^(abs((param - targets.analysis{1}.value(4))/(1+abs(targets.analysis{1}.value(4)))));
                        end
                    else
                        staticAntiDive = 0;
                    end
                else
                    staticAntiDive = 0;
                end
                if targets.analysis{1}.enable(5) %Caster
                    param = caster(X);
                    if not(all(param < targets.analysis{1}.value(5)+targets.analysis{1}.tol(5))*all(param > targets.analysis{1}.value(5)-targets.analysis{1}.tol(5)))
                        if config.settings{5}.lin
                            staticCaster = abs((param - targets.analysis{1}.value(5))/(1+abs(targets.analysis{1}.value(5))))*targets.analysis{1}.weight(5);
                        elseif config.settings{5}.exp
                            staticCaster = (config.settings{5}.expBase*targets.analysis{1}.weight(5)).^(abs((param - targets.analysis{1}.value(5))/(1+abs(targets.analysis{1}.value(5)))));
                        end
                    else 
                        staticCaster = 0;
                    end
                else
                    staticCaster = 0;
                end
                if targets.analysis{1}.enable(6) %KPI
                    param = kpi(X);
                    if not(all(param < targets.analysis{1}.value(6)+targets.analysis{1}.tol(6))*all(param > targets.analysis{1}.value(6)-targets.analysis{1}.tol(6))) 
                        if config.settings{5}.lin
                            staticKPI = abs((param - targets.analysis{1}.value(6))/(1+abs(targets.analysis{1}.value(6))))*targets.analysis{1}.weight(6);
                        elseif config.settings{5}.exp
                            staticKPI = (config.settings{5}.expBase*targets.analysis{1}.weight(6)).^(abs((param - targets.analysis{1}.value(6))/(1+abs(targets.analysis{1}.value(6)))));
                        end
                    else
                        staticKPI = 0;
                    end
                else
                    staticKPI = 0;
                end
                staticVal = staticRC + staticTrail + staticScrub + staticAntiDive + staticCaster + staticKPI;
            else
                staticVal = 0;
            end
            if targets.enable(2) % Heave targets
                try
                    [X_heave, wheelTravel_heave] = parallel(X, config.settings{1}.step, config.settings{1}.limits);
                    X_R_heave = symSuspensionPoints(X_heave);
                    if targets.analysis{2}.enable(1) % Camber gain
                        param = camber(X_heave, wheelTravel_heave);
                        param = max(param)-min(param);
                        if not(all(param < targets.analysis{2}.value(1)+targets.analysis{2}.tol(1))*all(param > targets.analysis{2}.value(1)-targets.analysis{2}.tol(1))) 
                            if config.settings{5}.lin
                                heaveCamberGain = abs((param - targets.analysis{2}.value(1))/(1+abs(targets.analysis{2}.value(1)))) * targets.analysis{2}.weight(1);
                            elseif config.settings{5}.exp
                                heaveCamberGain = (config.settings{5}.expBase*targets.analysis{2}.weight(1)).^(abs((param - targets.analysis{2}.value(1))/(1+abs(targets.analysis{2}.value(1)))));
                            end
                        else
                            heaveCamberGain = 0;
                        end
                    else
                        heaveCamberGain = 0;
                    end
                    if targets.analysis{2}.enable(2) % Bump-steer
                        param = toe(X_heave, wheelTravel_heave);
                        var = max(param)-min(param);
                        if var > targets.analysis{2}.tol(2)
                            param = var/(1+abs(mean(~isnan(param))));
                            if config.settings{5}.lin
                                heaveToe = param*targets.analysis{2}.weight(2);
                            elseif config.settings{5}.exp
                                heaveToe = (config.settings{5}.expBase*targets.analysis{2}.weight(2)).^(param);
                            end
                        else
                            heaveToe = 0;
                        end
                    else
                        heaveToe = 0;
                    end
                    if targets.analysis{2}.enable(3) % RC height variation
                        param = rollCenter(X_heave, X_R_heave, dataset.TireRadius, wheelTravel_heave);
                        param = squeeze(param(3,:)); %RC Height
                        var = max(param)-min(param);
                        if var > targets.analysis{2}.tol(3) 
                            param = var/(1+abs(mean(~isnan(param))));
                            if config.settings{5}.lin
                                heaveRC = param*targets.analysis{2}.weight(3);
                            elseif config.settings{5}.exp
                                heaveRC = (config.settings{5}.expBase*targets.analysis{2}.weight(3)).^(param);
                            end
                        else
                            heaveRC = 0;
                        end
                    else
                        heaveRC = 0;
                    end
                    if targets.analysis{2}.enable(4) % Trail variation
                        param = trail(X_heave, dataset.TireRadius, wheelTravel_heave);
                        var = max(param)-min(param);
                        if var > targets.analysis{2}.tol(4)
                            param = var/(1+abs(mean(~isnan(param))));
                            if config.settings{5}.lin
                                heaveTrail = param*targets.analysis{2}.weight(4);
                            elseif config.settings{5}.exp
                                heaveTrail = (config.settings{5}.expBase*targets.analysis{2}.weight(4)).^(param);
                            end
                        else
                            heaveTrail = 0;
                        end
                    else
                        heaveTrail = 0;
                    end
                    if targets.analysis{2}.enable(5) % Scrub variation
                        param = scrub(X_heave, dataset.TireRadius, wheelTravel_heave);
                        var = max(param)-min(param);
                        if var > targets.analysis{2}.tol(5) 
                            param = var/(1+abs(mean(~isnan(param))));
                            if config.settings{5}.lin
                                heaveScrub = param*targets.analysis{2}.weight(5);
                            elseif config.settings{5}.exp
                                heaveScrub = (config.settings{5}.expBase*targets.analysis{2}.weight(5)).^(param);
                            end
                        else
                            heaveScrub = 0;
                        end
                    else
                        heaveScrub = 0;
                    end
                    if targets.analysis{2}.enable(6) % Anti-dive variation
                        param = antiDive(X_heave,dataset.TireRadius, CG, wheelTravel_heave);
                        var = max(param)-min(param);
                        if var > targets.analysis{2}.tol(6)
                            param = var/(1+abs(mean(~isnan(param))));
                            if config.settings{5}.lin
                                heaveAntiDive = param*targets.analysis{2}.weight(6);
                            elseif config.settings{5}.exp
                                heaveAntiDive = (config.settings{5}.expBase*targets.analysis{2}.weight(6)).^(param);
                            end
                        else
                            heaveAntiDive = 0;
                        end
                    else
                        heaveAntiDive = 0;
                    end
                    heaveVal = heaveCamberGain + heaveToe + heaveRC + heaveTrail + heaveScrub + heaveAntiDive;
                catch err
                    disp(err)
                    val = inf;
                    return;
                end
            else
                heaveVal = 0;
            end
            if targets.enable(3) % Roll targets
                try
                    [X_roll, X_R_roll, rollAngle, ~] = roll(X, dataset.TireRadius, config.settings{2}.step, config.settings{2}.limits, config.settings{2}.tol);
                    if targets.analysis{3}.enable(1) % Camber gain on outside wheel
                        param = camber(X_roll, rollAngle);
                        i = find(min(abs(rollAngle) == abs(rollAngle)));
                        var = max(param(1:i))-min(param(1:i));
                        %% Para ajustar al valor objetivo utilizar esta parte %%
                         if not(all(var < targets.analysis{3}.value(1)+targets.analysis{3}.tol(1))*all(var > targets.analysis{3}.value(1)-targets.analysis{3}.tol(1))) 
                         param = abs((var-targets.analysis{3}.value(1))/(1+abs(targets.analysis{3}.value(1))));
                        %% -------------------------------------------------- %%
                        
                        %% Para minimizar variación utilizar esta parte %%
%                          if var > targets.analysis{3}.tol(1)
%                          param = var/(1+abs(mean(~isnan(param))));
                        %% -------------------------------------------------- %%
                            if config.settings{5}.lin
                                 rollCamberOut = param* targets.analysis{3}.weight(1);
                            elseif config.settings{5}.exp
                                rollCamberOut = (config.settings{5}.expBase*targets.analysis{3}.weight(1)).^(param);
                            end
                        else
                            rollCamberOut = 0;
                        end
                    else
                        rollCamberOut = 0;
                    end
                    if targets.analysis{3}.enable(2) % Camber gain on inside wheel
                        if ~targets.analysis{3}.enable(1)
                            param = camber(X_roll, rollAngle);
                            i = find(min(abs(rollAngle) == abs(rollAngle)));
                        end
                        var = max(param(i:end))-min(param(i:end));
                        %% Para ajustar al valor objetivo utilizar esta parte %%
                         if not(all(var < targets.analysis{3}.value(2)+targets.analysis{3}.tol(2))*all(var > targets.analysis{3}.value(2)-targets.analysis{3}.tol(2))) 
                            param = abs((var-targets.analysis{3}.value(2))/(1+abs(targets.analysis{3}.value(2))));
                        %% -------------------------------------------------- %%
                        
                        %% Para minimizar variación utilizar esta parte %%
%                        if var > targets.analysis{3}.tol(1)
%                           param = var/(1+abs(mean(~isnan(param))));
                        %% -------------------------------------------------- %%
                            if config.settings{5}.lin
                                rollCamberIn = param * targets.analysis{3}.weight(2);
                            elseif config.settings{5}.exp
                                rollCamberIn = (config.settings{5}.expBase*targets.analysis{3}.weight(2)).^(param);
                            end
                        else
                            rollCamberIn = 0;
                        end
                    else
                        rollCamberIn = 0;
                    end
                    if targets.analysis{3}.enable(3) % RC height variation
                        rollRC = rollCenter(X_roll, X_R_roll, dataset.TireRadius, rollAngle);
                        param = squeeze(rollRC(3,:));
                        var = max(param) - min(param);
                        if var > targets.analysis{3}.tol(3)
                            param = var/(1+abs(mean(~isnan(param))));
                            if config.settings{5}.lin
                                rollRCheight = param*targets.analysis{3}.weight(3);
                            elseif config.settings{5}.exp
                                rollRCheight = (config.settings{5}.expBase*targets.analysis{3}.weight(3)).^(param);
                            end
                        else
                            rollRCheight = 0;
                        end
                    else
                        rollRCheight = 0;
                    end
                    if targets.analysis{3}.enable(4) % RC migration
                        if ~targets.analysis{3}.enable(3)
                            rollRC = rollCenter(X_roll, X_R_roll, dataset.TireRadius, rollAngle);
                        end
                        param = squeeze(rollRC(2,:));
                        var = max(param) - min(param);
                        if var > targets.analysis{3}.tol(4)
                            param = var/(1+abs(mean(~isnan(param))));
                            if config.settings{5}.lin
                                rollRCmig = param*targets.analysis{3}.weight(4);
                            elseif config.settings{5}.exp
                                rollRCmig = (config.settings{5}.expBase*targets.analysis{3}.weight(4)).^(param);
                            end
                        else
                            rollRCmig = 0;
                        end
                    else
                        rollRCmig = 0;
                    end
                    rollVal = rollCamberOut + rollCamberIn + rollRCheight + rollRCmig;
                catch err
                    disp(err)
                    val = inf;
                    return;
                end
            else
                rollVal = 0;
            end
            if targets.enable(4) % Steering targets
                try
                    [X_steer, X_R_steer, steerDisp] = steering(X, config.settings{3}.step, config.settings{3}.limits);
                    if targets.analysis{4}.enable(1) % Camber gain outside wheel
                        if dataset.X(22) > dataset.X(16) % If tie-rod is in front of kpi axis
                            steerCamber = camber(X_steer, steerDisp); % Camber of left wheel. Left wheels is the outside wheel when steerDisp is negative
                        else
                            steerCamber = camber(X_R_steer, steerDisp); % Camber of right wheel. Right wheels is the outside wheel when steerDisp is negative
                        end
                        i = find(min(abs(steerDisp) == abs(steerDisp)));
                        var = max(steerCamber(1:i))-min(steerCamber(1:i));
                        if var > targets.analysis{4}.tol(1)
                            param = abs((var-targets.analysis{4}.value(1))/(1+abs(targets.analysis{4}.value(1))));
                            if config.settings{5}.lin
                                steerCamberOut = param* targets.analysis{4}.weight(1);
                            elseif config.settings{5}.exp
                                steerCamberOut = (config.settings{5}.expBase*targets.analysis{4}.weight(1)).^(param);
                            end
                        else
                            steerCamberOut = 0;
                        end
                    else
                        steerCamberOut = 0;
                    end
                    if targets.analysis{4}.enable(2) % Camber gain inside wheel
                        if ~targets.analysis{4}.enable(1)
                            if dataset.X(22) > dataset.X(16) % If tie-rod is in front of kpi axis
                                steerCamber = camber(X_steer, steerDisp); % Camber of left wheel. Left wheels is the outside wheel when steerDisp is negative
                            else
                                steerCamber = camber(X_R_steer, steerDisp); % Camber of right wheel. Right wheels is the outside wheel when steerDisp is negative
                            end
                            i = find(min(abs(steerDisp) == abs(steerDisp)));
                        end
                        var = min(steerCamber(i:end))-max(steerCamber(i:end));
                        if var > targets.analysis{4}.tol(2)
                            param = abs((var - targets.analysis{4}.value(2))/(1+abs(targets.analysis{4}.value(2))));
                            if config.settings{5}.lin
                                steerCamberIn = param * targets.analysis{4}.weight(2);
                            elseif config.settings{5}.exp
                                steerCamberIn = (config.settings{5}.expBase*targets.analysis{4}.weight(2)).^(param);
                            end
                        else
                            steerCamberIn = 0;
                        end
                    else
                        steerCamberIn = 0;
                    end
                    if targets.analysis{4}.enable(3) % Trail variation
                        param = trail(X_steer, dataset.TireRadius, steerDisp);
                        var = max(param) - min(param);
                        if var > targets.analysis{4}.tol(3)
                            param = var/(1+abs(mean(~isnan(param))));
                            if config.settings{5}.lin
                                steerTrail = abs(param)*targets.analysis{4}.weight(3);
                            elseif config.settings{5}.exp
                                steerTrail = (config.settings{5}.expBase*targets.analysis{4}.weight(3)).^(param);
                            end
                        else
                            steerTrail = 0;
                        end
                    else
                        steerTrail = 0;
                    end
                    if targets.analysis{4}.enable(4) % Scrub variation
                        param = scrub(X_steer, dataset.TireRadius,steerDisp);
                        var = max(param) - min(param);
                        if var > targets.analysis{4}.tol(4)
                            param = var/(1+abs(mean(~isnan(param))));
                            if config.settings{5}.lin
                                steerScrub = param*targets.analysis{4}.weight(4);
                            elseif config.settings{5}.exp
                                steerScrub = (config.settings{5}.expBase*targets.analysis{4}.weight(4)).^(param);
                            end
                        else
                            steerScrub = 0;
                        end
                    else
                        steerScrub = 0;
                    end
                    if targets.analysis{4}.enable(5) % Ackerman
                        ack = ackerman(X_steer, X_R_steer, dataset.VehicleParams.WheelBase, dataset.VehicleParams.Track, steerDisp);
                        var = mean(ack(~isnan(ack)));
                        if not(all(var < targets.analysis{4}.value(3)+targets.analysis{4}.tol(5))*all(var > targets.analysis{4}.value(3)-targets.analysis{4}.tol(5)))
                            param = abs((var-targets.analysis{4}.value(3))/(1+abs(targets.analysis{4}.value(3))));
                            if config.settings{5}.lin
                                steerAckerman = param*targets.analysis{4}.weight(5);
                            elseif config.settings{5}.exp
                                steerAckerman = (config.settings{5}.expBase*targets.analysis{4}.weight(5)).^(param);
                            end
                        else
                            steerAckerman = 0;
                        end
                        if targets.analysis{4}.enable(6) % variation
                            ackVar = abs(max(ack)-min(ack)/(1+abs(mean(ack(~isnan(ack))))));
                            steerAckerman = steerAckerman + ackVar*targets.analysis{4}.weight(6);
                        end
                        if targets.analysis{4}.enable(7) % linearity
                            ack_1 = diff(ack);
                            ackLin = abs((max(ack_1)-min(ack_1))/(1+abs(mean(ack(~isnan(ack))))));
                            steerAckerman = steerAckerman + ackLin*targets.analysis{4}.weight(7);
                        end
                        if targets.analysis{4}.enable(8) % progressiveness
                            ack_1 = diff(ack(~isnan(ack)));
                            ackProg = min(exp(mean(ack_1)*sign(targets.analysis{4}.weight(8))),1);
                            steerAckerman = steerAckerman + ackProg*targets.analysis{4}.weight(8);
                        end
                    else
                        steerAckerman = 0;
                    end
                    if targets.analysis{4}.enable(9)% Steering angles(wheel) range
                        steerRange = toe(X_steer, steerDisp);
                        var = max(steerRange)-min(steerRange);
                        if not(all(var < targets.analysis{4}.value(4)+targets.analysis{4}.tol(6))*all(var > targets.analysis{4}.value(4)-targets.analysis{4}.tol(6)))
                            param = abs((var-targets.analysis{4}.value(4))/(1+abs(targets.analysis{4}.value(4))));
                            if config.settings{5}.lin
                                steerRange = param*targets.analysis{4}.weight(9);
                            elseif config.settings{5}.exp
                                steerRange = (config.settings{5}.expBase*targets.analysis{4}.weight(9)).^(param);
                            end
                        else
                            steerRange = 0;
                        end
                    else
                        steerRange = 0;
                    end
                    steerVal = steerCamberOut + steerCamberIn + steerTrail + steerScrub + steerAckerman + steerRange;
                catch err
                    disp(err)
                    val = inf;
                    return;
                end
            else
                steerVal = 0;
            end
            val = staticVal + heaveVal + rollVal + steerVal;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, data)
            %Initialize app properties
            app.OptimAnalysisList = {'Static' 'Heave' 'Roll' 'Steering'};
            app.BoundsList = {'Upper A-arm' 'Lower A-arm' 'Steering'};
            app.AnalysisList.ID = {'Heave' 'Roll' 'Steer'};
            app.AnalysisList.Xaxis = {'Wheel Travel' 'Roll angle' 'Rack displacement'};
            app.ParametersList = {'Camber' 'Toe' 'KPI' 'Caster' 'Trail' 'Scrub' 'RC Height' 'RC Migration' 'Anti-Dive' 'Ackerman'};
            temp = load('AarmsOptimConfig.mat');
            app.Configuration = temp.OptimConfig;
            %% Save data
            app.CallingApp = mainapp;
            app.Dataset = data.dataset;
            app.Analysis = data.Analysis;
            app.SessionData = {};
            %% Add original dataset to SessionData
            optimData.X = app.Dataset.X;
            optimData.ID = app.Dataset.ID;
            optimData.Targets = '';
            optimData.Bounds.lb = '';
            optimData.Bounds.ub = '';
            optimData.Bounds.bEn = '';
            
            % Initialize app components
            %% Initialize position
            app.UIFigure.Position(1) = mainapp.UIFigure.Position(1)+mainapp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = mainapp.UIFigure.Position(2)+mainapp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            %% Icons
            path = fullfile(matlabroot, 'toolbox', 'matlab');
            app.RunButton.Icon = fullfile(path,'addons','release','images','toolstrip','Run_24.png');
            app.CancelButton.Icon = fullfile(matlabroot,'toolbox', 'matlab','addons','release','images','rte_error.png');
            %% Dropdown menus
            app.TargetDropDown.Items = app.OptimAnalysisList;
            app.BoundsDropDown.Items = app.BoundsList;
            app.AnalysisSelectDropDown.Items = app.AnalysisList.ID;
            app.ParameterSelectDropDown.Items = app.ParametersList;
            
            % Load data to app components
            %% Load last configuration
            loadConfig(app)
            %%% Update targets panel according to configuration
            TargetCheckBoxValueChanged(app, '')
            %%% Update bounds panel
            BoundsCheckBoxValueChanged(app, '')
            %% Load original parameters
            loadParams(app)
            %% Add original dataset to SessionData
            addResult(app,optimData)
            updateResultsPlot(app)
            
            % Wait until data is loaded to enable app resize
            app.UIFigure.Resize = 'on';
        end

        % Close request function: UIFigure
        function closeReqFcn(app, event)
            s = uiconfirm(app.UIFigure,'If you continue, unsaved results will be lost. Do you want to continue?','Exit confirm');
            switch s
                case 'OK'
                    saveConfig(app)
                    app.CallingApp.SuspOptimButton.Enable = 'on';
                    delete(app)
                case 'Cancel'
                    return;
            end
            
        end

        % Value changed function: TargetDropDown
        function TargetDropDownValueChanged(app, event)
            children = app.TargetsTabGroup.Children;
            a = false(1,length(children));
            for i=1:length(children)
                if strcmp(children(i).Title,app.TargetDropDown.Value)
                    a(i) = true;
                end
            end
            if sum(a)
                app.TargetsTabGroup.SelectedTab = children(a);
            else
                app.TargetsTabGroup.SelectedTab = app.TargetsTabGroup.Children(5); %Blanc tab
            end
        end

        % Value changed function: HeaveCheckBox, RollCheckBox, 
        % StaticCheckBox, SteeringCheckBox
        function TargetCheckBoxValueChanged(app, event)
            selected = app.TargetDropDown.Value;
            app.TargetDropDown.Items = {};
            targets = app.SelectTargetsPanel.Children;
            for i=1:length(app.OptimAnalysisList)
                if targets(i).Value
                    app.TargetDropDown.Items{end+1} = targets(i).Text;
                end
            end
            if sum(strcmp(selected,app.TargetDropDown.Items))
                app.TargetDropDown.Value = selected;
            end
            TargetDropDownValueChanged(app, event)
        end

        % Value changed function: LowerAarmCheckBox, 
        % SteeringBoundsCheckBox, UpperAarmCheckBox
        function BoundsCheckBoxValueChanged(app, event)
            selected = app.BoundsDropDown.Value;
            app.BoundsDropDown.Items = {};
            bounds = app.SelectBoundsPanel.Children;
            for i=1:length(app.BoundsList)
                if bounds(i).Value
                    app.BoundsDropDown.Items{end+1} = bounds(i).Text;
                end
            end
            if sum(strcmp(selected,app.BoundsDropDown.Items))
                app.BoundsDropDown.Value = selected;
            end
            BoundsDropDownValueChanged(app, event)
        end

        % Value changed function: BoundsDropDown
        function BoundsDropDownValueChanged(app, event)
            children = app.BoundsTabGroup.Children;
            a = false(1,length(children));
            for i=1:length(children)
                if strcmp(children(i).Title,app.BoundsDropDown.Value)
                    a(i) = true;
                end
            end
            if sum(a)
                app.BoundsTabGroup.SelectedTab = children(a);
            else
                app.BoundsTabGroup.SelectedTab = app.BoundsTabGroup.Children(4); %Blanc tab
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            runOptim(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closeReqFcn(app, event)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveResultsListBox.Items = {};
            for i=1:length(app.SessionData)
                app.SaveResultsListBox.Items{end+1} = app.SessionData{i}.ID;
            end
            switch app.SaveResultsPanel.Visible
                case 'off'
                    app.SaveResultsPanel.Visible = 'on';
                case 'on'
                    app.SaveResultsPanel.Visible = 'off';
            end
        end

        % Button pushed function: SaveCancelButton
        function SaveCancelButtonPushed(app, event)
            app.SaveResultsPanel.Visible = 'off';
        end

        % Button pushed function: SaveSelectionAsButton
        function SaveSelectionAsButtonPushed(app, event)
            if ~isempty(app.SaveResultsListBox.Value)
                o = strcmp(app.SaveResultsListBox.Value, app.SaveResultsListBox.Items);
            else
                return;
            end
            try
                data = app.Dataset;
                data.X = app.SessionData{o}.X;
                data.ID = app.SessionData{o}.ID;
                optimSaveAsCB(app.CallingApp, data, app)
            catch
                uialert(app.UIFigure,'Data could not be saved','Warning','Icon','warning');
            end
        end

        % Button pushed function: SaveSelectionButton
        function SaveSelectionButtonPushed(app, event)
            if ~isempty(app.SaveResultsListBox.Value)
                o = strcmp(app.SaveResultsListBox.Value, app.SaveResultsListBox.Items);
            else
                return;
            end
            try
                data = app.Dataset;
                data.X = app.SessionData{o}.X;
                optimCB(app.CallingApp, data, app)
            catch
                uialert(app.UIFigure,'Data could not be saved','Warning','Icon','warning');
            end
            app.SaveResultsPanel.Visible = 'off';
        end

        % Value changed function: HeaveAntiDiveTol, 
        % HeaveAntiDiveWeight, HeaveBumpSteerTol, 
        % HeaveBumpSteerWeight, HeaveCamberGainEditField, 
        % HeaveCamberGainTol, HeaveCamberGainWeight, HeaveRCTol, 
        % HeaveRCWeight, HeaveScrubTol, HeaveScrubWeight, 
        % HeaveTrailTol, HeaveTrailWeight, RollCamberInEditField, 
        % RollCamberInTol, RollCamberInWeight, 
        % RollCamberOutEditField, RollCamberOutTol, 
        % RollCamberOutWeight, RollRCHeightTol, RollRCHeightWeight, 
        % RollRCMigrationTol, RollRCMigrationWeight, 
        % StaticAntiDiveTol, StaticAntiDiveWeight, 
        % StaticCasterEditField, StaticCasterTol, 
        % StaticCasterWeight, StaticKPIEditField, StaticKPITol, 
        % StaticKPIWeight, StaticRCEditField, StaticRCTol, 
        % StaticRCWeight, StaticScrubEditField, StaticScrubTol, 
        % StaticScrubWeight, StaticTrailEditField, StaticTrailTol, 
        % StaticTrailWeight, SteeringAckermanEditField, 
        % SteeringAckermanLinearityWeight, 
        % SteeringAckermanProgressivenessWeight, 
        % SteeringAckermanTol, SteeringAckermanVariationWeight, 
        % SteeringAckermanWeight, SteeringCamberGainInTol, 
        % SteeringCamberGainInWeight, SteeringCamberGainOutTol, 
        % SteeringCamberGainOutWeight, SteeringCamberInEditField, 
        % SteeringCamberOutEditField, SteeringRangeEditField, 
        % SteeringRangeTol, SteeringRangeWeight, SteeringScrubTol, 
        % SteeringScrubWeight, SteeringTrailTol, SteeringTrailWeight
        function AASrcChangeColorValueChanged(app, event)
            event.Source.BackgroundColor = [1 1 1];
        end

        % Value changed function: AnalysisSelectDropDown
        function AnalysisSelectDropDownValueChanged(app, event)
            updateResultsPlot(app)
        end

        % Value changed function: ParameterSelectDropDown
        function ParameterSelectDropDownValueChanged(app, event)
            updateResultsPlot(app)
        end

        % Button pushed function: ChangeParameterDownButton
        function ChangeParameterDownButtonPushed(app, event)
            p = find(strcmp(app.ParametersList,app.ParameterSelectDropDown.Value));
            if p > 1
                app.ParameterSelectDropDown.Value = app.ParameterSelectDropDown.Items{p-1};
            else
                return;
            end
            updateResultsPlot(app)
        end

        % Button pushed function: ChangeParameterUpButton
        function ChangeParameterUpButtonPushed(app, event)
            p = find(strcmp(app.ParametersList,app.ParameterSelectDropDown.Value));
            if p < length(app.ParametersList)
                app.ParameterSelectDropDown.Value = app.ParameterSelectDropDown.Items{p+1};
            else
                return;
            end
            updateResultsPlot(app)
        end

        % Value changed function: LinButton
        function LinButtonValueChanged(app, event)
            switch app.LinButton.Value
                case 0
                   app.LinButton.Value = 1;
                case 1
                    app.ExpButton.Value = 0;
            end     
        end

        % Value changed function: ExpButton
        function ExpButtonValueChanged(app, event)
            switch app.ExpButton.Value
                case 0
                   app.ExpButton.Value = 1;
                case 1
                    app.LinButton.Value = 0;
            end    
        end

        % Button pushed function: LoadSessionButton
        function LoadSessionButtonPushed(app, event)
            defaultFileName = fullfile(pwd, '*.mat');
            [filename,path] = uigetfile(defaultFileName, 'Select a .mat file containing a suspension dataset');
            drawnow; %Prevent window to go to background
            figure(app.UIFigure);
            if filename == 0 % User clicked the Cancel button.
                return;
            end
            temp = load(fullfile(path, filename));
            app.Configuration = temp.OptimConfig;
            loadConfig(app)
        end

        % Button pushed function: SaveSessionButton
        function SaveSessionButtonPushed(app, event)
            try
                defaultFileName = fullfile(pwd, ['Optimization session - ' app.Dataset.ID '.mat']);
                [filename,path] = uiputfile(defaultFileName,'Save session configuration');
                drawnow; %Prevent window to go to background
                figure(app.UIFigure);
                if filename == 0 % User clicked the Cancel button.
                    return;
                end
                saveConfig(app,fullfile(path, filename));
            catch err
                uialert(app.UIFigure,['saveSession error. ' err.message],'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Suspension optimization';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeReqFcn, true);
            app.GlobalContainer = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.GlobalContainer);
            app.SidePanelContainer = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.SidePanelContainer);
            app.OptimizationOptionsPanel = uipanel(app.GridLayout2);
            app.CancelButton = uibutton(app.OptimizationOptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = '';
            app.SaveButton = uibutton(app.OptimizationOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Panel_5 = uipanel(app.GridLayout2);
            app.RunButton = uibutton(app.Panel_5, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Panel_9 = uipanel(app.GridLayout2);
            app.TabGroup = uitabgroup(app.Panel_9);
            app.TargetsTab = uitab(app.TabGroup);
            app.TargetsTab.Title = 'Targets';
            app.TargetDropDown = uidropdown(app.TargetsTab);
            app.TargetDropDown.Items = {'Static', 'Heave', 'Roll', 'Steering'};
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetDropDownValueChanged, true);
            app.TargetDropDown.Value = 'Static';
            app.Panel_3 = uipanel(app.TargetsTab);
            app.TargetsTabGroup = uitabgroup(app.Panel_3);
            app.StaticTab = uitab(app.TargetsTabGroup);
            app.StaticTab.Title = 'Static';
            app.StaticKPIWeight = uispinner(app.StaticTab);
            app.StaticKPIWeight.Step = 0.01;
            app.StaticKPIWeight.Limits = [0 10];
            app.StaticKPIWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticKPIWeight.Value = 0.5;
            app.StaticKPITol = uispinner(app.StaticTab);
            app.StaticKPITol.Step = 0.1;
            app.StaticKPITol.Limits = [0 Inf];
            app.StaticKPITol.ValueDisplayFormat = '%3.2f';
            app.StaticKPITol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticKPITol.Value = 0.1;
            app.StaticKPIEditField = uieditfield(app.StaticTab, 'numeric');
            app.StaticKPIEditField.ValueDisplayFormat = '%.2f º';
            app.StaticKPIEditField.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticCasterWeight = uispinner(app.StaticTab);
            app.StaticCasterWeight.Step = 0.01;
            app.StaticCasterWeight.Limits = [0 10];
            app.StaticCasterWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticCasterWeight.Value = 0.5;
            app.StaticAntiDiveWeight = uispinner(app.StaticTab);
            app.StaticAntiDiveWeight.Step = 0.01;
            app.StaticAntiDiveWeight.Limits = [0 10];
            app.StaticAntiDiveWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticAntiDiveWeight.Value = 0.5;
            app.StaticScrubWeight = uispinner(app.StaticTab);
            app.StaticScrubWeight.Step = 0.01;
            app.StaticScrubWeight.Limits = [0 10];
            app.StaticScrubWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticScrubWeight.Value = 0.5;
            app.StaticCasterTol = uispinner(app.StaticTab);
            app.StaticCasterTol.Step = 0.1;
            app.StaticCasterTol.Limits = [0 Inf];
            app.StaticCasterTol.ValueDisplayFormat = '%3.2f';
            app.StaticCasterTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticCasterTol.Value = 0.1;
            app.StaticAntiDiveTol = uispinner(app.StaticTab);
            app.StaticAntiDiveTol.Limits = [0 Inf];
            app.StaticAntiDiveTol.ValueDisplayFormat = '%3.2f';
            app.StaticAntiDiveTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticAntiDiveTol.Value = 0.1;
            app.StaticScrubTol = uispinner(app.StaticTab);
            app.StaticScrubTol.Limits = [0 Inf];
            app.StaticScrubTol.ValueDisplayFormat = '%3.2f';
            app.StaticScrubTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticScrubTol.Value = 0.1;
            app.StaticCasterEditField = uieditfield(app.StaticTab, 'numeric');
            app.StaticCasterEditField.ValueDisplayFormat = '%.2f º';
            app.StaticCasterEditField.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticAntiDiveEditField = uieditfield(app.StaticTab, 'numeric');
            app.StaticAntiDiveEditField.ValueDisplayFormat = '%.2f';
            app.StaticScrubEditField = uieditfield(app.StaticTab, 'numeric');
            app.StaticScrubEditField.ValueDisplayFormat = '%.2f mm';
            app.StaticScrubEditField.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticKPILabel = uilabel(app.StaticTab);
            app.StaticKPILabel.Text = 'KPI';
            app.StaticCasteLabel = uilabel(app.StaticTab);
            app.StaticCasteLabel.Text = 'Caster';
            app.StaticAntiDiveLabel = uilabel(app.StaticTab);
            app.StaticAntiDiveLabel.Text = 'Anti-dive/squat';
            app.StaticScrubLabel = uilabel(app.StaticTab);
            app.StaticScrubLabel.Text = 'Scrub radius';
            app.StaticTrailWeight = uispinner(app.StaticTab);
            app.StaticTrailWeight.Step = 0.01;
            app.StaticTrailWeight.Limits = [0 10];
            app.StaticTrailWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticTrailWeight.Value = 0.5;
            app.StaticRCWeight = uispinner(app.StaticTab);
            app.StaticRCWeight.Step = 0.01;
            app.StaticRCWeight.Limits = [0 10];
            app.StaticRCWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticRCWeight.Value = 0.5;
            app.StaticTrailTol = uispinner(app.StaticTab);
            app.StaticTrailTol.Limits = [0 Inf];
            app.StaticTrailTol.ValueDisplayFormat = '%3.2f';
            app.StaticTrailTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticTrailTol.Value = 0.1;
            app.StaticRCTol = uispinner(app.StaticTab);
            app.StaticRCTol.Limits = [0 Inf];
            app.StaticRCTol.ValueDisplayFormat = '%3.2f';
            app.StaticRCTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticRCTol.Value = 0.1;
            app.StaticTrailEditField = uieditfield(app.StaticTab, 'numeric');
            app.StaticTrailEditField.ValueDisplayFormat = '%.2f mm';
            app.StaticTrailEditField.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticRCEditField = uieditfield(app.StaticTab, 'numeric');
            app.StaticRCEditField.ValueDisplayFormat = '%.2f mm';
            app.StaticRCEditField.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.StaticTrailLabel = uilabel(app.StaticTab);
            app.StaticTrailLabel.Text = 'Mechanical Trail';
            app.StaticRCLabel = uilabel(app.StaticTab);
            app.StaticRCLabel.Text = 'Roll Center Height';
            app.StaticKPICheckBox = uicheckbox(app.StaticTab);
            app.StaticKPICheckBox.Tooltip = {'Enable minimize trail variation'};
            app.StaticKPICheckBox.Text = '';
            app.StaticKPICheckBox.Value = true;
            app.StaticCasterCheckBox = uicheckbox(app.StaticTab);
            app.StaticCasterCheckBox.Tooltip = {'Enable the target to minimize roll center height variation'};
            app.StaticCasterCheckBox.Text = '';
            app.StaticCasterCheckBox.Value = true;
            app.StaticAntiDiveCheckBox = uicheckbox(app.StaticTab);
            app.StaticAntiDiveCheckBox.Tooltip = {'Enable minimize trail variation'};
            app.StaticAntiDiveCheckBox.Text = '';
            app.StaticAntiDiveCheckBox.Value = true;
            app.StaticScrubCheckBox = uicheckbox(app.StaticTab);
            app.StaticScrubCheckBox.Tooltip = {'Enable the target to minimize roll center height variation'};
            app.StaticScrubCheckBox.Text = '';
            app.StaticScrubCheckBox.Value = true;
            app.StaticTrailCheckBox = uicheckbox(app.StaticTab);
            app.StaticTrailCheckBox.Tooltip = {'Enable the target of minimizing bump-steer'};
            app.StaticTrailCheckBox.Text = '';
            app.StaticTrailCheckBox.Value = true;
            app.StaticRCCheckBox = uicheckbox(app.StaticTab);
            app.StaticRCCheckBox.Tooltip = {'Enable camber gain target'};
            app.StaticRCCheckBox.Text = '';
            app.StaticRCCheckBox.Value = true;
            app.StaticWeightLabel = uilabel(app.StaticTab);
            app.StaticWeightLabel.Tooltip = {'Weight of each parameter in the objective function'};
            app.StaticWeightLabel.Text = 'Weight';
            app.StaticValueMaxLabel = uilabel(app.StaticTab);
            app.StaticValueMaxLabel.Tooltip = {'Tolerance. It represents a range of values within which the target is considered achieved'};
            app.StaticValueMaxLabel.Text = 'tol.';
            app.StaticValueLabel = uilabel(app.StaticTab);
            app.StaticValueLabel.Tooltip = {'Target value'};
            app.StaticValueLabel.Text = 'Value';
            app.HeaveTab = uitab(app.TargetsTabGroup);
            app.HeaveTab.Title = 'Heave';
            app.HeaveAntiDiveWeight = uispinner(app.HeaveTab);
            app.HeaveAntiDiveWeight.Step = 0.01;
            app.HeaveAntiDiveWeight.Limits = [0 10];
            app.HeaveAntiDiveWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveAntiDiveWeight.Value = 0.5;
            app.HeaveScrubWeight = uispinner(app.HeaveTab);
            app.HeaveScrubWeight.Step = 0.01;
            app.HeaveScrubWeight.Limits = [0 10];
            app.HeaveScrubWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveScrubWeight.Value = 0.5;
            app.HeaveTrailWeight = uispinner(app.HeaveTab);
            app.HeaveTrailWeight.Step = 0.01;
            app.HeaveTrailWeight.Limits = [0 10];
            app.HeaveTrailWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveTrailWeight.Value = 0.5;
            app.HeaveRCWeight = uispinner(app.HeaveTab);
            app.HeaveRCWeight.Step = 0.01;
            app.HeaveRCWeight.Limits = [0 10];
            app.HeaveRCWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveRCWeight.Value = 0.5;
            app.HeaveAntiDiveTol = uispinner(app.HeaveTab);
            app.HeaveAntiDiveTol.Step = 0.5;
            app.HeaveAntiDiveTol.Limits = [0 Inf];
            app.HeaveAntiDiveTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveAntiDiveTol.Value = 0.1;
            app.HeaveScrubTol = uispinner(app.HeaveTab);
            app.HeaveScrubTol.Step = 0.5;
            app.HeaveScrubTol.Limits = [0 Inf];
            app.HeaveScrubTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveScrubTol.Value = 0.1;
            app.HeaveTrailTol = uispinner(app.HeaveTab);
            app.HeaveTrailTol.Step = 0.1;
            app.HeaveTrailTol.Limits = [0 Inf];
            app.HeaveTrailTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveTrailTol.Value = 0.1;
            app.HeaveRCTol = uispinner(app.HeaveTab);
            app.HeaveRCTol.Limits = [0 Inf];
            app.HeaveRCTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveRCTol.Value = 0.1;
            app.HeaveBumpSteerWeight = uispinner(app.HeaveTab);
            app.HeaveBumpSteerWeight.Step = 0.01;
            app.HeaveBumpSteerWeight.Limits = [0 10];
            app.HeaveBumpSteerWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveBumpSteerWeight.Value = 0.5;
            app.HeaveBumpSteerTol = uispinner(app.HeaveTab);
            app.HeaveBumpSteerTol.Step = 0.05;
            app.HeaveBumpSteerTol.Limits = [0 10];
            app.HeaveBumpSteerTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveBumpSteerTol.Value = 0.1;
            app.HeaveCamberGainWeight = uispinner(app.HeaveTab);
            app.HeaveCamberGainWeight.Step = 0.01;
            app.HeaveCamberGainWeight.Limits = [0 10];
            app.HeaveCamberGainWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveCamberGainWeight.Value = 0.5;
            app.HeaveWeightLabel = uilabel(app.HeaveTab);
            app.HeaveWeightLabel.Tooltip = {'Weight of each parameter in the objective function'};
            app.HeaveWeightLabel.Text = 'Weight';
            app.HeaveCamberGainTol = uispinner(app.HeaveTab);
            app.HeaveCamberGainTol.Step = 0.1;
            app.HeaveCamberGainTol.Limits = [0 Inf];
            app.HeaveCamberGainTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveCamberGainTol.Value = 0.1;
            app.HeaveTolLabel = uilabel(app.HeaveTab);
            app.HeaveTolLabel.Tooltip = {'Tolerance. It represents a range of values within which the target is considered achieved'};
            app.HeaveTolLabel.Text = 'tol.';
            app.HeaveCamberGainEditField = uieditfield(app.HeaveTab, 'numeric');
            app.HeaveCamberGainEditField.ValueDisplayFormat = '%.2f º';
            app.HeaveCamberGainEditField.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.HeaveValueLabel = uilabel(app.HeaveTab);
            app.HeaveValueLabel.Tooltip = {'Target value'};
            app.HeaveValueLabel.Text = 'Value';
            app.HeaveAntiDiveLabel = uilabel(app.HeaveTab);
            app.HeaveAntiDiveLabel.Text = 'Anti-dive variation';
            app.HeaveScrubLabel = uilabel(app.HeaveTab);
            app.HeaveScrubLabel.Text = 'Scrub variation';
            app.HeaveTrailLabel = uilabel(app.HeaveTab);
            app.HeaveTrailLabel.Text = 'Trail variation';
            app.HeaveRCLabel = uilabel(app.HeaveTab);
            app.HeaveRCLabel.Text = 'RC height variation  ';
            app.HeaveBumpSteerLabel = uilabel(app.HeaveTab);
            app.HeaveBumpSteerLabel.Text = 'Bump-steer';
            app.HeaveCamberGainLabel = uilabel(app.HeaveTab);
            app.HeaveCamberGainLabel.Text = 'Camber gain';
            app.HeaveAntiDiveCheckBox = uicheckbox(app.HeaveTab);
            app.HeaveAntiDiveCheckBox.Tooltip = {'Enable minimize scrub variation'};
            app.HeaveAntiDiveCheckBox.Text = '';
            app.HeaveAntiDiveCheckBox.Value = true;
            app.HeaveScrubCheckBox = uicheckbox(app.HeaveTab);
            app.HeaveScrubCheckBox.Tooltip = {'Enable minimize scrub variation'};
            app.HeaveScrubCheckBox.Text = '';
            app.HeaveScrubCheckBox.Value = true;
            app.HeaveTrailCheckBox = uicheckbox(app.HeaveTab);
            app.HeaveTrailCheckBox.Tooltip = {'Enable minimize trail variation'};
            app.HeaveTrailCheckBox.Text = '';
            app.HeaveTrailCheckBox.Value = true;
            app.HeaveRCCheckBox = uicheckbox(app.HeaveTab);
            app.HeaveRCCheckBox.Tooltip = {'Enable the target to minimize roll center height variation'};
            app.HeaveRCCheckBox.Text = '';
            app.HeaveRCCheckBox.Value = true;
            app.HeaveBumpSteerCheckBox = uicheckbox(app.HeaveTab);
            app.HeaveBumpSteerCheckBox.Tooltip = {'Enable the target of minimizing bump-steer'};
            app.HeaveBumpSteerCheckBox.Text = '';
            app.HeaveBumpSteerCheckBox.Value = true;
            app.HeaveCamberGainCheckBox = uicheckbox(app.HeaveTab);
            app.HeaveCamberGainCheckBox.Tooltip = {'Enable camber gain target'};
            app.HeaveCamberGainCheckBox.Text = '';
            app.HeaveCamberGainCheckBox.Value = true;
            app.RollTab = uitab(app.TargetsTabGroup);
            app.RollTab.Title = 'Roll';
            app.RollRCMigrationWeight = uispinner(app.RollTab);
            app.RollRCMigrationWeight.Step = 0.01;
            app.RollRCMigrationWeight.Limits = [0 10];
            app.RollRCMigrationWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.RollRCMigrationWeight.Value = 0.5;
            app.RollRCHeightWeight = uispinner(app.RollTab);
            app.RollRCHeightWeight.Step = 0.01;
            app.RollRCHeightWeight.Limits = [0 10];
            app.RollRCHeightWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.RollRCHeightWeight.Value = 0.5;
            app.RollRCMigrationTol = uispinner(app.RollTab);
            app.RollRCMigrationTol.Step = 5;
            app.RollRCMigrationTol.Limits = [0 Inf];
            app.RollRCMigrationTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.RollRCMigrationTol.Value = 0.1;
            app.RollRCHeightTol = uispinner(app.RollTab);
            app.RollRCHeightTol.Step = 0.1;
            app.RollRCHeightTol.Limits = [0 Inf];
            app.RollRCHeightTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.RollRCHeightTol.Value = 0.1;
            app.RollCamberInWeight = uispinner(app.RollTab);
            app.RollCamberInWeight.Step = 0.01;
            app.RollCamberInWeight.Limits = [0 10];
            app.RollCamberInWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.RollCamberInWeight.Value = 0.5;
            app.RollCamberInTol = uispinner(app.RollTab);
            app.RollCamberInTol.Step = 0.1;
            app.RollCamberInTol.Limits = [0 Inf];
            app.RollCamberInTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.RollCamberInTol.Value = 0.1;
            app.RollCamberInEditField = uieditfield(app.RollTab, 'numeric');
            app.RollCamberInEditField.ValueDisplayFormat = '%.2f º';
            app.RollCamberInEditField.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.RollCamberOutWeight = uispinner(app.RollTab);
            app.RollCamberOutWeight.Step = 0.01;
            app.RollCamberOutWeight.Limits = [0 10];
            app.RollCamberOutWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.RollCamberOutWeight.Value = 0.5;
            app.RollCamberOutTol = uispinner(app.RollTab);
            app.RollCamberOutTol.Step = 0.1;
            app.RollCamberOutTol.Limits = [0 Inf];
            app.RollCamberOutTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.RollCamberOutTol.Value = 0.1;
            app.RollCamberOutEditField = uieditfield(app.RollTab, 'numeric');
            app.RollCamberOutEditField.ValueDisplayFormat = '%.2f º';
            app.RollCamberOutEditField.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.RollWeightLabel = uilabel(app.RollTab);
            app.RollWeightLabel.Tooltip = {'Weight of each parameter in the objective function'};
            app.RollWeightLabel.Text = 'Weight';
            app.RollValueLabel_2 = uilabel(app.RollTab);
            app.RollValueLabel_2.Tooltip = {'Tolerance. It represents a range of values within which the target is considered achieved'};
            app.RollValueLabel_2.Text = 'tol.';
            app.RollValueLabel = uilabel(app.RollTab);
            app.RollValueLabel.Tooltip = {'Target value'};
            app.RollValueLabel.Text = 'Value';
            app.RollRCMigrationLabel = uilabel(app.RollTab);
            app.RollRCMigrationLabel.Text = 'RC migration';
            app.RollRCHeightLabel = uilabel(app.RollTab);
            app.RollRCHeightLabel.Text = 'RC height variation  ';
            app.RollCamberInLabel = uilabel(app.RollTab);
            app.RollCamberInLabel.Tooltip = {'Camber gain of the inside wheel'};
            app.RollCamberInLabel.Text = 'Camber gain - in';
            app.RollCamberOutLabel = uilabel(app.RollTab);
            app.RollCamberOutLabel.Tooltip = {'Camber gain of the outside wheel'};
            app.RollCamberOutLabel.Text = 'Camber gain - out';
            app.RollRCMigrationCheckBox = uicheckbox(app.RollTab);
            app.RollRCMigrationCheckBox.Tooltip = {'Enable minimize trail variation'};
            app.RollRCMigrationCheckBox.Text = '';
            app.RollRCMigrationCheckBox.Value = true;
            app.RollRCHeightCheckBox = uicheckbox(app.RollTab);
            app.RollRCHeightCheckBox.Tooltip = {'Enable the target to minimize roll center height variation'};
            app.RollRCHeightCheckBox.Text = '';
            app.RollRCHeightCheckBox.Value = true;
            app.RollCamberInCheckBox = uicheckbox(app.RollTab);
            app.RollCamberInCheckBox.Tooltip = {'Enable the target of minimizing bump-steer'};
            app.RollCamberInCheckBox.Text = '';
            app.RollCamberInCheckBox.Value = true;
            app.RollCamberOutCheckBox = uicheckbox(app.RollTab);
            app.RollCamberOutCheckBox.Tooltip = {'Enable camber gain target'};
            app.RollCamberOutCheckBox.Text = '';
            app.RollCamberOutCheckBox.Value = true;
            app.SteeringTab = uitab(app.TargetsTabGroup);
            app.SteeringTab.Title = 'Steering';
            app.SteeringRangeWeight = uispinner(app.SteeringTab);
            app.SteeringRangeWeight.Step = 0.01;
            app.SteeringRangeWeight.Limits = [0 10];
            app.SteeringRangeWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringRangeWeight.Value = 0.5;
            app.SteeringAckermanProgressivenessWeight = uispinner(app.SteeringTab);
            app.SteeringAckermanProgressivenessWeight.Step = 0.01;
            app.SteeringAckermanProgressivenessWeight.Limits = [0 10];
            app.SteeringAckermanProgressivenessWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringAckermanProgressivenessWeight.Value = 0.5;
            app.SteeringRangeTol = uispinner(app.SteeringTab);
            app.SteeringRangeTol.Limits = [0 Inf];
            app.SteeringRangeTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringRangeTol.Value = 0.1;
            app.SteeringAckermanLinearityWeight = uispinner(app.SteeringTab);
            app.SteeringAckermanLinearityWeight.Step = 0.01;
            app.SteeringAckermanLinearityWeight.Limits = [0 10];
            app.SteeringAckermanLinearityWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringAckermanLinearityWeight.Value = 0.5;
            app.SteeringAckermanVariationWeight = uispinner(app.SteeringTab);
            app.SteeringAckermanVariationWeight.Step = 0.01;
            app.SteeringAckermanVariationWeight.Limits = [0 10];
            app.SteeringAckermanVariationWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringAckermanVariationWeight.Value = 0.5;
            app.SteeringRangeEditField = uieditfield(app.SteeringTab, 'numeric');
            app.SteeringRangeEditField.ValueDisplayFormat = '%.2f º';
            app.SteeringRangeEditField.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringRangeEditField.Tooltip = {'Maximum degrees of wheel turn'};
            app.SteeringAckermanWeight = uispinner(app.SteeringTab);
            app.SteeringAckermanWeight.Step = 0.01;
            app.SteeringAckermanWeight.Limits = [0 10];
            app.SteeringAckermanWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringAckermanWeight.Value = 0.5;
            app.SteeringAckermanTol = uispinner(app.SteeringTab);
            app.SteeringAckermanTol.Step = 5;
            app.SteeringAckermanTol.Limits = [0 Inf];
            app.SteeringAckermanTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringAckermanTol.Value = 0.1;
            app.SteeringAckermanEditField = uieditfield(app.SteeringTab, 'numeric');
            app.SteeringAckermanEditField.ValueDisplayFormat = '%.2f';
            app.SteeringAckermanEditField.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringAckermanEditField.Tooltip = {'Ackerman percentage (%): '; ' - 100% = ideal Ackerman'; ' - 0% = parallel steering'; ''; ''};
            app.SteeringScrubWeight = uispinner(app.SteeringTab);
            app.SteeringScrubWeight.Step = 0.01;
            app.SteeringScrubWeight.Limits = [0 10];
            app.SteeringScrubWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringScrubWeight.Value = 0.5;
            app.SteeringScrubTol = uispinner(app.SteeringTab);
            app.SteeringScrubTol.Limits = [0 Inf];
            app.SteeringScrubTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringScrubTol.Value = 0.1;
            app.SteeringAckermanProgressivenessLabel = uilabel(app.SteeringTab);
            app.SteeringAckermanProgressivenessLabel.Text = 'Progressiveness';
            app.SteeringRangeLabel = uilabel(app.SteeringTab);
            app.SteeringRangeLabel.Text = 'Steering range';
            app.SteeringAckermanLinearityLabel = uilabel(app.SteeringTab);
            app.SteeringAckermanLinearityLabel.Text = 'Linearity';
            app.SteeringAckermanVariationLabel = uilabel(app.SteeringTab);
            app.SteeringAckermanVariationLabel.Text = 'Variation';
            app.SteeringAckermanLabel = uilabel(app.SteeringTab);
            app.SteeringAckermanLabel.Text = 'Ackerman';
            app.SteeringScrubLabel = uilabel(app.SteeringTab);
            app.SteeringScrubLabel.Text = 'Scrub variation';
            app.SteeringAckermanProgressivenessCheckBox = uicheckbox(app.SteeringTab);
            app.SteeringAckermanProgressivenessCheckBox.Tooltip = {'Enable minimize trail variation'};
            app.SteeringAckermanProgressivenessCheckBox.Text = '';
            app.SteeringAckermanProgressivenessCheckBox.Value = true;
            app.SteeringAckermanLinearityCheckBox = uicheckbox(app.SteeringTab);
            app.SteeringAckermanLinearityCheckBox.Tooltip = {'Enable minimize trail variation'};
            app.SteeringAckermanLinearityCheckBox.Text = '';
            app.SteeringAckermanLinearityCheckBox.Value = true;
            app.SteeringAckermanVariationCheckBox = uicheckbox(app.SteeringTab);
            app.SteeringAckermanVariationCheckBox.Tooltip = {'Enable minimize trail variation'};
            app.SteeringAckermanVariationCheckBox.Text = '';
            app.SteeringAckermanVariationCheckBox.Value = true;
            app.SteeringRangeCheckBox = uicheckbox(app.SteeringTab);
            app.SteeringRangeCheckBox.Tooltip = {'Enable minimize trail variation'};
            app.SteeringRangeCheckBox.Text = '';
            app.SteeringRangeCheckBox.Value = true;
            app.SteeringAckermanCheckbox = uicheckbox(app.SteeringTab);
            app.SteeringAckermanCheckbox.Tooltip = {'Enable minimize trail variation'};
            app.SteeringAckermanCheckbox.Text = '';
            app.SteeringAckermanCheckbox.Value = true;
            app.SteeringScrubCheckBox = uicheckbox(app.SteeringTab);
            app.SteeringScrubCheckBox.Tooltip = {'Enable minimize trail variation'};
            app.SteeringScrubCheckBox.Text = '';
            app.SteeringScrubCheckBox.Value = true;
            app.SteeringTrailWeight = uispinner(app.SteeringTab);
            app.SteeringTrailWeight.Step = 0.01;
            app.SteeringTrailWeight.Limits = [0 10];
            app.SteeringTrailWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringTrailWeight.Value = 0.5;
            app.SteeringTrailTol = uispinner(app.SteeringTab);
            app.SteeringTrailTol.Limits = [0 Inf];
            app.SteeringTrailTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringTrailTol.Value = 0.1;
            app.SteeringTrailLabel = uilabel(app.SteeringTab);
            app.SteeringTrailLabel.Text = 'Trail variation';
            app.SteeringTrailCheckBox = uicheckbox(app.SteeringTab);
            app.SteeringTrailCheckBox.Tooltip = {'Enable the target to minimize roll center height variation'};
            app.SteeringTrailCheckBox.Text = '';
            app.SteeringTrailCheckBox.Value = true;
            app.SteeringCamberGainInWeight = uispinner(app.SteeringTab);
            app.SteeringCamberGainInWeight.Step = 0.01;
            app.SteeringCamberGainInWeight.Limits = [0 10];
            app.SteeringCamberGainInWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringCamberGainInWeight.Value = 0.5;
            app.SteeringCamberGainInTol = uispinner(app.SteeringTab);
            app.SteeringCamberGainInTol.Step = 0.1;
            app.SteeringCamberGainInTol.Limits = [0 Inf];
            app.SteeringCamberGainInTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringCamberGainInTol.Value = 0.1;
            app.SteeringCamberInEditField = uieditfield(app.SteeringTab, 'numeric');
            app.SteeringCamberInEditField.ValueDisplayFormat = '%.2f º';
            app.SteeringCamberInEditField.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringCamberGainInLabel = uilabel(app.SteeringTab);
            app.SteeringCamberGainInLabel.Tooltip = {'Camber gain of the inside wheel'};
            app.SteeringCamberGainInLabel.Text = 'Camber gain - in';
            app.SteeringCamberGainInCheckBox = uicheckbox(app.SteeringTab);
            app.SteeringCamberGainInCheckBox.Tooltip = {'Enable the target of minimizing bump-steer'};
            app.SteeringCamberGainInCheckBox.Text = '';
            app.SteeringCamberGainInCheckBox.Value = true;
            app.SteeringCamberGainOutWeight = uispinner(app.SteeringTab);
            app.SteeringCamberGainOutWeight.Step = 0.01;
            app.SteeringCamberGainOutWeight.Limits = [0 10];
            app.SteeringCamberGainOutWeight.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringCamberGainOutWeight.Value = 0.5;
            app.SteeringCamberGainOutTol = uispinner(app.SteeringTab);
            app.SteeringCamberGainOutTol.Step = 0.1;
            app.SteeringCamberGainOutTol.Limits = [0 Inf];
            app.SteeringCamberGainOutTol.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringCamberGainOutTol.Value = 0.1;
            app.SteeringCamberOutEditField = uieditfield(app.SteeringTab, 'numeric');
            app.SteeringCamberOutEditField.ValueDisplayFormat = '%.2f º';
            app.SteeringCamberOutEditField.ValueChangedFcn = createCallbackFcn(app, @AASrcChangeColorValueChanged, true);
            app.SteeringCamberGainOutLabel = uilabel(app.SteeringTab);
            app.SteeringCamberGainOutLabel.Tooltip = {'Camber gain of the outside wheel'};
            app.SteeringCamberGainOutLabel.Text = 'Camber gain - out';
            app.SteeringCamberGainOutCheckBox = uicheckbox(app.SteeringTab);
            app.SteeringCamberGainOutCheckBox.Tooltip = {'Enable camber gain target'};
            app.SteeringCamberGainOutCheckBox.Text = '';
            app.SteeringCamberGainOutCheckBox.Value = true;
            app.SteeringWeightLabel = uilabel(app.SteeringTab);
            app.SteeringWeightLabel.Tooltip = {'Weight of each parameter in the objective function'};
            app.SteeringWeightLabel.Text = 'Weight';
            app.SteeringValueLabel_2 = uilabel(app.SteeringTab);
            app.SteeringValueLabel_2.Tooltip = {'Tolerance. It represents a range of values within which the target is considered achieved'};
            app.SteeringValueLabel_2.Text = 'tol.';
            app.SteeringValueLabel = uilabel(app.SteeringTab);
            app.SteeringValueLabel.Tooltip = {'Target value'};
            app.SteeringValueLabel.Text = 'Value';
            app.Tab = uitab(app.TargetsTabGroup);
            app.Panel_4 = uipanel(app.Panel_3);
            app.Panel_4.Title = '.';
            app.SelectTargetsPanel = uipanel(app.TargetsTab);
            app.SteeringCheckBox = uicheckbox(app.SelectTargetsPanel);
            app.SteeringCheckBox.ValueChangedFcn = createCallbackFcn(app, @TargetCheckBoxValueChanged, true);
            app.SteeringCheckBox.Text = 'Steering';
            app.SteeringCheckBox.Value = true;
            app.RollCheckBox = uicheckbox(app.SelectTargetsPanel);
            app.RollCheckBox.ValueChangedFcn = createCallbackFcn(app, @TargetCheckBoxValueChanged, true);
            app.RollCheckBox.Text = 'Roll';
            app.RollCheckBox.Value = true;
            app.HeaveCheckBox = uicheckbox(app.SelectTargetsPanel);
            app.HeaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @TargetCheckBoxValueChanged, true);
            app.HeaveCheckBox.Text = 'Heave';
            app.HeaveCheckBox.Value = true;
            app.StaticCheckBox = uicheckbox(app.SelectTargetsPanel);
            app.StaticCheckBox.ValueChangedFcn = createCallbackFcn(app, @TargetCheckBoxValueChanged, true);
            app.StaticCheckBox.Text = 'Static';
            app.StaticCheckBox.Value = true;
            app.BoundsTab = uitab(app.TabGroup);
            app.BoundsTab.Title = 'Bounds';
            app.SelectBoundsPanel = uipanel(app.BoundsTab);
            app.SteeringBoundsCheckBox = uicheckbox(app.SelectBoundsPanel);
            app.SteeringBoundsCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoundsCheckBoxValueChanged, true);
            app.SteeringBoundsCheckBox.Text = 'Steering';
            app.SteeringBoundsCheckBox.Value = true;
            app.LowerAarmCheckBox = uicheckbox(app.SelectBoundsPanel);
            app.LowerAarmCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoundsCheckBoxValueChanged, true);
            app.LowerAarmCheckBox.Text = 'Lower A-arm';
            app.LowerAarmCheckBox.Value = true;
            app.UpperAarmCheckBox = uicheckbox(app.SelectBoundsPanel);
            app.UpperAarmCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoundsCheckBoxValueChanged, true);
            app.UpperAarmCheckBox.Text = 'Upper A-arm';
            app.UpperAarmCheckBox.Value = true;
            app.BoundsDropDown = uidropdown(app.BoundsTab);
            app.BoundsDropDown.Items = {'Upper A-arm', 'Lower A-arm', 'Steering'};
            app.BoundsDropDown.ValueChangedFcn = createCallbackFcn(app, @BoundsDropDownValueChanged, true);
            app.BoundsDropDown.Value = 'Upper A-arm';
            app.Panel_7 = uipanel(app.BoundsTab);
            app.BoundsTabGroup = uitabgroup(app.Panel_7);
            app.UpperAarmTab = uitab(app.BoundsTabGroup);
            app.UpperAarmTab.Title = 'Upper A-arm';
            app.UCAforePanel = uipanel(app.UpperAarmTab);
            app.UCAforePanel.Title = 'UCA fore';
            app.lowerLabel = uilabel(app.UCAforePanel);
            app.lowerLabel.Text = 'lower';
            app.upperLabel = uilabel(app.UCAforePanel);
            app.upperLabel.Text = 'upper';
            app.UCAforeXlower = uispinner(app.UCAforePanel);
            app.UCAforeXlower.Value = -50;
            app.UCAforeXupper = uispinner(app.UCAforePanel);
            app.UCAforeXupper.Value = 50;
            app.UCAforeYlower = uispinner(app.UCAforePanel);
            app.UCAforeYlower.Value = -50;
            app.UCAforeYupper = uispinner(app.UCAforePanel);
            app.UCAforeYupper.Value = 50;
            app.UCAforeZlower = uispinner(app.UCAforePanel);
            app.UCAforeZlower.Value = -50;
            app.UCAforeZupper = uispinner(app.UCAforePanel);
            app.UCAforeZupper.Value = 50;
            app.XLabel = uilabel(app.UCAforePanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UCAforePanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.UCAforePanel);
            app.ZLabel.Text = 'Z';
            app.UCAaftPanel = uipanel(app.UpperAarmTab);
            app.UCAaftPanel.Title = 'UCA aft';
            app.lowerLabel_2 = uilabel(app.UCAaftPanel);
            app.lowerLabel_2.Text = 'lower';
            app.upperLabel_2 = uilabel(app.UCAaftPanel);
            app.upperLabel_2.Text = 'upper';
            app.UCAaftXlower = uispinner(app.UCAaftPanel);
            app.UCAaftXlower.Value = -50;
            app.UCAaftXupper = uispinner(app.UCAaftPanel);
            app.UCAaftXupper.Value = 50;
            app.UCAaftYlower = uispinner(app.UCAaftPanel);
            app.UCAaftYlower.Value = -50;
            app.UCAaftYupper = uispinner(app.UCAaftPanel);
            app.UCAaftYupper.Value = 50;
            app.UCAaftZlower = uispinner(app.UCAaftPanel);
            app.UCAaftZlower.Value = -50;
            app.UCAaftZupper = uispinner(app.UCAaftPanel);
            app.UCAaftZupper.Value = 50;
            app.XLabel_2 = uilabel(app.UCAaftPanel);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.UCAaftPanel);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.UCAaftPanel);
            app.ZLabel_2.Text = 'Z';
            app.UCAuprightPanel = uipanel(app.UpperAarmTab);
            app.UCAuprightPanel.Title = 'UCA upright';
            app.lowerLabel_3 = uilabel(app.UCAuprightPanel);
            app.lowerLabel_3.Text = 'lower';
            app.upperLabel_3 = uilabel(app.UCAuprightPanel);
            app.upperLabel_3.Text = 'upper';
            app.UCAuprightXlower = uispinner(app.UCAuprightPanel);
            app.UCAuprightXlower.Value = -50;
            app.UCAuprightXupper = uispinner(app.UCAuprightPanel);
            app.UCAuprightXupper.Value = 50;
            app.UCAuprightYlower = uispinner(app.UCAuprightPanel);
            app.UCAuprightYlower.Value = -50;
            app.UCAuprightYupper = uispinner(app.UCAuprightPanel);
            app.UCAuprightYupper.Value = 50;
            app.UCAuprightZlower = uispinner(app.UCAuprightPanel);
            app.UCAuprightZlower.Value = -50;
            app.UCAuprightZupper = uispinner(app.UCAuprightPanel);
            app.UCAuprightZupper.Value = 50;
            app.XLabel_3 = uilabel(app.UCAuprightPanel);
            app.XLabel_3.Text = 'X';
            app.YLabel_3 = uilabel(app.UCAuprightPanel);
            app.YLabel_3.Text = 'Y';
            app.ZLabel_3 = uilabel(app.UCAuprightPanel);
            app.ZLabel_3.Text = 'Z';
            app.UCAforeCheckBox = uicheckbox(app.UpperAarmTab);
            app.UCAforeCheckBox.Text = '';
            app.UCAforeCheckBox.Value = true;
            app.UCAaftCheckBox = uicheckbox(app.UpperAarmTab);
            app.UCAaftCheckBox.Text = '';
            app.UCAaftCheckBox.Value = true;
            app.UCAuprightCheckBox = uicheckbox(app.UpperAarmTab);
            app.UCAuprightCheckBox.Text = '';
            app.UCAuprightCheckBox.Value = true;
            app.LowerAarmTab = uitab(app.BoundsTabGroup);
            app.LowerAarmTab.Title = 'Lower A-arm';
            app.LCAforePanel = uipanel(app.LowerAarmTab);
            app.LCAforePanel.Title = 'LCA fore';
            app.lowerLabel_9 = uilabel(app.LCAforePanel);
            app.lowerLabel_9.Text = 'lower';
            app.upperLabel_9 = uilabel(app.LCAforePanel);
            app.upperLabel_9.Text = 'upper';
            app.LCAforeXlower = uispinner(app.LCAforePanel);
            app.LCAforeXlower.Value = -50;
            app.LCAforeXupper = uispinner(app.LCAforePanel);
            app.LCAforeXupper.Value = 50;
            app.LCAforeYlower = uispinner(app.LCAforePanel);
            app.LCAforeYlower.Value = -50;
            app.LCAforeYupper = uispinner(app.LCAforePanel);
            app.LCAforeYupper.Value = 50;
            app.LCAforeZlower = uispinner(app.LCAforePanel);
            app.LCAforeZlower.Value = -50;
            app.LCAforeZupper = uispinner(app.LCAforePanel);
            app.LCAforeZupper.Value = 50;
            app.XLabel_9 = uilabel(app.LCAforePanel);
            app.XLabel_9.Text = 'X';
            app.YLabel_9 = uilabel(app.LCAforePanel);
            app.YLabel_9.Text = 'Y';
            app.ZLabel_9 = uilabel(app.LCAforePanel);
            app.ZLabel_9.Text = 'Z';
            app.LCAaftPanel = uipanel(app.LowerAarmTab);
            app.LCAaftPanel.Title = 'LCA aft';
            app.lowerLabel_10 = uilabel(app.LCAaftPanel);
            app.lowerLabel_10.Text = 'lower';
            app.upperLabel_10 = uilabel(app.LCAaftPanel);
            app.upperLabel_10.Text = 'upper';
            app.LCAaftXlower = uispinner(app.LCAaftPanel);
            app.LCAaftXlower.Value = -50;
            app.LCAaftXupper = uispinner(app.LCAaftPanel);
            app.LCAaftXupper.Value = 50;
            app.LCAaftYlower = uispinner(app.LCAaftPanel);
            app.LCAaftYlower.Value = -50;
            app.LCAaftYupper = uispinner(app.LCAaftPanel);
            app.LCAaftYupper.Value = 50;
            app.LCAaftZlower = uispinner(app.LCAaftPanel);
            app.LCAaftZlower.Value = -50;
            app.LCAaftZupper = uispinner(app.LCAaftPanel);
            app.LCAaftZupper.Value = 50;
            app.XLabel_10 = uilabel(app.LCAaftPanel);
            app.XLabel_10.Text = 'X';
            app.YLabel_10 = uilabel(app.LCAaftPanel);
            app.YLabel_10.Text = 'Y';
            app.ZLabel_10 = uilabel(app.LCAaftPanel);
            app.ZLabel_10.Text = 'Z';
            app.LCAuprightPanel = uipanel(app.LowerAarmTab);
            app.LCAuprightPanel.Title = 'LCA upright';
            app.lowerLabel_11 = uilabel(app.LCAuprightPanel);
            app.lowerLabel_11.Text = 'lower';
            app.upperLabel_11 = uilabel(app.LCAuprightPanel);
            app.upperLabel_11.Text = 'upper';
            app.LCAuprightXlower = uispinner(app.LCAuprightPanel);
            app.LCAuprightXlower.Value = -50;
            app.LCAuprightXupper = uispinner(app.LCAuprightPanel);
            app.LCAuprightXupper.Value = 50;
            app.LCAuprightYlower = uispinner(app.LCAuprightPanel);
            app.LCAuprightYlower.Value = -50;
            app.LCAuprightYupper = uispinner(app.LCAuprightPanel);
            app.LCAuprightYupper.Value = 50;
            app.LCAuprightZlower = uispinner(app.LCAuprightPanel);
            app.LCAuprightZlower.Value = -50;
            app.LCAuprightZupper = uispinner(app.LCAuprightPanel);
            app.LCAuprightZupper.Value = 50;
            app.XLabel_11 = uilabel(app.LCAuprightPanel);
            app.XLabel_11.Text = 'X';
            app.YLabel_11 = uilabel(app.LCAuprightPanel);
            app.YLabel_11.Text = 'Y';
            app.ZLabel_11 = uilabel(app.LCAuprightPanel);
            app.ZLabel_11.Text = 'Z';
            app.LCAforeCheckBox = uicheckbox(app.LowerAarmTab);
            app.LCAforeCheckBox.Text = '';
            app.LCAforeCheckBox.Value = true;
            app.LCAaftCheckBox = uicheckbox(app.LowerAarmTab);
            app.LCAaftCheckBox.Text = '';
            app.LCAaftCheckBox.Value = true;
            app.LCAuprightCheckBox = uicheckbox(app.LowerAarmTab);
            app.LCAuprightCheckBox.Text = '';
            app.LCAuprightCheckBox.Value = true;
            app.SteeringTab_2 = uitab(app.BoundsTabGroup);
            app.SteeringTab_2.Title = 'Steering';
            app.SteeringRackPanel = uipanel(app.SteeringTab_2);
            app.SteeringRackPanel.Title = 'Steering Rack';
            app.lowerLabel_7 = uilabel(app.SteeringRackPanel);
            app.lowerLabel_7.Text = 'lower';
            app.upperLabel_7 = uilabel(app.SteeringRackPanel);
            app.upperLabel_7.Text = 'upper';
            app.SteeringRackXlower = uispinner(app.SteeringRackPanel);
            app.SteeringRackXlower.Value = -50;
            app.SteeringRackXupper = uispinner(app.SteeringRackPanel);
            app.SteeringRackXupper.Value = 50;
            app.SteeringRackYlower = uispinner(app.SteeringRackPanel);
            app.SteeringRackYlower.Value = -50;
            app.SteeringRackYupper = uispinner(app.SteeringRackPanel);
            app.SteeringRackYupper.Value = 50;
            app.SteeringRackZlower = uispinner(app.SteeringRackPanel);
            app.SteeringRackZlower.Value = -50;
            app.SteeringRackZupper = uispinner(app.SteeringRackPanel);
            app.SteeringRackZupper.Value = 50;
            app.XLabel_7 = uilabel(app.SteeringRackPanel);
            app.XLabel_7.Text = 'X';
            app.YLabel_7 = uilabel(app.SteeringRackPanel);
            app.YLabel_7.Text = 'Y';
            app.ZLabel_7 = uilabel(app.SteeringRackPanel);
            app.ZLabel_7.Text = 'Z';
            app.SteeringUprightPanel = uipanel(app.SteeringTab_2);
            app.SteeringUprightPanel.Title = 'Steering Upright';
            app.lowerLabel_8 = uilabel(app.SteeringUprightPanel);
            app.lowerLabel_8.Text = 'lower';
            app.upperLabel_8 = uilabel(app.SteeringUprightPanel);
            app.upperLabel_8.Text = 'upper';
            app.SteeringUprightXlower = uispinner(app.SteeringUprightPanel);
            app.SteeringUprightXlower.Value = -50;
            app.SteeringUprightXupper = uispinner(app.SteeringUprightPanel);
            app.SteeringUprightXupper.Value = 50;
            app.SteeringUprightYlower = uispinner(app.SteeringUprightPanel);
            app.SteeringUprightYlower.Value = -50;
            app.SteeringUprightYupper = uispinner(app.SteeringUprightPanel);
            app.SteeringUprightYupper.Value = 50;
            app.SteeringUprightZlower = uispinner(app.SteeringUprightPanel);
            app.SteeringUprightZlower.Value = -50;
            app.SteeringUprightZupper = uispinner(app.SteeringUprightPanel);
            app.SteeringUprightZupper.Value = 50;
            app.XLabel_8 = uilabel(app.SteeringUprightPanel);
            app.XLabel_8.Text = 'X';
            app.YLabel_8 = uilabel(app.SteeringUprightPanel);
            app.YLabel_8.Text = 'Y';
            app.ZLabel_8 = uilabel(app.SteeringUprightPanel);
            app.ZLabel_8.Text = 'Z';
            app.SteeringRackCheckBox = uicheckbox(app.SteeringTab_2);
            app.SteeringRackCheckBox.Text = '';
            app.SteeringRackCheckBox.Value = true;
            app.SteeringUprightCheckBox = uicheckbox(app.SteeringTab_2);
            app.SteeringUprightCheckBox.Text = '';
            app.SteeringUprightCheckBox.Value = true;
            app.Tab_2 = uitab(app.BoundsTabGroup);
            app.Panel_6 = uipanel(app.Panel_7);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.Panel_8 = uipanel(app.SettingsTab);
            app.RollLimit = uieditfield(app.Panel_8, 'numeric');
            app.RollLimit.Limits = [0 10];
            app.RollLimit.ValueDisplayFormat = '%11.4gº';
            app.RollLimit.Value = 3;
            app.LimitsLabel = uilabel(app.Panel_8);
            app.LimitsLabel.Text = 'Limits';
            app.SteeringUpperLimit = uieditfield(app.Panel_8, 'numeric');
            app.SteeringUpperLimit.Limits = [0 100];
            app.SteeringUpperLimit.ValueDisplayFormat = '%11.4gmm';
            app.SteeringUpperLimit.Value = 36;
            app.SteeringLowerLimit = uieditfield(app.Panel_8, 'numeric');
            app.SteeringLowerLimit.Limits = [-100 0];
            app.SteeringLowerLimit.ValueDisplayFormat = '%11.4gmm';
            app.SteeringLowerLimit.Value = -36;
            app.HeaveUpperLimit = uieditfield(app.Panel_8, 'numeric');
            app.HeaveUpperLimit.Limits = [0 100];
            app.HeaveUpperLimit.ValueDisplayFormat = '%11.4gmm';
            app.HeaveUpperLimit.Value = 25;
            app.HeaveLowerLimit = uieditfield(app.Panel_8, 'numeric');
            app.HeaveLowerLimit.Limits = [-100 0];
            app.HeaveLowerLimit.ValueDisplayFormat = '%11.4gmm';
            app.HeaveLowerLimit.Value = -25;
            app.SteeringStep = uieditfield(app.Panel_8, 'numeric');
            app.SteeringStep.Limits = [0.001 10];
            app.SteeringStep.ValueDisplayFormat = '%11.4gmm';
            app.SteeringStep.Value = 4;
            app.RollStep = uieditfield(app.Panel_8, 'numeric');
            app.RollStep.Limits = [0.001 1];
            app.RollStep.ValueDisplayFormat = '%11.4gº';
            app.RollStep.Value = 0.7;
            app.HeaveStep = uieditfield(app.Panel_8, 'numeric');
            app.HeaveStep.Limits = [0.001 10];
            app.HeaveStep.ValueDisplayFormat = '%11.4gmm';
            app.HeaveStep.Value = 5;
            app.StepLabel = uilabel(app.Panel_8);
            app.StepLabel.Text = 'Step';
            app.SteeringLabel = uilabel(app.Panel_8);
            app.SteeringLabel.Text = 'Steering';
            app.RollLabel = uilabel(app.Panel_8);
            app.RollLabel.Text = 'Roll';
            app.HeaveLabel = uilabel(app.Panel_8);
            app.HeaveLabel.Text = 'Heave';
            app.ToleranceLabel = uilabel(app.Panel_8);
            app.ToleranceLabel.Text = 'Tolerance';
            app.RollTol = uieditfield(app.Panel_8, 'numeric');
            app.RollTol.Limits = [0.001 1];
            app.RollTol.Tooltip = {'Precission of the roll analysis. Lower values take longer to analyze'};
            app.RollTol.Value = 0.5;
            app.InterpolationPanel = uipanel(app.Panel_8);
            app.InterpolationPanel.Title = 'Interpolation';
            app.SolverToleranceEditFieldLabel = uilabel(app.InterpolationPanel);
            app.SolverToleranceEditFieldLabel.Text = 'Solver Tolerance';
            app.SolverToleranceEditField = uieditfield(app.InterpolationPanel, 'numeric');
            app.SolverToleranceEditField.Value = 0.01;
            app.LinButton = uibutton(app.InterpolationPanel, 'state');
            app.LinButton.ValueChangedFcn = createCallbackFcn(app, @LinButtonValueChanged, true);
            app.LinButton.Tooltip = {'Linear type objective function'};
            app.LinButton.Text = 'Lin';
            app.LinButton.Value = true;
            app.ExpButton = uibutton(app.InterpolationPanel, 'state');
            app.ExpButton.ValueChangedFcn = createCallbackFcn(app, @ExpButtonValueChanged, true);
            app.ExpButton.Tooltip = {'Exponential type objective function'};
            app.ExpButton.Text = 'Exp';
            app.ObjectiveFunctionLabel = uilabel(app.InterpolationPanel);
            app.ObjectiveFunctionLabel.Text = 'Objective Function';
            app.PlotOptimProgressCheckBox = uicheckbox(app.InterpolationPanel);
            app.PlotOptimProgressCheckBox.Text = '';
            app.PlotOptimProgressCheckBox.Value = true;
            app.PlotoptimizationprogressLabel = uilabel(app.InterpolationPanel);
            app.PlotoptimizationprogressLabel.Text = 'Plot optimization progress';
            app.LoadSessionButton = uibutton(app.Panel_9, 'push');
            app.LoadSessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSessionButtonPushed, true);
            app.LoadSessionButton.Icon = 'file_open.png';
            app.LoadSessionButton.Tooltip = {'Load session'};
            app.LoadSessionButton.Text = '';
            app.SaveSessionButton = uibutton(app.Panel_9, 'push');
            app.SaveSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSessionButtonPushed, true);
            app.SaveSessionButton.Icon = 'file_save.png';
            app.SaveSessionButton.Tooltip = {'Save session'};
            app.SaveSessionButton.Text = '';
            app.Panel_10 = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel_10);
            app.ResultsListHeadingPanel = uipanel(app.GridLayout5);
            app.ResultsListHeadingPanel.Title = 'Results List';
            app.IDLabel = uilabel(app.ResultsListHeadingPanel);
            app.IDLabel.Text = 'ID';
            app.ResultsList = uipanel(app.GridLayout5);
            app.Panel_11 = uipanel(app.GridLayout5);
            app.AnalysisSelectDropDown = uidropdown(app.Panel_11);
            app.AnalysisSelectDropDown.Items = {'Heave', 'Roll', 'Steering'};
            app.AnalysisSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisSelectDropDownValueChanged, true);
            app.AnalysisSelectDropDown.Value = 'Heave';
            app.ParameterSelectDropDown = uidropdown(app.Panel_11);
            app.ParameterSelectDropDown.Items = {'Camber', 'Toe', 'KPI', 'Caster', 'Trail', 'Scrub', 'RC Height', 'RC Migration', 'Anti-Dive', 'Ackerman'};
            app.ParameterSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterSelectDropDownValueChanged, true);
            app.ParameterSelectDropDown.Value = 'Camber';
            app.ChangeParameterUpButton = uibutton(app.Panel_11, 'push');
            app.ChangeParameterUpButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeParameterUpButtonPushed, true);
            app.ChangeParameterUpButton.Text = '⯈';
            app.ChangeParameterDownButton = uibutton(app.Panel_11, 'push');
            app.ChangeParameterDownButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeParameterDownButtonPushed, true);
            app.ChangeParameterDownButton.Text = '⯇';
            app.ResultAxes = uiaxes(app.GridLayout5);
            app.SaveResultsPanel = uipanel(app.UIFigure);
            app.SaveResultsPanel.Title = 'Select result to save';
            app.SaveResultsPanel.Visible = 'off';
            app.SaveResultsListBox = uilistbox(app.SaveResultsPanel);
            app.SaveResultsListBox.Items = {};
            app.SaveResultsListBox.Value = {};
            app.SaveSelectionButton = uibutton(app.SaveResultsPanel, 'push');
            app.SaveSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectionButtonPushed, true);
            app.SaveSelectionButton.Text = 'Save';
            app.SaveCancelButton = uibutton(app.SaveResultsPanel, 'push');
            app.SaveCancelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCancelButtonPushed, true);
            app.SaveCancelButton.Text = 'Cancel';
            app.SaveSelectionAsButton = uibutton(app.SaveResultsPanel, 'push');
            app.SaveSelectionAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectionAsButtonPushed, true);
            app.SaveSelectionAsButton.Text = 'Save as...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aArmsOptim(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end