classdef bellcrankOptim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GlobalContainer                 matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        SidePanelContainer              matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        OptimizationOptionsPanel        matlab.ui.container.Panel
        BoundsPanel                     matlab.ui.container.Panel
        BellcrankPushSpinnerLabel       matlab.ui.control.Label
        BellcrankPushSpinner_lower      matlab.ui.control.Spinner
        BellcrankDamperSpinnerLabel     matlab.ui.control.Label
        BellcrankDamperSpinner_lower    matlab.ui.control.Spinner
        BellcrankangleSpinnerLabel      matlab.ui.control.Label
        BellcrankAngleSpinner_lower     matlab.ui.control.Spinner
        BellcrankPushSpinner_upper      matlab.ui.control.Spinner
        BellcrankDamperSpinner_upper    matlab.ui.control.Spinner
        BellcrankAngleSpinner_upper     matlab.ui.control.Spinner
        UpperLabel                      matlab.ui.control.Label
        LowerLabel                      matlab.ui.control.Label
        BellcrankPushCheckBox           matlab.ui.control.CheckBox
        BellcrankDamperCheckBox         matlab.ui.control.CheckBox
        BellcrankAngleCheckBox          matlab.ui.control.CheckBox
        ObjectivefunctionPanel          matlab.ui.container.Panel
        StaticValueSpinnerLabel         matlab.ui.control.Label
        StaticValueSpinner              matlab.ui.control.Spinner
        VariationSpinnerLabel           matlab.ui.control.Label
        VariationSpinner                matlab.ui.control.Spinner
        LinearitySpinnerLabel           matlab.ui.control.Label
        LinearitySpinner                matlab.ui.control.Spinner
        VariationCheckBox               matlab.ui.control.CheckBox
        LinearityCheckBox               matlab.ui.control.CheckBox
        ProgressivenessCheckBox         matlab.ui.control.CheckBox
        ProgressivenessSpinner_2Label   matlab.ui.control.Label
        ProgressivenessSpinner          matlab.ui.control.Spinner
        TargetPanel                     matlab.ui.container.Panel
        MotionRatioEditFieldLabel       matlab.ui.control.Label
        MotionRatioEditField            matlab.ui.control.NumericEditField
        DamperstaticlengthEditFieldLabel  matlab.ui.control.Label
        DamperLengthEditField           matlab.ui.control.NumericEditField
        RunButton                       matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        OptionsButton                   matlab.ui.control.Button
        SaveResultsPanel                matlab.ui.container.Panel
        SaveResultsListBox              matlab.ui.control.ListBox
        SaveSelectionButton             matlab.ui.control.Button
        SaveCancelButton                matlab.ui.control.Button
        SaveSelectionAsButton           matlab.ui.control.Button
        ResultsContainer                matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ResultAxes                      matlab.ui.control.UIAxes
        ResultsListPanel                matlab.ui.container.Panel
        IDLabel                         matlab.ui.control.Label
        L_pushLabel                     matlab.ui.control.Label
        L_damperLabel                   matlab.ui.control.Label
        AngleLabel                      matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ResultsList                     matlab.ui.container.Panel
        OptimizationconfigurationPanel  matlab.ui.container.Panel
        OptimConfigOKButton             matlab.ui.control.Button
        OptimConfigCancelButton         matlab.ui.control.Button
        LimitsLabel                     matlab.ui.control.Label
        ParallelUpperLimit              matlab.ui.control.NumericEditField
        ParallelLowerLimit              matlab.ui.control.NumericEditField
        ParallelStep                    matlab.ui.control.NumericEditField
        StepLabel                       matlab.ui.control.Label
        WheeltravelLabel                matlab.ui.control.Label
        SolverConfigPanel               matlab.ui.container.Panel
        SolvertoleranceLabel            matlab.ui.control.Label
        SolverTolEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp
        SessionData
        OptimConfig % Description
    end
    
    methods (Access = private)
        
        function addResult(app,OptimData)
            try
                s = length(app.SessionData.Optimization)+1;
                if s > 1
                    if isfield(OptimData,'ID') && ~isempty(OptimData.ID)
                        OptimData.ID = ['Test ' num2str(s-1) ' - ' OptimData.ID];
                    else
                        OptimData.ID = ['Test ' num2str(s-1)];
                    end
                end
                app.SessionData.Optimization{s} = OptimData;
                
                % Add data to results list
                children = app.ResultsList.Children;
                checked = false(1,s);
                checked(end) = true;
                j = 1;
                for i=1:length(children)
                    if strcmp(children(i).Type,'uicheckbox')
                        if children(i).Value
                            checked(end-j) = true;
                        end
                        j = j+1;
                    end
                    delete(children(i));
                end
                for i=1:s
                    if s ==1
                        zPos = 77;
                    elseif s < app.ResultsList.Position(4)/20
                        zPos = app.ResultsList.Position(4)-25-(i-1)*20;
                    else
                        zPos = 20*(s-i)+5;
                    end
                    uicheckbox(app.ResultsList, ...
                        'Text',app.SessionData.Optimization{i}.ID, ...
                        'Value',checked(i), ...
                        'Position', [10 zPos 400 20], ...
                        'ValueChangedFcn', @app.resultCheckBoxCB, ...
                        'Tooltip',{['Targets: Motion Ratio = ' sprintf('%.3f',app.SessionData.Optimization{i}.Targets.MotionRatio) ' Damper Length = ' sprintf('%.1f',app.SessionData.Optimization{i}.Targets.DamperLength)], ...
                        ['Weights = [' sprintf('%.2f',app.SessionData.Optimization{i}.Weights.Static) ' ' sprintf('%.2f',app.SessionData.Optimization{i}.Weights.Variation) ' ' sprintf('%.2f',app.SessionData.Optimization{i}.Weights.Linearity) ' ' sprintf('%.2f',app.SessionData.Optimization{i}.Weights.Progressiveness) ']']});
                    uilabel(app.ResultsList, ...
                        'Text', sprintf('%.1f', norm(app.SessionData.Optimization{i}.X(37:39)-app.SessionData.Optimization{i}.X(34:36))), ...
                        'Position', [415 zPos 50 20], ...
                        'HorizontalAlignment','center');
                    uilabel(app.ResultsList, ...
                        'Text', sprintf('%.1f', norm(app.SessionData.Optimization{i}.X(37:39)-app.SessionData.Optimization{i}.X(40:42))), ...
                        'Position', [485 zPos 50 20], ...
                        'HorizontalAlignment','center');
                    uilabel(app.ResultsList, ...
                        'Text', sprintf('%.1f', vectsAngle(app.SessionData.Optimization{i}.X(40:42)-app.SessionData.Optimization{i}.X(37:39),app.SessionData.Optimization{i}.X(34:36)-app.SessionData.Optimization{i}.X(37:39))), ...
                        'Position', [555 zPos 50 20], ...
                        'HorizontalAlignment','center');
                end
            catch err
                uialert(app.UIFigure,['addResult error. Data could not be stored. ' err.message],'Error');
                app.SessionData.Optimization(s) = [];
            end
        end
        
        function updateResultsPlot(app)
            index = false(1,length(app.ResultsList.Children));
            for i=1:length(app.ResultsList.Children)
                if strcmp(app.ResultsList.Children(i).Type,'uicheckbox')
                    index(i) = true;
                end
            end
            children = app.ResultsList.Children(index);
            children = flip(children);
            ax = app.ResultAxes;
            cla(ax);
            hold(ax,'on');
            legend(ax);
            for i=1:length(children)
                if children(i).Value
                    plot(ax,app.SessionData.Optimization{i}.wheelTravel,app.SessionData.Optimization{i}.MR);
                    ax.Legend.String{end} = app.SessionData.Optimization{i}.ID;
                end
            end
        end
        function resultCheckBoxCB(app,~,~)
            updateResultsPlot(app)
        end
        
        function runOptim(app)
            try
                optimProgressUI = uiprogressdlg(app.UIFigure,'Title','Optimization in progress','Indeterminate','on');
                % Suspension Coordinates
                X = app.SessionData.dataset.X;
                % Optimization parameters
                %% Bounds for bellcrank dimension -> [bellcrankPush bellcrankDamper bellcrankAngle]
                lb = [app.BellcrankPushSpinner_lower.Value*app.BellcrankPushCheckBox.Value + norm(X(34:36)-X(37:39))*(1-app.BellcrankPushCheckBox.Value)...
                    app.BellcrankDamperSpinner_lower.Value*app.BellcrankDamperCheckBox.Value + norm(X(37:39)-X(40:42))*(1-app.BellcrankDamperCheckBox.Value) ...
                    app.BellcrankAngleSpinner_lower.Value*app.BellcrankAngleCheckBox.Value + vectsAngle(X(34:36)-X(37:39),X(40:42)-X(37:39))*(1-app.BellcrankAngleCheckBox.Value)];
                ub = [app.BellcrankPushSpinner_upper.Value*app.BellcrankPushCheckBox.Value + norm(X(34:36)-X(37:39))*(1-app.BellcrankPushCheckBox.Value) ...
                    app.BellcrankDamperSpinner_upper.Value*app.BellcrankDamperCheckBox.Value + norm(X(37:39)-X(40:42))*(1-app.BellcrankDamperCheckBox.Value) ...
                    app.BellcrankAngleSpinner_upper.Value*app.BellcrankAngleCheckBox.Value + vectsAngle(X(34:36)-X(37:39),X(40:42)-X(37:39))*(1-app.BellcrankAngleCheckBox.Value)];
                %% Number of variables to optimize
                nvars = 3; %bellcrankDimension = [brazoPush brazoDamper angle]
                %% Equality/Inequality constraints
                A = [];
                b = [];
                Aeq = [];
                beq = [];
                % Static motion ratio value target
                staticMR = app.MotionRatioEditField.Value;
                % Weights of objective function. Weight of each parameter used to define objective function.
                % weights -> [staticValue totalVariation linearity progresiveness]Parameters
                weights = [app.StaticValueSpinner.Value ...
                    app.VariationSpinner.Value*app.VariationCheckBox.Value ...
                    app.LinearitySpinner.Value*app.LinearityCheckBox.Value ...
                    app.ProgressivenessSpinner.Value*app.ProgressivenessCheckBox.Value];
                
                fun = @(bc)app.motionRatio_ofun(bc, X, staticMR, weights);
                options = optimoptions('ga','FunctionTolerance',app.OptimConfig{2}.tol);
                bellcrank = ga(fun,nvars,A,b,Aeq,beq,lb,ub,[],options);
                close(optimProgressUI);
                selection = uiconfirm(app.UIFigure, ...
                    {'Optimization completed. The following results were obtained:', ...
                    [' -L_push = ' sprintf('%.1f', bellcrank(1)) ' mm'], ...
                    [' -L_damper = ' sprintf('%.1f', bellcrank(2)) ' mm'], ...
                    [' -Bellcrank angle = ' sprintf('%.1f', bellcrank(3)) ' deg']}, ...
                    'Add result', ...
                    'Options',{'Add' 'Dismiss'}, ...
                    'CancelOption',2, ...
                    'DefaultOption','Add');
                switch selection
                    case 'Dismiss'
                        return;
                    case 'Add'
                        [A,B] = intersectCircleSphere(X(37:39), bellcrank(2), cross(X(34:36)-X(37:39), X(37:39)-X(40:42)), X(43:45), app.DamperLengthEditField.Value);
                        if sign(dot(cross(X(43:45)-X(40:42),X(40:42)-X(37:39)), cross(X(43:45)-A',A'-X(37:39)))) == 1 %norm(A-X(40:42)') < norm(B-X(40:42)')
                            P_bellcrankDamper = A;
                        else
                            P_bellcrankDamper = B;
                        end
                        A = rotatePointFixedAxis(bellcrank(3), cross(X(37:39)'-P_bellcrankDamper, X(34:36)'-X(37:39)'), X(37:39), P_bellcrankDamper);
                        B = rotatePointFixedAxis(bellcrank(3), -cross(X(37:39)'-P_bellcrankDamper, X(34:36)'-X(37:39)'), X(37:39), P_bellcrankDamper);
                        if norm(A-X(34:36)') < norm(B-X(34:36)')
                            P_bellcrankPush = X(37:39)' + (A-X(37:39)')*bellcrank(1)/bellcrank(2);
                        else
                            P_bellcrankPush = X(37:39)' + (B-X(37:39)')*bellcrank(1)/bellcrank(2);
                        end
                        X(34:36) = P_bellcrankPush(:);
                        X(40:42) = P_bellcrankDamper(:);
                        [X_parallel, wheelTravel] = parallel(X, 0.5, [app.OptimConfig{1}.limits(1) app.OptimConfig{1}.limits(2)]);
                        optimData.X = X;
                        optimData.wheelTravel = wheelTravel;
                        optimData.MR = motionRatio(X_parallel,wheelTravel);
                        optimData.ID = app.SessionData.dataset.ID;
                        optimData.Targets.MotionRatio = app.MotionRatioEditField.Value;
                        optimData.Targets.DamperLength = app.DamperLengthEditField.Value;
                        optimData.Weights.Static = app.StaticValueSpinner.Value;
                        optimData.Weights.Variation = app.VariationSpinner.Value*app.VariationCheckBox.Value;
                        optimData.Weights.Linearity = app.LinearitySpinner.Value*app.LinearityCheckBox.Value;
                        optimData.Weights.Progressiveness = app.ProgressivenessSpinner.Value*app.ProgressivenessCheckBox.Value;
                        addResult(app,optimData)
                        updateResultsPlot(app)
                end
            catch err
                close(optimProgressUI);
                uialert(app.UIFigure,['runOptim error. Optimization failed. ' err.message], 'Error');
            end
        end
        
        function [val] = motionRatio_ofun(app,bellcrank,X,staticMR,weights)
            %%Defines objective function to calculate optimum bellcrank dimensions for:
            %   -Certain motion ratio static value target
            %   -Minimum variation of motion ratio
            %   -Maximum linearity of motion ratio
            %'bellcrank' is a row vector of length = 3, such that:
            %   -bellcrank(1) = push side bellcrank arm length
            %   -bellcrank(2) = damper side bellcrank arm length
            %   -bellcrank(3) = angle between bellcrank arms
            
            % Calculate points of bellcrank based on dimensions and attachment points
            [A,B] = intersectCircleSphere(X(37:39), bellcrank(2), cross(X(34:36)-X(37:39), X(37:39)-X(40:42)), X(43:45), app.DamperLengthEditField.Value);
            if sign(dot(cross(X(43:45)-X(40:42),X(40:42)-X(37:39)), cross(X(43:45)-A',A'-X(37:39)))) == 1 %norm(A-X(40:42)') < norm(B-X(40:42)')
                P_bellcrankDamper = A;
            else
                P_bellcrankDamper = B;
            end
            A = rotatePointFixedAxis(bellcrank(3), cross(X(37:39)'-P_bellcrankDamper, X(34:36)'-X(37:39)'), X(37:39), P_bellcrankDamper);
            B = rotatePointFixedAxis(bellcrank(3), -cross(X(37:39)'-P_bellcrankDamper, X(34:36)'-X(37:39)'), X(37:39), P_bellcrankDamper);
            if norm(A-X(34:36)') < norm(B-X(34:36)')
                P_bellcrankPush = X(37:39)' + (A-X(37:39)')*bellcrank(1)/bellcrank(2);
            else
                P_bellcrankPush = X(37:39)' + (B-X(37:39)')*bellcrank(1)/bellcrank(2);
            end
            X(34:36) = P_bellcrankPush(:);
            X(40:42) = P_bellcrankDamper(:);
            
            [X_parallel, wheelTravel] = parallel(X, app.OptimConfig{1}.step, [app.OptimConfig{1}.limits(1) app.OptimConfig{1}.limits(2)]);
            MR = motionRatio(X_parallel, wheelTravel);
            
            MR_1 = diff(MR); %Derivate of MR
            staticValue = MR(abs(wheelTravel) == min(abs(wheelTravel)))-staticMR;
            variation = max(MR)-min(MR)/staticMR;
            %linearity = (max(MR_1)-min(MR_1))/staticMR;
            linearity = std(MR_1);
            progressiveness = min(exp(mean(MR_1)*sign(weights(4))),1); % Tends to zero when motion ratio curve is drecressing (negative slope)
            
            val = [abs(staticValue) abs(variation) abs(linearity) progressiveness]*weights';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, data)
            % Initialize app components
            app.UIFigure.Position(1) = mainapp.UIFigure.Position(1)+mainapp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = mainapp.UIFigure.Position(2)+mainapp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            app.CancelButton.Icon = fullfile(matlabroot,'toolbox', 'matlab','addons','release','images','rte_error.png');
            app.RunButton.Icon = fullfile(matlabroot, 'toolbox', 'matlab','addons','release','images','toolstrip','Run_24.png');
            app.OptionsButton.Icon = fullfile(matlabroot, 'toolbox', 'matlab','appdesigner', 'web', 'release', 'uicomponents_appdesigner_plugin', 'images', 'configure-16.png');
            axtoolbar(app.ResultAxes,{'datacursor','pan','zoomin','rotate','zoomout','restoreview'});
            temp = load('BellcrankOptimConfig.mat');
            app.OptimConfig = temp.OptimConfiguration;
            
            % Save data
            app.SessionData = {};
            app.SessionData.Optimization = {};
            app.CallingApp = mainapp;
            app.SessionData.dataset = data.dataset;
            
            % Load data to app components
            app.ParallelLowerLimit.Value = app.OptimConfig{1}.limits(1);
            app.ParallelUpperLimit.Value = app.OptimConfig{1}.limits(2);
            app.ParallelStep.Value = app.OptimConfig{1}.step;
            app.SolverTolEditField.Value = app.OptimConfig{2}.tol;
            
            app.DamperLengthEditField.Value = norm(app.SessionData.dataset.X(43:45)-app.SessionData.dataset.X(40:42));
            try
                app.BellcrankPushSpinner_lower.Value = norm(app.SessionData.dataset.X(37:39)-app.SessionData.dataset.X(34:36))-20;
            catch
                app.BellcrankPushSpinner_lower.Value = 0;
            end
            app.BellcrankPushSpinner_upper.Value = norm(app.SessionData.dataset.X(37:39)-app.SessionData.dataset.X(34:36))+20;
            try
                app.BellcrankDamperSpinner_lower.Value = norm(app.SessionData.dataset.X(37:39)-app.SessionData.dataset.X(40:42))-20;
            catch
                app.BellcrankDamperSpinner_lower.Value = 0;
            end
            app.BellcrankDamperSpinner_upper.Value = norm(app.SessionData.dataset.X(37:39)-app.SessionData.dataset.X(40:42))+20;
            try
                app.BellcrankAngleSpinner_lower.Value = vectsAngle(app.SessionData.dataset.X(37:39)-app.SessionData.dataset.X(40:42),app.SessionData.dataset.X(37:39)-app.SessionData.dataset.X(34:36))-20;
            catch
                app.BellcrankAngleSpinner_lower.Value = 0.1;
            end
            app.BellcrankAngleSpinner_upper.Value = vectsAngle(app.SessionData.dataset.X(37:39)-app.SessionData.dataset.X(40:42),app.SessionData.dataset.X(37:39)-app.SessionData.dataset.X(34:36))+20;
            % Add data to results list
            optimData.X = app.SessionData.dataset.X;
            [X_parallel, wheelTravel] = parallel(optimData.X, 0.5, [app.OptimConfig{1}.limits(1) app.OptimConfig{1}.limits(2)]);
            optimData.wheelTravel = wheelTravel;
            optimData.MR = motionRatio(X_parallel,wheelTravel);
            optimData.ID = app.SessionData.dataset.ID;
            optimData.Targets.MotionRatio = optimData.MR(abs(wheelTravel) == min(abs(wheelTravel)));
            optimData.Targets.DamperLength = app.DamperLengthEditField.Value;
            optimData.Weights.Static = 0;
            optimData.Weights.Variation = 0;
            optimData.Weights.Linearity = 0;
            optimData.Weights.Progressiveness = 0;
            addResult(app,optimData)
            updateResultsPlot(app)
            % Wait until data is loaded to enable app resize
            app.UIFigure.Resize = 'on';
        end

        % Close request function: UIFigure
        function closeReqFcn(app, event)
            s = uiconfirm(app.UIFigure,'If you continue, unsaved results will be lost. Do you want to continue?','Exit confirm');
            switch s
                case 'OK'
                    app.CallingApp.BellcrankOptimButton.Enable = 'on';
                    delete(app)
                case 'Cancel'
                    return;
            end
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
            switch app.OptimizationconfigurationPanel.Visible
                case 'off'
                    app.OptimizationconfigurationPanel.Position = [137 ... %left
                                                       app.UIFigure.Position(4)-269 ... %bottom
                                                       318 ... %width
                                                       199 ]; %height
                    app.OptimizationconfigurationPanel.Visible = 'on';
                case 'on'
                    OptimConfigCancelButtonPushed(app, event)
            end
        end

        % Button pushed function: OptimConfigCancelButton
        function OptimConfigCancelButtonPushed(app, event)
            app.OptimizationconfigurationPanel.Visible = 'off';
            app.ParallelLowerLimit.Value = app.OptimConfig{1}.limits(1);
            app.ParallelUpperLimit.Value = app.OptimConfig{1}.limits(2);
            app.ParallelStep.Value = app.OptimConfig{1}.step;
            app.SolverTolEditField.Value = app.OptimConfig{2}.tol;
        end

        % Button pushed function: OptimConfigOKButton
        function OptimConfigOKButtonPushed(app, event)
            app.OptimizationconfigurationPanel.Visible = 'off';
            app.OptimConfig{1}.limits(1) = app.ParallelLowerLimit.Value;
            app.OptimConfig{1}.limits(2) = app.ParallelUpperLimit.Value;
            app.OptimConfig{1}.step = app.ParallelStep.Value;
            app.OptimConfig{2}.tol = app.SolverTolEditField.Value;
            OptimConfiguration = app.OptimConfig;
            save(fullfile('AppData','BellcrankOptimConfig.mat'),'OptimConfiguration');
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closeReqFcn(app, event)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveResultsListBox.Items = {};
            for i=1:length(app.SessionData.Optimization)
                app.SaveResultsListBox.Items{end+1} = app.SessionData.Optimization{i}.ID;
            end
            switch app.SaveResultsPanel.Visible
                case 'off'
                    app.SaveResultsPanel.Visible = 'on';
                case 'on'
                    app.SaveResultsPanel.Visible = 'off';
            end
        end

        % Button pushed function: SaveCancelButton
        function SaveCancelButtonPushed(app, event)
            app.SaveResultsPanel.Visible = 'off';
        end

        % Button pushed function: SaveSelectionButton
        function SaveSelectionButtonPushed(app, event)
            if ~isempty(app.SaveResultsListBox.Value)
                o = strcmp(app.SaveResultsListBox.Value, app.SaveResultsListBox.Items);
            else
                return;
            end
            try
                data = app.SessionData.dataset;
                data.X = app.SessionData.Optimization{o}.X;
                optimCB(app.CallingApp, data, app)
            catch
                uialert(app.UIFigure,'Data could not be saved','Warning','Icon','warning');
            end
            app.SaveResultsPanel.Visible = 'off';
        end

        % Button pushed function: SaveSelectionAsButton
        function SaveSelectionAsButtonPushed(app, event)
            if ~isempty(app.SaveResultsListBox.Value)
                o = strcmp(app.SaveResultsListBox.Value, app.SaveResultsListBox.Items);
            else
                return;
            end
            try
                data = app.SessionData.dataset;
                data.X = app.SessionData.Optimization{o}.X;
                data.ID = app.SessionData.Optimization{o}.ID;
                optimSaveAsCB(app.CallingApp, data, app);
            catch
                uialert(app.UIFigure,'Data could not be saved','Warning','Icon','warning');
            end
        end

        % Value changed function: VariationCheckBox
        function VariationCheckBoxValueChanged(app, event)
            switch app.VariationCheckBox.Value
                case 1 % On
                    app.VariationSpinner.Enable = 'on';
                case 0 % Off
                    app.VariationSpinner.Enable = 'off';
            end
        end

        % Value changed function: LinearityCheckBox
        function LinearityCheckBoxValueChanged(app, event)
            switch app.LinearityCheckBox.Value
                case 1 % On
                    app.LinearitySpinner.Enable = 'on';
                case 0 % Off
                    app.LinearitySpinner.Enable = 'off';
            end
        end

        % Value changed function: ProgressivenessCheckBox
        function ProgressivenessCheckBoxValueChanged(app, event)
            switch app.ProgressivenessCheckBox.Value
                case 1 % On
                    app.ProgressivenessSpinner.Enable = 'on';
                case 0 % Off
                    app.ProgressivenessSpinner.Enable = 'off';
            end
        end

        % Value changed function: BellcrankPushCheckBox
        function BellcrankPushCheckBoxValueChanged(app, event)
            switch app.BellcrankPushCheckBox.Value
                case 1 % On
                    app.BellcrankPushSpinner_lower.Enable = 'on';
                    app.BellcrankPushSpinner_upper.Enable = 'on';
                case 0 % Off
                    app.BellcrankPushSpinner_lower.Enable = 'off';
                    app.BellcrankPushSpinner_upper.Enable = 'off';
            end
        end

        % Value changed function: BellcrankDamperCheckBox
        function BellcrankDamperCheckBoxValueChanged(app, event)
            switch app.BellcrankDamperCheckBox.Value
                case 1 % On
                    app.BellcrankDamperSpinner_lower.Enable = 'on';
                    app.BellcrankDamperSpinner_upper.Enable = 'on';
                case 0 % Off
                    app.BellcrankDamperSpinner_lower.Enable = 'off';
                    app.BellcrankDamperSpinner_upper.Enable = 'off';
            end
        end

        % Value changed function: BellcrankAngleCheckBox
        function BellcrankAngleCheckBoxValueChanged(app, event)
            switch app.BellcrankAngleCheckBox.Value
                case 1 % On
                    app.BellcrankAngleSpinner_lower.Enable = 'on';
                    app.BellcrankAngleSpinner_upper.Enable = 'on';
                case 0 % Off
                    app.BellcrankAngleSpinner_lower.Enable = 'off';
                    app.BellcrankAngleSpinner_upper.Enable = 'off';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            runOptim(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Bellcrank Optimization';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeReqFcn, true);
            app.GlobalContainer = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.GlobalContainer);
            app.SidePanelContainer = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.SidePanelContainer);
            app.OptimizationOptionsPanel = uipanel(app.GridLayout2);
            app.BoundsPanel = uipanel(app.OptimizationOptionsPanel);
            app.BoundsPanel.Title = 'Bounds';
            app.BellcrankPushSpinnerLabel = uilabel(app.BoundsPanel);
            app.BellcrankPushSpinnerLabel.Text = 'Bellcrank-Push';
            app.BellcrankPushSpinner_lower = uispinner(app.BoundsPanel);
            app.BellcrankPushSpinner_lower.LowerLimitInclusive = 'off';
            app.BellcrankPushSpinner_lower.Limits = [0 Inf];
            app.BellcrankPushSpinner_lower.Value = 1;
            app.BellcrankDamperSpinnerLabel = uilabel(app.BoundsPanel);
            app.BellcrankDamperSpinnerLabel.Text = 'Bellcrank-Damper';
            app.BellcrankDamperSpinner_lower = uispinner(app.BoundsPanel);
            app.BellcrankDamperSpinner_lower.LowerLimitInclusive = 'off';
            app.BellcrankDamperSpinner_lower.Limits = [0 Inf];
            app.BellcrankDamperSpinner_lower.Value = 1;
            app.BellcrankangleSpinnerLabel = uilabel(app.BoundsPanel);
            app.BellcrankangleSpinnerLabel.Text = 'Bellcrank angle';
            app.BellcrankAngleSpinner_lower = uispinner(app.BoundsPanel);
            app.BellcrankAngleSpinner_lower.LowerLimitInclusive = 'off';
            app.BellcrankAngleSpinner_lower.Limits = [0 Inf];
            app.BellcrankAngleSpinner_lower.Value = 1;
            app.BellcrankPushSpinner_upper = uispinner(app.BoundsPanel);
            app.BellcrankPushSpinner_upper.Limits = [0 Inf];
            app.BellcrankDamperSpinner_upper = uispinner(app.BoundsPanel);
            app.BellcrankDamperSpinner_upper.Limits = [0 Inf];
            app.BellcrankAngleSpinner_upper = uispinner(app.BoundsPanel);
            app.BellcrankAngleSpinner_upper.Limits = [0 Inf];
            app.UpperLabel = uilabel(app.BoundsPanel);
            app.UpperLabel.Text = 'Upper';
            app.LowerLabel = uilabel(app.BoundsPanel);
            app.LowerLabel.Text = 'Lower';
            app.BellcrankPushCheckBox = uicheckbox(app.BoundsPanel);
            app.BellcrankPushCheckBox.ValueChangedFcn = createCallbackFcn(app, @BellcrankPushCheckBoxValueChanged, true);
            app.BellcrankPushCheckBox.Text = '';
            app.BellcrankPushCheckBox.Value = true;
            app.BellcrankDamperCheckBox = uicheckbox(app.BoundsPanel);
            app.BellcrankDamperCheckBox.ValueChangedFcn = createCallbackFcn(app, @BellcrankDamperCheckBoxValueChanged, true);
            app.BellcrankDamperCheckBox.Text = '';
            app.BellcrankDamperCheckBox.Value = true;
            app.BellcrankAngleCheckBox = uicheckbox(app.BoundsPanel);
            app.BellcrankAngleCheckBox.ValueChangedFcn = createCallbackFcn(app, @BellcrankAngleCheckBoxValueChanged, true);
            app.BellcrankAngleCheckBox.Text = '';
            app.BellcrankAngleCheckBox.Value = true;
            app.ObjectivefunctionPanel = uipanel(app.OptimizationOptionsPanel);
            app.ObjectivefunctionPanel.Title = 'Objective function weights';
            app.StaticValueSpinnerLabel = uilabel(app.ObjectivefunctionPanel);
            app.StaticValueSpinnerLabel.Text = 'Static Value';
            app.StaticValueSpinner = uispinner(app.ObjectivefunctionPanel);
            app.StaticValueSpinner.Step = 0.01;
            app.StaticValueSpinner.Limits = [0 1];
            app.StaticValueSpinner.Value = 0.5;
            app.VariationSpinnerLabel = uilabel(app.ObjectivefunctionPanel);
            app.VariationSpinnerLabel.Text = 'Variation';
            app.VariationSpinner = uispinner(app.ObjectivefunctionPanel);
            app.VariationSpinner.Step = 0.01;
            app.VariationSpinner.Limits = [0 1];
            app.VariationSpinner.Value = 0.5;
            app.LinearitySpinnerLabel = uilabel(app.ObjectivefunctionPanel);
            app.LinearitySpinnerLabel.Text = 'Linearity';
            app.LinearitySpinner = uispinner(app.ObjectivefunctionPanel);
            app.LinearitySpinner.Step = 0.01;
            app.LinearitySpinner.Limits = [0 1];
            app.LinearitySpinner.Value = 0.5;
            app.VariationCheckBox = uicheckbox(app.ObjectivefunctionPanel);
            app.VariationCheckBox.ValueChangedFcn = createCallbackFcn(app, @VariationCheckBoxValueChanged, true);
            app.VariationCheckBox.Text = '';
            app.VariationCheckBox.Value = true;
            app.LinearityCheckBox = uicheckbox(app.ObjectivefunctionPanel);
            app.LinearityCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinearityCheckBoxValueChanged, true);
            app.LinearityCheckBox.Text = '';
            app.LinearityCheckBox.Value = true;
            app.ProgressivenessCheckBox = uicheckbox(app.ObjectivefunctionPanel);
            app.ProgressivenessCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProgressivenessCheckBoxValueChanged, true);
            app.ProgressivenessCheckBox.Text = '';
            app.ProgressivenessCheckBox.Value = true;
            app.ProgressivenessSpinner_2Label = uilabel(app.ObjectivefunctionPanel);
            app.ProgressivenessSpinner_2Label.Text = 'Progressiveness';
            app.ProgressivenessSpinner = uispinner(app.ObjectivefunctionPanel);
            app.ProgressivenessSpinner.Step = 0.01;
            app.ProgressivenessSpinner.Limits = [-1 1];
            app.ProgressivenessSpinner.Value = 0.5;
            app.TargetPanel = uipanel(app.OptimizationOptionsPanel);
            app.TargetPanel.Title = 'Target';
            app.MotionRatioEditFieldLabel = uilabel(app.TargetPanel);
            app.MotionRatioEditFieldLabel.Text = 'Motion Ratio';
            app.MotionRatioEditField = uieditfield(app.TargetPanel, 'numeric');
            app.MotionRatioEditField.LowerLimitInclusive = 'off';
            app.MotionRatioEditField.Limits = [0 Inf];
            app.MotionRatioEditField.Value = 1;
            app.DamperstaticlengthEditFieldLabel = uilabel(app.TargetPanel);
            app.DamperstaticlengthEditFieldLabel.Text = 'Damper static length';
            app.DamperLengthEditField = uieditfield(app.TargetPanel, 'numeric');
            app.DamperLengthEditField.LowerLimitInclusive = 'off';
            app.DamperLengthEditField.Limits = [0 Inf];
            app.DamperLengthEditField.Value = 1;
            app.RunButton = uibutton(app.OptimizationOptionsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CancelButton = uibutton(app.OptimizationOptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = '';
            app.SaveButton = uibutton(app.OptimizationOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OptionsButton = uibutton(app.OptimizationOptionsPanel, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Text = '';
            app.SaveResultsPanel = uipanel(app.OptimizationOptionsPanel);
            app.SaveResultsPanel.Title = 'Select result to save';
            app.SaveResultsPanel.Visible = 'off';
            app.SaveResultsListBox = uilistbox(app.SaveResultsPanel);
            app.SaveResultsListBox.Items = {};
            app.SaveResultsListBox.Value = {};
            app.SaveSelectionButton = uibutton(app.SaveResultsPanel, 'push');
            app.SaveSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectionButtonPushed, true);
            app.SaveSelectionButton.Text = 'Save';
            app.SaveCancelButton = uibutton(app.SaveResultsPanel, 'push');
            app.SaveCancelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCancelButtonPushed, true);
            app.SaveCancelButton.Text = 'Cancel';
            app.SaveSelectionAsButton = uibutton(app.SaveResultsPanel, 'push');
            app.SaveSelectionAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectionAsButtonPushed, true);
            app.SaveSelectionAsButton.Text = 'Save as...';
            app.ResultsContainer = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.ResultsContainer);
            app.ResultAxes = uiaxes(app.GridLayout3);
            title(app.ResultAxes, '')
            xlabel(app.ResultAxes, 'Wheel Travel')
            ylabel(app.ResultAxes, 'Motion Ratio')
            app.ResultsListPanel = uipanel(app.GridLayout3);
            app.ResultsListPanel.Title = 'Results List';
            app.IDLabel = uilabel(app.ResultsListPanel);
            app.IDLabel.Text = 'ID';
            app.L_pushLabel = uilabel(app.ResultsListPanel);
            app.L_pushLabel.Text = 'L_push';
            app.L_damperLabel = uilabel(app.ResultsListPanel);
            app.L_damperLabel.Text = 'L_damper';
            app.AngleLabel = uilabel(app.ResultsListPanel);
            app.AngleLabel.Text = 'Angle';
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.ResultsList = uipanel(app.GridLayout4);
            app.OptimizationconfigurationPanel = uipanel(app.UIFigure);
            app.OptimizationconfigurationPanel.Title = 'Optimization configuration';
            app.OptimizationconfigurationPanel.Visible = 'off';
            app.OptimConfigOKButton = uibutton(app.OptimizationconfigurationPanel, 'push');
            app.OptimConfigOKButton.ButtonPushedFcn = createCallbackFcn(app, @OptimConfigOKButtonPushed, true);
            app.OptimConfigOKButton.Text = 'OK';
            app.OptimConfigCancelButton = uibutton(app.OptimizationconfigurationPanel, 'push');
            app.OptimConfigCancelButton.ButtonPushedFcn = createCallbackFcn(app, @OptimConfigCancelButtonPushed, true);
            app.OptimConfigCancelButton.Text = 'Cancel';
            app.LimitsLabel = uilabel(app.OptimizationconfigurationPanel);
            app.LimitsLabel.Text = 'Limits';
            app.ParallelUpperLimit = uieditfield(app.OptimizationconfigurationPanel, 'numeric');
            app.ParallelUpperLimit.Limits = [0 100];
            app.ParallelLowerLimit = uieditfield(app.OptimizationconfigurationPanel, 'numeric');
            app.ParallelLowerLimit.Limits = [-100 0];
            app.ParallelStep = uieditfield(app.OptimizationconfigurationPanel, 'numeric');
            app.ParallelStep.Limits = [0.001 10];
            app.ParallelStep.Value = 0.1;
            app.StepLabel = uilabel(app.OptimizationconfigurationPanel);
            app.StepLabel.Text = 'Step';
            app.WheeltravelLabel = uilabel(app.OptimizationconfigurationPanel);
            app.WheeltravelLabel.Text = 'Wheel travel';
            app.SolverConfigPanel = uipanel(app.OptimizationconfigurationPanel);
            app.SolverConfigPanel.Title = 'Interpolation';
            app.SolvertoleranceLabel = uilabel(app.SolverConfigPanel);
            app.SolvertoleranceLabel.Text = 'Solver tolerance';
            app.SolverTolEditField = uieditfield(app.SolverConfigPanel, 'numeric');
            app.SolverTolEditField.Limits = [0.001 1];
            app.SolverTolEditField.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bellcrankOptim(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end