classdef datasetConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OKButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        OptionalPanel                  matlab.ui.container.Panel
        ChassisgeometryEditFieldLabel  matlab.ui.control.Label
        RodsDEditField                 matlab.ui.control.EditField
        ChassisGeomLabel               matlab.ui.control.Label
        ChassisGeomLamp                matlab.ui.control.Lamp
        SelectChassisGeomButton        matlab.ui.control.Button
        RmChassisGeomButton            matlab.ui.control.Button
        CenterofgravitycoordinatesEditFieldLabel  matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        CGXEditField                   matlab.ui.control.EditField
        ZLabel                         matlab.ui.control.Label
        CGZEditField                   matlab.ui.control.EditField
        TrackEditFieldLabel            matlab.ui.control.Label
        TrackEditField                 matlab.ui.control.EditField
        WheelbaseLabel                 matlab.ui.control.Label
        WheelBaseEditField             matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Data % Description
        CallingApp % Description
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, data)
            app.RmChassisGeomButton.Icon = fullfile(matlabroot,'toolbox', 'matlab','addons','release','images','rte_error.png');
            app.UIFigure.Position(1) = mainapp.UIFigure.Position(1)+mainapp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;  
            app.UIFigure.Position(2) = mainapp.UIFigure.Position(2)+mainapp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.Data = data;
            % Update UI with input values
            app.CGXEditField.Value = num2str(data.CGX);
            app.CGZEditField.Value = num2str(data.CGZ);
            app.TrackEditField.Value = num2str(data.Track);
            app.WheelBaseEditField.Value = num2str(data.WheelBase);
            app.RodsDEditField.Value = num2str(data.RodD);
            if ~isempty(data.ChassisGeom)
                app.ChassisGeomLamp.Color = [.1 .8 .1];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                %Re-enable settings button
                app.CallingApp.DatasetConfigButton.Enable = 'on';
            catch ME
                disp(ME.message)
            end
            delete(app)
        end

        % Value changed function: CGXEditField
        function CGXEditFieldValueChanged(app, event)
            %Check if new value is valid
            if isnan(str2double(event.Value))
                event.Source.Value = event.PreviousValue;
                return;
            end
            app.Data.CGX = str2double(event.Source.Value);
        end

        % Value changed function: CGZEditField
        function CGZEditFieldValueChanged(app, event)
            %Check if new value is valid
            if isnan(str2double(event.Value))
                event.Source.Value = event.PreviousValue;
                return;
            end
            app.Data.CGZ = str2double(event.Source.Value);
        end

        % Value changed function: TrackEditField
        function TrackEditFieldValueChanged(app, event)
            %Check if new value is valid
            if isnan(str2double(event.Value))
                event.Source.Value = event.PreviousValue;
                return;
            end
            app.Data.Track = str2double(event.Source.Value);
        end

        % Value changed function: WheelBaseEditField
        function WheelBaseEditFieldValueChanged(app, event)
            %Check if new value is valid
            if isnan(str2double(event.Value))
                event.Source.Value = event.PreviousValue;
                return;
            end
            app.Data.WheelBase = str2double(event.Source.Value);
        end

        % Value changed function: RodsDEditField
        function RodsDEditFieldValueChanged(app, event)
            %Check if new value is valid
            if isnan(str2double(event.Value))
                event.Source.Value = event.PreviousValue;
                return;
            end
            app.Data.RodD = str2double(event.Source.Value);
        end

        % Button pushed function: SelectChassisGeomButton
        function SelectChassisGeomButtonPushed(app, event)
            % Get the name of the mat file that the user wants to use.
            defaultFileName = fullfile(pwd, '*.stl');
            [baseFileName, folder] = uigetfile(defaultFileName, 'Select .stl file containing chassis geometry');
            drawnow; %Prevent window to go to background
            figure(app.UIFigure);
            if baseFileName == 0 % User clicked the Cancel button.
                return;
            end
            try
                temp = stlread(fullfile(folder, baseFileName));
                app.Data.ChassisGeom.vertices = temp.Points;
                app.Data.ChassisGeom.faces = temp.ConnectivityList;
                app.ChassisGeomLamp.Color = [.1 .8 .1];
            catch ME
                uialert(app.UIFigure,['File could not be loaded' ME.message],'Warning',...
                'Icon','warning');
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %Call main app
            updateDatasetConfig(app.CallingApp,app.Data)
            %Close dialog
            UIFigureCloseRequest(app, event)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            UIFigureCloseRequest(app, event)
        end

        % Button pushed function: RmChassisGeomButton
        function RmChassisGeomButtonPushed(app, event)
            app.Data.ChassisGeom = [];
            app.ChassisGeomLamp.Color = [1 1 1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Vehicle Parameters';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OptionalPanel = uipanel(app.UIFigure);
            app.OptionalPanel.Title = 'Optional';
            app.ChassisgeometryEditFieldLabel = uilabel(app.OptionalPanel);
            app.ChassisgeometryEditFieldLabel.Text = 'Rods Diameter';
            app.RodsDEditField = uieditfield(app.OptionalPanel, 'text');
            app.RodsDEditField.ValueChangedFcn = createCallbackFcn(app, @RodsDEditFieldValueChanged, true);
            app.RodsDEditField.Tooltip = {'(mm)'};
            app.ChassisGeomLabel = uilabel(app.OptionalPanel);
            app.ChassisGeomLabel.Text = 'Chassis geometry';
            app.ChassisGeomLamp = uilamp(app.OptionalPanel);
            app.SelectChassisGeomButton = uibutton(app.OptionalPanel, 'push');
            app.SelectChassisGeomButton.ButtonPushedFcn = createCallbackFcn(app, @SelectChassisGeomButtonPushed, true);
            app.SelectChassisGeomButton.Tooltip = {''};
            app.SelectChassisGeomButton.Text = '...';
            app.RmChassisGeomButton = uibutton(app.OptionalPanel, 'push');
            app.RmChassisGeomButton.ButtonPushedFcn = createCallbackFcn(app, @RmChassisGeomButtonPushed, true);
            app.RmChassisGeomButton.Tooltip = {''};
            app.RmChassisGeomButton.Text = '';
            app.CenterofgravitycoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.CenterofgravitycoordinatesEditFieldLabel.Text = 'Center of gravity coordinates';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X = ';
            app.CGXEditField = uieditfield(app.UIFigure, 'text');
            app.CGXEditField.ValueChangedFcn = createCallbackFcn(app, @CGXEditFieldValueChanged, true);
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z =  ';
            app.CGZEditField = uieditfield(app.UIFigure, 'text');
            app.CGZEditField.ValueChangedFcn = createCallbackFcn(app, @CGZEditFieldValueChanged, true);
            app.TrackEditFieldLabel = uilabel(app.UIFigure);
            app.TrackEditFieldLabel.Text = 'Track';
            app.TrackEditField = uieditfield(app.UIFigure, 'text');
            app.TrackEditField.ValueChangedFcn = createCallbackFcn(app, @TrackEditFieldValueChanged, true);
            app.TrackEditField.Tooltip = {'(mm)'};
            app.WheelbaseLabel = uilabel(app.UIFigure);
            app.WheelbaseLabel.Text = 'Wheelbase';
            app.WheelBaseEditField = uieditfield(app.UIFigure, 'text');
            app.WheelBaseEditField.ValueChangedFcn = createCallbackFcn(app, @WheelBaseEditFieldValueChanged, true);
            app.WheelBaseEditField.Tooltip = {'(mm)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = datasetConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end