classdef suspensionEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        UpperArmPanel             matlab.ui.container.Panel
        UpperArm_X_up             matlab.ui.control.Button
        text46                    matlab.ui.control.Label
        UpperArm_X_down           matlab.ui.control.Button
        UpperArm_Y_left           matlab.ui.control.Button
        UpperArm_Y_down           matlab.ui.control.Button
        text44                    matlab.ui.control.Label
        UpperArm_Y_up             matlab.ui.control.Button
        UpperArm_Z_right          matlab.ui.control.Button
        UpperArm_Z_left           matlab.ui.control.Button
        UpperArm_Z_down           matlab.ui.control.Button
        text45                    matlab.ui.control.Label
        UpperArm_Y_right          matlab.ui.control.Button
        UpperArm_Z_up             matlab.ui.control.Button
        UpperArm_Separation_up    matlab.ui.control.Button
        text47                    matlab.ui.control.Label
        UpperArm_Separation_down  matlab.ui.control.Button
        LowerArmPanel             matlab.ui.container.Panel
        LowerArm_X_up             matlab.ui.control.Button
        text46_2                  matlab.ui.control.Label
        LowerArm_X_down           matlab.ui.control.Button
        LowerArm_Y_left           matlab.ui.control.Button
        LowerArm_Y_down           matlab.ui.control.Button
        text44_2                  matlab.ui.control.Label
        LowerArm_Y_up             matlab.ui.control.Button
        LowerArm_Y_right          matlab.ui.control.Button
        LowerArm_Z_left           matlab.ui.control.Button
        LowerArm_Z_down           matlab.ui.control.Button
        text45_2                  matlab.ui.control.Label
        LowerArm_Z_right          matlab.ui.control.Button
        LowerArm_Z_up             matlab.ui.control.Button
        LowerArm_Separation_up    matlab.ui.control.Button
        text47_2                  matlab.ui.control.Label
        LowerArm_Separation_down  matlab.ui.control.Button
        uipanel11                 matlab.ui.container.Panel
        SteeringRack_Z_down       matlab.ui.control.Button
        text61                    matlab.ui.control.Label
        Steering_Y_down           matlab.ui.control.Button
        text60_2                  matlab.ui.control.Label
        SteeringRack_Z_up         matlab.ui.control.Button
        SteeringRack_X_down       matlab.ui.control.Button
        Steering_Y_up             matlab.ui.control.Button
        text60                    matlab.ui.control.Label
        SteeringRack_X_up         matlab.ui.control.Button
        uipanel5                  matlab.ui.container.Panel
        UpperUpright_X_up         matlab.ui.control.Button
        UpperUpright_Y_up         matlab.ui.control.Button
        text13                    matlab.ui.control.Label
        text15                    matlab.ui.control.Label
        UpperUpright_Z_up         matlab.ui.control.Button
        text14                    matlab.ui.control.Label
        UpperUpright_X_down       matlab.ui.control.Button
        UpperUpright_Y_down       matlab.ui.control.Button
        UpperUpright_Z_down       matlab.ui.control.Button
        uipanel8                  matlab.ui.container.Panel
        LowerUpright_X_up         matlab.ui.control.Button
        text48                    matlab.ui.control.Label
        LowerUpright_Y_up         matlab.ui.control.Button
        text50                    matlab.ui.control.Label
        LowerUpright_X_down       matlab.ui.control.Button
        LowerUpright_Y_down       matlab.ui.control.Button
        LowerUpright_Z_up         matlab.ui.control.Button
        text49                    matlab.ui.control.Label
        LowerUpright_Z_down       matlab.ui.control.Button
        uipanel12                 matlab.ui.container.Panel
        text64                    matlab.ui.control.Label
        text65                    matlab.ui.control.Label
        text66                    matlab.ui.control.Label
        text67                    matlab.ui.control.Label
        text68                    matlab.ui.control.Label
        text69                    matlab.ui.control.Label
        text70                    matlab.ui.control.Label
        text71                    matlab.ui.control.Label
        text72                    matlab.ui.control.Label
        text73                    matlab.ui.control.Label
        text74                    matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        CoordinatesPanel          matlab.ui.container.Panel
        UpperFront_X              matlab.ui.control.Label
        UpperFront_Y              matlab.ui.control.Label
        UpperFront_Z              matlab.ui.control.Label
        UpperRear_X               matlab.ui.control.Label
        UpperRear_Y               matlab.ui.control.Label
        UpperRear_Z               matlab.ui.control.Label
        LowerFront_X              matlab.ui.control.Label
        LowerFront_Y              matlab.ui.control.Label
        LowerFront_Z              matlab.ui.control.Label
        LowerRear_X               matlab.ui.control.Label
        LowerRear_Y               matlab.ui.control.Label
        LowerRear_Z               matlab.ui.control.Label
        UpperUpright_X            matlab.ui.control.Label
        UpperUpright_Y            matlab.ui.control.Label
        UpperUpright_Z            matlab.ui.control.Label
        LowerUpright_X            matlab.ui.control.Label
        LowerUpright_Y            matlab.ui.control.Label
        LowerUpright_Z            matlab.ui.control.Label
        SteeringRack_X            matlab.ui.control.Label
        SteeringRack_Y            matlab.ui.control.Label
        SteeringRack_Z            matlab.ui.control.Label
        SteeringUpright_X         matlab.ui.control.Label
        SteeringUpright_Y         matlab.ui.control.Label
        SteeringUpright_Z         matlab.ui.control.Label
        StepSpinnerLabel          matlab.ui.control.Label
        StepSpinner               matlab.ui.control.Spinner
        UndoButton                matlab.ui.control.Button
        RedoButton                matlab.ui.control.Button
        uipanel11_2               matlab.ui.container.Panel
        Steering_Length_up        matlab.ui.control.Button
        text59                    matlab.ui.control.Label
        Steering_Length_down      matlab.ui.control.Button
        Steering_Z_right          matlab.ui.control.Button
        text62                    matlab.ui.control.Label
        SteeringUpright_Z_up      matlab.ui.control.Button
        SteeringUpright_Z_down    matlab.ui.control.Button
        Steering_Z_left           matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        View3D_Axes               matlab.ui.control.UIAxes
        BottomView_Button         matlab.ui.control.Button
        LeftSideView_Button       matlab.ui.control.Button
        FrontView_Button          matlab.ui.control.Button
        RightSideView_Button      matlab.ui.control.Button
        TopView_Button            matlab.ui.control.Button
        View3DContextMenu         matlab.ui.container.ContextMenu
        view3DcheckMenu           matlab.ui.container.Menu
        Reset3DViewMenu           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        CallingApp 
        SessionData
        DataHaveChanged = 0;
    end
    
    methods (Access = private)
        
        function solid = solidPlot(app,solid,color)
            switch nargin
                case 2
                    color = [.8 .8 .85];
            end
            solid = patch(app.View3D_Axes, solid, ...
                'FaceColor', color, ...
                'EdgeColor', 'none', ...
                'FaceLighting', 'gouraud', ...
                'FaceAlpha',0.7, ...
                'AmbientStrength', 0.2, ...
                'SpecularStrength', 0.1);
            %Delete existing lights and add
            c = app.View3D_Axes.Children;
            for i=1:length(c)
                if strcmp(c(i).Type,'light')
                    delete(c(i));
                end
            end
            light(app.View3D_Axes,'Style','local','Position',[2000 1000 800]);
            light(app.View3D_Axes,'Style','local','Position',[2000 -1000 800]);
            light(app.View3D_Axes,'Style','local','Position',[-2000 1000 800]);
            light(app.View3D_Axes,'Style','local','Position',[-2000 -1000 800]);
        end
        
        function kinematicsPlot(app,X)
            if app.view3DcheckMenu.Checked
                %Upper A-Arm
                [Cx, Cy, Cz] = cylinder2P(app.SessionData.Dataset.VehicleParams.RodD/2,15,X(1:3),X(13:15));
                surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',[.4 .4 .5],'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                [Cx, Cy, Cz] = cylinder2P(app.SessionData.Dataset.VehicleParams.RodD/2,15,X(4:6),X(13:15));
                surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',[.4 .4 .5],'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                %Lower A-Arm
                [Cx, Cy, Cz] = cylinder2P(app.SessionData.Dataset.VehicleParams.RodD/2,15,X(7:9),X(16:18));
                surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',[.4 .4 .5],'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                [Cx, Cy, Cz] = cylinder2P(app.SessionData.Dataset.VehicleParams.RodD/2,15,X(10:12),X(16:18));
                surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',[.4 .4 .5],'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                %Steering tie-rod
                [Cx, Cy, Cz] = cylinder2P(app.SessionData.Dataset.VehicleParams.RodD/2,15,X(19:21),X(22:24));
                surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',[.4 .4 .5],'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                %Wheel
                plot3(app.View3D_Axes,X(25), X(26), X(27),'o','color',[0 0 0]);
                plotCircle3D(app.View3D_Axes, X(25:27), X(28:30),app.SessionData.Dataset.TireRadius);
            else
                %Upper A-Arm
                plot3(app.View3D_Axes,[X(1) X(13)],[X(2) X(14)],[X(3) X(15)],'color',[.4 .4 .5]);%Left
                plot3(app.View3D_Axes,[X(4) X(13)],[X(5) X(14)],[X(6) X(15)],'color',[.4 .4 .5]);
                %Lower A-Arm
                plot3(app.View3D_Axes,[X(7) X(16)],[X(8) X(17)],[X(9) X(18)],'color',[.4 .4 .5]);%Left
                plot3(app.View3D_Axes,[X(10) X(16)],[X(11) X(17)],[X(12) X(18)],'color',[.4 .4 .5]);
                %Steering tie-rod
                plot3(app.View3D_Axes,[X(19) X(22)],[X(20) X(23)],[X(21) X(24)],'color',[.4 .4 .5]);
                %Wheel
                plot3(app.View3D_Axes,X(25), X(26), X(27),'o','color',[0 0 0]);
                plotCircle3D(app.View3D_Axes, X([25 26 27]),X([28 29 30]),app.SessionData.Dataset.TireRadius);
                if length(X) == 45
                    %Push
                    plot3(app.View3D_Axes,[X(31) X(34)],[X(32) X(35)],[X(33) X(36)],'color',[.4 .4 .5]);
                    %Bellcrank
                    plot3(app.View3D_Axes,[X(37) X(34)],[X(38) X(35)],[X(39) X(36)],'color',[.4 .4 .5]);
                    plot3(app.View3D_Axes,[X(37) X(40)],[X(38) X(41)],[X(39) X(42)],'color',[.4 .4 .5]);
                    plot3(app.View3D_Axes,[X(40) X(34)],[X(41) X(35)],[X(42) X(36)],'color',[.4 .4 .5]);
                    %Damper
                    plot3(app.View3D_Axes,[X(40) X(43)],[X(41) X(44)],[X(42) X(45)],'color',[.4 .4 .5]);
                end
            end
        end
        
        function loadDataset(app)
            %A-Arms Coordinates
            boxes = app.CoordinatesPanel.Children;
            for i=1:24
                boxes(25-i).Text = sprintf('%.2f',app.SessionData.Dataset.X(i)); %Order of boxes is inverted respect to dataset coordinates hence the 25-i
            end
        end
        
        
        function update3Dview(app)
            ax = app.View3D_Axes;
            xLim = ax.XLim;
            yLim = ax.YLim;
            zLim = ax.ZLim;
            cla(ax);
            hold(ax,'on');
            % Plot stuff
            %% A-arms
            kinematicsPlot(app, app.SessionData.Dataset.X(1:30));
            %% Chassis, if data is present and 3D option is selected
            if app.view3DcheckMenu.Checked && ~isempty(app.SessionData.Dataset.VehicleParams.ChassisGeom)
                solidPlot(app,app.SessionData.Dataset.VehicleParams.ChassisGeom);
                ax.XLim = xLim;
                ax.YLim = yLim;
                ax.ZLim = zLim;
            else
                ax.YLimMode = 'auto';
                ax.XLimMode = 'auto';
                ax.ZLimMode = 'auto';
            end
            hold(ax,'off');
        end
        
        function editDataset(app,event)
            % Clear RedoStack
            app.SessionData.RedoStack = {};
            % Save data in UndoStack
            app.SessionData.UndoStack = app.SessionData;
            
            res_dist = app.StepSpinner.Value;
            res_ang = app.StepSpinner.Value/10;
            X = app.SessionData.Dataset.X;
            Xmod = X;
            switch event.Source.Tag
                %% -- Upper A-Arm -- %%
                % Lengthening
                case 'UpperArm_Separation_up' %extend in the direction of Front-Rear Upper A-arm , keeping upper A-Arm plane
                    upperAxis = (X([1 2 3])-X([4 5 6]))/norm(X([1 2 3])-X([4 5 6]));
                    Xmod(1:3) = X(1:3) + upperAxis*res_dist/2;
                    Xmod(4:6) = X(4:6) - upperAxis*res_dist/2;
                case 'UpperArm_Separation_down' %extend in the direction of Front-Rear Upper A-arm , keeping upper A-Arm plane
                    upperAxis = (X([1 2 3])-X([4 5 6]))/norm(X([1 2 3])-X([4 5 6]));
                    Xmod(1:3) = X(1:3) + upperAxis*res_dist/2*(-1);
                    Xmod(4:6) = X(4:6) - upperAxis*res_dist/2*(-1);
                case 'UpperArm_X_up' %translate in the direction of Front-Rear Upper A-arm keeping upper A-Arm plane
                    upperAxis = (X([1 2 3])-X([4 5 6]))/norm(X([1 2 3])-X([4 5 6]));
                    Xmod(1:3) = X(1:3) + upperAxis*res_dist;
                    Xmod(4:6) = X(4:6) + upperAxis*res_dist;
                case 'UpperArm_X_down' %translate in the direction of Front-Rear Upper A-arm keeping upper A-Arm plane
                    upperAxis = (X([1 2 3])-X([4 5 6]))/norm(X([1 2 3])-X([4 5 6]));
                    Xmod(1:3) = X(1:3) + upperAxis*res_dist*(-1);
                    Xmod(4:6) = X(4:6) + upperAxis*res_dist*(-1);
                case 'UpperArm_Y_up' %extend each rod of the A-arm until the intersection of a vertical plane with an offset in Y direction
                    Xmod(1:3) = intersectPlaneLine(cross([0 0 1],X(1:3)-X(4:6)),X(1:3)-[0 res_dist 0]',X(1:3)-X(13:15),X(1:3));
                    Xmod(4:6) = intersectPlaneLine(cross([0 0 1],X(1:3)-X(4:6)),X(1:3)-[0 res_dist 0]',X(4:6)-X(13:15),X(4:6));
                case 'UpperArm_Y_down' %extend each rod of the A-arm until the intersection of a vertical plane with an offset in Y direction
                    Xmod(1:3) = intersectPlaneLine(cross([0 0 1],X(1:3)-X(4:6)),X(1:3)-[0 res_dist*(-1) 0]',X(1:3)-X(13:15),X(1:3));
                    Xmod(4:6) = intersectPlaneLine(cross([0 0 1],X(1:3)-X(4:6)),X(1:3)-[0 res_dist*(-1) 0]',X(4:6)-X(13:15),X(4:6));
                case 'UpperArm_Z_up' %translate in Z direction
                    Xmod(3) = X(3) + res_dist ;
                    Xmod(6) = X(6) + res_dist ;
                case 'UpperArm_Z_down' %translate in Z direction
                    Xmod(3) = X(3) + res_dist*(-1);
                    Xmod(6) = X(6) + res_dist*(-1);
                % Rotating
                case 'UpperArm_Z_left' %rotate around Z keeping upper A-Arm plane
                    rotationCenter = X(1:3)+(X(4:6)-X(1:3))/2;
                    P = rotatePointFixedAxis(res_ang ,[0 0 1],rotationCenter,X(1:3));
                    Xmod(1:3) = intersectPlaneLine(cross([0 0 1],P-rotationCenter'),P,X(1:3)-X(13:15),X(1:3));
                    Xmod(4:6) = intersectPlaneLine(cross([0 0 1],P-rotationCenter'),P,X(4:6)-X(13:15),X(4:6));
                case 'UpperArm_Z_right' %rotate around Z keeping upper A-Arm plane
                    rotationCenter = X(1:3)+(X(4:6)-X(1:3))/2;
                    P = rotatePointFixedAxis(res_ang*(-1),[0 0 1],rotationCenter,X(1:3));
                    Xmod(1:3) = intersectPlaneLine(cross([0 0 1],P-rotationCenter'),P,X(1:3)-X(13:15),X(1:3));
                    Xmod(4:6) = intersectPlaneLine(cross([0 0 1],P-rotationCenter'),P,X(4:6)-X(13:15),X(4:6));
                case 'UpperArm_Y_left' %rotate around Y
                    rotationCenter = X(1:3)+(X(4:6)-X(1:3))/2;
                    Xmod(1:3) = rotatePointFixedAxis(res_ang*(-1),cross(X(4:6)-X(1:3),[0 0 1]),rotationCenter,X(1:3));
                    Xmod(4:6) = rotatePointFixedAxis(res_ang*(-1),cross(X(4:6)-X(1:3),[0 0 1]),rotationCenter,X(4:6));
                case 'UpperArm_Y_right' %rotate around Y
                    rotationCenter = X(1:3)+(X(4:6)-X(1:3))/2;
                    Xmod(1:3) = rotatePointFixedAxis(res_ang ,cross(X(4:6)-X(1:3),[0 0 1]),rotationCenter,X(1:3));
                    Xmod(4:6) = rotatePointFixedAxis(res_ang ,cross(X(4:6)-X(1:3),[0 0 1]),rotationCenter,X(4:6));
                % Upright
                case 'UpperUpright_X_up' %translate in X direction
                    Xmod(13) = X(13) + res_dist ;
                case 'UpperUpright_X_down' %translate in X direction
                    Xmod(13) = X(13) + res_dist*(-1);
                case 'UpperUpright_Y_up' %translate in Y direction keeping upper A-Arm plane and A-arm rods angle
                    Xmod(13:15) = intersect3planes([1 0 0],X(13:15),cross(X(1:3)-X(13:15),X(13:15)-X(4:6)),X(13:15),[0 1 0],X(13:15) + [0 res_dist  0]');
                    %         Xmod(1:3) = intersectPlaneLine(cross([0 0 1],X(1:3)-X(4:6)),X(1:3),X(1:3)-X(13:15),Xmod(13:15));
                    %         Xmod(4:6) = intersectPlaneLine(cross([0 0 1],X(1:3)-X(4:6)),X(1:3),X(4:6)-X(13:15),Xmod(13:15));
                case 'UpperUpright_Y_down' %translate in Y direction keeping upper A-Arm plane and A-arm rods angle
                    Xmod(13:15) = intersect3planes([1 0 0],X(13:15),cross(X(1:3)-X(13:15),X(13:15)-X(4:6)),X(13:15),[0 1 0],X(13:15) + [0 res_dist*(-1) 0]');
                    %         Xmod(1:3) = intersectPlaneLine(cross([0 0 1],X(1:3)-X(4:6)),X(1:3),X(1:3)-X(13:15),Xmod(13:15));
                    %         Xmod(4:6) = intersectPlaneLine(cross([0 0 1],X(1:3)-X(4:6)),X(1:3),X(4:6)-X(13:15),Xmod(13:15));
                case 'UpperUpright_Z_up' %translate in Z direction
                    Xmod(15) = X(15) + res_dist ;
                case 'UpperUpright_Z_down' %translate in Z direction
                    Xmod(15) = X(15) + res_dist*(-1);
                    
                %% -- Lower A-Arm -- %%
                % Lengthening
                case 'LowerArm_Separation_up' %extend in the direction of Front-Rear Lower A-arm , keeping lower A-Arm plane
                    lowerAxis = (X(7:9)-X(10:12))/norm(X(7:9)-X(10:12));
                    Xmod(7:9) = X(7:9) + lowerAxis*res_dist/2;
                    Xmod(10:12) = X(10:12) - lowerAxis*res_dist/2;
                case 'LowerArm_Separation_down' %extend in the direction of Front-Rear Lower A-arm , keeping lower A-Arm plane
                    lowerAxis = (X(7:9)-X(10:12))/norm(X(7:9)-X(10:12));
                    Xmod(7:9) = X(7:9) + lowerAxis*res_dist/2*(-1);
                    Xmod(10:12) = X(10:12) - lowerAxis*res_dist/2*(-1);
                case 'LowerArm_X_up' %translate in the direction of Front-Rear Lower A-arm keeping lower A-Arm plane
                    lowerAxis = (X(7:9)-X(10:12))/norm(X(7:9)-X(10:12));
                    Xmod(7:9) = X(7:9) + lowerAxis*res_dist;
                    Xmod(10:12) = X(10:12) + lowerAxis*res_dist;
                case 'LowerArm_X_down' %translate in the direction of Front-Rear Lower A-arm keeping lower A-Arm plane
                    lowerAxis = (X(7:9)-X(10:12))/norm(X(7:9)-X(10:12));
                    Xmod(7:9) = X(7:9) + lowerAxis*res_dist*(-1);
                    Xmod(10:12) = X(10:12) + lowerAxis*res_dist*(-1);
                case 'LowerArm_Y_up' %extend each rod of the A-arm until the intersection of a vertical plane with an offset in Y direction
                    Xmod(7:9) = intersectPlaneLine(cross([0 0 1],X(7:9)-X(10:12)),X(7:9)-[0 res_dist  0]',X(7:9)-X(16:18),X(7:9));
                    Xmod(10:12) = intersectPlaneLine(cross([0 0 1],X(7:9)-X(10:12)),X(7:9)-[0 res_dist  0]',X(10:12)-X(16:18),X(10:12));
                case 'LowerArm_Y_down' %extend each rod of the A-arm until the intersection of a vertical plane with an offset in Y direction
                    Xmod(7:9) = intersectPlaneLine(cross([0 0 1],X(7:9)-X(10:12)),X(7:9)-[0 res_dist*(-1) 0]',X(7:9)-X(16:18),X(7:9));
                    Xmod(10:12) = intersectPlaneLine(cross([0 0 1],X(7:9)-X(10:12)),X(7:9)-[0 res_dist*(-1) 0]',X(10:12)-X(16:18),X(10:12));
                case 'LowerArm_Z_up' %translate in Z direction
                    Xmod(9) = X(9) + res_dist ;
                    Xmod(12) = X(12) + res_dist ;
                case 'LowerArm_Z_down' %translate in Z direction
                    Xmod(9) = X(9) + res_dist*(-1);
                    Xmod(12) = X(12) + res_dist*(-1);
                % Rotating
                case 'LowerArm_Z_left' %rotate around Z keeping lower A-Arm plane
                    rotationCenter = X(7:9)+(X(10:12)-X(7:9))/2;
                    P = rotatePointFixedAxis(res_ang ,[0 0 1],rotationCenter,X(7:9));
                    Xmod(7:9) = intersectPlaneLine(cross([0 0 1],P-rotationCenter'),P,X(7:9)-X(16:18),X(7:9));
                    Xmod(10:12) = intersectPlaneLine(cross([0 0 1],P-rotationCenter'),P,X(10:12)-X(16:18),X(10:12));
                case 'LowerArm_Z_right' %rotate around Z keeping lower A-Arm plane
                    rotationCenter = X(7:9)+(X(10:12)-X(7:9))/2;
                    P = rotatePointFixedAxis(res_ang*(-1),[0 0 1],rotationCenter,X(7:9));
                    Xmod(7:9) = intersectPlaneLine(cross([0 0 1],P-rotationCenter'),P,X(7:9)-X(16:18),X(7:9));
                    Xmod(10:12) = intersectPlaneLine(cross([0 0 1],P-rotationCenter'),P,X(10:12)-X(16:18),X(10:12));
                case 'LowerArm_Y_left' %rotate around Y
                    rotationCenter = X(7:9)+(X(10:12)-X(7:9))/2;
                    Xmod(7:9) = rotatePointFixedAxis(res_ang*(-1),cross(X(10:12)-X(7:9),[0 0 1]),rotationCenter,X(7:9));
                    Xmod(10:12) = rotatePointFixedAxis(res_ang*(-1),cross(X(10:12)-X(7:9),[0 0 1]),rotationCenter,X(10:12));
                case 'LowerArm_Y_right' %rotate around Y
                    rotationCenter = X(7:9)+(X(10:12)-X(7:9))/2;
                    Xmod(7:9) = rotatePointFixedAxis(res_ang ,cross(X(10:12)-X(7:9),[0 0 1]),rotationCenter,X(7:9));
                    Xmod(10:12) = rotatePointFixedAxis(res_ang ,cross(X(10:12)-X(7:9),[0 0 1]),rotationCenter,X(10:12));
                % Upright
                case 'LowerUpright_X_up' %translate in X direction
                    Xmod(16) = X(16) + res_dist ;
                case 'LowerUpright_X_down' %translate in X direction
                    Xmod(16) = X(16) + res_dist*(-1);
                case 'LowerUpright_Y_up' %translate in Y direction keeping lower A-Arm plane and A-arm rods angle
                    Xmod(16:18) = intersect3planes([1 0 0],X(16:18),cross(X(7:9)-X(16:18),X(16:18)-X(10:12)),X(16:18),[0 1 0],X(16:18) + [0 res_dist  0]');
                    %         Xmod(7:9) = intersectPlaneLine(cross([0 0 1],X(7:9)-X(10:12)),X(7:9),X(7:9)-X(16:18),Xmod(16:18));
                    %         Xmod(10:12) = intersectPlaneLine(cross([0 0 1],X(7:9)-X(10:12)),X(7:9),X(10:12)-X(16:18),Xmod(16:18));
                case 'LowerUpright_Y_down' %translate in Y direction keeping lower A-Arm plane and A-arm rods angle
                    Xmod(16:18) = intersect3planes([1 0 0],X(16:18),cross(X(7:9)-X(16:18),X(16:18)-X(10:12)),X(16:18),[0 1 0],X(16:18) + [0 res_dist*(-1) 0]');
                    %         Xmod(7:9) = intersectPlaneLine(cross([0 0 1],X(7:9)-X(10:12)),X(7:9),X(7:9)-X(16:18),Xmod(16:18));
                    %         Xmod(10:12) = intersectPlaneLine(cross([0 0 1],X(7:9)-X(10:12)),X(7:9),X(10:12)-X(16:18),Xmod(16:18));
                case 'LowerUpright_Z_up' %translate in Z direction
                    Xmod(18) = X(18) + res_dist ;
                case 'LowerUpright_Z_down' %translate in Z direction
                    Xmod(18) = X(18) + res_dist*(-1);
                    
                %% -- Steering rod -- %%
                case 'SteeringRack_X_up'
                    Xmod(19) = X(19) + res_dist;
                case 'SteeringRack_X_down'
                    Xmod(19) = X(19) - res_dist;
                case 'Steering_Y_up'
                    Xmod(20) = X(20) + res_dist;
                case 'Steering_Y_down'
                    Xmod(20) = X(20) - res_dist;
                case 'SteeringRack_Z_up'
                    Xmod(21) = X(21) + res_dist;
                case 'SteeringRack_Z_down'
                    Xmod(21) = X(21) - res_dist;
                case 'Steering_Length_up'
                    Xmod(22:24) = X(22:24) + res_dist*(X(22:24)-X(19:21))/norm(X(22:24)-X(19:21));
                    %Xmod(19:21) = X(19:21) - res_dist*(X(22:24)-X(19:21))/norm(X(22:24)-X(19:21));
                case 'Steering_Length_down'
                    Xmod(22:24) = X(22:24) - res_dist*(X(22:24)-X(19:21))/norm(X(22:24)-X(19:21));
                    %Xmod(19:21) = X(19:21) + res_dist*(X(22:24)-X(19:21))/norm(X(22:24)-X(19:21));
                case 'Steering_Z_left'
                    P = rotatePointFixedAxis(res_ang,cross(X(19:21),[1 0 0]),X(19:21),X(22:24));
                    Xmod(22:24) = intersectPlaneLine([0 1 0], X(22:24), P'-X(19:21), X(19:21));
                case 'Steering_Z_right'
                    P = rotatePointFixedAxis(res_ang*(-1),cross(X(19:21),[1 0 0]),X(19:21),X(22:24));
                    Xmod(22:24) = intersectPlaneLine([0 1 0], X(22:24), P'-X(19:21), X(19:21));
                case 'SteeringUpright_Z_up'
                    Xmod(24) = X(24) + res_dist;
                case 'SteeringUpright_Z_down'
                    Xmod(24) = X(24) + res_dist*(-1);
            end
            app.SessionData.Dataset.X = Xmod;
            app.DataHaveChanged = 1;
            loadDataset(app);
            update3Dview(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, dataset)
            % Initilize app properties
            app.CallingApp = mainapp;
            app.SessionData = [];
            app.SessionData.Dataset = dataset;
            app.SessionData.UndoStack = {};
            app.SessionData.RedoStack = {};
            
            % Initilize app components
            %% Initialize position
            app.UIFigure.Position(1) = mainapp.UIFigure.Position(1)+mainapp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;  
            app.UIFigure.Position(2) = mainapp.UIFigure.Position(2)+mainapp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            %% Initialize Icons
            path = fullfile(matlabroot, 'toolbox', 'matlab');
            app.UndoButton.Icon = fullfile(path,'addons','release','images','toolstrip','Undo_24.png');
            app.RedoButton.Icon = fullfile(path,'addons','release','images','toolstrip','Redo_24.png');
            %% Initialize axes
            axtoolbar(app.View3D_Axes,{'datacursor','rotate'});
            app.View3D_Axes.Toolbar.Visible = 'on';
            app.View3D_Axes.Clipping = 'off';
%           app.View3D_Axes.View = [120,20];
            app.View3D_Axes.PlotBoxAspectRatio = [
                abs(app.View3D_Axes.XLim(2)-app.View3D_Axes.XLim(1))
                abs(app.View3D_Axes.YLim(2)-app.View3D_Axes.YLim(1))
                abs(app.View3D_Axes.ZLim(2)-app.View3D_Axes.ZLim(1))];
            
            % Load data to ui
            loadDataset(app);
            update3Dview(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
            app.CallingApp.SuspensionEditButton.Enable = 'on';
            catch err
                disp(err.message)
            end
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.DataHaveChanged
                editDatasetCB(app.CallingApp,app.SessionData.Dataset)
                UIFigureCloseRequest(app, event)
            else
                UIFigureCloseRequest(app, event)
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            s = uiconfirm(app.UIFigure,'If you continue, changes will not be saved. Do you want to continue?','Exit confirm');
            switch s
                case 'OK'
                    UIFigureCloseRequest(app, event)
                case 'Cancel'
                    return;
            end
        end

        % Button pushed function: TopView_Button
        function TopView_ButtonPushed(app, event)
            app.View3D_Axes.View = [180,90];
        end

        % Button pushed function: RightSideView_Button
        function LeftSideView_ButtonPushed(app, event)
            app.View3D_Axes.View = [180,0];
        end

        % Button pushed function: BottomView_Button
        function BottomView_ButtonPushed(app, event)
            app.View3D_Axes.View = [180,-90];
        end

        % Button pushed function: LeftSideView_Button
        function RightSideView_ButtonPushed(app, event)
            app.View3D_Axes.View = [0,0];
        end

        % Button pushed function: FrontView_Button
        function FrontView_ButtonPushed(app, event)
            app.View3D_Axes.View = [90,0];
        end

        % Button pushed function: LowerArm_Separation_down, 
        % LowerArm_Separation_up, LowerArm_X_down, LowerArm_X_up, 
        % LowerArm_Y_down, LowerArm_Y_left, LowerArm_Y_right, 
        % LowerArm_Y_up, LowerArm_Z_down, LowerArm_Z_left, 
        % LowerArm_Z_right, LowerArm_Z_up, LowerUpright_X_down, 
        % LowerUpright_X_up, LowerUpright_Y_down, 
        % LowerUpright_Y_up, LowerUpright_Z_down, 
        % LowerUpright_Z_up, SteeringRack_X_down, 
        % SteeringRack_X_up, SteeringRack_Z_down, 
        % SteeringRack_Z_up, SteeringUpright_Z_down, 
        % SteeringUpright_Z_up, Steering_Length_down, 
        % Steering_Length_up, Steering_Y_down, Steering_Y_up, 
        % Steering_Z_left, Steering_Z_right, 
        % UpperArm_Separation_down, UpperArm_Separation_up, 
        % UpperArm_X_down, UpperArm_X_up, UpperArm_Y_down, 
        % UpperArm_Y_left, UpperArm_Y_right, UpperArm_Y_up, 
        % UpperArm_Z_down, UpperArm_Z_left, UpperArm_Z_right, 
        % UpperArm_Z_up, UpperUpright_X_down, UpperUpright_X_up, 
        % UpperUpright_Y_down, UpperUpright_Y_up, 
        % UpperUpright_Z_down, UpperUpright_Z_up
        function editDataset_ButtonPushed(app, event)
            editDataset(app,event)
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if ~isempty(app.SessionData.UndoStack)
                app.SessionData.UndoStack.RedoStack = app.SessionData;
                app.SessionData = app.SessionData.UndoStack;
                loadDataset(app);
                update3Dview(app);
            end
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            if ~isempty(app.SessionData.RedoStack)
                app.SessionData = app.SessionData.RedoStack;
                loadDataset(app);
                update3Dview(app);
            end
        end

        % Menu selected function: view3DcheckMenu
        function view3DcheckMenuSelected(app, event)
            switch app.view3DcheckMenu.Checked
                case 'on'
                    app.view3DcheckMenu.Checked = 'off';
                case 'off'
                    app.view3DcheckMenu.Checked = 'on';
            end
            update3Dview(app)
        end

        % Menu selected function: Reset3DViewMenu
        function Reset3DViewMenuSelected(app, event)
            switch app.view3DcheckMenu.Checked
                case 'on'
                    app.View3D_Axes.XLim = [app.SessionData.Dataset.X(25)-400 app.SessionData.Dataset.X(25)+400];
                    app.View3D_Axes.YLim = [0 app.SessionData.Dataset.X(26)+50];
                    app.View3D_Axes.ZLim = [app.SessionData.Dataset.X(27)-app.SessionData.Dataset.TireRadius-50 app.SessionData.Dataset.X(27)+app.SessionData.Dataset.TireRadius+200];
                case 'off'
                    app.View3D_Axes.XLimMode = 'auto';
                    app.View3D_Axes.YLimMode = 'auto';
                    app.View3D_Axes.ZLimMode = 'auto';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Suspension edit';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.UpperArmPanel = uipanel(app.Panel);
            app.UpperArmPanel.Title = 'Upper Arm';
            app.UpperArmPanel.Tag = 'uipanel4';
            app.UpperArm_X_up = uibutton(app.UpperArmPanel, 'push');
            app.UpperArm_X_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperArm_X_up.Tag = 'UpperArm_X_up';
            app.UpperArm_X_up.Text = '▲';
            app.text46 = uilabel(app.UpperArmPanel);
            app.text46.Tag = 'text46';
            app.text46.Text = 'X';
            app.UpperArm_X_down = uibutton(app.UpperArmPanel, 'push');
            app.UpperArm_X_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperArm_X_down.Tag = 'UpperArm_X_down';
            app.UpperArm_X_down.Text = '▼';
            app.UpperArm_Y_left = uibutton(app.UpperArmPanel, 'push');
            app.UpperArm_Y_left.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperArm_Y_left.Tag = 'UpperArm_Y_left';
            app.UpperArm_Y_left.Text = '↺';
            app.UpperArm_Y_down = uibutton(app.UpperArmPanel, 'push');
            app.UpperArm_Y_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperArm_Y_down.Tag = 'UpperArm_Y_down';
            app.UpperArm_Y_down.Text = '▼';
            app.text44 = uilabel(app.UpperArmPanel);
            app.text44.Tag = 'text44';
            app.text44.Text = 'Y';
            app.UpperArm_Y_up = uibutton(app.UpperArmPanel, 'push');
            app.UpperArm_Y_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperArm_Y_up.Tag = 'UpperArm_Y_up';
            app.UpperArm_Y_up.Text = '▲';
            app.UpperArm_Z_right = uibutton(app.UpperArmPanel, 'push');
            app.UpperArm_Z_right.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperArm_Z_right.Tag = 'UpperArm_Z_right';
            app.UpperArm_Z_right.Text = '↻';
            app.UpperArm_Z_left = uibutton(app.UpperArmPanel, 'push');
            app.UpperArm_Z_left.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperArm_Z_left.Tag = 'UpperArm_Z_left';
            app.UpperArm_Z_left.Text = '↺';
            app.UpperArm_Z_down = uibutton(app.UpperArmPanel, 'push');
            app.UpperArm_Z_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperArm_Z_down.Tag = 'UpperArm_Z_down';
            app.UpperArm_Z_down.Text = '▼';
            app.text45 = uilabel(app.UpperArmPanel);
            app.text45.Tag = 'text45';
            app.text45.Text = 'Z';
            app.UpperArm_Y_right = uibutton(app.UpperArmPanel, 'push');
            app.UpperArm_Y_right.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperArm_Y_right.Tag = 'UpperArm_Y_right';
            app.UpperArm_Y_right.Text = '↻';
            app.UpperArm_Z_up = uibutton(app.UpperArmPanel, 'push');
            app.UpperArm_Z_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperArm_Z_up.Tag = 'UpperArm_Z_up';
            app.UpperArm_Z_up.Text = '▲';
            app.UpperArm_Separation_up = uibutton(app.UpperArmPanel, 'push');
            app.UpperArm_Separation_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperArm_Separation_up.Tag = 'UpperArm_Separation_up';
            app.UpperArm_Separation_up.Text = '▲';
            app.text47 = uilabel(app.UpperArmPanel);
            app.text47.Tag = 'text47';
            app.text47.Text = '↔';
            app.UpperArm_Separation_down = uibutton(app.UpperArmPanel, 'push');
            app.UpperArm_Separation_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperArm_Separation_down.Tag = 'UpperArm_Separation_down';
            app.UpperArm_Separation_down.Text = '▼';
            app.LowerArmPanel = uipanel(app.Panel);
            app.LowerArmPanel.Title = 'Lower Arm';
            app.LowerArmPanel.Tag = 'uipanel4';
            app.LowerArm_X_up = uibutton(app.LowerArmPanel, 'push');
            app.LowerArm_X_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerArm_X_up.Tag = 'LowerArm_X_up';
            app.LowerArm_X_up.Text = '▲';
            app.text46_2 = uilabel(app.LowerArmPanel);
            app.text46_2.Tag = 'text46';
            app.text46_2.Text = 'X';
            app.LowerArm_X_down = uibutton(app.LowerArmPanel, 'push');
            app.LowerArm_X_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerArm_X_down.Tag = 'LowerArm_X_down';
            app.LowerArm_X_down.Text = '▼';
            app.LowerArm_Y_left = uibutton(app.LowerArmPanel, 'push');
            app.LowerArm_Y_left.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerArm_Y_left.Tag = 'LowerArm_Y_left';
            app.LowerArm_Y_left.Text = '↺';
            app.LowerArm_Y_down = uibutton(app.LowerArmPanel, 'push');
            app.LowerArm_Y_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerArm_Y_down.Tag = 'LowerArm_Y_down';
            app.LowerArm_Y_down.Text = '▼';
            app.text44_2 = uilabel(app.LowerArmPanel);
            app.text44_2.Tag = 'text44';
            app.text44_2.Text = 'Y';
            app.LowerArm_Y_up = uibutton(app.LowerArmPanel, 'push');
            app.LowerArm_Y_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerArm_Y_up.Tag = 'LowerArm_Y_up';
            app.LowerArm_Y_up.Text = '▲';
            app.LowerArm_Y_right = uibutton(app.LowerArmPanel, 'push');
            app.LowerArm_Y_right.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerArm_Y_right.Tag = 'LowerArm_Y_right';
            app.LowerArm_Y_right.Text = '↻';
            app.LowerArm_Z_left = uibutton(app.LowerArmPanel, 'push');
            app.LowerArm_Z_left.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerArm_Z_left.Tag = 'LowerArm_Z_left';
            app.LowerArm_Z_left.Text = '↺';
            app.LowerArm_Z_down = uibutton(app.LowerArmPanel, 'push');
            app.LowerArm_Z_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerArm_Z_down.Tag = 'LowerArm_Z_down';
            app.LowerArm_Z_down.Text = '▼';
            app.text45_2 = uilabel(app.LowerArmPanel);
            app.text45_2.Tag = 'text45';
            app.text45_2.Text = 'Z';
            app.LowerArm_Z_right = uibutton(app.LowerArmPanel, 'push');
            app.LowerArm_Z_right.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerArm_Z_right.Tag = 'LowerArm_Z_right';
            app.LowerArm_Z_right.Text = '↻';
            app.LowerArm_Z_up = uibutton(app.LowerArmPanel, 'push');
            app.LowerArm_Z_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerArm_Z_up.Tag = 'LowerArm_Z_up';
            app.LowerArm_Z_up.Text = '▲';
            app.LowerArm_Separation_up = uibutton(app.LowerArmPanel, 'push');
            app.LowerArm_Separation_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerArm_Separation_up.Tag = 'LowerArm_Separation_up';
            app.LowerArm_Separation_up.Text = '▲';
            app.text47_2 = uilabel(app.LowerArmPanel);
            app.text47_2.Tag = 'LowerArm_Separation_down';
            app.text47_2.Text = '↔';
            app.LowerArm_Separation_down = uibutton(app.LowerArmPanel, 'push');
            app.LowerArm_Separation_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerArm_Separation_down.Tag = 'LowerArm_Separation_down';
            app.LowerArm_Separation_down.Text = '▼';
            app.uipanel11 = uipanel(app.Panel);
            app.uipanel11.Title = 'Steering Rack';
            app.uipanel11.Tag = 'uipanel11';
            app.SteeringRack_Z_down = uibutton(app.uipanel11, 'push');
            app.SteeringRack_Z_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.SteeringRack_Z_down.Tag = 'SteeringRack_Z_down';
            app.SteeringRack_Z_down.Text = '▼';
            app.text61 = uilabel(app.uipanel11);
            app.text61.Tag = 'text61';
            app.text61.Text = 'Z';
            app.Steering_Y_down = uibutton(app.uipanel11, 'push');
            app.Steering_Y_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.Steering_Y_down.Tag = 'Steering_Y_down';
            app.Steering_Y_down.Text = '▼';
            app.text60_2 = uilabel(app.uipanel11);
            app.text60_2.Tag = 'text60';
            app.text60_2.Text = 'Y';
            app.SteeringRack_Z_up = uibutton(app.uipanel11, 'push');
            app.SteeringRack_Z_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.SteeringRack_Z_up.Tag = 'SteeringRack_Z_up';
            app.SteeringRack_Z_up.Text = '▲';
            app.SteeringRack_X_down = uibutton(app.uipanel11, 'push');
            app.SteeringRack_X_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.SteeringRack_X_down.Tag = 'SteeringRack_X_down';
            app.SteeringRack_X_down.Text = '▼';
            app.Steering_Y_up = uibutton(app.uipanel11, 'push');
            app.Steering_Y_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.Steering_Y_up.Tag = 'Steering_Y_up';
            app.Steering_Y_up.Text = '▲';
            app.text60 = uilabel(app.uipanel11);
            app.text60.Tag = 'text60';
            app.text60.Text = 'X';
            app.SteeringRack_X_up = uibutton(app.uipanel11, 'push');
            app.SteeringRack_X_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.SteeringRack_X_up.Tag = 'SteeringRack_X_up';
            app.SteeringRack_X_up.Text = '▲';
            app.uipanel5 = uipanel(app.Panel);
            app.uipanel5.Title = 'Upper Upright';
            app.uipanel5.Tag = 'uipanel5';
            app.UpperUpright_X_up = uibutton(app.uipanel5, 'push');
            app.UpperUpright_X_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperUpright_X_up.Tag = 'UpperUpright_X_up';
            app.UpperUpright_X_up.Text = '▲';
            app.UpperUpright_Y_up = uibutton(app.uipanel5, 'push');
            app.UpperUpright_Y_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperUpright_Y_up.Tag = 'UpperUpright_Y_up';
            app.UpperUpright_Y_up.Text = '▲';
            app.text13 = uilabel(app.uipanel5);
            app.text13.Tag = 'text13';
            app.text13.Text = 'X';
            app.text15 = uilabel(app.uipanel5);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Y';
            app.UpperUpright_Z_up = uibutton(app.uipanel5, 'push');
            app.UpperUpright_Z_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperUpright_Z_up.Tag = 'UpperUpright_Z_up';
            app.UpperUpright_Z_up.Text = '▲';
            app.text14 = uilabel(app.uipanel5);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Z';
            app.UpperUpright_X_down = uibutton(app.uipanel5, 'push');
            app.UpperUpright_X_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperUpright_X_down.Tag = 'UpperUpright_X_down';
            app.UpperUpright_X_down.Text = '▼';
            app.UpperUpright_Y_down = uibutton(app.uipanel5, 'push');
            app.UpperUpright_Y_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperUpright_Y_down.Tag = 'UpperUpright_Y_down';
            app.UpperUpright_Y_down.Text = '▼';
            app.UpperUpright_Z_down = uibutton(app.uipanel5, 'push');
            app.UpperUpright_Z_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.UpperUpright_Z_down.Tag = 'UpperUpright_Z_down';
            app.UpperUpright_Z_down.Text = '▼';
            app.uipanel8 = uipanel(app.Panel);
            app.uipanel8.Title = 'Lower Upright';
            app.uipanel8.Tag = 'uipanel8';
            app.LowerUpright_X_up = uibutton(app.uipanel8, 'push');
            app.LowerUpright_X_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerUpright_X_up.Tag = 'LowerUpright_X_up';
            app.LowerUpright_X_up.Text = '▲';
            app.text48 = uilabel(app.uipanel8);
            app.text48.Tag = 'text48';
            app.text48.Text = 'X';
            app.LowerUpright_Y_up = uibutton(app.uipanel8, 'push');
            app.LowerUpright_Y_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerUpright_Y_up.Tag = 'LowerUpright_Y_up';
            app.LowerUpright_Y_up.Text = '▲';
            app.text50 = uilabel(app.uipanel8);
            app.text50.Tag = 'text50';
            app.text50.Text = 'Y';
            app.LowerUpright_X_down = uibutton(app.uipanel8, 'push');
            app.LowerUpright_X_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerUpright_X_down.Tag = 'LowerUpright_X_down';
            app.LowerUpright_X_down.Text = '▼';
            app.LowerUpright_Y_down = uibutton(app.uipanel8, 'push');
            app.LowerUpright_Y_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerUpright_Y_down.Tag = 'LowerUpright_Y_down';
            app.LowerUpright_Y_down.Text = '▼';
            app.LowerUpright_Z_up = uibutton(app.uipanel8, 'push');
            app.LowerUpright_Z_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerUpright_Z_up.Tag = 'LowerUpright_Z_up';
            app.LowerUpright_Z_up.Text = '▲';
            app.text49 = uilabel(app.uipanel8);
            app.text49.Tag = 'text49';
            app.text49.Text = 'Z';
            app.LowerUpright_Z_down = uibutton(app.uipanel8, 'push');
            app.LowerUpright_Z_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.LowerUpright_Z_down.Tag = 'LowerUpright_Z_down';
            app.LowerUpright_Z_down.Text = '▼';
            app.uipanel12 = uipanel(app.Panel);
            app.uipanel12.Title = 'Coordinates';
            app.uipanel12.Tag = 'uipanel12';
            app.text64 = uilabel(app.uipanel12);
            app.text64.Tag = 'text64';
            app.text64.Text = '1 - Upper Front';
            app.text65 = uilabel(app.uipanel12);
            app.text65.Tag = 'text65';
            app.text65.Text = 'X';
            app.text66 = uilabel(app.uipanel12);
            app.text66.Tag = 'text66';
            app.text66.Text = 'Y';
            app.text67 = uilabel(app.uipanel12);
            app.text67.Tag = 'text67';
            app.text67.Text = 'Z';
            app.text68 = uilabel(app.uipanel12);
            app.text68.Tag = 'text68';
            app.text68.Text = '2 - Upper Rear';
            app.text69 = uilabel(app.uipanel12);
            app.text69.Tag = 'text69';
            app.text69.Text = '3 - Lower Front';
            app.text70 = uilabel(app.uipanel12);
            app.text70.Tag = 'text70';
            app.text70.Text = '4 - Lower Rear';
            app.text71 = uilabel(app.uipanel12);
            app.text71.Tag = 'text71';
            app.text71.Text = '5 - Upper Upright';
            app.text72 = uilabel(app.uipanel12);
            app.text72.Tag = 'text72';
            app.text72.Text = '6 - Lower Upright';
            app.text73 = uilabel(app.uipanel12);
            app.text73.Tag = 'text73';
            app.text73.Text = '7 - Steering Rack';
            app.text74 = uilabel(app.uipanel12);
            app.text74.Tag = 'text74';
            app.text74.Text = '8 - Steering Upright';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CoordinatesPanel = uipanel(app.Panel);
            app.UpperFront_X = uilabel(app.CoordinatesPanel);
            app.UpperFront_X.Tag = 'UpperFront_X';
            app.UpperFront_X.Text = '';
            app.UpperFront_Y = uilabel(app.CoordinatesPanel);
            app.UpperFront_Y.Tag = 'UpperFront_Y';
            app.UpperFront_Y.Text = '';
            app.UpperFront_Z = uilabel(app.CoordinatesPanel);
            app.UpperFront_Z.Tag = 'UpperFront_Z';
            app.UpperFront_Z.Text = '';
            app.UpperRear_X = uilabel(app.CoordinatesPanel);
            app.UpperRear_X.Tag = 'UpperRear_X';
            app.UpperRear_X.Text = '';
            app.UpperRear_Y = uilabel(app.CoordinatesPanel);
            app.UpperRear_Y.Tag = 'UpperRear_Y';
            app.UpperRear_Y.Text = '';
            app.UpperRear_Z = uilabel(app.CoordinatesPanel);
            app.UpperRear_Z.Tag = 'UpperRear_Z';
            app.UpperRear_Z.Text = '';
            app.LowerFront_X = uilabel(app.CoordinatesPanel);
            app.LowerFront_X.Tag = 'LowerFront_X';
            app.LowerFront_X.Text = '';
            app.LowerFront_Y = uilabel(app.CoordinatesPanel);
            app.LowerFront_Y.Tag = 'LowerFront_Y';
            app.LowerFront_Y.Text = '';
            app.LowerFront_Z = uilabel(app.CoordinatesPanel);
            app.LowerFront_Z.Tag = 'LowerFront_Z';
            app.LowerFront_Z.Text = '';
            app.LowerRear_X = uilabel(app.CoordinatesPanel);
            app.LowerRear_X.Tag = 'LowerRear_X';
            app.LowerRear_X.Text = '';
            app.LowerRear_Y = uilabel(app.CoordinatesPanel);
            app.LowerRear_Y.Tag = 'LowerRear_Y';
            app.LowerRear_Y.Text = '';
            app.LowerRear_Z = uilabel(app.CoordinatesPanel);
            app.LowerRear_Z.Tag = 'LowerRear_Z';
            app.LowerRear_Z.Text = '';
            app.UpperUpright_X = uilabel(app.CoordinatesPanel);
            app.UpperUpright_X.Tag = 'UpperUpright_X';
            app.UpperUpright_X.Text = '';
            app.UpperUpright_Y = uilabel(app.CoordinatesPanel);
            app.UpperUpright_Y.Tag = 'UpperUpright_Y';
            app.UpperUpright_Y.Text = '';
            app.UpperUpright_Z = uilabel(app.CoordinatesPanel);
            app.UpperUpright_Z.Tag = 'UpperUpright_Z';
            app.UpperUpright_Z.Text = '';
            app.LowerUpright_X = uilabel(app.CoordinatesPanel);
            app.LowerUpright_X.Tag = 'LowerUpright_X';
            app.LowerUpright_X.Text = '';
            app.LowerUpright_Y = uilabel(app.CoordinatesPanel);
            app.LowerUpright_Y.Tag = 'LowerUpright_Y';
            app.LowerUpright_Y.Text = '';
            app.LowerUpright_Z = uilabel(app.CoordinatesPanel);
            app.LowerUpright_Z.Tag = 'LowerUpright_Z';
            app.LowerUpright_Z.Text = '';
            app.SteeringRack_X = uilabel(app.CoordinatesPanel);
            app.SteeringRack_X.Tag = 'SteeringRack_X';
            app.SteeringRack_X.Text = '';
            app.SteeringRack_Y = uilabel(app.CoordinatesPanel);
            app.SteeringRack_Y.Tag = 'SteeringRack_Y';
            app.SteeringRack_Y.Text = '';
            app.SteeringRack_Z = uilabel(app.CoordinatesPanel);
            app.SteeringRack_Z.Tag = 'SteeringRack_Z';
            app.SteeringRack_Z.Text = '';
            app.SteeringUpright_X = uilabel(app.CoordinatesPanel);
            app.SteeringUpright_X.Tag = 'SteeringUpright_X';
            app.SteeringUpright_X.Text = '';
            app.SteeringUpright_Y = uilabel(app.CoordinatesPanel);
            app.SteeringUpright_Y.Tag = 'SteeringUpright_Y';
            app.SteeringUpright_Y.Text = '';
            app.SteeringUpright_Z = uilabel(app.CoordinatesPanel);
            app.SteeringUpright_Z.Tag = 'SteeringUpright_Z';
            app.SteeringUpright_Z.Text = '';
            app.StepSpinnerLabel = uilabel(app.Panel);
            app.StepSpinnerLabel.Text = 'Step';
            app.StepSpinner = uispinner(app.Panel);
            app.StepSpinner.Limits = [0 100];
            app.StepSpinner.Value = 1;
            app.UndoButton = uibutton(app.Panel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = '';
            app.RedoButton = uibutton(app.Panel, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = '';
            app.uipanel11_2 = uipanel(app.Panel);
            app.uipanel11_2.Title = 'Steering Upright';
            app.uipanel11_2.Tag = 'uipanel11';
            app.Steering_Length_up = uibutton(app.uipanel11_2, 'push');
            app.Steering_Length_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.Steering_Length_up.Tag = 'Steering_Length_up';
            app.Steering_Length_up.Text = '▲';
            app.text59 = uilabel(app.uipanel11_2);
            app.text59.Tag = 'text59';
            app.text59.Text = '↔';
            app.Steering_Length_down = uibutton(app.uipanel11_2, 'push');
            app.Steering_Length_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.Steering_Length_down.Tag = 'Steering_Length_down';
            app.Steering_Length_down.Text = '▼';
            app.Steering_Z_right = uibutton(app.uipanel11_2, 'push');
            app.Steering_Z_right.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.Steering_Z_right.Tag = 'Steering_Z_right';
            app.Steering_Z_right.Text = '↻';
            app.text62 = uilabel(app.uipanel11_2);
            app.text62.Tag = 'text62';
            app.text62.Text = 'Z';
            app.SteeringUpright_Z_up = uibutton(app.uipanel11_2, 'push');
            app.SteeringUpright_Z_up.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.SteeringUpright_Z_up.Tag = 'SteeringUpright_Z_up';
            app.SteeringUpright_Z_up.Text = '▲';
            app.SteeringUpright_Z_down = uibutton(app.uipanel11_2, 'push');
            app.SteeringUpright_Z_down.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.SteeringUpright_Z_down.Tag = 'SteeringUpright_Z_down';
            app.SteeringUpright_Z_down.Text = '▼';
            app.Steering_Z_left = uibutton(app.uipanel11_2, 'push');
            app.Steering_Z_left.ButtonPushedFcn = createCallbackFcn(app, @editDataset_ButtonPushed, true);
            app.Steering_Z_left.Tag = 'Steering_Z_left';
            app.Steering_Z_left.Text = '↺';
            app.Panel_2 = uipanel(app.UIFigure);
            app.View3D_Axes = uiaxes(app.Panel_2);
            app.View3D_Axes.Tag = 'View3D_Axes';
            app.BottomView_Button = uibutton(app.Panel_2, 'push');
            app.BottomView_Button.ButtonPushedFcn = createCallbackFcn(app, @BottomView_ButtonPushed, true);
            app.BottomView_Button.Tag = 'BottomView_Button';
            app.BottomView_Button.Text = '⇧';
            app.LeftSideView_Button = uibutton(app.Panel_2, 'push');
            app.LeftSideView_Button.ButtonPushedFcn = createCallbackFcn(app, @RightSideView_ButtonPushed, true);
            app.LeftSideView_Button.Tag = 'LeftSideView_Button';
            app.LeftSideView_Button.Text = '⇦';
            app.FrontView_Button = uibutton(app.Panel_2, 'push');
            app.FrontView_Button.ButtonPushedFcn = createCallbackFcn(app, @FrontView_ButtonPushed, true);
            app.FrontView_Button.Tag = 'FrontView_Button';
            app.FrontView_Button.Text = '⧈';
            app.RightSideView_Button = uibutton(app.Panel_2, 'push');
            app.RightSideView_Button.ButtonPushedFcn = createCallbackFcn(app, @LeftSideView_ButtonPushed, true);
            app.RightSideView_Button.Tag = 'RightSideView_Button';
            app.RightSideView_Button.Text = '⇨';
            app.TopView_Button = uibutton(app.Panel_2, 'push');
            app.TopView_Button.ButtonPushedFcn = createCallbackFcn(app, @TopView_ButtonPushed, true);
            app.TopView_Button.Tag = 'TopView_Button';
            app.TopView_Button.Text = '⇩';
            app.View3DContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.View3DContextMenu
            app.Panel_2.ContextMenu = app.View3DContextMenu;
            app.View3D_Axes.ContextMenu = app.View3DContextMenu;
            app.view3DcheckMenu = uimenu(app.View3DContextMenu);
            app.view3DcheckMenu.MenuSelectedFcn = createCallbackFcn(app, @view3DcheckMenuSelected, true);
            app.view3DcheckMenu.Text = '3D';
            app.Reset3DViewMenu = uimenu(app.View3DContextMenu);
            app.Reset3DViewMenu.MenuSelectedFcn = createCallbackFcn(app, @Reset3DViewMenuSelected, true);
            app.Reset3DViewMenu.Text = 'Reset View';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = suspensionEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end