classdef kinematic3DAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AnalysisConfigurationPanel      matlab.ui.container.Panel
        AnalysisConfigOKButton          matlab.ui.control.Button
        AnalysisConfigCancelButton      matlab.ui.control.Button
        InterpolationPanel              matlab.ui.container.Panel
        InterpStepsLabel                matlab.ui.control.Label
        InterpolateLabel                matlab.ui.control.Label
        InterpolateCheckBox             matlab.ui.control.CheckBox
        InterpolateStepsSpinner         matlab.ui.control.Spinner
        ToleranceLabel                  matlab.ui.control.Label
        SteeringUpperLimit              matlab.ui.control.NumericEditField
        SteeringLowerLimit              matlab.ui.control.NumericEditField
        SteeringStep                    matlab.ui.control.NumericEditField
        RollLimit                       matlab.ui.control.NumericEditField
        RollTol                         matlab.ui.control.NumericEditField
        RollStep                        matlab.ui.control.NumericEditField
        OpositeUpperLimit               matlab.ui.control.NumericEditField
        OpositeLowerLimit               matlab.ui.control.NumericEditField
        OpositeStep                     matlab.ui.control.NumericEditField
        ParallelUpperLimit              matlab.ui.control.NumericEditField
        ParallelLowerLimit              matlab.ui.control.NumericEditField
        ParallelStep                    matlab.ui.control.NumericEditField
        StepLabel                       matlab.ui.control.Label
        LimitsLabel                     matlab.ui.control.Label
        ParallelLabel                   matlab.ui.control.Label
        RollLabel                       matlab.ui.control.Label
        OpositeLabel                    matlab.ui.control.Label
        SteeringLabel                   matlab.ui.control.Label
        SelectCompareDatasets_Panel     matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        PlotOptionsOKButton             matlab.ui.control.Button
        SelectCompareDatasets_SubPanel  matlab.ui.container.Panel
        GlobalContainer                 matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        BottomContainerPanel            matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        ProgressWheel                   matlab.ui.control.Image
        MsgLabel                        matlab.ui.control.Label
        CentralContainer                matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        PlotsContainer                  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        DataViewTabGroup                matlab.ui.container.TabGroup
        ThreeDViewTab                   matlab.ui.container.Tab
        Displacement_2                  matlab.ui.control.Label
        Displacement_Label_2            matlab.ui.control.Label
        Displacement_Slider_2           matlab.ui.control.Slider
        TopView_Button                  matlab.ui.control.Button
        RightSideView_Button            matlab.ui.control.Button
        FrontView_Button                matlab.ui.control.Button
        LeftSideView_Button             matlab.ui.control.Button
        BottomView_Button               matlab.ui.control.Button
        Displacement                    matlab.ui.control.Label
        Displacement_Label              matlab.ui.control.Label
        Displacement_Slider             matlab.ui.control.Slider
        View3D_Axes                     matlab.ui.control.UIAxes
        GraphsTab                       matlab.ui.container.Tab
        ChangeParameterDownButton       matlab.ui.control.Button
        ChangeParameterUpButton         matlab.ui.control.Button
        GraphsSliderValue               matlab.ui.control.Label
        GraphsSliderLabel               matlab.ui.control.Label
        GraphsSlider                    matlab.ui.control.Slider
        Graphs_Axes                     matlab.ui.control.UIAxes
        LeftPanelContainer              matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ParametersPanel                 matlab.ui.container.Panel
        AnalysisExportButton            matlab.ui.control.Button
        AnalysisSelectPanel             matlab.ui.container.Panel
        AnalysisDropDownLabel           matlab.ui.control.Label
        AnalysisSelectDropDown          matlab.ui.control.DropDown
        AnalysisConfigButton            matlab.ui.control.Button
        MotionRatio                     matlab.ui.control.Label
        MotionRatioMin                  matlab.ui.control.Label
        MotionRatioMax                  matlab.ui.control.Label
        MotionRatioLabel                matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        AckermanLabel                   matlab.ui.control.Label
        AckermanMax                     matlab.ui.control.Label
        AckermanMin                     matlab.ui.control.Label
        Ackerman                        matlab.ui.control.Label
        PlotOptionsButton               matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        ParameterSelectDropDown         matlab.ui.control.DropDown
        ParamToolsLabel                 matlab.ui.control.Label
        maxtext                         matlab.ui.control.Label
        mintext                         matlab.ui.control.Label
        TrailMax                        matlab.ui.control.Label
        ScrubMax                        matlab.ui.control.Label
        CasterMax                       matlab.ui.control.Label
        KPIMax                          matlab.ui.control.Label
        ToeMax                          matlab.ui.control.Label
        CamberMax                       matlab.ui.control.Label
        AntiDiveMax                     matlab.ui.control.Label
        RollCenterHeightMax             matlab.ui.control.Label
        TrailMin                        matlab.ui.control.Label
        ScrubMin                        matlab.ui.control.Label
        CasterMin                       matlab.ui.control.Label
        KPIMin                          matlab.ui.control.Label
        ToeMin                          matlab.ui.control.Label
        CamberMin                       matlab.ui.control.Label
        AntiDiveMin                     matlab.ui.control.Label
        RollCenterHeightMin             matlab.ui.control.Label
        Trail                           matlab.ui.control.Label
        Scrub                           matlab.ui.control.Label
        Caster                          matlab.ui.control.Label
        KPI                             matlab.ui.control.Label
        Toe                             matlab.ui.control.Label
        Camber                          matlab.ui.control.Label
        AntiDive                        matlab.ui.control.Label
        RollCenterHeight                matlab.ui.control.Label
        text32                          matlab.ui.control.Label
        text31                          matlab.ui.control.Label
        text30                          matlab.ui.control.Label
        text29                          matlab.ui.control.Label
        text27                          matlab.ui.control.Label
        text28                          matlab.ui.control.Label
        text25                          matlab.ui.control.Label
        text24                          matlab.ui.control.Label
        DataSetTabGroup                 matlab.ui.container.TabGroup
        aArmsTab                        matlab.ui.container.Tab
        SuspOptimButton                 matlab.ui.control.Button
        SuspensionCoordinatesPanel      matlab.ui.container.Panel
        WheelPlane_Z                    matlab.ui.control.EditField
        WheelPlane_Y                    matlab.ui.control.EditField
        WheelPlane_X                    matlab.ui.control.EditField
        WheelCenter_Z                   matlab.ui.control.EditField
        WheelCenter_Y                   matlab.ui.control.EditField
        WheelCenter_X                   matlab.ui.control.EditField
        SteeringUpright_Z               matlab.ui.control.EditField
        SteeringUpright_Y               matlab.ui.control.EditField
        SteeringUpright_X               matlab.ui.control.EditField
        SteeringRack_Z                  matlab.ui.control.EditField
        SteeringRack_Y                  matlab.ui.control.EditField
        SteeringRack_X                  matlab.ui.control.EditField
        LowerUpright_Z                  matlab.ui.control.EditField
        LowerUpright_Y                  matlab.ui.control.EditField
        LowerUpright_X                  matlab.ui.control.EditField
        UpperUpright_Z                  matlab.ui.control.EditField
        UpperUpright_Y                  matlab.ui.control.EditField
        UpperUpright_X                  matlab.ui.control.EditField
        LowerRear_Z                     matlab.ui.control.EditField
        LowerRear_Y                     matlab.ui.control.EditField
        LowerRear_X                     matlab.ui.control.EditField
        LowerFront_Z                    matlab.ui.control.EditField
        LowerFront_Y                    matlab.ui.control.EditField
        LowerFront_X                    matlab.ui.control.EditField
        UpperRear_Z                     matlab.ui.control.EditField
        UpperRear_Y                     matlab.ui.control.EditField
        UpperRear_X                     matlab.ui.control.EditField
        UpperFront_Z                    matlab.ui.control.EditField
        UpperFront_Y                    matlab.ui.control.EditField
        UpperFront_X                    matlab.ui.control.EditField
        SuspensionToolsLabel            matlab.ui.control.Label
        SuspensionEditButton            matlab.ui.control.Button
        StaticCamber_Label              matlab.ui.control.Label
        StaticToe_Label                 matlab.ui.control.Label
        StaticCamber                    matlab.ui.control.EditField
        StaticToe                       matlab.ui.control.EditField
        TireRadius_Label                matlab.ui.control.Label
        TireRadius                      matlab.ui.control.EditField
        xCoordinateLabel                matlab.ui.control.Label
        yCoordinateLabel                matlab.ui.control.Label
        zCoordinateLabel                matlab.ui.control.Label
        WheelPlane_Label                matlab.ui.control.Label
        WheelCenter_Label               matlab.ui.control.Label
        SteeringUprigth_Label           matlab.ui.control.Label
        SteeringRack_Label              matlab.ui.control.Label
        LowerUpright_Label              matlab.ui.control.Label
        UpperUpright_Label              matlab.ui.control.Label
        LowerRear_Label                 matlab.ui.control.Label
        LowerFront_Label                matlab.ui.control.Label
        UpperRear_Label                 matlab.ui.control.Label
        UpperFront_Label                matlab.ui.control.Label
        suspMechTab                     matlab.ui.container.Tab
        BellcrankCheckPlaneButton       matlab.ui.control.Button
        BellcrankparametersPanel        matlab.ui.container.Panel
        BellcrankAngleSpinner           matlab.ui.control.Spinner
        BellcrankAngleSpinnerLabel      matlab.ui.control.Label
        BellcrankDamperarmSpinner       matlab.ui.control.Spinner
        BellcrankDamperarmSpinnerLabel  matlab.ui.control.Label
        BellcrankPusharmSpinner         matlab.ui.control.Spinner
        BellcrankPusharmSpinnerLabel    matlab.ui.control.Label
        DamperlengthSpinner             matlab.ui.control.Spinner
        DamperlengthSpinnerLabel        matlab.ui.control.Label
        SuspMechCoordinatesPanel        matlab.ui.container.Panel
        DamperBracket_Z                 matlab.ui.control.EditField
        DamperBracket_Y                 matlab.ui.control.EditField
        DamperBracket_X                 matlab.ui.control.EditField
        BellcrankDamper_Z               matlab.ui.control.EditField
        BellcrankDamper_Y               matlab.ui.control.EditField
        BellcrankDamper_X               matlab.ui.control.EditField
        BellcrankBracket_Z              matlab.ui.control.EditField
        BellcrankBracket_Y              matlab.ui.control.EditField
        BellcrankBracket_X              matlab.ui.control.EditField
        BellcrankPush_Z                 matlab.ui.control.EditField
        BellcrankPush_Y                 matlab.ui.control.EditField
        BellcrankPush_X                 matlab.ui.control.EditField
        PushBracket_Z                   matlab.ui.control.EditField
        PushBracket_Y                   matlab.ui.control.EditField
        PushBracket_X                   matlab.ui.control.EditField
        BellcrankOptimButton            matlab.ui.control.Button
        xCoordinateLabel_2              matlab.ui.control.Label
        yCoordinateLabel_2              matlab.ui.control.Label
        zCoordinateLabel_2              matlab.ui.control.Label
        BellcrankToolsLabel_2           matlab.ui.control.Label
        DamperBracket_Label             matlab.ui.control.Label
        BellcrankDamper_Label           matlab.ui.control.Label
        BellcrankBracket_Label          matlab.ui.control.Label
        BellcrankPush_Label             matlab.ui.control.Label
        PushBracket_Label               matlab.ui.control.Label
        AppButtonsPanel                 matlab.ui.container.Panel
        RemoveButton                    matlab.ui.control.Button
        DatasetConfigButton             matlab.ui.control.Button
        RedoButton                      matlab.ui.control.Button
        UndoButton                      matlab.ui.control.Button
        NewButton                       matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        SelectDataSetDropDown           matlab.ui.control.DropDown
        SaveContextMenu                 matlab.ui.container.ContextMenu
        SaveasMenu                      matlab.ui.container.Menu
        GraphsContextMenu               matlab.ui.container.ContextMenu
        ResetViewMenu                   matlab.ui.container.Menu
        GraphsCompareWithMenu           matlab.ui.container.Menu
        CombinedanalysisMenu            matlab.ui.container.Menu
        PlotsurfaceMenuCheck            matlab.ui.container.Menu
        ChangeprimaryaxisMenu           matlab.ui.container.Menu
        GraphsSaveAsMenu                matlab.ui.container.Menu
        ImageMenu                       matlab.ui.container.Menu
        MatlabFigureMenu                matlab.ui.container.Menu
        MatlabDatamatMenu               matlab.ui.container.Menu
        ExporttoworkspaceMenu           matlab.ui.container.Menu
        View3DContextMenu               matlab.ui.container.ContextMenu
        view3DcheckMenu                 matlab.ui.container.Menu
        Reset3DViewMenu                 matlab.ui.container.Menu
        view3DCompareWithMenu           matlab.ui.container.Menu
        ExportContextMenu               matlab.ui.container.ContextMenu
        ExportSavetoworkspaceMenu       matlab.ui.container.Menu
        SaveImages                      matlab.ui.container.Menu
        SaveFiguresMenu                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        DatasetConfigApp
        SuspensionEditApp 
        SuspensionOptimApp
        BellcrankOptimApp
        SessionData
        SelectedData
        MsgStack = {};
        CurrentTimerID
        LastDirectory
        ParametersList
        AnalysisList
        AnalysisConfig
        SliderValue_temp
    end
    
    methods (Access = public)
        
        function updateDatasetConfig(app,data)
            app.SessionData{app.SelectedData}.dataset.VehicleParams = data;
            %Set outdated parameters property
            for i = 1:length(app.AnalysisList.ID)
                app.SessionData{app.SelectedData}.Analysis{i}.IsUpToDate = 0;
            end
            app.SessionData{app.SelectedData}.IsSaved = 0;
            updateParamsPanel(app);
        end
         
        function editDatasetCB(app,dataset)
            s = app.SelectedData;
            % Clear RedoStack
            app.SessionData{s}.RedoStack = {};
            % Save data in UndoStack
            app.SessionData{s}.UndoStack = app.SessionData{s};
            % Perform changes
            app.SessionData{s}.dataset = dataset;
            loadDataset(app);
            %Set outdated parameters property
            for i = 1:length(app.AnalysisList.ID)
                app.SessionData{s}.Analysis{i}.IsUpToDate = 0;
            end
            app.SessionData{s}.IsSaved = 0;
            updateParamsPanel(app);
            update3Dview(app);
            updateGraphs(app);
            updateSlider(app);
            updateDatasetList(app);
        end
        
        function optimCB(app,dataset,callingApp)
            try
                s = strcmp(dataset.ID,app.SelectDataSetDropDown.Items);
                % Clear RedoStack
                app.SessionData{s}.RedoStack = {};
                % Save data in UndoStack
                app.SessionData{s}.UndoStack = app.SessionData{s};
                % Perform changes
                app.SessionData{s}.dataset.X = dataset.X;
                loadDataset(app);
                for i = 1:length(app.AnalysisList.ID)
                    app.SessionData{s}.Analysis{i}.IsUpToDate = 0;
                end
                app.SessionData{s}.IsSaved = 0;
                if app.SelectedData == find(s)
                    update3Dview(app);
                    updateGraphs(app);
                    updateParamsPanel(app);
                end
                uialert(callingApp.UIFigure,'Data saved successfully','','Icon','info')
            catch err
                addMsg(app,['optimCB callback error. Data could not be saved' err.message],'err',4)
                uialert(callingApp.UIFigure,'Data could not be saved','Warning','Icon','warning')
            end
        end
        
        function optimSaveAsCB(app,dataset,callingApp)
            try
            addDataset(app, dataset)
            updateSlider(app)
            restore3DView(app)
            loadDataset(app)
            updateCompareMenu(app)
            updateParamsPanel(app)
            update3Dview(app)
            uialert(callingApp.UIFigure,['Data saved successfully. New dataset was added with name ' app.SessionData{app.SelectedData}.dataset.ID],'','Icon','info')
            catch err
                addMsg(app,['optimSaveAsCB callback error. Data could not be saved. ' err.message],'err',4)
                uialert(callingApp.UIFigure,['Error while saving data' err.message],'Warning','Icon','warning')
            end
        end
    end
    
    methods (Access = private)
        
        function solid = solidPlot(app,solid,color)
            switch nargin
                case 2
                    color = [.8 .8 .85];
            end
            solid = patch(app.View3D_Axes, solid, ...
                'FaceColor', color, ...
                'EdgeColor', 'none', ...
                'FaceLighting', 'gouraud', ...
                'FaceAlpha',0.7, ...
                'AmbientStrength', 0.2, ...
                'SpecularStrength', 0.1);
            %Delete existing lights and add
            c = app.View3D_Axes.Children;
            for i=1:length(c)
                if strcmp(c(i).Type,'light')
                    delete(c(i));
                end
            end
            light(app.View3D_Axes,'Style','local','Position',[2000 1000 800]);
            light(app.View3D_Axes,'Style','local','Position',[2000 -1000 800]);
            light(app.View3D_Axes,'Style','local','Position',[-2000 1000 800]);
            light(app.View3D_Axes,'Style','local','Position',[-2000 -1000 800]);
        end
        
        function kinematicsPlot(app,X,color)
            s = app.SelectedData;
            tireRadius = app.SessionData{s}.dataset.TireRadius;
            X = reshape(X,1,[]);
            if nargin == 2
                color = [.4 .4 .5];
            end
            if app.view3DcheckMenu.Checked 
                if ~isempty(app.SessionData{s}.dataset.VehicleParams.RodD)
                %Upper A-Arm
                [Cx, Cy, Cz] = cylinder2P(app.SessionData{s}.dataset.VehicleParams.RodD/2,15,X(1:3),X(13:15));
                surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',color,'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                [Cx, Cy, Cz] = cylinder2P(app.SessionData{s}.dataset.VehicleParams.RodD/2,15,X(4:6),X(13:15));
                surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',color,'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                %Lower A-Arm
                [Cx, Cy, Cz] = cylinder2P(app.SessionData{s}.dataset.VehicleParams.RodD/2,15,X(7:9),X(16:18));
                surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',color,'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                [Cx, Cy, Cz] = cylinder2P(app.SessionData{s}.dataset.VehicleParams.RodD/2,15,X(10:12),X(16:18));
                surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',color,'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                %Steering tie-rod
                [Cx, Cy, Cz] = cylinder2P(app.SessionData{s}.dataset.VehicleParams.RodD/2,15,X(19:21),X(22:24));
                surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',color,'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                %Wheel
                plot3(app.View3D_Axes,X(25), X(26), X(27),'o','color',[0 0 0]);
                plotCircle3D(app.View3D_Axes, X(25:27), X(28:30),tireRadius);
                if length(X) == 45
                    %Push
                    [Cx, Cy, Cz] = cylinder2P(app.SessionData{s}.dataset.VehicleParams.RodD/2,15,X(31:33),X(34:36));
                    surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',color,'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                    %Bellcrank
                    plot3(app.View3D_Axes,[X(37) X(34)],[X(38) X(35)],[X(39) X(36)],'color',color,"LineWidth",4);
                    plot3(app.View3D_Axes,[X(37) X(40)],[X(38) X(41)],[X(39) X(42)],'color',color,"LineWidth",4);
                    plot3(app.View3D_Axes,[X(40) X(34)],[X(41) X(35)],[X(42) X(36)],'color',color,"LineWidth",4);
                    %Damper
                    p = X(43:45) + norm(app.SessionData{s}.dataset.X(43:45)-app.SessionData{s}.dataset.X(40:42))*2/3*(X(40:42)-X(43:45))/norm(X(40:42)-X(43:45));
                    [Cx, Cy, Cz] = cylinder2P(10,15,X(43:45),p);
                    surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',[.2 .2 .2],'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                    p = X(43:45) + norm(app.SessionData{s}.dataset.X(43:45)-app.SessionData{s}.dataset.X(40:42))*1/3*(X(40:42)-X(43:45))/norm(X(40:42)-X(43:45));
                    [Cx, Cy, Cz] = cylinder2P(7,15,X(40:42),p);
                    surf(app.View3D_Axes,Cx,Cy,Cz,'FaceColor',[.5 .5 .5],'FaceAlpha',.7,'EdgeAlpha',.5,'EdgeColor','none');
                end
                else
                    addMsg(app,'Missing rod diameter data. Add value in vehicle parameters configuration menu','w','2')
                end
            else
                %Upper A-Arm
                plot3(app.View3D_Axes,[X(1) X(13)],[X(2) X(14)],[X(3) X(15)],'color',color);%Left
                plot3(app.View3D_Axes,[X(4) X(13)],[X(5) X(14)],[X(6) X(15)],'color',color);
                %Lower A-Arm
                plot3(app.View3D_Axes,[X(7) X(16)],[X(8) X(17)],[X(9) X(18)],'color',color);%Left
                plot3(app.View3D_Axes,[X(10) X(16)],[X(11) X(17)],[X(12) X(18)],'color',color);
                %Steering tie-rod
                plot3(app.View3D_Axes,[X(19) X(22)],[X(20) X(23)],[X(21) X(24)],'color',color);
                %Wheel
                plot3(app.View3D_Axes,X(25), X(26), X(27),'o','color',[0 0 0]);
                plotCircle3D(app.View3D_Axes, X([25 26 27]),X([28 29 30]),tireRadius);
                if length(X) == 45
                    %Push
                    plot3(app.View3D_Axes,[X(31) X(34)],[X(32) X(35)],[X(33) X(36)],'color',color);
                    %Bellcrank
                    plot3(app.View3D_Axes,[X(37) X(34)],[X(38) X(35)],[X(39) X(36)],'color',color);
                    plot3(app.View3D_Axes,[X(37) X(40)],[X(38) X(41)],[X(39) X(42)],'color',color);
                    plot3(app.View3D_Axes,[X(40) X(34)],[X(41) X(35)],[X(42) X(36)],'color',color);
                    %Damper
                    plot3(app.View3D_Axes,[X(40) X(43)],[X(41) X(44)],[X(42) X(45)],'color',color);
                end
            end
        end
        
        function view3DcompareWithCB(app,src,~)
            if ~src.Checked
                children = app.view3DCompareWithMenu.Children;
                for i=1:length(children)
                    children(i).Checked = 'off';
                end
                src.Checked = 'on';
                s = strcmp(src.Text, app.SelectDataSetDropDown.Items);
            else
                src.Checked = 'off';
                s = 0;
            end
            updateSlider(app)
            updateParamsPanel(app)
            update3Dview(app)
            % Hide Slider
            app.Displacement_Slider.Visible = 'off';
            app.Displacement.Visible = 'off';
            app.Displacement_Label.Visible = 'off';
            % Hide Slider
            app.Displacement_Slider_2.Visible = 'off';
            app.Displacement_2.Visible = 'off';
            app.Displacement_Label_2.Visible = 'off';
            if sum(s)
                hold(app.View3D_Axes,'on');
                %% A-arms
                kinematicsPlot(app, app.SessionData{s}.dataset.X,[1 .45 .5]);
                kinematicsPlot(app, symSuspensionPoints(app.SessionData{s}.dataset.X),[1 .45 .5]);
                hold(app.View3D_Axes,'off');
            end
        end
        
        function graphsCompareWithCB(app,src,~)
            switch src.Checked
                case 'on'
                    src.Checked = 'off';
                case 'off'
                    src.Checked = 'on';
            end
            children = app.SelectCompareDatasets_SubPanel.Children;
            for i=1:length(children)
                if strcmp(children(i).Type,'uicheckbox')
                    if strcmp(children(i).Text,src.Text)
                        children(i).Value = src.Checked;
                    end
                end
            end
            PlotButtonPushed(app, '');
        end
        
        function addDataset(app,dataset)
            try
            if ischar(dataset)
                path = dataset;
                dataset = load(path);
            end
            %Check if dataset is valid
            NoError = 1;
            try
                if isa(dataset.X,'double') ~= 1 || (length(dataset.X) ~= 30 && length(dataset.X) ~= 45)
                    error('Coordinates data have wrong format');
                end
                if isa(dataset.StaticCamber,'double') ~= 1 || length(dataset.StaticCamber) ~= 1
                    error('Camber data has wrong format');
                end
                if isa(dataset.StaticToe,'double') ~= 1 || length(dataset.StaticToe) ~= 1
                    error('Toe data has wrong format');
                end
                if isa(dataset.TireRadius,'double') ~= 1 || length(dataset.TireRadius) ~= 1
                    error('Tire radius data has wrong format');
                end
                if ~isfield(dataset, 'VehicleParams')
                    dataset.VehicleParams = [];
                    dataset.VehicleParams.Track = [];
                    dataset.VehicleParams.WheelBase = [];
                    dataset.VehicleParams.CGX = [];
                    dataset.VehicleParams.CGZ = [];
                    dataset.VehicleParams.ChassisGeom = [];
                    dataset.VehicleParams.RodD = 14; %default diameter value
                    
                    addMsg(app,'Missing vehicle parameters','warning');
                end
                if ~iscolumn(dataset.X)
                    dataset.X = dataset.X';
                    if ~iscolumn(dataset.X)
                        error('Coordinates data have wrong format');
                    end
                end
            catch err
                NoError = 0;
                addMsg(app,['File has data with invalid format. ' err.message],'error',7);
            end
            if NoError %Add dataset to app data
                s = length(app.SessionData)+1;
                if exist('path','var')
                    dataset.ID = [];
                    [~,dataset.ID,~] = fileparts(path); %match dataset ID to file name
                    dataset.ID = matlab.lang.makeUniqueStrings(dataset.ID, app.SelectDataSetDropDown.Items);
                    app.SessionData{s}.IsSaved = 1;
                    app.SessionData{s}.DatasetPath = path;
                else
                    dataset.ID = matlab.lang.makeUniqueStrings(dataset.ID, app.SelectDataSetDropDown.Items);
                    app.SessionData{s}.IsSaved = 0;
                    app.SessionData{s}.DatasetPath = [];
                end
                app.SessionData{s}.dataset = dataset;
                app.SessionData{s}.UndoStack = {};
                app.SessionData{s}.RedoStack = {};
                app.SessionData{s}.Analysis = {};
                app.SessionData{s}.ParameterHasChanged = 1;
                for i=1:length(app.AnalysisList.ID)
                    app.SessionData{s}.Analysis{i}.ID = app.AnalysisList.ID{i};
                    app.SessionData{s}.Analysis{i}.Xaxis.ID = app.AnalysisList.Xaxis{i};
                    app.SessionData{s}.Analysis{i}.Yaxis.ID = app.AnalysisList.Yaxis{i};
                    app.SessionData{s}.Analysis{i}.Xaxis.Value = [];
                    app.SessionData{s}.Analysis{i}.Yaxis.Value = [];
                    app.SessionData{s}.Analysis{i}.PrimaryAxis = 1;
                    app.SessionData{s}.Analysis{i}.Parameter = {};
                    app.SessionData{s}.Analysis{i}.IsUpToDate = 0;
                    for j=1:length(app.ParametersList)
                        app.SessionData{s}.Analysis{i}.Parameter{j}.Value = [];
                        app.SessionData{s}.Analysis{i}.Parameter{j}.ID = app.ParametersList{j};
                    end
                end
                app.SelectDataSetDropDown.Items{s} = dataset.ID;
                app.SelectedData = s;
                app.SelectDataSetDropDown.Value = app.SelectDataSetDropDown.Items{s};
            end
            catch err
                addMsg(app,['addDataset error. ' err.message],'err');
            end
        end
        
        function retrieveDatasets(app)
            files = dir(fullfile('./','Datasets','*.mat')); %get .mat files in the 'Dataset' directory
            [~,idx] = sort([files.datenum]); %Sort files by date so the most recent is loaded first
            files = files(flip(idx));
            for i=1:length(files)
                addDataset(app,fullfile('./','Datasets',files(i).name));
            end
        end
        
        function addMsg(app,msg,type,time)
            switch nargin %Define default message time if not specified
                case 3
                    time = 2;
            end 
            if ~isempty(app.MsgStack)
                if strcmp(app.MsgStack{end}.msg, msg)
                    return;
                end
            end
            app.MsgStack{end+1}.msg = msg;
            app.MsgStack{end}.type = type;
            app.MsgStack{end}.time = time;
            if length(app.MsgStack) == 1
                dispMsg(app)
            else
                txt = app.MsgLabel.Text;
                if length(app.MsgStack) > 2
                    txt = txt(5:end);
                end
                nMsg = ['(' num2str(length(app.MsgStack)) ') '];
                app.MsgLabel.Text = [nMsg txt];
            end
        end
        
        function dispMsg(app)
            try
                Msg = app.MsgStack{1}; %Get last message
                %Message type
                switch Msg.type
                    case {'error','err'}
                        app.MsgLabel.FontColor = [1 .1 .1];
                    case 'warning'
                        app.MsgLabel.FontColor = [1 .75 .25];
                    case 'info'
                        app.MsgLabel.FontColor = [.1 .1 .1];
                    case 'success'
                        app.MsgLabel.FontColor = [.3 .8 0];
                end
                %Display message
                if length(app.MsgStack) > 1
                    nMsg = ['(' num2str(length(app.MsgStack)) ') '];
                else
                    nMsg = '';
                end
                app.MsgLabel.Text = [nMsg Msg.msg];
                %Setup timer to remove message after time
                t = timer;
                app.CurrentTimerID = rand;
                t.UserData.timerID = app.CurrentTimerID;
                t.TimerFcn = @app.rmMsg;
                t.StartDelay = Msg.time;
                start(t);
            catch
                warning('error while printing message');
            end
        end
        
        function rmMsg(app, src, ~)
            if src.UserData.timerID == app.CurrentTimerID
                app.MsgStack(1) = []; %Remove message from stack
                if ~isempty(app.MsgStack)
                    dispMsg(app)
                else
                    app.MsgLabel.Text = '';
                    app.MsgLabel.FontColor = [0 0 0];
                end
            end
            stop(src);
            delete(src);
        end
        
        function loadDataset(app)
            dataset = app.SessionData{app.SelectedData}.dataset;
            %A-Arms Coordinates
            AArmsEditBoxes = app.SuspensionCoordinatesPanel.Children;
            for i=1:30
                AArmsEditBoxes(31-i).Value = sprintf('%.1f',dataset.X(i));%Order of boxes is inverted respect to dataset coordinates hence the 31-i
            end
            %Suspension Mechanism Coordinates
            BellcrankEditBoxes = app.SuspMechCoordinatesPanel.Children;
            if length(dataset.X)==45
                for i=1:15
                    BellcrankEditBoxes(16-i).Value = sprintf('%.1f',dataset.X(i+30));%Order of boxes is inverted respect to dataset coordinates hence the 16-i
                end
                try
                app.DamperlengthSpinner.Value = norm(dataset.X(43:45)-dataset.X(40:42));
                app.BellcrankDamperarmSpinner.Value = norm(dataset.X(37:39)-dataset.X(40:42));
                app.BellcrankPusharmSpinner.Value = norm(dataset.X(37:39)-dataset.X(34:36));
                app.BellcrankAngleSpinner.Value = vectsAngle(dataset.X(40:42)-dataset.X(37:39),dataset.X(34:36)-dataset.X(37:39));
                catch
                end
            else
                 for i=1:15
                    BellcrankEditBoxes(i).Value = '';
                 end
                 app.DamperlengthSpinner.Value = 0;
                app.BellcrankDamperarmSpinner.Value = 0;
                app.BellcrankPusharmSpinner.Value = 0;
                app.BellcrankAngleSpinner.Value = 0;
            end
            %Camber, Toe and Tire radius
            app.StaticCamber.Value = num2str(dataset.StaticCamber);
            app.StaticToe.Value = num2str(dataset.StaticToe);
            app.TireRadius.Value = num2str(dataset.TireRadius);
        end
        
        function updateCoordinates(app,event)
            %Check if new value is valid
            if isnan(str2double(event.Value))
                event.Source.Value = event.PreviousValue;
                return;
            else
                event.Source.Value = sprintf('%.1f',str2double(event.Value));
            end
            %Check if dataset is loaded
            if isempty(app.SessionData)
                addMsg(app,'Create new dataset first','warning');
                event.Source.Value = event.PreviousValue;
                return;
            end
            try
                s = app.SelectedData;
                % Clear RedoStack
                app.SessionData{s}.RedoStack = {};
                % Save data in UndoStack
                app.SessionData{s}.UndoStack = app.SessionData{s};
                % Perform changes
                %% Find index to edit in dataset
                srcTag = event.Source.Tag;
                boxes = event.Source.Parent.Children;
                tags = cell(1,length(boxes));
                for i=1:length(boxes)
                    tags{i} = boxes(i).Tag;
                end
                if length(boxes) == 30
                    app.SessionData{s}.dataset.X(flip(strcmp(tags,srcTag))) = str2double(event.Value); %Order of boxes is inverted respect to dataset coordinates, hence the flip
                elseif length(boxes) == 15
                    %Check if dataset contains bellcrank data or not
                    if length(app.SessionData{s}.dataset.X) == 30 %If not, then add suspension mechanism data (zeros)
                        app.SessionData{s}.dataset.X = [app.SessionData{s}.dataset.X; zeros(15,1)];
                        loadDataset(app);
                        event.Source.Value = sprintf('%.1f',str2double(event.Value));
                    end
                    index = 16-find(strcmp(tags,srcTag)); %Order of boxes is inverted respect to dataset coordinates hence the 16-index
                    app.SessionData{s}.dataset.X(index+30) = str2double(event.Value);
                    try
                    app.DamperlengthSpinner.Value = norm(app.SessionData{s}.dataset.X(43:45)-app.SessionData{s}.dataset.X(40:42));
                    app.BellcrankDamperarmSpinner.Value = norm(app.SessionData{s}.dataset.X(37:39)-app.SessionData{s}.dataset.X(40:42));
                    app.BellcrankPusharmSpinner.Value = norm(app.SessionData{s}.dataset.X(37:39)-app.SessionData{s}.dataset.X(34:36));
                    app.BellcrankAngleSpinner.Value = vectsAngle(app.SessionData{s}.dataset.X(40:42)-app.SessionData{s}.dataset.X(37:39),app.SessionData{s}.dataset.X(34:36)-app.SessionData{s}.dataset.X(37:39));
                    catch
                    end
                end
                %Set outdated parameters property
                for i = 1:length(app.AnalysisList.ID)
                    app.SessionData{s}.Analysis{i}.IsUpToDate = 0;
                end
                app.SessionData{s}.IsSaved = 0;
                updateParamsPanel(app);
                updateSlider(app);
                update3Dview(app);
            catch err
                addMsg(app,['updateCoordinates error. ' err.message],'err',5)
            end
        end
        
        function setParamsColor(app,color)
            if app.Camber.FontColor == color
                return;
            end
            app.Camber.FontColor = color;
            app.CamberMax.FontColor = color;
            app.CamberMin.FontColor = color;
            app.Toe.FontColor = color;
            app.ToeMax.FontColor = color;
            app.ToeMin.FontColor = color;
            app.KPI.FontColor = color;
            app.KPIMax.FontColor = color;
            app.KPIMin.FontColor = color;
            app.Caster.FontColor = color;
            app.CasterMax.FontColor = color;
            app.CasterMin.FontColor = color;
            app.Trail.FontColor = color;
            app.TrailMax.FontColor = color;
            app.TrailMin.FontColor = color;
            app.Scrub.FontColor = color;
            app.ScrubMax.FontColor = color;
            app.ScrubMin.FontColor = color;
            app.RollCenterHeight.FontColor = color;
            app.RollCenterHeightMax.FontColor = color;
            app.RollCenterHeightMin.FontColor = color;
            app.AntiDive.FontColor = color;
            app.AntiDiveMax.FontColor = color;
            app.AntiDiveMin.FontColor = color;
            app.Ackerman.FontColor = color;
            app.AckermanMax.FontColor = color;
            app.AckermanMin.FontColor = color;
            app.MotionRatio.FontColor = color;
            app.MotionRatioMax.FontColor = color;
            app.MotionRatioMin.FontColor = color;
        end
        function update3Dview(app)
            s = app.SelectedData;
            if s == 0
                return;
            end
            Data = app.SessionData{s};
            try
                a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value); %get index of selected analysis
                % Clear axes and activate hold
                cla(app.View3D_Axes);
                hold(app.View3D_Axes,'on');
                % Plot stuff
                %% A-arms
                if isempty(Data.Analysis{a}.Xaxis.Value) || ~Data.Analysis{a}.IsUpToDate %If no analysis data or not up to date, plot static geometry
                    kinematicsPlot(app, app.SessionData{app.SelectedData}.dataset.X);
                    kinematicsPlot(app, symSuspensionPoints(app.SessionData{app.SelectedData}.dataset.X));
                else
                    sliderValueX = round(interp1(app.Displacement_Slider.Limits,[1 size(Data.Analysis{a}.X,2)],app.SliderValue_temp.X));
                    sliderValueY = round(interp1(app.Displacement_Slider_2.Limits,[1 size(Data.Analysis{a}.X,3)],app.SliderValue_temp.Y));
                    RCZ = Data.Analysis{a}.Parameter{strcmp(app.ParametersList,'RC Height')}.Value(round(app.SliderValue_temp.X), round(app.SliderValue_temp.Y)); %Get roll center data to plot the point
                    RCY = Data.Analysis{a}.Parameter{strcmp(app.ParametersList,'RC Migration')}.Value(round(app.SliderValue_temp.X), round(app.SliderValue_temp.Y));
                    RCX = Data.Analysis{a}.X(25,sliderValueX,sliderValueY);
                    RC = [RCX RCY RCZ];
                    plot3(app.View3D_Axes, RC(1), RC(2), RC(3) + Data.dataset.X(27) - Data.dataset.TireRadius, 'o', 'Color', [.2 .4 .9]); %Plot roll center
                    kinematicsPlot(app, Data.Analysis{a}.X(:,sliderValueX,sliderValueY)); %Plot left side A-arms
                    kinematicsPlot(app, Data.Analysis{a}.X_R(:,sliderValueX,sliderValueY)); %Plot right side A-arms
                end
                %% Chassis, if data is present and 3D option is selected
                if app.view3DcheckMenu.Checked && ~isempty(app.SessionData{app.SelectedData}.dataset.VehicleParams.ChassisGeom)
                    chassis = solidPlot(app,app.SessionData{app.SelectedData}.dataset.VehicleParams.ChassisGeom);
                    %%% If roll analysis is selected, rotate the chassis
                    if ~isempty(Data.Analysis{a}.Xaxis.Value) && Data.Analysis{a}.IsUpToDate
                        switch app.AnalysisSelectDropDown.Value
                            case {'Roll','Roll-Steer'}
                                rotate(chassis,[1 0 0],Data.Analysis{a}.Xaxis.Value(round(app.SliderValue_temp.X)),[0 0 RC(3)+Data.dataset.X(27)-Data.dataset.TireRadius]);
                            case 'Ride-Roll'
                                rotate(chassis,[1 0 0],Data.Analysis{a}.Yaxis.Value(round(app.SliderValue_temp.Y)),[0 0 RC(3)+Data.dataset.X(27)-Data.dataset.TireRadius]);
                        end
                    end
                end
                hold(app.View3D_Axes,'off');
            catch err
                addMsg(app,['update3DView error: ' err.message],'error',5);
            end
        end
        
        function restore3DView(app)
            try
                s = app.SelectedData;
                if s
                    app.View3D_Axes.XLim = [app.SessionData{s}.dataset.X(25)-400 app.SessionData{s}.dataset.X(25)+400];
                    app.View3D_Axes.YLim = [-app.SessionData{s}.dataset.X(26)-150 app.SessionData{s}.dataset.X(26)+150];
                    app.View3D_Axes.ZLim = [app.SessionData{s}.dataset.X(27)-app.SessionData{s}.dataset.TireRadius-50 app.SessionData{s}.dataset.X(27)+app.SessionData{s}.dataset.TireRadius+400];
                else
                    app.View3D_Axes.XLim = [-1100 2000];
                    app.View3D_Axes.YLim = [-800 800];
                    app.View3D_Axes.ZLim = [-200 800];
                end
                app.View3D_Axes.PlotBoxAspectRatio = [
                    abs(app.View3D_Axes.XLim(2)-app.View3D_Axes.XLim(1))
                    abs(app.View3D_Axes.YLim(2)-app.View3D_Axes.YLim(1))
                    abs(app.View3D_Axes.ZLim(2)-app.View3D_Axes.ZLim(1))];
                    app.View3D_Axes.View = [120,20];
            catch
            end
        end
        
        function performAnalysis(app,s,a)
            if ~exist('s','var')
                s = false(1,length(app.SessionData));
                s(app.SelectedData) = true;
            end
            if ~exist('a','var')
                a = find(strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value));
            end
            %Perform analysis
            try
                for i = find(s)
                    for k = a
                        app.SessionData{i}.Analysis{k}.X = []; %clear existing data, since the necessary matrix dimensions might have changed
                        app.SessionData{i}.Analysis{k}.X_R = [];
                        app.SessionData{i}.Analysis{k}.Xaxis.Value = [];
                        app.SessionData{i}.Analysis{k}.Yaxis.Value = [];
                        for p = 1:length(app.ParametersList)
                            app.SessionData{i}.Analysis{k}.Parameter{p}.Value = [];
                        end
                        switch k
                            case 1 %Parallel
                                % Suspension coordinates
                                [app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.Xaxis.Value] = parallel(app.SessionData{i}.dataset.X, app.AnalysisConfig{k}.step, app.AnalysisConfig{k}.limits);
                                app.SessionData{i}.Analysis{k}.X_R = symSuspensionPoints(app.SessionData{i}.Analysis{k}.X);
                                % Parameters
                                %% Motion ratio
                                if length(app.SessionData{i}.dataset.X)==45
                                    val = motionRatio(app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.Xaxis.Value);
                                    app.SessionData{i}.Analysis{k}.Parameter{11}.Value = val';
                                end
                            case 2 %Oposite
                                % Suspension coordinates
                                [app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.Xaxis.Value] = parallel(app.SessionData{i}.dataset.X, app.AnalysisConfig{k}.step, app.AnalysisConfig{k}.limits);
                                app.SessionData{i}.Analysis{k}.X_R = symSuspensionPoints(fliplr(app.SessionData{i}.Analysis{k}.X));
                                % Parameters
                                %% Motion ratio
                                if length(app.SessionData{i}.dataset.X)==45
                                    val = motionRatio(app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.Xaxis.Value);
                                    app.SessionData{i}.Analysis{k}.Parameter{11}.Value = val';
                                end
                            case 3 %Roll
                                % Suspension coordinates
                                [app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.X_R, app.SessionData{i}.Analysis{k}.Xaxis.Value] = roll(app.SessionData{i}.dataset.X, app.SessionData{i}.dataset.TireRadius, app.AnalysisConfig{k}.step, app.AnalysisConfig{k}.limits, app.AnalysisConfig{k}.tol);
                            case 4 %Steer
                                % Suspension coordinates
                                [app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.X_R, app.SessionData{i}.Analysis{k}.Xaxis.Value] = steering(app.SessionData{i}.dataset.X, app.AnalysisConfig{k}.step, app.AnalysisConfig{k}.limits);
                                % Ackerman
                                app.SessionData{i}.Analysis{k}.Parameter{10}.Value = ackerman(app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.X_R, app.SessionData{i}.dataset.VehicleParams.WheelBase, app.SessionData{i}.dataset.VehicleParams.Track, app.SessionData{i}.Analysis{k}.Xaxis.Value, app.SessionData{i}.Analysis{k}.Yaxis.Value);
                            case 5 %Ride-Roll
                                [X_ride, app.SessionData{i}.Analysis{k}.Xaxis.Value] = parallel(app.SessionData{i}.dataset.X, app.AnalysisConfig{1}.step, app.AnalysisConfig{1}.limits);
                                for j=1:length(app.SessionData{i}.Analysis{k}.Xaxis.Value)
                                    [app.SessionData{i}.Analysis{k}.X(:,j,:), app.SessionData{i}.Analysis{k}.X_R(:,j,:), app.SessionData{i}.Analysis{k}.Yaxis.Value] = roll(X_ride(:,j), app.SessionData{i}.dataset.TireRadius, app.AnalysisConfig{3}.step, app.AnalysisConfig{3}.limits, app.AnalysisConfig{3}.tol);
                                end
                            case 6 % Ride-Steer
                                [X_ride, app.SessionData{i}.Analysis{k}.Xaxis.Value] = parallel(app.SessionData{i}.dataset.X, app.AnalysisConfig{1}.step, app.AnalysisConfig{1}.limits);
                                for j=1:length(app.SessionData{i}.Analysis{k}.Xaxis.Value)
                                    [app.SessionData{i}.Analysis{k}.X(:,j,:), app.SessionData{i}.Analysis{k}.X_R(:,j,:), app.SessionData{i}.Analysis{k}.Yaxis.Value] = steering(X_ride(:,j), app.AnalysisConfig{4}.step, app.AnalysisConfig{4}.limits);
                                end
                                % Ackerman
                                app.SessionData{i}.Analysis{k}.Parameter{10}.Value = ackerman(app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.X_R, app.SessionData{i}.dataset.VehicleParams.WheelBase, app.SessionData{i}.dataset.VehicleParams.Track, app.SessionData{i}.Analysis{k}.Xaxis.Value, app.SessionData{i}.Analysis{k}.Yaxis.Value);
                            case 7 % Roll-Steer
                                [X_roll, X_roll_R, app.SessionData{i}.Analysis{k}.Xaxis.Value] = roll(app.SessionData{i}.dataset.X, app.SessionData{i}.dataset.TireRadius, app.AnalysisConfig{3}.step, app.AnalysisConfig{3}.limits, app.AnalysisConfig{3}.tol);
                                for j=1:length(app.SessionData{i}.Analysis{k}.Xaxis.Value)
                                    [app.SessionData{i}.Analysis{k}.X(:,j,:), ~, app.SessionData{i}.Analysis{k}.Yaxis.Value] = steering(X_roll(:,j), app.AnalysisConfig{4}.step, app.AnalysisConfig{4}.limits);
                                    [~, app.SessionData{i}.Analysis{k}.X_R(:,j,:), ~] = steering(symSuspensionPoints(X_roll_R(:,j)), app.AnalysisConfig{4}.step, app.AnalysisConfig{4}.limits);
                                end
                            otherwise
                                addMsg(app,'Analysis type not found','err',2)
                                return;
                        end
                        % Parameters that are common to every analysis are calculated here
                        app.SessionData{i}.Analysis{k}.Parameter{1}.Value = camber(app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.Xaxis.Value, app.SessionData{i}.Analysis{k}.Yaxis.Value);      %Camber
                        app.SessionData{i}.Analysis{k}.Parameter{2}.Value = toe(app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.Xaxis.Value, app.SessionData{i}.Analysis{k}.Yaxis.Value);            %Toe
                        app.SessionData{i}.Analysis{k}.Parameter{3}.Value = kpi(app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.Xaxis.Value, app.SessionData{i}.Analysis{k}.Yaxis.Value);            %KPI
                        app.SessionData{i}.Analysis{k}.Parameter{4}.Value = caster(app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.Xaxis.Value, app.SessionData{i}.Analysis{k}.Yaxis.Value);      %Caster
                        app.SessionData{i}.Analysis{k}.Parameter{5}.Value = trail(app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.dataset.TireRadius, app.SessionData{i}.Analysis{k}.Xaxis.Value, app.SessionData{i}.Analysis{k}.Yaxis.Value);        %Trail
                        app.SessionData{i}.Analysis{k}.Parameter{6}.Value = scrub(app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.dataset.TireRadius, app.SessionData{i}.Analysis{k}.Xaxis.Value, app.SessionData{i}.Analysis{k}.Yaxis.Value);        %Scrub
                        RC = rollCenter(app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.Analysis{k}.X_R, app.SessionData{i}.dataset.TireRadius, app.SessionData{i}.Analysis{k}.Xaxis.Value, app.SessionData{i}.Analysis{k}.Yaxis.Value); %RC
                        height = squeeze(RC(3,:,:)); %RC Height
                        if isrow(height)
                            app.SessionData{i}.Analysis{k}.Parameter{7}.Value(:,:) = height';
                        else
                            app.SessionData{i}.Analysis{k}.Parameter{7}.Value(:,:) = height;
                        end
                        mig = squeeze(RC(2,:,:)); %RC Migration
                        if isrow(height)
                            app.SessionData{i}.Analysis{k}.Parameter{8}.Value(:,:) = mig';
                        else
                            app.SessionData{i}.Analysis{k}.Parameter{8}.Value(:,:) = mig;
                        end
                        app.SessionData{i}.Analysis{k}.Parameter{9}.Value = antiDive(app.SessionData{i}.Analysis{k}.X, app.SessionData{i}.dataset.TireRadius, [app.SessionData{i}.dataset.VehicleParams.CGX 0 app.SessionData{i}.dataset.VehicleParams.CGZ], app.SessionData{i}.Analysis{k}.Xaxis.Value, app.SessionData{i}.Analysis{k}.Yaxis.Value);%Anti-dive
                        % Interpolation
                        if app.InterpolateCheckBox.Value
                            Xval = app.SessionData{i}.Analysis{k}.Xaxis.Value;
                            Yval = app.SessionData{i}.Analysis{k}.Yaxis.Value;
                            if ~isempty(Yval)
                                [Y, X] = meshgrid(Yval,Xval);
                                Xval = linspace(min(Xval),max(Xval),(app.InterpolateStepsSpinner.Value*2)*length(Xval));
                                if ~sum(Xval==0) %If zero displacement is not in displacemente vector, include it
                                    indexZero = sum(Xval<0)+1;
                                    Xval(indexZero+1:end+1) = Xval(indexZero:end);
                                    Xval(indexZero) = 0;
                                end
                                Yval = linspace(min(Yval),max(Yval),(app.InterpolateStepsSpinner.Value*2)*length(Yval));
                                if ~sum(Yval==0) %If zero displacement is not in displacemente vector, include it
                                    indexZero = sum(Yval<0)+1;
                                    Yval(indexZero+1:end+1) = Yval(indexZero:end);
                                    Yval(indexZero) = 0;
                                end
                                app.SessionData{i}.Analysis{k}.Xaxis.Value = Xval;
                                app.SessionData{i}.Analysis{k}.Yaxis.Value = Yval;
                                [Ymat, Xmat] = meshgrid(Yval,Xval);
                                for p = 1:length(app.ParametersList)
                                    val = app.SessionData{i}.Analysis{k}.Parameter{p}.Value;
                                    if ~isempty(val)
                                        app.SessionData{i}.Analysis{k}.Parameter{p}.Value = interp2(Y,X,val,Ymat,Xmat,'spline');
                                    end
                                end
                            else
                                Xval = linspace(min(Xval),max(Xval),(app.InterpolateStepsSpinner.Value*2)*length(Xval));
                                if ~sum(Xval==0) %If zero displacement is not in displacemente vector, include it
                                    indexZero = sum(Xval<0)+1;
                                    Xval(indexZero+1:end+1) = Xval(indexZero:end);
                                    Xval(indexZero) = 0;
                                end
                                for p = 1:length(app.ParametersList)
                                    val = app.SessionData{i}.Analysis{k}.Parameter{p}.Value;
                                    if ~isempty(val)
                                        app.SessionData{i}.Analysis{k}.Parameter{p}.Value = interp1(app.SessionData{i}.Analysis{k}.Xaxis.Value,val,Xval,'spline')';
                                    end
                                end
                                app.SessionData{i}.Analysis{k}.Xaxis.Value = Xval;
                            end
                            
                        end
                        % Update analysis
                        app.SessionData{i}.Analysis{k}.IsUpToDate = 1;
                    end
                end
            catch err
                addMsg(app,['Analysis could not be performed. Check suspension data and vehicle parameters. ' err.message],'error',7);
                %Clear Xaxis/Yaxis value to know that analysis was not performed correctly
                for i = find(s)
                    for k = a
                        app.SessionData{i}.Analysis{k}.Xaxis.Value = [];
                        app.SessionData{i}.Analysis{k}.Yaxis.Value = [];
                        app.SessionData{i}.Analysis{k}.IsUpToDate = 0;
                    end
                end
            end
        end
        
        function updateParamsPanel(app)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            try
            if ~isempty(app.SessionData{s}.Analysis{a}.Xaxis.Value)
                %Set outdated or up-to-date color
                if app.SessionData{s}.Analysis{a}.IsUpToDate == 0
                    setParamsColor(app,[1 .7 .2]);
                else
                    setParamsColor(app,[0 0 0]);
                end
                sliderValueX = round(app.SliderValue_temp.X);
                sliderValueY = round(app.SliderValue_temp.Y);
                app.Camber.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Parameter{1}.Value(sliderValueX,sliderValueY));
                app.CamberMax.Text = sprintf('%.2f',max(app.SessionData{s}.Analysis{a}.Parameter{1}.Value(:)));
                app.CamberMin.Text = sprintf('%.2f',min(app.SessionData{s}.Analysis{a}.Parameter{1}.Value(:)));
                app.Toe.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Parameter{2}.Value(sliderValueX,sliderValueY));
                app.ToeMax.Text = sprintf('%.2f',max(app.SessionData{s}.Analysis{a}.Parameter{2}.Value(:)));
                app.ToeMin.Text = sprintf('%.2f',min(app.SessionData{s}.Analysis{a}.Parameter{2}.Value(:)));
                app.KPI.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Parameter{3}.Value(sliderValueX,sliderValueY));
                app.KPIMax.Text = sprintf('%.2f',max(app.SessionData{s}.Analysis{a}.Parameter{3}.Value(:)));
                app.KPIMin.Text = sprintf('%.2f',min(app.SessionData{s}.Analysis{a}.Parameter{3}.Value(:)));
                app.Caster.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Parameter{4}.Value(sliderValueX,sliderValueY));
                app.CasterMax.Text = sprintf('%.2f',max(app.SessionData{s}.Analysis{a}.Parameter{4}.Value(:)));
                app.CasterMin.Text = sprintf('%.2f',min(app.SessionData{s}.Analysis{a}.Parameter{4}.Value(:)));
                app.Trail.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Parameter{5}.Value(sliderValueX,sliderValueY));
                app.TrailMax.Text = sprintf('%.2f',max(app.SessionData{s}.Analysis{a}.Parameter{5}.Value(:)));
                app.TrailMin.Text = sprintf('%.2f',min(app.SessionData{s}.Analysis{a}.Parameter{5}.Value(:)));
                app.Scrub.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Parameter{6}.Value(sliderValueX,sliderValueY));
                app.ScrubMax.Text = sprintf('%.2f',max(app.SessionData{s}.Analysis{a}.Parameter{6}.Value(:)));
                app.ScrubMin.Text = sprintf('%.2f',min(app.SessionData{s}.Analysis{a}.Parameter{6}.Value(:)));
                app.RollCenterHeight.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Parameter{7}.Value(sliderValueX,sliderValueY));
                app.RollCenterHeightMax.Text = sprintf('%.2f',max(app.SessionData{s}.Analysis{a}.Parameter{7}.Value(:)));
                app.RollCenterHeightMin.Text = sprintf('%.2f',min(app.SessionData{s}.Analysis{a}.Parameter{7}.Value(:)));
                app.AntiDive.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Parameter{9}.Value(sliderValueX,sliderValueY));
                app.AntiDiveMax.Text = sprintf('%.2f',max(app.SessionData{s}.Analysis{a}.Parameter{9}.Value(:)));
                app.AntiDiveMin.Text = sprintf('%.2f',min(app.SessionData{s}.Analysis{a}.Parameter{9}.Value(:)));
                switch find(a)
                    case {1,2}
                        if length(app.SessionData{s}.dataset.X) == 45
                            app.MotionRatio.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Parameter{11}.Value(sliderValueX));
                            app.MotionRatioMax.Text = sprintf('%.2f',max(app.SessionData{s}.Analysis{a}.Parameter{11}.Value(:)));
                            app.MotionRatioMin.Text = sprintf('%.2f',min(app.SessionData{s}.Analysis{a}.Parameter{11}.Value(:)));
                        else
                            app.MotionRatio.Text =  '-';
                            app.MotionRatioMax.Text = '-';
                            app.MotionRatioMin.Text = '-';
                        end
                        app.Ackerman.Text = '-';
                        app.AckermanMax.Text = '-';
                        app.AckermanMin.Text = '-';
                    case 4
                        app.Ackerman.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Parameter{10}.Value(sliderValueX,sliderValueY));
                        app.AckermanMax.Text = sprintf('%.2f',max(app.SessionData{s}.Analysis{a}.Parameter{10}.Value(:)));
                        app.AckermanMin.Text = sprintf('%.2f',min(app.SessionData{s}.Analysis{a}.Parameter{10}.Value(:)));
                        app.MotionRatio.Text =  '-';
                        app.MotionRatioMax.Text = '-';
                        app.MotionRatioMin.Text = '-';
                    otherwise
                        app.MotionRatio.Text =  '-';
                        app.MotionRatioMax.Text = '-';
                        app.MotionRatioMin.Text = '-';
                        app.Ackerman.Text = '-';
                        app.AckermanMax.Text = '-';
                        app.AckermanMin.Text = '-';
                end
            else
                app.Camber.Text = '';
                app.CamberMax.Text = '';
                app.CamberMin.Text = '';
                app.Toe.Text = '';
                app.ToeMax.Text = '';
                app.ToeMin.Text = '';
                app.KPI.Text = '';
                app.KPIMax.Text = '';
                app.KPIMin.Text = '';
                app.Caster.Text = '';
                app.CasterMax.Text = '';
                app.CasterMin.Text = '';
                app.Trail.Text = '';
                app.TrailMax.Text = '';
                app.TrailMin.Text = '';
                app.Scrub.Text = '';
                app.ScrubMax.Text = '';
                app.ScrubMin.Text = '';
                app.RollCenterHeight.Text = '';
                app.RollCenterHeightMax.Text = '';
                app.RollCenterHeightMin.Text = '';
                app.AntiDive.Text = '';
                app.AntiDiveMax.Text = '';
                app.AntiDiveMin.Text = '';
                app.Ackerman.Text = '';
                app.AckermanMax.Text = '';
                app.AckermanMin.Text = '';
                app.MotionRatio.Text = '';
                app.MotionRatioMax.Text = '';
                app.MotionRatioMin.Text = '';
            end
            catch err
                addMsg(app,['updateParameters error:' err.message],'err', 5)
            end
        end
        
        function updateGraphs(app,ax)
            switch nargin
                case 1
                    ax = app.Graphs_Axes; %if not specified, select default axes
            end
            try
                %Find selected datasets indices
                children = app.SelectCompareDatasets_SubPanel.Children;
                s = zeros(1,length(app.SessionData));
                for i=1:length(children)
                    if strcmp(children(i).Type,'uicheckbox')
                        if children(i).Value
                            s = s + strcmp(children(i).Text,app.SelectDataSetDropDown.Items);
                        end
                    end
                end
                a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
                p = strcmp(app.ParametersList,app.ParameterSelectDropDown.Value);
                % Plot data
                surfChek = app.PlotsurfaceMenuCheck.Checked && ~isempty(app.SessionData{app.SelectedData}.Analysis{a}.Yaxis.Value);
                primAx = app.SessionData{app.SelectedData}.Analysis{a}.PrimaryAxis;
                ax.XLimMode = 'manual';
                ax.YLimMode = 'manual';
                ax.ZLimMode = 'manual';
                view2D = [0 0]; % Default view
                cla(ax);
                hold(ax,'on');
                legend(ax);
                for i = find(s)
                    if ~isempty(app.SessionData{i}.Analysis{a}.Xaxis.Value)
                        val = app.SessionData{i}.Analysis{a}.Parameter{p}.Value;
                        if ~isempty(val)
                            switch primAx
                                case 1
                                    iX = 1:length(app.SessionData{i}.Analysis{a}.Xaxis.Value);
                                    iY = round(app.SliderValue_temp.Y);
                                    view2D = [0 0];
                                case 2
                                    iX = round(app.SliderValue_temp.X);
                                    iY = 1:length(app.SessionData{i}.Analysis{a}.Yaxis.Value);
                                    view2D = [90 0];
                            end
                            Xval = app.SessionData{i}.Analysis{a}.Xaxis.Value;
                            Yval = app.SessionData{i}.Analysis{a}.Yaxis.Value;
                            if isempty(Yval)
                                Yval = 1;
                            end
                            [Ymat, Xmat] = meshgrid(Yval,Xval);
                            plot3(ax, Xmat(iX,iY), Ymat(iX,iY), val(iX,iY),'LineWidth',1);
                            if surfChek
                                surf(ax, Xmat, Ymat, val, 'EdgeColor','none');
                                colormap(ax,"winter");
                                ax.Legend.String(end) = []; %remove surface from legend
                                ax.Legend.String{end} = app.SessionData{i}.dataset.ID;
                            else
                                ax.Legend.String{end} = app.SessionData{i}.dataset.ID;
                            end
                        end
                    end
                end
                if app.SessionData{app.SelectedData}.ParameterHasChanged
                    if ~isempty(app.SessionData{app.SelectedData}.Analysis{a}.Xaxis.Value)
                        ax.XLim = [min(app.SessionData{app.SelectedData}.Analysis{a}.Xaxis.Value) max(app.SessionData{app.SelectedData}.Analysis{a}.Xaxis.Value)];
                    else
                        ax.XLimMode = 'auto';
                    end
                    if ~isempty(app.SessionData{app.SelectedData}.Analysis{a}.Yaxis.Value)
                        ax.YLim = [min(app.SessionData{app.SelectedData}.Analysis{a}.Yaxis.Value) max(app.SessionData{app.SelectedData}.Analysis{a}.Yaxis.Value)];
                    else
                        ax.YLimMode = 'auto';
                    end
                    ax.ZLimMode = 'auto';
                    ax.XLabel.String = app.AnalysisList.Xaxis{a};
                    ax.YLabel.String = app.AnalysisList.Yaxis{a};
                    ax.ZLabel.String = app.ParametersList{p};
                    ax.Title.String = strcat(app.AnalysisList.ID{a},{' - '},app.ParametersList{p});
                    if surfChek
                        ax.View = [60 30];
                        axtoolbar(ax,{'datacursor','pan','rotate','zoomin','zoomout','restoreview'});
                        ax.Interactions = [rotateInteraction rulerPanInteraction dataTipInteraction];
                    else
                        ax.View = view2D;
                        axtoolbar(ax,{'datacursor','pan','zoomin','rotate','zoomout'});
                        ax.Interactions = [zoomInteraction rulerPanInteraction dataTipInteraction];
                    end 
                    app.SessionData{app.SelectedData}.ParameterHasChanged = 0;
                end
            catch err
                addMsg(app,['updateGraphs error. ' err.message],'err', 5);
            end
        end
        
        function updateCompareMenu(app)
            %Update compare panel with existing datasets
            children = app.SelectCompareDatasets_SubPanel.Children;
            for i=1:length(children)
                if strcmp(children(i).Type,'uicheckbox')
                    delete(children(i));
                end
            end
            L = length(app.SelectDataSetDropDown.Items);
            app.SelectCompareDatasets_Panel.Position(4) = min(60+20*L,300);
            app.SelectCompareDatasets_Panel.Position(2) = app.PlotOptionsButton.Position(2)+app.ParametersPanel.Position(2)-app.SelectCompareDatasets_Panel.Position(4)/2+app.PlotOptionsButton.Position(4)/2+50; %+50 because of grid padding
            for i=1:L
                cb = uicheckbox(app.SelectCompareDatasets_SubPanel, ...
                    'Text',app.SelectDataSetDropDown.Items{i}, ...
                    'Value',false, ...
                    'Position',[10 app.SelectCompareDatasets_Panel.Position(4)-75-(i-1)*20 app.SelectCompareDatasets_Panel.Position(3)-40 20] );
                if strcmp(cb.Text,app.SessionData{app.SelectedData}.dataset.ID)
                    %Force selection in compare menu of selected dataset
                    cb.Value = true;
                    cb.Enable = 'off';
                end
            end
            app.SelectCompareDatasets_Panel.Position(4) = min(60+20*L,300);
            app.SelectCompareDatasets_Panel.Position(2) = app.PlotOptionsButton.Position(2)+app.ParametersPanel.Position(2)-app.SelectCompareDatasets_Panel.Position(4)/2+app.PlotOptionsButton.Position(4)/2+50; %+50 because of grid padding
            %Update 3D view context menu
            children = app.view3DCompareWithMenu.Children;
            for i=1:length(children)
                delete(children(i));
            end
            L = length(app.SelectDataSetDropDown.Items);
            for i=1:L
                m = uimenu(app.view3DCompareWithMenu, ...
                    'Text',app.SelectDataSetDropDown.Items{i},...
                    'MenuSelectedFcn',@app.view3DcompareWithCB);
                if strcmp(m.Text,app.SessionData{app.SelectedData}.dataset.ID)
                    delete(m);
                end
            end
            %Update graphs context menu
            children = app.GraphsCompareWithMenu.Children;
            for i=1:length(children)
                delete(children(i));
            end
            L = length(app.SelectDataSetDropDown.Items);
            for i=1:L
                m = uimenu(app.GraphsCompareWithMenu, ...
                    'Text',app.SelectDataSetDropDown.Items{i},...
                    'MenuSelectedFcn',@app.graphsCompareWithCB);
                if strcmp(m.Text,app.SessionData{app.SelectedData}.dataset.ID)
                    delete(m);
                end
            end
        end
        
        function updateSlider(app)
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value); %Find Analysis index
            s = app.SelectedData;
            try
                if ~isempty(app.SessionData{s}.Analysis{a}.Xaxis.Value)
                    % Update Slider
                    app.Displacement_Slider.Limits = [1 length(app.SessionData{s}.Analysis{a}.Xaxis.Value)];
                    app.Displacement_Slider.Value = find(abs(app.SessionData{s}.Analysis{a}.Xaxis.Value) == min(abs(app.SessionData{s}.Analysis{a}.Xaxis.Value)),1);
                    app.SliderValue_temp.X = app.Displacement_Slider.Value;
                    % Show slider
                    app.Displacement_Slider.Visible = 'on';
                    app.Displacement.Visible = 'on';
                    app.Displacement_Label.Visible = 'on';
                    app.ChangeParameterDownButton.Visible = 'on';
                    app.ChangeParameterUpButton.Visible = 'on';
                    %Update slider text values
                    app.Displacement.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Xaxis.Value(app.Displacement_Slider.Value));
                    app.Displacement_Label.Text = app.SessionData{s}.Analysis{a}.Xaxis.ID;
                else
                    % Hide Slider
                    app.Displacement_Slider.Visible = 'off';
                    app.Displacement.Visible = 'off';
                    app.Displacement_Label.Visible = 'off';
                    app.ChangeParameterDownButton.Visible = 'off';
                    app.ChangeParameterUpButton.Visible = 'off';
                    % Update Slider
                    app.Displacement_Slider.Limits = [0 1];
                    app.Displacement_Slider.Value = 1;
                    app.SliderValue_temp.X = app.Displacement_Slider.Value;
                end
                if ~isempty(app.SessionData{s}.Analysis{a}.Yaxis.Value)
                    % Update Yaxis Slider
                    app.Displacement_Slider_2.Limits = [1 length(app.SessionData{s}.Analysis{a}.Yaxis.Value)];
                    app.Displacement_Slider_2.Value = round(length(app.SessionData{s}.Analysis{a}.Yaxis.Value)/2);
                    app.SliderValue_temp.Y = app.Displacement_Slider_2.Value;
                    % Show slider
                    app.Displacement_Slider_2.Visible = 'on';
                    app.Displacement_2.Visible = 'on';
                    app.Displacement_Label_2.Visible = 'on';
                    %Update slider text values
                    app.Displacement_2.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Yaxis.Value(app.Displacement_Slider_2.Value));
                    app.Displacement_Label_2.Text = app.SessionData{s}.Analysis{a}.Yaxis.ID;
                    %
                    switch app.SessionData{s}.Analysis{a}.PrimaryAxis
                        case 1
                            % Update graphs Slider
                            app.GraphsSlider.Limits = app.Displacement_Slider_2.Limits;
                            app.GraphsSlider.Value = app.Displacement_Slider_2.Value;
                            %Update slider text values
                            app.GraphsSliderValue.Text = app.Displacement_2.Text;
                            app.GraphsSliderLabel.Text = app.Displacement_Label_2.Text;
                        case 2
                            % Update graphs Slider
                            app.GraphsSlider.Limits = app.Displacement_Slider.Limits;
                            app.GraphsSlider.Value = app.Displacement_Slider.Value;
                            %Update slider text values
                            app.GraphsSliderValue.Text = app.Displacement.Text;
                            app.GraphsSliderLabel.Text = app.Displacement_Label.Text;
                    end
                    % Show slider
                    app.GraphsSlider.Visible = 'on';
                    app.GraphsSliderLabel.Visible = 'on';
                    app.GraphsSliderValue.Visible = 'on';
                else
                    % Hide Slider
                    app.Displacement_Slider_2.Visible = 'off';
                    app.Displacement_2.Visible = 'off';
                    app.Displacement_Label_2.Visible = 'off';
                    % Update Slider
                    app.Displacement_Slider_2.Limits = [0 1];
                    app.Displacement_Slider_2.Value = 1;
                    app.SliderValue_temp.Y = app.Displacement_Slider_2.Value;
                    % Hide Graphs Slider
                    app.GraphsSlider.Visible = 'off';
                    app.GraphsSliderValue.Visible = 'off';
                    app.GraphsSliderLabel.Visible = 'off';
                    % Update Slider
                    app.GraphsSlider.Limits = [0 1];
                    app.GraphsSlider.Value = 1;
                end
            catch err
                addMsg(app,['updateSlider error: ' err.message],'err',5);
            end
        end
        
        function saveGraph(app,format)
            switch nargin
                case 1
                    format = '.png';
            end
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            p = strcmp(app.ParametersList,app.ParameterSelectDropDown.Value);
            if ~isempty(app.SessionData{s}.Analysis{a}.Xaxis.Value) && ~isempty(app.SessionData{s}.Analysis{a}.Parameter{p}.Value)
                defaultFileName = fullfile(app.LastDirectory, strcat(app.SessionData{s}.Analysis{a}.ID,{' - '}, app.SessionData{s}.Analysis{a}.Parameter{p}.ID, format));
                [filename,path] = uiputfile(defaultFileName,'Save dataset to file');
                drawnow;
                figure(app.UIFigure);
                if filename == 0 % User clicked the Cancel button.
                    return;
                end
                fullFileName = fullfile(path, filename);
                h = figure('Position', [0 0 1800 1200]);
                h.Visible = 'off';
                h.CreateFcn = 'set(gcf,''Visible'',''on'')';
                ax = axes(h);
                limX = app.Graphs_Axes.XLim;
                limY = app.Graphs_Axes.YLim;
                limZ = app.Graphs_Axes.ZLim;
                view = app.Graphs_Axes.View;
                titl = app.Graphs_Axes.Title.String;
                xlabl = app.Graphs_Axes.XLabel.String;
                ylabl = app.Graphs_Axes.YLabel.String;
                zlabl = app.Graphs_Axes.ZLabel.String;
                updateGraphs(app,ax);
                ax.XLim = limX;
                ax.YLim = limY;
                ax.ZLim = limZ;
                ax.View = view;
                ax.XGrid = 'on';
                ax.YGrid = 'on';
                ax.ZGrid = 'on';
                ax.Title.String = titl;
                ax.XLabel.String = xlabl;
                ax.YLabel.String = ylabl;
                ax.ZLabel.String = zlabl;
                saveas(h,fullFileName);
                delete(h);
                app.LastDirectory = path;
                addMsg(app,'Graph saved successfully','success',1);
            else
                addMsg(app,'Analysis has to be performed first','warning');
            end
        end
        function saveAllGraphs(app,format)
            p0 = strcmp(app.ParametersList,app.ParameterSelectDropDown.Value);
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            folderPath = uigetdir(app.LastDirectory);
            drawnow; %Prevent window to go to background
            figure(app.UIFigure);
            if folderPath == 0 % User clicked the Cancel button.
                return;
            end
            app.LastDirectory = folderPath;
            if ~isempty(app.SessionData{s}.Analysis{a}.Xaxis.Value)
                app.ProgressWheel.Visible = 'on';
                app.ParameterSelectDropDown.Enable = 'off';
                addMsg(app,'Wait while saving images...','info',3);
                dirList = dir(folderPath);
                folderName = matlab.lang.makeUniqueStrings(strcat(app.SessionData{s}.Analysis{a}.ID,'_Img_',app.SessionData{s}.dataset.ID), {dirList.name});
                [status,msg] = mkdir(fullfile(folderPath,folderName));
                if status
                    try
                        for p=1:length(app.ParametersList)
                            if ~isempty(app.SessionData{s}.Analysis{a}.Parameter{p}.Value)
                                app.ParameterSelectDropDown.Value = app.ParameterSelectDropDown.Items{p};
                                h = figure('Position', [0 0 1800 1200]);
                                h.Visible = 'off';
                                h.CreateFcn = 'set(gcf,''Visible'',''on'')';
                                ax = axes(h);
                                limX = app.Graphs_Axes.XLim;
                                limY = app.Graphs_Axes.YLim;
                                limZ = app.Graphs_Axes.ZLim;
                                view = app.Graphs_Axes.View;
                                titl = app.Graphs_Axes.Title.String;
                                xlabl = app.Graphs_Axes.XLabel.String;
                                ylabl = app.Graphs_Axes.YLabel.String;
                                zlabl = app.Graphs_Axes.ZLabel.String;
                                updateGraphs(app,ax);
                                ax.XLim = limX;
                                ax.YLim = limY;
                                ax.ZLim = limZ;
                                ax.View = view;
                                ax.XGrid = 'on';
                                ax.YGrid = 'on';
                                ax.ZGrid = 'on';
                                ax.Title.String = titl;
                                ax.XLabel.String = xlabl;
                                ax.YLabel.String = ylabl;
                                ax.ZLabel.String = zlabl;
                                if nargin == 1
                                    format = '.png';
                                end
                                fullFileName = char(fullfile(folderPath,folderName, strcat(app.SessionData{s}.Analysis{a}.ID,{' - '}, app.SessionData{s}.Analysis{a}.Parameter{p}.ID, format)));
                                saveas(h,fullFileName);
                                delete(h);
                            end
                        end
                        addMsg(app,'Graphs saved successfully as images','success');
                    catch err
                        addMsg(app,['Error while saving graphs:' err.message],'e',4)
                    end
                else
                    addMsg(app,strcat('Folder could not be created: ',msg),'error',4);
                end
                
            else
                addMsg(app,'Analysis has to be performed first','warning');
            end
            app.ParameterSelectDropDown.Value = app.ParameterSelectDropDown.Items{p0};
            app.ParameterSelectDropDown.Enable = 'on';
            app.ProgressWheel.Visible = 'off';
        end
        function editBellcrank(app)
            s = app.SelectedData;
            % Clear RedoStack
            app.SessionData{s}.RedoStack = {};
            % Save data in UndoStack
            app.SessionData{s}.UndoStack = app.SessionData{s};
            try
            % Perform changes
            X = app.SessionData{s}.dataset.X;
            [A,B] = intersectCircleSphere(X(37:39), app.BellcrankDamperarmSpinner.Value, cross(X(34:36)-X(37:39), X(37:39)-X(40:42)), X(43:45), app.DamperlengthSpinner.Value);
            if norm(A-X(40:42)') < norm(B-X(40:42)')
                P_bellcrankDamper = A;
            else
                P_bellcrankDamper = B;
            end
            A = rotatePointFixedAxis(app.BellcrankAngleSpinner.Value, cross(P_bellcrankDamper'-X(37:39), X(43:45)-X(37:39)), X(37:39), P_bellcrankDamper);
            B = rotatePointFixedAxis(app.BellcrankAngleSpinner.Value, -cross(P_bellcrankDamper'-X(37:39), X(43:45)-X(37:39)), X(37:39), P_bellcrankDamper);
            if norm(A-X(34:36)') < norm(B-X(34:36)')
                P_bellcrankPush = X(37:39)' + (A-X(37:39)')*app.BellcrankPusharmSpinner.Value/app.BellcrankDamperarmSpinner.Value;
            else
                P_bellcrankPush = X(37:39)' + (B-X(37:39)')*app.BellcrankPusharmSpinner.Value/app.BellcrankDamperarmSpinner.Value;
            end
            app.SessionData{s}.dataset.X(34:36) = P_bellcrankPush(:);
            app.SessionData{s}.dataset.X(40:42) = P_bellcrankDamper(:);
            %Set outdated parameters property
            for i = 1:length(app.AnalysisList.ID)
                app.SessionData{s}.Analysis{i}.IsUpToDate = 0;
            end
            app.SessionData{s}.IsSaved = 0;
            updateSlider(app);
            update3Dview(app);
            updateDatasetList(app)
            catch
            end
            end
        
        function updateDatasetList(app)
            for i=1:length(app.SessionData)
                app.SelectDataSetDropDown.Items{i} = app.SessionData{i}.dataset.ID;
                if ~app.SessionData{i}.IsSaved
                    app.SelectDataSetDropDown.Items{i} = strcat(app.SelectDataSetDropDown.Items{i},' *');
                end
            end
            app.SelectDataSetDropDown.Value = app.SelectDataSetDropDown.Items{app.SelectedData};
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadUI = uiprogressdlg(app.UIFigure,'Indeterminate','on','Message','Loading app...');
            
            %Add functions to path
            lastwarn('') % Clear last warning message
            try
                addpath('Functions/kinematic');
                addpath('Functions/geometric');
                addpath('AppData');
            catch
            end
            [warnMsg, ~] = lastwarn;
            if ~isempty(warnMsg)
                uialert(app.UIFigure,'Applications files could not be loaded. Check matlab working directory.','Error loading app','Icon','error')
            end
            
            %Initialize app properties
            app.SessionData = {};
            app.SelectedData = 0;
            app.LastDirectory = fullfile(pwd,'Datasets');
            app.AnalysisList.ID = {'Parallel' 'Oposite' 'Roll' 'Steer' 'Ride-Roll' 'Ride-Steer' 'Roll-Steer'};
            app.AnalysisList.Xaxis = {'Wheel Travel' 'Wheel Travel' 'Roll angle' 'Rack displacement' 'Wheel Travel' 'Wheel Travel' 'Roll angle' 'Wheel Travel'};
            app.AnalysisList.Yaxis = {'' '' '' '' 'Roll angle' 'Rack displacement' 'Rack displacement' 'Rack displacement'};
            app.ParametersList = {'Camber' 'Toe' 'KPI' 'Caster' 'Trail' 'Scrub' 'RC Height' 'RC Migration' 'Anti-Dive' 'Ackerman' 'Motion Ratio'};
            temp = load('AnalysisConfig.mat');
            app.AnalysisConfig = temp.AnalysisConfiguration;
            app.SliderValue_temp.X = 1;
            app.SliderValue_temp.Y = 1;
            
            %Retrieve existing datasets
            retrieveDatasets(app);
            
            % Initialize app components
            %% Load icons
            path = fullfile(matlabroot, 'toolbox', 'matlab');
            app.ExportButton.Icon = fullfile(path,'toolstrip','web','mpcdesigner_icons','ExportAll.png');
            app.NewButton.Icon = fullfile(path,'addons','release','images','toolstrip','New_24.png');
            app.RemoveButton.Icon = fullfile(path,'addons','release','images','toolstrip','Delete_16.png');
            app.UndoButton.Icon = fullfile(path,'addons','release','images','toolstrip','Undo_24.png');
            app.RedoButton.Icon = fullfile(path,'addons','release','images','toolstrip','Redo_24.png');
            app.RunButton.Icon = fullfile(path,'addons','release','images','toolstrip','Run_24.png');
            app.DatasetConfigButton.Icon = fullfile(path,'appdesigner', 'web', 'release', 'uicomponents_appdesigner_plugin', 'images', 'configure-16.png');
            app.AnalysisConfigButton.Icon = fullfile(path,'appdesigner', 'web', 'release', 'uicomponents_appdesigner_plugin', 'images', 'configure-16.png');
            app.ProgressWheel.ImageSource = fullfile(path,'addons','release','images','busy.gif');
            %% Initialize axes
            axtoolbar(app.View3D_Axes,{'datacursor','rotate','pan','zoomin','zoomout'});
            app.View3D_Axes.Toolbar.Visible = 'on';
            app.View3D_Axes.XGrid = 'on';
            app.View3D_Axes.YGrid = 'on';
            app.View3D_Axes.ZGrid = 'on';
            %% Analysis Configuration
            app.ParallelStep.Value = app.AnalysisConfig{1}.step;
            app.ParallelLowerLimit.Value = app.AnalysisConfig{1}.limits(1);
            app.ParallelUpperLimit.Value = app.AnalysisConfig{1}.limits(2);
            app.OpositeStep.Value = app.AnalysisConfig{2}.step;
            app.OpositeLowerLimit.Value = app.AnalysisConfig{2}.limits(1);
            app.OpositeUpperLimit.Value = app.AnalysisConfig{2}.limits(2);
            app.RollStep.Value = app.AnalysisConfig{3}.step;
            app.RollLimit.Value = app.AnalysisConfig{3}.limits;
            app.RollTol.Value = app.AnalysisConfig{3}.tol;
            app.SteeringStep.Value = app.AnalysisConfig{4}.step;
            app.SteeringLowerLimit.Value = app.AnalysisConfig{4}.limits(1);
            app.SteeringUpperLimit.Value = app.AnalysisConfig{4}.limits(2);
            app.InterpolateCheckBox.Value = app.AnalysisConfig{5}.check;
            app.InterpolateLabel.Enable = app.InterpolateCheckBox.Value;
            app.InterpStepsLabel.Enable = app.InterpolateCheckBox.Value;
            app.InterpolateStepsSpinner.Enable = app.InterpolateCheckBox.Value;
            app.InterpolateStepsSpinner.Value = app.AnalysisConfig{5}.steps;
            %% Analysis drop-down selector
            app.AnalysisSelectDropDown.Items = app.AnalysisList.ID;
            app.ParameterSelectDropDown.Items = app.ParametersList;
            
            %Load data of most recent dataset
            if ~isempty(app.SessionData)
                app.SelectedData = 1;
                loadDataset(app);
                app.SelectDataSetDropDown.Value = app.SelectDataSetDropDown.Items{1};
                restore3DView(app);
                update3Dview(app);
                updateCompareMenu(app);
            end
            
            %Show app contents when app is completly loaded
            app.UIFigure.Resize = 'on';
            app.GlobalContainer.Visible = 'on';
            
            close(loadUI);
        end

        % Close request function: UIFigure
        function closeRequest(app, event)
            try
                notSaved = cell(length(app.SessionData),1);
                for i=1:length(app.SessionData)
                    if app.SessionData{i}.IsSaved == 0
                        notSaved{i} = app.SessionData{i}.dataset.ID;
                    end
                end
                notSaved = notSaved(~cellfun('isempty',notSaved));
                if ~isempty(notSaved)
                    selection = uiconfirm(app.UIFigure, ...
                        ['There are ' num2str(length(notSaved)) ' datasets with unsaved changes. Do you want to exit whitout saving them?'], ...
                        'Close confirm', ...
                        'Options',{'Exit' 'Cancel'}, ...
                        'CancelOption',2, ...
                        'DefaultOption','Cancel');
                    if strcmp(selection, 'Cancel')
                        updateDatasetList(app)
                        return;
                    end
                end
                %Delete apps
                delete(app.BellcrankOptimApp)
                delete(app.SuspensionEditApp)
                delete(app.SuspensionOptimApp)
                delete(app.DatasetConfigApp)
                delete(app)
            catch err
                disp(err.message)
            end
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
    if isempty(event.Modifier)
        return;
    end
    key = event.Key;
    mod = event.Modifier;
    if sum(strcmp(mod,'control'))
        if sum(strcmp(mod,'shift')) %Ctrol+Shift + key
            switch key
                case 's'
                    SaveasMenuSelected(app,event)
            end
        else %Ctrol + key
            switch key
                case 'z'
                    UndoButtonPushed(app, event)
                case 'y'
                    RedoButtonPushed(app, event)
                case 'n'
                    NewButtonPushed(app, event)
                case 'o'
                    OpenButtonPushed(app, event)
                case 's'
                    SaveButtonPushed(app,event)
                case 'q'
                    closeRequest(app,event)
            end
        end
        
    else
    end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            startingFolder = app.LastDirectory;
            % Get the name of the mat file that the user wants to use.
            defaultFileName = fullfile(startingFolder, '*.mat');
            [baseFileName, folder] = uigetfile(defaultFileName, 'Select a .mat file containing a suspension dataset');
            drawnow; %Prevent window to go to background
            figure(app.UIFigure);
            if baseFileName == 0 % User clicked the Cancel button.
                return;
            end
            app.LastDirectory = folder;
            addDataset(app,fullfile(folder, baseFileName));
            updateSlider(app)
            restore3DView(app)
            loadDataset(app)
            updateCompareMenu(app)
            updateParamsPanel(app)
            update3Dview(app)
            updateGraphs(app)
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            newDataset = [];
            %%Empty suspension data with default parameters
            newDataset.ID = 'newDataset';
            newDataset.X = zeros(30,1);
            newDataset.StaticCamber = 0;
            newDataset.StaticToe = 0;
            newDataset.TireRadius = 18*25.4/2; %18 inches to mm
            %%Default Vehicle parameterss
            newDataset.VehicleParams.CGX = 66.5264;
            newDataset.VehicleParams.CGZ = 262.8525;
            newDataset.VehicleParams.WheelBase = 1550;
            newDataset.VehicleParams.Track = 1200;
            newDataset.VehicleParams.ChassisGeom = [];
            newDataset.VehicleParams.RodD = 14;
            addDataset(app,newDataset)
            updateSlider(app)
            restore3DView(app)
            loadDataset(app)
            updateCompareMenu(app)
            updateParamsPanel(app)
            update3Dview(app)
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            s = app.SelectedData;
            if ~app.SessionData{s}.IsSaved
                selection = uiconfirm(app.UIFigure, ...
                        'There are unsaved changes in selected dataset. Do you want to remove it without saving?', ...
                        'Close confirm', ...
                        'Options',{'Remove' 'Cancel'}, ...
                        'CancelOption',2, ...
                        'DefaultOption','Cancel');
                    if strcmp(selection, 'Cancel')
                        return;
                    end
            end
            if s %Only if s ~= 0 will changes be made
                app.SessionData(s) = [];
                app.SelectDataSetDropDown.Items(s) = [];
            else
                return;
            end
            if ~isempty(app.SessionData)
                if s > length(app.SessionData)
                    app.SelectedData = s-1;
                end
                app.SelectDataSetDropDown.Value = app.SelectDataSetDropDown.Items{app.SelectedData};
            else
                app.SelectedData = 0;
                return;
            end
            addMsg(app,'Data removed from session','i',1)
            updateSlider(app)
            restore3DView(app)
            loadDataset(app)
            updateCompareMenu(app)
            updateParamsPanel(app)
            update3Dview(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                s = app.SelectedData;
                Data = app.SessionData{s};
                if Data.IsSaved
                    addMsg(app,'Already saved','info');
                    return;
                end
                if isfile(fullfile(Data.DatasetPath))
                    SavedDataset = Data.dataset;
                    save(fullfile(Data.DatasetPath),'-struct','SavedDataset');
                    addMsg(app,'File saved succesfully','success', 1);
                    app.SessionData{s}.IsSaved = 1;
                else
                    SaveasMenuSelected(app, event);
                end
                updateDatasetList(app)
            catch err
                addMsg(app,['File could not be saved. ' err.message],'error', 5);
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            try
                s = app.SelectedData;
                Data = app.SessionData{s};
                startingFolder = app.LastDirectory;
                files = dir(startingFolder); %get .mat files in the 'Dataset' directory
                names = cell(1,length(files));
                for i=1:length(files)
                    [~,names{i},~] = fileparts(files(i).name);
                end
                defaultFileName = fullfile(startingFolder, [matlab.lang.makeUniqueStrings([Data.dataset.ID], names) '.mat']);
                [filename,path] = uiputfile(defaultFileName,'Save dataset to file');
                drawnow; %Prevent window to go to background
                figure(app.UIFigure);
                if filename == 0 % User clicked the Cancel button.
                    return;
                end
                fullFileName = fullfile(path, filename);
                app.SessionData{s}.DatasetPath = fullFileName;
                [~,Data.dataset.ID,~] = fileparts(fullFileName); %match dataset ID to file name
                SavedDataset = Data.dataset;
                save(fullFileName,'-struct','SavedDataset');
                addMsg(app,'File saved succesfully','success',3);
                app.LastDirectory = path;
                if strcmp(event.Source.Tag,'saveAs_button')
                    addDataset(app, fullFileName); %When dataset is added, app.SelectedData changes, but 's' does not.
                    app.SessionData{app.SelectedData}.UndoStack = app.SessionData{s}.UndoStack; 
                    app.SessionData{app.SelectedData}.RedoStack = app.SessionData{s}.RedoStack;
                    while ~isempty(app.SessionData{s}.UndoStack) % Restore changes on original dataset
                        app.SessionData{s}.UndoStack.RedoStack = app.SessionData{s};
                        %outdatedParameters(app, handles);
                        app.SessionData{s} = app.SessionData{s}.UndoStack;
                        %handles = parametersHaveChanged(app, handles);
                        loadDataset(app);
                        update3Dview(app);
                    end
                    app.SessionData{s}.UndoStack = {};
                    app.SessionData{s}.RedoStack = {};
                    updateDatasetList(app)
                    updateCompareMenu(app)
                    updateSlider(app)
                    restore3DView(app)
                    updateParamsPanel(app)
                    update3Dview(app)
                end
                app.SessionData{s}.IsSaved = 1;
            catch err
                addMsg(app,['File could not be saved: ' err.message],'error',10);
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            try
                s = app.SelectedData;
                DataSet = app.SessionData{s}.dataset;
                startingFolder = app.LastDirectory;
                defaultFileName = fullfile(startingFolder, [DataSet.ID '.xlsx']);
                [filename,path] = uiputfile('*.xlsx','Export dataset to Excel file',defaultFileName);
                drawnow; %Prevent window to go to background
                figure(app.UIFigure);
                if filename == 0% User clicked the Cancel button.
                    return;
                end
                fullFileName = fullfile(path, filename);
                if ~isfile(fullFileName) %file does not exist
                    %Create table
                    Upper_Front_X = DataSet.X(1);
                    Upper_Front_Y = DataSet.X(2);
                    Upper_Front_Z = DataSet.X(3);
                    Upper_Rear_X = DataSet.X(4);
                    Upper_Rear_Y = DataSet.X(5);
                    Upper_Rear_Z = DataSet.X(6);
                    Lower_Front_X = DataSet.X(7);
                    Lower_Front_Y = DataSet.X(8);
                    Lower_Front_Z = DataSet.X(9);
                    Lower_Rear_X = DataSet.X(10);
                    Lower_Rear_Y = DataSet.X(11);
                    Lower_Rear_Z = DataSet.X(12);
                    Upper_Upright_X = DataSet.X(13);
                    Upper_Upright_Y = DataSet.X(14);
                    Upper_Upright_Z = DataSet.X(15);
                    Lower_Upright_X = DataSet.X(16);
                    Lower_Upright_Y = DataSet.X(17);
                    Lower_Upright_Z = DataSet.X(18);
                    Steering_Rack_X = DataSet.X(19);
                    Steering_Rack_Y = DataSet.X(20);
                    Steering_Rack_Z = DataSet.X(21);
                    Steering_Upright_X = DataSet.X(22);
                    Steering_Upright_Y = DataSet.X(23);
                    Steering_Upright_Z = DataSet.X(24);
                    Wheel_Center_X = DataSet.X(25);
                    Wheel_Center_Y = DataSet.X(26);
                    Wheel_Center_Z = DataSet.X(27);
                    Wheel_Plane_X = DataSet.X(28);
                    Wheel_Plane_Y = DataSet.X(29);
                    Wheel_Plane_Z = DataSet.X(30);
                    if length(DataSet.X) == 45
                        Push_Bracket_X = DataSet.X(31);
                        Push_Bracket_Y = DataSet.X(32);
                        Push_Bracket_Z = DataSet.X(33);
                        Bellcrank_Push_X = DataSet.X(34);
                        Bellcrank_Push_Y = DataSet.X(35);
                        Bellcrank_Push_Z = DataSet.X(36);
                        Bellcrank_Bracket_X = DataSet.X(37);
                        Bellcrank_Bracket_Y = DataSet.X(38);
                        Bellcrank_Bracket_Z = DataSet.X(39);
                        Bellcrank_Damper_X = DataSet.X(40);
                        Bellcrank_Damper_Y = DataSet.X(41);
                        Bellcrank_Damper_Z = DataSet.X(42);
                        Damper_Bracket_X = DataSet.X(43);
                        Damper_Bracket_Y = DataSet.X(44);
                        Damper_Bracket_Z = DataSet.X(45);
                        T=table(...
                        Upper_Front_X,Upper_Front_Y,Upper_Front_Z,...
                        Upper_Rear_X,Upper_Rear_Y,Upper_Rear_Z,...
                        Lower_Front_X,Lower_Front_Y,Lower_Front_Z,...
                        Lower_Rear_X,Lower_Rear_Y,Lower_Rear_Z,...
                        Upper_Upright_X,Upper_Upright_Y,Upper_Upright_Z,...
                        Lower_Upright_X,Lower_Upright_Y,Lower_Upright_Z,...
                        Steering_Rack_X,Steering_Rack_Y,Steering_Rack_Z,...
                        Steering_Upright_X,Steering_Upright_Y,Steering_Upright_Z,...
                        Wheel_Center_X,Wheel_Center_Y,Wheel_Center_Z,...
                        Wheel_Plane_X,Wheel_Plane_Y,Wheel_Plane_Z,...
                        Push_Bracket_X, Push_Bracket_Y, Push_Bracket_Z, ...
                        Bellcrank_Push_X, Bellcrank_Push_Y, Bellcrank_Push_Z,...
                        Bellcrank_Bracket_X, Bellcrank_Bracket_Y, Bellcrank_Bracket_Z,...
                        Bellcrank_Damper_X, Bellcrank_Damper_Y, Bellcrank_Damper_Z,...
                        Damper_Bracket_X, Damper_Bracket_Y, Damper_Bracket_Z);
                    else
                        T=table(...
                        Upper_Front_X,Upper_Front_Y,Upper_Front_Z,...
                        Upper_Rear_X,Upper_Rear_Y,Upper_Rear_Z,...
                        Lower_Front_X,Lower_Front_Y,Lower_Front_Z,...
                        Lower_Rear_X,Lower_Rear_Y,Lower_Rear_Z,...
                        Upper_Upright_X,Upper_Upright_Y,Upper_Upright_Z,...
                        Lower_Upright_X,Lower_Upright_Y,Lower_Upright_Z,...
                        Steering_Rack_X,Steering_Rack_Y,Steering_Rack_Z,...
                        Steering_Upright_X,Steering_Upright_Y,Steering_Upright_Z,...
                        Wheel_Center_X,Wheel_Center_Y,Wheel_Center_Z,...
                        Wheel_Plane_X,Wheel_Plane_Y,Wheel_Plane_Z);
                    end
                    writetable(T,fullFileName)
                else
                    T = readtable(fullFileName);
                    writematrix(DataSet.X,fullFileName,'Sheet',1,'Range',strcat('A',int2str(size(T)*[1 0]'+2)));
                end
                addMsg(app,'File exported succesfully','success', 1);
                app.LastDirectory = path;
            catch err
                addMsg(app,['File could not be exported: ' err.message],'error', 5);
            end
        end

        % Menu selected function: SaveImages
        function SaveImagesMenuSelected(app, event)
            saveAllGraphs(app)
        end

        % Menu selected function: SaveFiguresMenu
        function SaveFiguresMenuSelected(app, event)
            saveAllGraphs(app,'.fig')
        end

        % Menu selected function: ExportSavetoworkspaceMenu
        function ExportSavetoworkspaceMenuSelected(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            try
                if ~isempty(app.SessionData{s}.Analysis{a}.Xaxis)
                    SavedData = app.SessionData{s}.Analysis{a};
                    SavedData = rmfield(SavedData,'IsUpToDate');
                    SavedData = rmfield(SavedData,'PrimaryAxis');
                    if isempty(SavedData.Yaxis.Value)
                        SavedData = rmfield(SavedData,'Yaxis');
                    end
                    assignin('base', matlab.lang.makeValidName([app.SessionData{s}.dataset.ID '_' app.AnalysisList.ID{a}]), SavedData);
                    addMsg(app,'Data saved succesfully','success',2);
                else
                    addMsg(app,'Analysis has to be performed first','info',2)
                end
            catch err
                addMsg(app,['Data could not be saved: ' err.message],'error',4);
            end
        end

        % Value changed function: SelectDataSetDropDown
        function SelectDataSetDropDownValueChanged(app, event)
            app.SelectedData = find(strcmp(app.SelectDataSetDropDown.Items,app.SelectDataSetDropDown.Value));
            updateSlider(app)
            restore3DView(app)
            loadDataset(app)
            updateCompareMenu(app)
            updateParamsPanel(app)
            update3Dview(app)
            updateGraphs(app)
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            s = app.SelectedData;
            if isempty(app.SessionData{s}.UndoStack)
                addMsg(app,'Nothing to undo','info',1);
            else
                app.SessionData{s}.UndoStack.RedoStack = app.SessionData{s};
                %outdatedParameters(app, handles);
                app.SessionData{s} = app.SessionData{s}.UndoStack;
                %handles = parametersHaveChanged(app, handles);
                loadDataset(app);
                updateSlider(app);
                update3Dview(app);
                updateParamsPanel(app);
            end
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            s = app.SelectedData;
            if isempty(app.SessionData{s}.RedoStack)
                addMsg(app,'Nothing to redo','info',1);
            else
                app.SessionData{s} = app.SessionData{s}.RedoStack;
                loadDataset(app);
                updateSlider(app);
                update3Dview(app);
                updateParamsPanel(app);
            end
        end

        % Button pushed function: DatasetConfigButton
        function DatasetConfigButtonPushed(app, event)
            %Disable settings button
            app.DatasetConfigButton.Enable = 'off';
            %Open dialog app
            app.DatasetConfigApp = datasetConfig(app, app.SessionData{app.SelectedData}.dataset.VehicleParams);
            updateDatasetList(app);
        end

        % Button pushed function: SuspensionEditButton
        function SuspensionEditButtonPushed(app, event)
            app.SuspensionEditButton.Enable = 'off';
            try
                app.SuspensionEditApp = suspensionEdit(app,app.SessionData{app.SelectedData}.dataset);
            catch err
                app.SuspensionEditButton.Enable = 'on';
                disp(err.message);
            end
        end

        % Button pushed function: SuspOptimButton
        function SuspOptimButtonPushed(app, event)
            app.SuspOptimButton.Enable = 'off';
            try
                s = false(1,length(app.SessionData));
                s(app.SelectedData) = true;
                performAnalysis(app, s, [1 3 4]);
                app.SuspensionOptimApp = aArmsOptim(app, app.SessionData{app.SelectedData});
            catch err
                app.SuspOptimButton.Enable = 'on';
                addMsg(app,['suspOptimButton error. Check suspension data. ' err.message], 'err', 5);
            end
        end

        % Button pushed function: BellcrankOptimButton
        function BellcrankOptimButtonPushed(app, event)
            if length(app.SessionData{app.SelectedData}.dataset.X) ~= 45
                addMsg(app,'Add suspension mechanism coordinates first','warning',3)
            else
            app.BellcrankOptimButton.Enable = 'off';
            app.BellcrankOptimApp = bellcrankOptim(app,app.SessionData{app.SelectedData});
            end
        end

        % Button pushed function: BellcrankCheckPlaneButton
        function BellcrankCheckPlaneButtonPushed(app, event)
            s = app.SelectedData;
            X = app.SessionData{s}.dataset.X;
            try
                if norm(projectPointPlane(X(34:36),cross(X(37:39)-X(31:33),X(43:45)-X(31:33)),X(37:39))-X(34:36)' + projectPointPlane(X(40:42),cross(X(37:39)-X(31:33),X(43:45)-X(31:33)),X(37:39))-X(40:42)') > 1e-6
                    % Clear RedoStack
                    app.SessionData{s}.RedoStack = {};
                    % Save data in UndoStack
                    app.SessionData{s}.UndoStack = app.SessionData{s};
                    % Perform changes
                    [A,B] = intersectCircleSphere(X(37:39), app.BellcrankDamperarmSpinner.Value, cross(X(43:45)-X(37:39), X(37:39)-X(31:33)), X(43:45), app.DamperlengthSpinner.Value);
                    if norm(A-X(40:42)') < norm(B-X(40:42)')
                        P_bellcrankDamper = A;
                    else
                        P_bellcrankDamper = B;
                    end
                    A = rotatePointFixedAxis(app.BellcrankAngleSpinner.Value, cross(P_bellcrankDamper'-X(37:39), X(43:45)-X(37:39)), X(37:39), P_bellcrankDamper);
                    B = rotatePointFixedAxis(app.BellcrankAngleSpinner.Value, -cross(P_bellcrankDamper'-X(37:39), X(43:45)-X(37:39)), X(37:39), P_bellcrankDamper);
                    if norm(A-X(34:36)') < norm(B-X(34:36)')
                        P_bellcrankPush = X(37:39)' + (A-X(37:39)')*app.BellcrankPusharmSpinner.Value/app.BellcrankDamperarmSpinner.Value;
                    else
                        P_bellcrankPush = X(37:39)' + (B-X(37:39)')*app.BellcrankPusharmSpinner.Value/app.BellcrankDamperarmSpinner.Value;
                    end
                    app.SessionData{s}.dataset.X(34:36) = P_bellcrankPush(:);
                    app.SessionData{s}.dataset.X(40:42) = P_bellcrankDamper(:);
                    loadDataset(app)
                    update3Dview(app)
                    %Set outdated parameters property
                    for i = 1:length(app.AnalysisList.ID)
                        app.SessionData{s}.Analysis{i}.IsUpToDate = 0;
                    end
                    app.SessionData{s}.IsSaved = 0;
                    updateParamsPanel(app)
                    addMsg(app,'Bellcrank points corrected','success',2)
                else
                    addMsg(app,'Bellcrank is already coplanar with the damper and push-rod','success',2)
                end
            catch err
                addMsg(app,['bellcrankCheckPlane error. ' err.message],'err',5);
            end
        end

        % Callback function: DamperlengthSpinner, DamperlengthSpinner
        function DamperlengthSpinnerValueChanged(app, event)
            if length(app.SessionData{app.SelectedData}.dataset.X) ~= 45
                addMsg(app,'Add suspension mechanism coordinates before editting bellcrank geometry', 'warning', 3)
                return;
            elseif app.DamperlengthSpinner.Value+app.BellcrankDamperarmSpinner.Value < norm(app.SessionData{app.SelectedData}.dataset.X(43:45)-app.SessionData{app.SelectedData}.dataset.X(37:39))
                addMsg(app,'Specified dimensions are not possible','warning',3)
                return;
            end
            editBellcrank(app);
            loadDataset(app);
            updateParamsPanel(app);
        end

        % Callback function: BellcrankPusharmSpinner, BellcrankPusharmSpinner
        function BellcrankPusharmSpinnerValueChanged(app, event)
            if length(app.SessionData{app.SelectedData}.dataset.X) ~= 45
                addMsg(app,'Add suspension mechanism coordinates before editting bellcrank geometry', 'warning', 3)
                return;
            end
            editBellcrank(app);
            loadDataset(app);
            updateParamsPanel(app);
        end

        % Callback function: BellcrankDamperarmSpinner, 
        % BellcrankDamperarmSpinner
        function BellcrankDamperarmSpinnerValueChanged(app, event)
            if length(app.SessionData{app.SelectedData}.dataset.X) ~= 45
                addMsg(app,'Add suspension mechanism coordinates before editting bellcrank geometry', 'warning', 3)
                return;
            elseif app.DamperlengthSpinner.Value+app.BellcrankDamperarmSpinner.Value < norm(app.SessionData{app.SelectedData}.dataset.X(43:45)-app.SessionData{app.SelectedData}.dataset.X(37:39))
                addMsg(app,'Specified dimensions are not possible','warning',3)
                return;
            end
            editBellcrank(app);
            loadDataset(app);
            updateParamsPanel(app);
        end

        % Callback function: BellcrankAngleSpinner, BellcrankAngleSpinner
        function BellcrankAngleSpinnerValueChanged(app, event)
            if length(app.SessionData{app.SelectedData}.dataset.X) ~= 45
                addMsg(app,'Add suspension mechanism coordinates before editting bellcrank geometry', 'warning', 3)
                return;
            end
            editBellcrank(app);
            loadDataset(app);
            updateParamsPanel(app);
        end

        % Value changed function: UpperFront_X
        function UpperFront_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: UpperFront_Y
        function UpperFront_YValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: UpperFront_Z
        function UpperFront_ZValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: UpperRear_X
        function UpperRear_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: UpperRear_Y
        function UpperRear_YValueChanged(app, event)
           updateCoordinates(app,event);
        end

        % Value changed function: UpperRear_Z
        function UpperRear_ZValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: LowerFront_X
        function LowerFront_XValueChanged(app, event)
           updateCoordinates(app,event);
        end

        % Value changed function: LowerFront_Y
        function LowerFront_YValueChanged(app, event)
          updateCoordinates(app,event);
        end

        % Value changed function: LowerFront_Z
        function LowerFront_ZValueChanged(app, event)
           updateCoordinates(app,event);
        end

        % Value changed function: LowerRear_X
        function LowerRear_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: LowerRear_Y
        function LowerRear_YValueChanged(app, event)
           updateCoordinates(app,event);
        end

        % Value changed function: LowerRear_Z
        function LowerRear_ZValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: UpperUpright_X
        function UpperUpright_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: UpperUpright_Y
        function UpperUpright_YValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: UpperUpright_Z
        function UpperUpright_ZValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: LowerUpright_X
        function LowerUpright_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: LowerUpright_Y
        function LowerUpright_YValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: LowerUpright_Z
        function LowerUpright_ZValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: SteeringRack_X
        function SteeringRack_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: SteeringRack_Y
        function SteeringRack_YValueChanged(app, event)
           updateCoordinates(app,event);
        end

        % Value changed function: SteeringRack_Z
        function SteeringRack_ZValueChanged(app, event)
           updateCoordinates(app,event);
        end

        % Value changed function: SteeringUpright_X
        function SteeringUpright_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: SteeringUpright_Y
        function SteeringUpright_YValueChanged(app, event)
           updateCoordinates(app,event);
        end

        % Value changed function: SteeringUpright_Z
        function SteeringUpright_ZValueChanged(app, event)
          updateCoordinates(app,event);
        end

        % Value changed function: WheelCenter_X
        function WheelCenter_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: WheelCenter_Y
        function WheelCenter_YValueChanged(app, event)
          updateCoordinates(app,event);
        end

        % Value changed function: WheelCenter_Z
        function WheelCenter_ZValueChanged(app, event)
           updateCoordinates(app,event);
        end

        % Value changed function: PushBracket_X
        function PushBracket_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: PushBracket_Y
        function PushBracket_YValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: PushBracket_Z
        function PushBracket_ZValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: BellcrankPush_X
        function BellcrankPush_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: BellcrankPush_Y
        function BellcrankPush_YValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: BellcrankPush_Z
        function BellcrankPush_ZValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: BellcrankBracket_X
        function BellcrankBracket_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: BellcrankBracket_Y
        function BellcrankBracket_YValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: BellcrankBracket_Z
        function BellcrankBracket_ZValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: BellcrankDamper_X
        function BellcrankDamper_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: BellcrankDamper_Y
        function BellcrankDamper_YValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: BellcrankDamper_Z
        function BellcrankDamper_ZValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: DamperBracket_X
        function DamperBracket_XValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: DamperBracket_Y
        function DamperBracket_YValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: DamperBracket_Z
        function DamperBracket_ZValueChanged(app, event)
            updateCoordinates(app,event);
        end

        % Value changed function: TireRadius
        function TireRadiusValueChanged(app, event)
            s = app.SelectedData;
            if ~isempty(app.SessionData)
                %Check if new value is valid
                if isnan(str2double(event.Value))
                    event.Source.Value = event.PreviousValue;
                    return;
                end
                app.SessionData{s}.dataset.TireRadius = str2double(event.Value);
                %Set outdated parameters property
                for i = 1:length(app.AnalysisList.ID)
                    app.SessionData{s}.Analysis{i}.IsUpToDate = 0;
                end
                app.SessionData{s}.IsSaved = 0;
                updateParamsPanel(app);
                update3Dview(app);
            end
        end

        % Value changed function: StaticCamber
        function StaticCamberValueChanged(app, event)
            s = app.SelectedData;
            if ~isempty(app.SessionData)
                %Check if new value is valid
                if isnan(str2double(event.Value))
                    event.Source.Value = event.PreviousValue;
                    return;
                end
                app.SessionData{s}.dataset.StaticCamber = str2double(event.Value);
                camber = pi/180*app.SessionData{s}.dataset.StaticCamber;
                toe = pi/180*app.SessionData{s}.dataset.StaticToe;
                wheelPlane = [cos(camber)*sin(toe) cos(camber)*cos(toe) -sin(camber)];
                app.SessionData{s}.dataset.X(28:30) = wheelPlane;
                %Set outdated parameters property
                for i = 1:length(app.AnalysisList.ID)
                    app.SessionData{s}.Analysis{i}.IsUpToDate = 0;
                end
                app.SessionData{s}.IsSaved = 0;
                updateParamsPanel(app);
                update3Dview(app);
            end
        end

        % Value changed function: StaticToe
        function StaticToeValueChanged(app, event)
            s = app.SelectedData;
            if ~isempty(app.SessionData)
                %Check if new value is valid
                if isnan(str2double(event.Value))
                    event.Source.Value = event.PreviousValue;
                    return;
                end
                app.SessionData{s}.dataset.StaticToe = str2double(event.Value);
                camber = pi/180*app.SessionData{s}.dataset.StaticCamber;
                toe = pi/180*app.SessionData{s}.dataset.StaticToe;
                wheelPlane = [cos(camber)*sin(toe) cos(camber)*cos(toe) -sin(camber)];
                app.SessionData{s}.dataset.X(28:30) = wheelPlane;
                %Set outdated parameters property
                for i = 1:length(app.AnalysisList.ID)
                    app.SessionData{s}.Analysis{i}.IsUpToDate = 0;
                end
                app.SessionData{s}.IsSaved = 0;
                updateParamsPanel(app);
                update3Dview(app);
            end
        end

        % Button pushed function: AnalysisConfigButton
        function AnalysisConfigButtonPushed(app, event)
    switch app.AnalysisConfigurationPanel.Visible
        case 'off'
            app.AnalysisConfigurationPanel.Position = [417 ... %left
                                                       app.UIFigure.Position(4)-800+190 ... %bottom
                                                       340 ... %width
                                                       272 ]; %height
            app.AnalysisConfigurationPanel.Visible = 'on';
        case 'on'
            AnalysisConfigCancelButtonPushed(app, event)
    end
        end

        % Value changed function: InterpolateCheckBox
        function InterpolateCheckBoxValueChanged(app, event)
            switch app.InterpolateCheckBox.Value
                case true
                    app.InterpolateStepsSpinner.Enable = 'on';
                    app.InterpolateLabel.Enable = 'on';
                    app.InterpStepsLabel.Enable = 'on';
                case false
                    app.InterpolateStepsSpinner.Enable = 'off';
                    app.InterpolateLabel.Enable = 'off';
                    app.InterpStepsLabel.Enable = 'off';
            end
        end

        % Button pushed function: AnalysisConfigCancelButton
        function AnalysisConfigCancelButtonPushed(app, event)
    app.AnalysisConfigurationPanel.Visible = 'off';
    temp = load('AnalysisConfig.mat');
    app.AnalysisConfig = temp.AnalysisConfiguration;
    app.ParallelStep.Value = app.AnalysisConfig{1}.step;
    app.ParallelLowerLimit.Value = app.AnalysisConfig{1}.limits(1);
    app.ParallelUpperLimit.Value = app.AnalysisConfig{1}.limits(2);
    app.OpositeStep.Value = app.AnalysisConfig{2}.step;
    app.OpositeLowerLimit.Value = app.AnalysisConfig{2}.limits(1);
    app.OpositeUpperLimit.Value = app.AnalysisConfig{2}.limits(2);
    app.RollStep.Value = app.AnalysisConfig{3}.step;
    app.RollLimit.Value = app.AnalysisConfig{3}.limits;
    app.SteeringStep.Value = app.AnalysisConfig{4}.step;
    app.SteeringLowerLimit.Value = app.AnalysisConfig{4}.limits(1);
    app.SteeringUpperLimit.Value = app.AnalysisConfig{4}.limits(2);
        end

        % Button pushed function: AnalysisConfigOKButton
        function AnalysisConfigOKButtonPushed(app, event)
    app.AnalysisConfigurationPanel.Visible = 'off';
    app.AnalysisConfig{1}.step = app.ParallelStep.Value;
    app.AnalysisConfig{1}.limits(1) = app.ParallelLowerLimit.Value;
    app.AnalysisConfig{1}.limits(2) = app.ParallelUpperLimit.Value;
    app.AnalysisConfig{2}.step = app.OpositeStep.Value;
    app.AnalysisConfig{2}.limits(1) = app.OpositeLowerLimit.Value;
    app.AnalysisConfig{2}.limits(2) = app.OpositeUpperLimit.Value;
    app.AnalysisConfig{3}.step = app.RollStep.Value;
    app.AnalysisConfig{3}.limits = app.RollLimit.Value;
    app.AnalysisConfig{3}.tol = app.RollTol.Value;
    app.AnalysisConfig{4}.step = app.SteeringStep.Value;
    app.AnalysisConfig{4}.limits(1) = app.SteeringLowerLimit.Value;
    app.AnalysisConfig{4}.limits(2) = app.SteeringUpperLimit.Value;
    app.AnalysisConfig{5}.check = app.InterpolateCheckBox.Value;
    app.AnalysisConfig{5}.steps = app.InterpolateStepsSpinner.Value;
    AnalysisConfiguration = app.AnalysisConfig;
    save(fullfile('AppData','AnalysisConfig.mat'),'AnalysisConfiguration');
    %Set outdated parameters property
    children = app.SelectCompareDatasets_SubPanel.Children;
    s = zeros(1,length(app.SessionData));
    for i=1:length(children)
        if strcmp(children(i).Type,'uicheckbox')
            if children(i).Value
                s = s + strcmp(children(i).Text,app.SelectDataSetDropDown.Items);
            end
        end
    end
    for i = find(s)
        for j = 1:length(app.AnalysisList.ID)
            app.SessionData{i}.Analysis{j}.IsUpToDate = 0;
        end
    end
    updateParamsPanel(app);
        end

        % Value changed function: ParameterSelectDropDown
        function ParameterSelectDropDownValueChanged(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            app.SessionData{s}.ParameterHasChanged = 1;
            if isempty(app.SessionData{s}.Analysis{a}.Xaxis.Value) || ~app.SessionData{s}.Analysis{a}.IsUpToDate
                addMsg(app,'Analysis has to be performed first','info',2)
            else
                updateGraphs(app);
            end
            app.DataViewTabGroup.SelectedTab = app.GraphsTab;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.ProgressWheel.Visible = 'on';
            pause(.001);
            %Find selected datasets indices
            children = app.SelectCompareDatasets_SubPanel.Children;
            s = zeros(1,length(app.SessionData));
            for i=1:length(children)
                if strcmp(children(i).Type,'uicheckbox')
                    if children(i).Value
                        s = s + strcmp(children(i).Text,app.SelectDataSetDropDown.Items);
                    end
                end
            end
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value); %Selected Analysis indices
            for i = find(s)
                if isempty(app.SessionData{i}.Analysis{a}.Xaxis.Value) || ~app.SessionData{i}.Analysis{a}.IsUpToDate %If analysis not performed yet or not up-to-date
                    performAnalysis(app,s);
                end
            end
            updateSlider(app);
            updateParamsPanel(app);
            app.SessionData{app.SelectedData}.ParameterHasChanged = 1;
            updateGraphs(app);
            app.DataViewTabGroup.SelectedTab = app.GraphsTab;
            app.ProgressWheel.Visible = 'off';
        end

        % Button pushed function: PlotOptionsButton
        function PlotOptionsButtonPushed(app, event)
            
            switch app.SelectCompareDatasets_Panel.Visible
                case 'off'
                    updateCompareMenu(app)
                    app.SelectCompareDatasets_Panel.Position = [ 395 ... %left
                        app.PlotOptionsButton.Position(2)+app.ParametersPanel.Position(2)-app.SelectCompareDatasets_Panel.Position(4)/2+app.PlotOptionsButton.Position(4)/2+50 ... %bottom
                        260 ... %width
                        min(60+20*length(app.SelectDataSetDropDown.Items),300)]; %height
                    app.SelectCompareDatasets_Panel.Visible = 'on';
                case 'on'
                    app.SelectCompareDatasets_Panel.Visible = 'off';
            end
        end

        % Button pushed function: PlotOptionsOKButton
        function PlotOptionsOKButtonPushed(app, event)
            app.SelectCompareDatasets_Panel.Visible = 'off';
            PlotButtonPushed(app, event);
        end

        % Button pushed function: AnalysisExportButton
        function AnalysisExportButtonPushed(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            try
                if ~isempty(app.SessionData{s}.Analysis{a}.Xaxis)
                    startingFolder = app.LastDirectory;
                    defaultFileName = fullfile(startingFolder, [app.AnalysisList.ID{a} '.mat']);
                    [filename, path] = uiputfile(defaultFileName,'Save data to file');
                    drawnow; %Prevent window to go to background
                    figure(app.UIFigure);
                    if filename == 0 % User clicked the Cancel button.
                        return;
                    end
                    fullFileName = fullfile(path, filename);
                    SavedData = app.SessionData{s}.Analysis{a};
                    SavedData = rmfield(SavedData,'IsUpToDate');
                    if isempty(SavedData.Yaxis.Value)
                        SavedData = rmfield(SavedData,'Yaxis');
                    end
                    save(fullFileName,'-struct','SavedData');
                    addMsg(app,'File saved succesfully','success',3);
                    app.LastDirectory = path;
                end
            catch err
                addMsg(app,['File could not be saved: ' err.message],'error',5);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.ProgressWheel.Visible = 'on';
            pause(.001);
            children = app.SelectCompareDatasets_SubPanel.Children;
            s = zeros(1,length(app.SessionData));
            for i=1:length(children)
                if strcmp(children(i).Type,'uicheckbox')
                    if children(i).Value
                        s = s + strcmp(children(i).Text,app.SelectDataSetDropDown.Items);
                    end
                end
            end
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value); %Selected Analysis indices
            for i = find(s)
                if isempty(app.SessionData{i}.Analysis{a}.Xaxis.Value) || ~app.SessionData{i}.Analysis{a}.IsUpToDate %If analysis not performed yet or not up-to-date
                    performAnalysis(app,s);
                else
                    addMsg(app,'Analysis is already up-to-date','info',2)
                end
            end
            updateSlider(app)
            updateParamsPanel(app)
            update3Dview(app)
            app.SessionData{app.SelectedData}.ParameterHasChanged = 1;
            updateGraphs(app)
            app.ProgressWheel.Visible = 'off';
        end

        % Button pushed function: TopView_Button
        function TopView_ButtonPushed(app, event)
            restore3DView(app)
            app.View3D_Axes.View = [180,90];
        end

        % Button pushed function: LeftSideView_Button
        function RightSideView_ButtonPushed(app, event)
            restore3DView(app)
            app.View3D_Axes.View = [0,0];
        end

        % Button pushed function: FrontView_Button
        function FrontView_ButtonPushed(app, event)
            restore3DView(app)
            app.View3D_Axes.View = [90,0];
        end

        % Button pushed function: RightSideView_Button
        function LeftSideView_ButtonPushed(app, event)
            restore3DView(app)
            app.View3D_Axes.View = [180,0];
        end

        % Button pushed function: BottomView_Button
        function BottomView_ButtonPushed(app, event)
            restore3DView(app)
            app.View3D_Axes.View = [180,-90];
        end

        % Value changed function: Displacement_Slider
        function Displacement_SliderValueChanged(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            app.Displacement_Slider.Value = round(app.Displacement_Slider.Value);
            app.SliderValue_temp.X = app.Displacement_Slider.Value;
            if app.SessionData{s}.Analysis{a}.PrimaryAxis == 2
                app.GraphsSlider.Value = app.Displacement_Slider.Value;
                app.GraphsSliderValue.Text = app.Displacement.Text;
            end
            update3Dview(app);
            updateGraphs(app);
        end

        % Value changed function: Displacement_Slider_2
        function Displacement_Slider_2ValueChanged(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            app.Displacement_Slider_2.Value = round(app.Displacement_Slider_2.Value);
            app.SliderValue_temp.Y = app.Displacement_Slider_2.Value;
            if app.SessionData{s}.Analysis{a}.PrimaryAxis == 1
                app.GraphsSlider.Value = app.Displacement_Slider_2.Value;
                app.GraphsSliderValue.Text = app.Displacement_2.Text;
            end
            update3Dview(app);
            updateGraphs(app);
        end

        % Value changing function: Displacement_Slider
        function Displacement_SliderValueChanging(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            app.SliderValue_temp.X = event.Value;
            app.Displacement.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Xaxis.Value(round(app.SliderValue_temp.X)));
            updateParamsPanel(app);
            update3Dview(app);
        end

        % Value changing function: Displacement_Slider_2
        function Displacement_Slider_2ValueChanging(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            app.SliderValue_temp.Y = event.Value;
            app.Displacement_2.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Yaxis.Value(round(app.SliderValue_temp.Y)));
            updateParamsPanel(app);
            update3Dview(app);
        end

        % Value changed function: GraphsSlider
        function GraphsSliderValueChanged(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            app.GraphsSlider.Value = round(app.GraphsSlider.Value);
            switch app.SessionData{s}.Analysis{a}.PrimaryAxis
                case 1
                    app.SliderValue_temp.Y = app.GraphsSlider.Value;
                    app.Displacement_Slider_2.Value = app.GraphsSlider.Value;
                    app.Displacement_2.Text = app.GraphsSliderValue.Text;
                case 2
                    app.SliderValue_temp.X = app.GraphsSlider.Value;
                    app.Displacement_Slider.Value = app.GraphsSlider.Value;
                    app.Displacement.Text = app.GraphsSliderValue.Text;
            end
            updateParamsPanel(app);
            update3Dview(app);
            updateGraphs(app);
        end

        % Value changing function: GraphsSlider
        function GraphsSliderValueChanging(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            switch app.SessionData{s}.Analysis{a}.PrimaryAxis
                case 1
                    app.SliderValue_temp.Y = event.Value;
                    app.GraphsSliderValue.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Yaxis.Value(round(app.SliderValue_temp.Y)));
                case 2
                    app.SliderValue_temp.X = event.Value;
                    app.GraphsSliderValue.Text = sprintf('%.2f',app.SessionData{s}.Analysis{a}.Xaxis.Value(round(app.SliderValue_temp.X)));
            end
            updateParamsPanel(app);
            updateGraphs(app);
        end

        % Menu selected function: ImageMenu
        function ImageMenuSelected(app, event)
            saveGraph(app)
        end

        % Menu selected function: MatlabFigureMenu
        function MatlabFigureMenuSelected(app, event)
            saveGraph(app,'.fig')
        end

        % Menu selected function: MatlabDatamatMenu
        function MatlabDatamatMenuSelected(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            p = strcmp(app.ParametersList,app.ParameterSelectDropDown.Value);
            try
                if ~isempty(app.SessionData{s}.Analysis{a}.Xaxis.Value) && ~isempty(app.SessionData{s}.Analysis{a}.Parameter{p}.Value)
                    parameterName = [app.AnalysisList.ID{a} '_' app.ParametersList{p}];
                    defaultFileName = fullfile(app.LastDirectory, [parameterName '.mat']);
                    [filename,path] = uiputfile(defaultFileName,'Save data to file');
                    drawnow; %Prevent window to go to background
                    figure(app.UIFigure);
                    if filename == 0 % User clicked the Cancel button.
                        return;
                    end
                    fullFileName = fullfile(path, filename);
                    SavedData.(matlab.lang.makeValidName(app.AnalysisList.Xaxis{a})) = app.SessionData{s}.Analysis{a}.Xaxis.Value;
                    SavedData.(matlab.lang.makeValidName(app.ParametersList{p})) = app.SessionData{s}.Analysis{a}.Parameter{p}.Value;
                    if ~isempty(app.SessionData{s}.Analysis{a}.Yaxis.Value)
                        SavedData.(matlab.lang.makeValidName(app.AnalysisList.Yaxis{a})) = app.SessionData{s}.Analysis{a}.Yaxis.Value;
                    end
                    save(fullFileName,'-struct','SavedData');
                    addMsg(app,'Data saved succesfully','success',3);
                    app.LastDirectory = path;
                else
                    addMsg(app,'No data to save. Run analysis first.','info',2)
                end
            catch err
                addMsg(app,['File could not be saved: ' err.message],'error',5);
            end
            
        end

        % Menu selected function: ExporttoworkspaceMenu
        function ExporttoworkspaceMenuSelected(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            p = strcmp(app.ParametersList,app.ParameterSelectDropDown.Value);
            if ~isempty(app.SessionData{s}.Analysis{a}.Xaxis.Value) && ~isempty(app.SessionData{s}.Analysis{a}.Parameter{p}.Value)
                assignin('base', matlab.lang.makeValidName([app.AnalysisList.ID{a} '_' app.ParametersList{p}]), app.SessionData{s}.Analysis{a}.Parameter{p}.Value);
                assignin('base', matlab.lang.makeValidName([app.AnalysisList.ID{a} '_' app.AnalysisList.Xaxis{a}]), app.SessionData{s}.Analysis{a}.Xaxis.Value);
                if ~isempty(app.SessionData{s}.Analysis{a}.Yaxis.Value)
                    assignin('base', matlab.lang.makeValidName([app.AnalysisList.ID{a} '_' app.AnalysisList.Yaxis{a}]), app.SessionData{s}.Analysis{a}.Yaxis.Value);
                end
            else
                addMsg(app,'No data to save. Run analysis first.','info',2)
            end
        end

        % Value changed function: AnalysisSelectDropDown
        function AnalysisSelectDropDownValueChanged(app, event)
            app.SessionData{app.SelectedData}.ParameterHasChanged = 1;
            updateSlider(app);
            update3Dview(app);
            updateGraphs(app);
            updateParamsPanel(app);
        end

        % Menu selected function: PlotsurfaceMenuCheck
        function PlotsurfaceMenuCheckSelected(app, event)
            switch app.PlotsurfaceMenuCheck.Checked
                case 'on'
                    app.PlotsurfaceMenuCheck.Checked = 'off';
                case 'off'
                    app.PlotsurfaceMenuCheck.Checked = 'on';
            end
            app.SessionData{app.SelectedData}.ParameterHasChanged = 1;
            updateGraphs(app);
        end

        % Menu selected function: ChangeprimaryaxisMenu
        function ChangeprimaryaxisMenuSelected(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            switch app.SessionData{s}.Analysis{a}.PrimaryAxis
                case 1
                    if ~isempty(app.SessionData{s}.Analysis{a}.Yaxis.Value)
                        app.SessionData{s}.Analysis{a}.PrimaryAxis = 2;
                    end
                case 2
                    app.SessionData{s}.Analysis{a}.PrimaryAxis = 1;
            end
            app.SessionData{app.SelectedData}.ParameterHasChanged = 1;
            updateSlider(app)
            updateGraphs(app)
        end

        % Menu selected function: ResetViewMenu
        function ResetViewMenuSelected(app, event)
            ax = app.Graphs_Axes;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            if ~isempty(app.SessionData{app.SelectedData}.Analysis{a}.Xaxis.Value)
                ax.XLim = [min(app.SessionData{app.SelectedData}.Analysis{a}.Xaxis.Value) max(app.SessionData{app.SelectedData}.Analysis{a}.Xaxis.Value)];
            else
                ax.XLimMode = 'auto';
            end
            if ~isempty(app.SessionData{app.SelectedData}.Analysis{a}.Yaxis.Value)
                ax.YLim = [min(app.SessionData{app.SelectedData}.Analysis{a}.Yaxis.Value) max(app.SessionData{app.SelectedData}.Analysis{a}.Yaxis.Value)];
            else
                ax.YLimMode = 'auto';
            end
            ax.ZLimMode = 'auto';
        end

        % Menu selected function: view3DcheckMenu
        function view3DcheckMenuSelected(app, event)
            switch app.view3DcheckMenu.Checked
                case 'on'
                    app.view3DcheckMenu.Checked = 'off';
                case 'off'
                    app.view3DcheckMenu.Checked = 'on';
            end
            update3Dview(app)
            children = app.view3DCompareWithMenu.Children;
            for i=1:length(children)
                children(i).Checked = 'off';
            end
        end

        % Menu selected function: Reset3DViewMenu
        function Reset3DViewMenuSelected(app, event)
            restore3DView(app)
        end

        % Button pushed function: ChangeParameterDownButton
        function ChangeParameterDownButtonPushed(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            p = find(strcmp(app.ParametersList,app.ParameterSelectDropDown.Value));
            if p > 1
                app.ParameterSelectDropDown.Value = app.ParameterSelectDropDown.Items{p-1};
            else
                return;
            end
            app.SessionData{s}.ParameterHasChanged = 1;
            if isempty(app.SessionData{s}.Analysis{a}.Xaxis.Value) || ~app.SessionData{s}.Analysis{a}.IsUpToDate
                addMsg(app,'Analysis has to be performed first','info',2)
            else
                updateGraphs(app);
            end
        end

        % Button pushed function: ChangeParameterUpButton
        function ChangeParameterUpButtonPushed(app, event)
            s = app.SelectedData;
            a = strcmp(app.AnalysisList.ID,app.AnalysisSelectDropDown.Value);
            p = find(strcmp(app.ParametersList,app.ParameterSelectDropDown.Value));
            if p < length(app.ParametersList)
                app.ParameterSelectDropDown.Value = app.ParameterSelectDropDown.Items{p+1};
            else
                return;
            end
            app.SessionData{s}.ParameterHasChanged = 1;
            if isempty(app.SessionData{s}.Analysis{a}.Xaxis.Value) || ~app.SessionData{s}.Analysis{a}.IsUpToDate
                addMsg(app,'Analysis has to be performed first','info',2)
            else
                updateGraphs(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Kinematic 3D Analysis';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GlobalContainer = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.GlobalContainer);
            app.CentralContainer = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CentralContainer);
            app.LeftPanelContainer = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.LeftPanelContainer);
            app.AppButtonsPanel = uipanel(app.GridLayout3);
            app.SelectDataSetDropDown = uidropdown(app.AppButtonsPanel);
            app.SelectDataSetDropDown.Items = {};
            app.SelectDataSetDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDataSetDropDownValueChanged, true);
            app.SelectDataSetDropDown.Value = {};
            app.ExportButton = uibutton(app.AppButtonsPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = '';
            app.SaveButton = uibutton(app.AppButtonsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tag = 'save_button';
            app.SaveButton.Icon = 'file_save.png';
            app.SaveButton.Text = '';
            app.OpenButton = uibutton(app.AppButtonsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Icon = 'file_open.png';
            app.OpenButton.Text = 'Open';
            app.NewButton = uibutton(app.AppButtonsPanel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Tag = 'NewButton';
            app.NewButton.Text = '';
            app.UndoButton = uibutton(app.AppButtonsPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = '';
            app.RedoButton = uibutton(app.AppButtonsPanel, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = '';
            app.DatasetConfigButton = uibutton(app.AppButtonsPanel, 'push');
            app.DatasetConfigButton.ButtonPushedFcn = createCallbackFcn(app, @DatasetConfigButtonPushed, true);
            app.DatasetConfigButton.Text = '';
            app.RemoveButton = uibutton(app.AppButtonsPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Tag = 'NewButton';
            app.RemoveButton.Text = '';
            app.DataSetTabGroup = uitabgroup(app.GridLayout3);
            app.aArmsTab = uitab(app.DataSetTabGroup);
            app.aArmsTab.Title = 'A-Arms';
            app.UpperFront_Label = uilabel(app.aArmsTab);
            app.UpperFront_Label.Tag = 'UpperFront_Label';
            app.UpperFront_Label.Text = '1 - Upper Front';
            app.UpperRear_Label = uilabel(app.aArmsTab);
            app.UpperRear_Label.Tag = 'UpperRear_Label';
            app.UpperRear_Label.Text = '2 - Upper Rear';
            app.LowerFront_Label = uilabel(app.aArmsTab);
            app.LowerFront_Label.Tag = 'LowerFront_Label';
            app.LowerFront_Label.Text = '3 - Lower Front';
            app.LowerRear_Label = uilabel(app.aArmsTab);
            app.LowerRear_Label.Tag = 'LowerRear_Label';
            app.LowerRear_Label.Text = '4 - Lower Rear';
            app.UpperUpright_Label = uilabel(app.aArmsTab);
            app.UpperUpright_Label.Tag = 'UpperUpright_Label';
            app.UpperUpright_Label.Text = '5 - Upper Upright';
            app.LowerUpright_Label = uilabel(app.aArmsTab);
            app.LowerUpright_Label.Tag = 'LowerUpright_Label';
            app.LowerUpright_Label.Text = '6 - Lower Upright';
            app.SteeringRack_Label = uilabel(app.aArmsTab);
            app.SteeringRack_Label.Tag = 'SteeringRack_Label';
            app.SteeringRack_Label.Text = '7 - Steering Rack';
            app.SteeringUprigth_Label = uilabel(app.aArmsTab);
            app.SteeringUprigth_Label.Tag = 'SteeringUprigth_Label';
            app.SteeringUprigth_Label.Text = '8 - Steering Uprigth';
            app.WheelCenter_Label = uilabel(app.aArmsTab);
            app.WheelCenter_Label.Tag = 'WheelCenter_Label';
            app.WheelCenter_Label.Text = '9 - Wheel Center';
            app.WheelPlane_Label = uilabel(app.aArmsTab);
            app.WheelPlane_Label.Tag = 'WheelPlane_Label';
            app.WheelPlane_Label.Text = '10 - Wheel Plane';
            app.zCoordinateLabel = uilabel(app.aArmsTab);
            app.zCoordinateLabel.Tag = 'zCoordinateLabel';
            app.zCoordinateLabel.Text = 'Z';
            app.yCoordinateLabel = uilabel(app.aArmsTab);
            app.yCoordinateLabel.Tag = 'yCoordinateLabel';
            app.yCoordinateLabel.Text = 'Y';
            app.xCoordinateLabel = uilabel(app.aArmsTab);
            app.xCoordinateLabel.Tag = 'xCoordinateLabel';
            app.xCoordinateLabel.Text = 'X';
            app.TireRadius = uieditfield(app.aArmsTab, 'text');
            app.TireRadius.ValueChangedFcn = createCallbackFcn(app, @TireRadiusValueChanged, true);
            app.TireRadius.Tag = 'TireRadius';
            app.TireRadius_Label = uilabel(app.aArmsTab);
            app.TireRadius_Label.Tag = 'TireRadius_Label';
            app.TireRadius_Label.Text = 'Tire Radius';
            app.StaticToe = uieditfield(app.aArmsTab, 'text');
            app.StaticToe.ValueChangedFcn = createCallbackFcn(app, @StaticToeValueChanged, true);
            app.StaticToe.Tag = 'StaticToe';
            app.StaticCamber = uieditfield(app.aArmsTab, 'text');
            app.StaticCamber.ValueChangedFcn = createCallbackFcn(app, @StaticCamberValueChanged, true);
            app.StaticCamber.Tag = 'StaticCamber';
            app.StaticToe_Label = uilabel(app.aArmsTab);
            app.StaticToe_Label.Tag = 'StaticToe_Label';
            app.StaticToe_Label.Text = 'Static Toe';
            app.StaticCamber_Label = uilabel(app.aArmsTab);
            app.StaticCamber_Label.Tag = 'StaticCamber_Label';
            app.StaticCamber_Label.Text = 'Static Camber';
            app.SuspensionEditButton = uibutton(app.aArmsTab, 'push');
            app.SuspensionEditButton.ButtonPushedFcn = createCallbackFcn(app, @SuspensionEditButtonPushed, true);
            app.SuspensionEditButton.Text = 'Edit';
            app.SuspensionToolsLabel = uilabel(app.aArmsTab);
            app.SuspensionToolsLabel.Text = 'Tools';
            app.SuspensionCoordinatesPanel = uipanel(app.aArmsTab);
            app.UpperFront_X = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.UpperFront_X.ValueChangedFcn = createCallbackFcn(app, @UpperFront_XValueChanged, true);
            app.UpperFront_X.Tag = 'UpperFront_X';
            app.UpperFront_Y = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.UpperFront_Y.ValueChangedFcn = createCallbackFcn(app, @UpperFront_YValueChanged, true);
            app.UpperFront_Y.Tag = 'UpperFront_Y';
            app.UpperFront_Z = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.UpperFront_Z.ValueChangedFcn = createCallbackFcn(app, @UpperFront_ZValueChanged, true);
            app.UpperFront_Z.Tag = 'UpperFront_Z';
            app.UpperRear_X = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.UpperRear_X.ValueChangedFcn = createCallbackFcn(app, @UpperRear_XValueChanged, true);
            app.UpperRear_X.Tag = 'UpperRear_X';
            app.UpperRear_Y = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.UpperRear_Y.ValueChangedFcn = createCallbackFcn(app, @UpperRear_YValueChanged, true);
            app.UpperRear_Y.Tag = 'UpperRear_Y';
            app.UpperRear_Z = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.UpperRear_Z.ValueChangedFcn = createCallbackFcn(app, @UpperRear_ZValueChanged, true);
            app.UpperRear_Z.Tag = 'UpperRear_Z';
            app.LowerFront_X = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.LowerFront_X.ValueChangedFcn = createCallbackFcn(app, @LowerFront_XValueChanged, true);
            app.LowerFront_X.Tag = 'LowerFront_X';
            app.LowerFront_Y = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.LowerFront_Y.ValueChangedFcn = createCallbackFcn(app, @LowerFront_YValueChanged, true);
            app.LowerFront_Y.Tag = 'LowerFront_Y';
            app.LowerFront_Z = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.LowerFront_Z.ValueChangedFcn = createCallbackFcn(app, @LowerFront_ZValueChanged, true);
            app.LowerFront_Z.Tag = 'LowerFront_Z';
            app.LowerRear_X = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.LowerRear_X.ValueChangedFcn = createCallbackFcn(app, @LowerRear_XValueChanged, true);
            app.LowerRear_X.Tag = 'LowerRear_X';
            app.LowerRear_Y = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.LowerRear_Y.ValueChangedFcn = createCallbackFcn(app, @LowerRear_YValueChanged, true);
            app.LowerRear_Y.Tag = 'LowerRear_Y';
            app.LowerRear_Z = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.LowerRear_Z.ValueChangedFcn = createCallbackFcn(app, @LowerRear_ZValueChanged, true);
            app.LowerRear_Z.Tag = 'LowerRear_Z';
            app.UpperUpright_X = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.UpperUpright_X.ValueChangedFcn = createCallbackFcn(app, @UpperUpright_XValueChanged, true);
            app.UpperUpright_X.Tag = 'UpperUpright_X';
            app.UpperUpright_Y = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.UpperUpright_Y.ValueChangedFcn = createCallbackFcn(app, @UpperUpright_YValueChanged, true);
            app.UpperUpright_Y.Tag = 'UpperUpright_Y';
            app.UpperUpright_Z = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.UpperUpright_Z.ValueChangedFcn = createCallbackFcn(app, @UpperUpright_ZValueChanged, true);
            app.UpperUpright_Z.Tag = 'UpperUpright_Z';
            app.LowerUpright_X = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.LowerUpright_X.ValueChangedFcn = createCallbackFcn(app, @LowerUpright_XValueChanged, true);
            app.LowerUpright_X.Tag = 'LowerUpright_X';
            app.LowerUpright_Y = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.LowerUpright_Y.ValueChangedFcn = createCallbackFcn(app, @LowerUpright_YValueChanged, true);
            app.LowerUpright_Y.Tag = 'LowerUpright_Y';
            app.LowerUpright_Z = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.LowerUpright_Z.ValueChangedFcn = createCallbackFcn(app, @LowerUpright_ZValueChanged, true);
            app.LowerUpright_Z.Tag = 'LowerUpright_Z';
            app.SteeringRack_X = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.SteeringRack_X.ValueChangedFcn = createCallbackFcn(app, @SteeringRack_XValueChanged, true);
            app.SteeringRack_X.Tag = 'SteeringRack_X';
            app.SteeringRack_Y = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.SteeringRack_Y.ValueChangedFcn = createCallbackFcn(app, @SteeringRack_YValueChanged, true);
            app.SteeringRack_Y.Tag = 'SteeringRack_Y';
            app.SteeringRack_Z = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.SteeringRack_Z.ValueChangedFcn = createCallbackFcn(app, @SteeringRack_ZValueChanged, true);
            app.SteeringRack_Z.Tag = 'SteeringRack_Z';
            app.SteeringUpright_X = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.SteeringUpright_X.ValueChangedFcn = createCallbackFcn(app, @SteeringUpright_XValueChanged, true);
            app.SteeringUpright_X.Tag = 'SteeringUpright_X';
            app.SteeringUpright_Y = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.SteeringUpright_Y.ValueChangedFcn = createCallbackFcn(app, @SteeringUpright_YValueChanged, true);
            app.SteeringUpright_Y.Tag = 'SteeringUpright_Y';
            app.SteeringUpright_Z = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.SteeringUpright_Z.ValueChangedFcn = createCallbackFcn(app, @SteeringUpright_ZValueChanged, true);
            app.SteeringUpright_Z.Tag = 'SteeringUpright_Z';
            app.WheelCenter_X = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.WheelCenter_X.ValueChangedFcn = createCallbackFcn(app, @WheelCenter_XValueChanged, true);
            app.WheelCenter_X.Tag = 'WheelCenter_X';
            app.WheelCenter_Y = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.WheelCenter_Y.ValueChangedFcn = createCallbackFcn(app, @WheelCenter_YValueChanged, true);
            app.WheelCenter_Y.Tag = 'WheelCenter_Y';
            app.WheelCenter_Z = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.WheelCenter_Z.ValueChangedFcn = createCallbackFcn(app, @WheelCenter_ZValueChanged, true);
            app.WheelCenter_Z.Tag = 'WheelCenter_Z';
            app.WheelPlane_X = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.WheelPlane_X.Tag = 'WheelPlane_X';
            app.WheelPlane_X.Editable = 'off';
            app.WheelPlane_Y = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.WheelPlane_Y.Tag = 'WheelPlane_Y';
            app.WheelPlane_Y.Editable = 'off';
            app.WheelPlane_Z = uieditfield(app.SuspensionCoordinatesPanel, 'text');
            app.WheelPlane_Z.Tag = 'WheelPlane_Z';
            app.WheelPlane_Z.Editable = 'off';
            app.SuspOptimButton = uibutton(app.aArmsTab, 'push');
            app.SuspOptimButton.ButtonPushedFcn = createCallbackFcn(app, @SuspOptimButtonPushed, true);
            app.SuspOptimButton.Text = 'Optimize';
            app.suspMechTab = uitab(app.DataSetTabGroup);
            app.suspMechTab.Title = 'Bellcrank';
            app.PushBracket_Label = uilabel(app.suspMechTab);
            app.PushBracket_Label.Text = '11 - Push Bracket';
            app.BellcrankPush_Label = uilabel(app.suspMechTab);
            app.BellcrankPush_Label.Text = '12 - Bellcrank Push';
            app.BellcrankBracket_Label = uilabel(app.suspMechTab);
            app.BellcrankBracket_Label.Text = '13 - Bellcrank Bracket';
            app.BellcrankDamper_Label = uilabel(app.suspMechTab);
            app.BellcrankDamper_Label.Text = '14 - Bellcrank Damper';
            app.DamperBracket_Label = uilabel(app.suspMechTab);
            app.DamperBracket_Label.Text = '15 - Damper Bracket';
            app.BellcrankToolsLabel_2 = uilabel(app.suspMechTab);
            app.BellcrankToolsLabel_2.Text = 'Tools';
            app.zCoordinateLabel_2 = uilabel(app.suspMechTab);
            app.zCoordinateLabel_2.Tag = 'zCoordinateLabel';
            app.zCoordinateLabel_2.Text = 'Z';
            app.yCoordinateLabel_2 = uilabel(app.suspMechTab);
            app.yCoordinateLabel_2.Tag = 'yCoordinateLabel';
            app.yCoordinateLabel_2.Text = 'Y';
            app.xCoordinateLabel_2 = uilabel(app.suspMechTab);
            app.xCoordinateLabel_2.Text = 'X';
            app.BellcrankOptimButton = uibutton(app.suspMechTab, 'push');
            app.BellcrankOptimButton.ButtonPushedFcn = createCallbackFcn(app, @BellcrankOptimButtonPushed, true);
            app.BellcrankOptimButton.Text = 'Optimize';
            app.SuspMechCoordinatesPanel = uipanel(app.suspMechTab);
            app.PushBracket_X = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.PushBracket_X.ValueChangedFcn = createCallbackFcn(app, @PushBracket_XValueChanged, true);
            app.PushBracket_X.Tag = 'PushBracket_X';
            app.PushBracket_Y = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.PushBracket_Y.ValueChangedFcn = createCallbackFcn(app, @PushBracket_YValueChanged, true);
            app.PushBracket_Y.Tag = 'PushBracket_Y';
            app.PushBracket_Z = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.PushBracket_Z.ValueChangedFcn = createCallbackFcn(app, @PushBracket_ZValueChanged, true);
            app.PushBracket_Z.Tag = 'PushBracket_Z';
            app.BellcrankPush_X = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.BellcrankPush_X.ValueChangedFcn = createCallbackFcn(app, @BellcrankPush_XValueChanged, true);
            app.BellcrankPush_X.Tag = 'BellcrankPush_X';
            app.BellcrankPush_Y = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.BellcrankPush_Y.ValueChangedFcn = createCallbackFcn(app, @BellcrankPush_YValueChanged, true);
            app.BellcrankPush_Y.Tag = 'BellcrankPush_Y';
            app.BellcrankPush_Z = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.BellcrankPush_Z.ValueChangedFcn = createCallbackFcn(app, @BellcrankPush_ZValueChanged, true);
            app.BellcrankPush_Z.Tag = 'BellcrankPush_Z';
            app.BellcrankBracket_X = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.BellcrankBracket_X.ValueChangedFcn = createCallbackFcn(app, @BellcrankBracket_XValueChanged, true);
            app.BellcrankBracket_X.Tag = 'BellcrankBracket_X';
            app.BellcrankBracket_Y = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.BellcrankBracket_Y.ValueChangedFcn = createCallbackFcn(app, @BellcrankBracket_YValueChanged, true);
            app.BellcrankBracket_Y.Tag = 'BellcrankBracket_Y';
            app.BellcrankBracket_Z = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.BellcrankBracket_Z.ValueChangedFcn = createCallbackFcn(app, @BellcrankBracket_ZValueChanged, true);
            app.BellcrankBracket_Z.Tag = 'BellcrankBracket_Z';
            app.BellcrankDamper_X = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.BellcrankDamper_X.ValueChangedFcn = createCallbackFcn(app, @BellcrankDamper_XValueChanged, true);
            app.BellcrankDamper_X.Tag = 'BellcrankDamper_X';
            app.BellcrankDamper_Y = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.BellcrankDamper_Y.ValueChangedFcn = createCallbackFcn(app, @BellcrankDamper_YValueChanged, true);
            app.BellcrankDamper_Y.Tag = 'BellcrankDamper_Y';
            app.BellcrankDamper_Z = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.BellcrankDamper_Z.ValueChangedFcn = createCallbackFcn(app, @BellcrankDamper_ZValueChanged, true);
            app.BellcrankDamper_Z.Tag = 'BellcrankDamper_Z';
            app.DamperBracket_X = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.DamperBracket_X.ValueChangedFcn = createCallbackFcn(app, @DamperBracket_XValueChanged, true);
            app.DamperBracket_X.Tag = 'DamperBracket_X';
            app.DamperBracket_Y = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.DamperBracket_Y.ValueChangedFcn = createCallbackFcn(app, @DamperBracket_YValueChanged, true);
            app.DamperBracket_Y.Tag = 'DamperBracket_Y';
            app.DamperBracket_Z = uieditfield(app.SuspMechCoordinatesPanel, 'text');
            app.DamperBracket_Z.ValueChangedFcn = createCallbackFcn(app, @DamperBracket_ZValueChanged, true);
            app.DamperBracket_Z.Tag = 'DamperBracket_Z';
            app.BellcrankparametersPanel = uipanel(app.suspMechTab);
            app.BellcrankparametersPanel.Title = 'Bellcrank parameters';
            app.DamperlengthSpinnerLabel = uilabel(app.BellcrankparametersPanel);
            app.DamperlengthSpinnerLabel.Text = 'Damper length';
            app.DamperlengthSpinner = uispinner(app.BellcrankparametersPanel);
            app.DamperlengthSpinner.ValueChangingFcn = createCallbackFcn(app, @DamperlengthSpinnerValueChanged, true);
            app.DamperlengthSpinner.Limits = [0 Inf];
            app.DamperlengthSpinner.ValueDisplayFormat = '%6.1f';
            app.DamperlengthSpinner.ValueChangedFcn = createCallbackFcn(app, @DamperlengthSpinnerValueChanged, true);
            app.BellcrankPusharmSpinnerLabel = uilabel(app.BellcrankparametersPanel);
            app.BellcrankPusharmSpinnerLabel.Text = 'Bellcrank-Push arm';
            app.BellcrankPusharmSpinner = uispinner(app.BellcrankparametersPanel);
            app.BellcrankPusharmSpinner.ValueChangingFcn = createCallbackFcn(app, @BellcrankPusharmSpinnerValueChanged, true);
            app.BellcrankPusharmSpinner.Limits = [0 Inf];
            app.BellcrankPusharmSpinner.ValueDisplayFormat = '%6.1f';
            app.BellcrankPusharmSpinner.ValueChangedFcn = createCallbackFcn(app, @BellcrankPusharmSpinnerValueChanged, true);
            app.BellcrankDamperarmSpinnerLabel = uilabel(app.BellcrankparametersPanel);
            app.BellcrankDamperarmSpinnerLabel.Text = 'Bellcrank-Damper arm';
            app.BellcrankDamperarmSpinner = uispinner(app.BellcrankparametersPanel);
            app.BellcrankDamperarmSpinner.ValueChangingFcn = createCallbackFcn(app, @BellcrankDamperarmSpinnerValueChanged, true);
            app.BellcrankDamperarmSpinner.Limits = [0 Inf];
            app.BellcrankDamperarmSpinner.ValueDisplayFormat = '%6.1f';
            app.BellcrankDamperarmSpinner.ValueChangedFcn = createCallbackFcn(app, @BellcrankDamperarmSpinnerValueChanged, true);
            app.BellcrankAngleSpinnerLabel = uilabel(app.BellcrankparametersPanel);
            app.BellcrankAngleSpinnerLabel.Text = 'Bellcrank Angle';
            app.BellcrankAngleSpinner = uispinner(app.BellcrankparametersPanel);
            app.BellcrankAngleSpinner.ValueChangingFcn = createCallbackFcn(app, @BellcrankAngleSpinnerValueChanged, true);
            app.BellcrankAngleSpinner.Limits = [0 Inf];
            app.BellcrankAngleSpinner.ValueDisplayFormat = '%6.1f';
            app.BellcrankAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @BellcrankAngleSpinnerValueChanged, true);
            app.BellcrankCheckPlaneButton = uibutton(app.suspMechTab, 'push');
            app.BellcrankCheckPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @BellcrankCheckPlaneButtonPushed, true);
            app.BellcrankCheckPlaneButton.Tooltip = {'Correct bellcrank points so bellcrank, damper and push-rod are coplanar'};
            app.BellcrankCheckPlaneButton.Text = 'Check Plane';
            app.ParametersPanel = uipanel(app.GridLayout3);
            app.text24 = uilabel(app.ParametersPanel);
            app.text24.Tag = 'text24';
            app.text24.Text = 'RC Height';
            app.text25 = uilabel(app.ParametersPanel);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Anti-dive (%)';
            app.text28 = uilabel(app.ParametersPanel);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Toe';
            app.text27 = uilabel(app.ParametersPanel);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Camber';
            app.text29 = uilabel(app.ParametersPanel);
            app.text29.Tag = 'text29';
            app.text29.Text = 'KPI';
            app.text30 = uilabel(app.ParametersPanel);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Caster';
            app.text31 = uilabel(app.ParametersPanel);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Scrub';
            app.text32 = uilabel(app.ParametersPanel);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Trail';
            app.RollCenterHeight = uilabel(app.ParametersPanel);
            app.RollCenterHeight.Tag = 'RollCenterHeight';
            app.RollCenterHeight.Text = '';
            app.AntiDive = uilabel(app.ParametersPanel);
            app.AntiDive.Tag = 'AntiDive';
            app.AntiDive.Text = '';
            app.Camber = uilabel(app.ParametersPanel);
            app.Camber.Tag = 'Camber';
            app.Camber.Text = '';
            app.Toe = uilabel(app.ParametersPanel);
            app.Toe.Tag = 'Toe';
            app.Toe.Text = '';
            app.KPI = uilabel(app.ParametersPanel);
            app.KPI.Tag = 'KPI';
            app.KPI.Text = '';
            app.Caster = uilabel(app.ParametersPanel);
            app.Caster.Tag = 'Caster';
            app.Caster.Text = '';
            app.Scrub = uilabel(app.ParametersPanel);
            app.Scrub.Tag = 'Scrub';
            app.Scrub.Text = '';
            app.Trail = uilabel(app.ParametersPanel);
            app.Trail.Tag = 'Trail';
            app.Trail.Text = '';
            app.RollCenterHeightMin = uilabel(app.ParametersPanel);
            app.RollCenterHeightMin.Tag = 'RollCenterHeightMin';
            app.RollCenterHeightMin.Text = '';
            app.AntiDiveMin = uilabel(app.ParametersPanel);
            app.AntiDiveMin.Tag = 'AntiDiveMin';
            app.AntiDiveMin.Text = '';
            app.CamberMin = uilabel(app.ParametersPanel);
            app.CamberMin.Tag = 'CamberMin';
            app.CamberMin.Text = '';
            app.ToeMin = uilabel(app.ParametersPanel);
            app.ToeMin.Tag = 'ToeMin';
            app.ToeMin.Text = '';
            app.KPIMin = uilabel(app.ParametersPanel);
            app.KPIMin.Tag = 'KPIMin';
            app.KPIMin.Text = '';
            app.CasterMin = uilabel(app.ParametersPanel);
            app.CasterMin.Tag = 'CasterMin';
            app.CasterMin.Text = '';
            app.ScrubMin = uilabel(app.ParametersPanel);
            app.ScrubMin.Tag = 'ScrubMin';
            app.ScrubMin.Text = '';
            app.TrailMin = uilabel(app.ParametersPanel);
            app.TrailMin.Tag = 'TrailMin';
            app.TrailMin.Text = '';
            app.RollCenterHeightMax = uilabel(app.ParametersPanel);
            app.RollCenterHeightMax.Tag = 'RollCenterHeightMax';
            app.RollCenterHeightMax.Text = '';
            app.AntiDiveMax = uilabel(app.ParametersPanel);
            app.AntiDiveMax.Tag = 'AntiDiveMax';
            app.AntiDiveMax.Text = '';
            app.CamberMax = uilabel(app.ParametersPanel);
            app.CamberMax.Tag = 'CamberMax';
            app.CamberMax.Text = '';
            app.ToeMax = uilabel(app.ParametersPanel);
            app.ToeMax.Tag = 'ToeMax';
            app.ToeMax.Text = '';
            app.KPIMax = uilabel(app.ParametersPanel);
            app.KPIMax.Tag = 'KPIMax';
            app.KPIMax.Text = '';
            app.CasterMax = uilabel(app.ParametersPanel);
            app.CasterMax.Tag = 'CasterMax';
            app.CasterMax.Text = '';
            app.ScrubMax = uilabel(app.ParametersPanel);
            app.ScrubMax.Tag = 'ScrubMax';
            app.ScrubMax.Text = '';
            app.TrailMax = uilabel(app.ParametersPanel);
            app.TrailMax.Tag = 'TrailMax';
            app.TrailMax.Text = '';
            app.mintext = uilabel(app.ParametersPanel);
            app.mintext.Text = 'min';
            app.maxtext = uilabel(app.ParametersPanel);
            app.maxtext.Tag = 'text63';
            app.maxtext.Text = 'max';
            app.ParamToolsLabel = uilabel(app.ParametersPanel);
            app.ParamToolsLabel.Text = 'Tools';
            app.ParameterSelectDropDown = uidropdown(app.ParametersPanel);
            app.ParameterSelectDropDown.Items = {'Camber', 'Toe', 'KPI', 'Caster', 'Trail', 'Scrub', 'RC Height', 'RC Migration', 'Anti-Dive', 'Ackerman', 'Motion Ratio'};
            app.ParameterSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterSelectDropDownValueChanged, true);
            app.ParameterSelectDropDown.Tag = 'ParameterSelection_Menu';
            app.ParameterSelectDropDown.Value = 'Camber';
            app.PlotButton = uibutton(app.ParametersPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tag = 'Plot';
            app.PlotButton.Text = 'Plot';
            app.PlotOptionsButton = uibutton(app.ParametersPanel, 'push');
            app.PlotOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOptionsButtonPushed, true);
            app.PlotOptionsButton.Tag = 'Compare';
            app.PlotOptionsButton.Text = '...';
            app.Ackerman = uilabel(app.ParametersPanel);
            app.Ackerman.Tag = 'AntiDive';
            app.Ackerman.Text = '';
            app.AckermanMin = uilabel(app.ParametersPanel);
            app.AckermanMin.Tag = 'AntiDiveMin';
            app.AckermanMin.Text = '';
            app.AckermanMax = uilabel(app.ParametersPanel);
            app.AckermanMax.Tag = 'AntiDiveMax';
            app.AckermanMax.Text = '';
            app.AckermanLabel = uilabel(app.ParametersPanel);
            app.AckermanLabel.Tag = 'text25';
            app.AckermanLabel.Text = 'Ackerman';
            app.RunButton = uibutton(app.ParametersPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.MotionRatioLabel = uilabel(app.ParametersPanel);
            app.MotionRatioLabel.Tag = 'text25';
            app.MotionRatioLabel.Text = 'Motion Ratio';
            app.MotionRatioMax = uilabel(app.ParametersPanel);
            app.MotionRatioMax.Tag = 'AntiDiveMax';
            app.MotionRatioMax.Text = '';
            app.MotionRatioMin = uilabel(app.ParametersPanel);
            app.MotionRatioMin.Tag = 'AntiDiveMin';
            app.MotionRatioMin.Text = '';
            app.MotionRatio = uilabel(app.ParametersPanel);
            app.MotionRatio.Tag = 'AntiDive';
            app.MotionRatio.Text = '';
            app.AnalysisSelectPanel = uipanel(app.ParametersPanel);
            app.AnalysisConfigButton = uibutton(app.AnalysisSelectPanel, 'push');
            app.AnalysisConfigButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisConfigButtonPushed, true);
            app.AnalysisConfigButton.Text = '';
            app.AnalysisSelectDropDown = uidropdown(app.AnalysisSelectPanel);
            app.AnalysisSelectDropDown.Items = {};
            app.AnalysisSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisSelectDropDownValueChanged, true);
            app.AnalysisSelectDropDown.Value = {};
            app.AnalysisDropDownLabel = uilabel(app.AnalysisSelectPanel);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisExportButton = uibutton(app.ParametersPanel, 'push');
            app.AnalysisExportButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisExportButtonPushed, true);
            app.AnalysisExportButton.Tag = 'Compare';
            app.AnalysisExportButton.Tooltip = {'Export data to matlab file'};
            app.AnalysisExportButton.Text = 'Export';
            app.PlotsContainer = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.PlotsContainer);
            app.DataViewTabGroup = uitabgroup(app.GridLayout4);
            app.ThreeDViewTab = uitab(app.DataViewTabGroup);
            app.ThreeDViewTab.Title = '3D View';
            app.View3D_Axes = uiaxes(app.ThreeDViewTab);
            app.View3D_Axes.Tag = 'View3D_Axes';
            app.Displacement_Slider = uislider(app.ThreeDViewTab);
            app.Displacement_Slider.Limits = [1 2];
            app.Displacement_Slider.MajorTicks = [];
            app.Displacement_Slider.ValueChangedFcn = createCallbackFcn(app, @Displacement_SliderValueChanged, true);
            app.Displacement_Slider.ValueChangingFcn = createCallbackFcn(app, @Displacement_SliderValueChanging, true);
            app.Displacement_Slider.MinorTicks = [];
            app.Displacement_Slider.Tag = 'Displacement_Slider';
            app.Displacement_Slider.Visible = 'off';
            app.Displacement_Slider.Value = 1.5;
            app.Displacement_Label = uilabel(app.ThreeDViewTab);
            app.Displacement_Label.Tag = 'Displacement_Label';
            app.Displacement_Label.Visible = 'off';
            app.Displacement_Label.Text = 'Displacement:';
            app.Displacement = uilabel(app.ThreeDViewTab);
            app.Displacement.Tag = 'Displacement';
            app.Displacement.Visible = 'off';
            app.Displacement.Text = '-';
            app.BottomView_Button = uibutton(app.ThreeDViewTab, 'push');
            app.BottomView_Button.ButtonPushedFcn = createCallbackFcn(app, @BottomView_ButtonPushed, true);
            app.BottomView_Button.Tag = 'BottomView_Button';
            app.BottomView_Button.Text = '⇧';
            app.LeftSideView_Button = uibutton(app.ThreeDViewTab, 'push');
            app.LeftSideView_Button.ButtonPushedFcn = createCallbackFcn(app, @RightSideView_ButtonPushed, true);
            app.LeftSideView_Button.Tag = 'LeftSideView_Button';
            app.LeftSideView_Button.Text = '⇦';
            app.FrontView_Button = uibutton(app.ThreeDViewTab, 'push');
            app.FrontView_Button.ButtonPushedFcn = createCallbackFcn(app, @FrontView_ButtonPushed, true);
            app.FrontView_Button.Tag = 'FrontView_Button';
            app.FrontView_Button.Text = '⧈';
            app.RightSideView_Button = uibutton(app.ThreeDViewTab, 'push');
            app.RightSideView_Button.ButtonPushedFcn = createCallbackFcn(app, @LeftSideView_ButtonPushed, true);
            app.RightSideView_Button.Tag = 'RightSideView_Button';
            app.RightSideView_Button.Text = '⇨';
            app.TopView_Button = uibutton(app.ThreeDViewTab, 'push');
            app.TopView_Button.ButtonPushedFcn = createCallbackFcn(app, @TopView_ButtonPushed, true);
            app.TopView_Button.Tag = 'TopView_Button';
            app.TopView_Button.Text = '⇩';
            app.Displacement_Slider_2 = uislider(app.ThreeDViewTab);
            app.Displacement_Slider_2.Limits = [1 2];
            app.Displacement_Slider_2.MajorTicks = [];
            app.Displacement_Slider_2.ValueChangedFcn = createCallbackFcn(app, @Displacement_Slider_2ValueChanged, true);
            app.Displacement_Slider_2.ValueChangingFcn = createCallbackFcn(app, @Displacement_Slider_2ValueChanging, true);
            app.Displacement_Slider_2.MinorTicks = [];
            app.Displacement_Slider_2.Tag = 'Displacement_Slider';
            app.Displacement_Slider_2.Visible = 'off';
            app.Displacement_Slider_2.Value = 1.5;
            app.Displacement_Label_2 = uilabel(app.ThreeDViewTab);
            app.Displacement_Label_2.Tag = 'Displacement_Label';
            app.Displacement_Label_2.Visible = 'off';
            app.Displacement_Label_2.Text = 'Displacement:';
            app.Displacement_2 = uilabel(app.ThreeDViewTab);
            app.Displacement_2.Tag = 'Displacement';
            app.Displacement_2.Visible = 'off';
            app.Displacement_2.Text = '-';
            app.GraphsTab = uitab(app.DataViewTabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.Graphs_Axes = uiaxes(app.GraphsTab);
            app.Graphs_Axes.XLim = [0 1];
            app.Graphs_Axes.YLim = [0 1];
            app.Graphs_Axes.Tag = 'View3D_Axes';
            app.GraphsSlider = uislider(app.GraphsTab);
            app.GraphsSlider.Limits = [1 2];
            app.GraphsSlider.MajorTicks = [];
            app.GraphsSlider.ValueChangedFcn = createCallbackFcn(app, @GraphsSliderValueChanged, true);
            app.GraphsSlider.ValueChangingFcn = createCallbackFcn(app, @GraphsSliderValueChanging, true);
            app.GraphsSlider.MinorTicks = [];
            app.GraphsSlider.Tag = 'Displacement_Slider';
            app.GraphsSlider.Visible = 'off';
            app.GraphsSlider.Value = 1.5;
            app.GraphsSliderLabel = uilabel(app.GraphsTab);
            app.GraphsSliderLabel.Tag = 'Displacement_Label';
            app.GraphsSliderLabel.Visible = 'off';
            app.GraphsSliderLabel.Text = 'Displacement:';
            app.GraphsSliderValue = uilabel(app.GraphsTab);
            app.GraphsSliderValue.Tag = 'Displacement';
            app.GraphsSliderValue.Visible = 'off';
            app.GraphsSliderValue.Text = '-';
            app.ChangeParameterUpButton = uibutton(app.GraphsTab, 'push');
            app.ChangeParameterUpButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeParameterUpButtonPushed, true);
            app.ChangeParameterUpButton.Visible = 'off';
            app.ChangeParameterUpButton.Text = '⯈';
            app.ChangeParameterDownButton = uibutton(app.GraphsTab, 'push');
            app.ChangeParameterDownButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeParameterDownButtonPushed, true);
            app.ChangeParameterDownButton.Visible = 'off';
            app.ChangeParameterDownButton.Text = '⯇';
            app.BottomContainerPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.BottomContainerPanel);
            app.MsgLabel = uilabel(app.GridLayout5);
            app.MsgLabel.Text = {''; ''};
            app.ProgressWheel = uiimage(app.GridLayout5);
            app.ProgressWheel.Visible = 'off';
            app.ProgressWheel.Tooltip = {'Analysis in progress'};
            app.SelectCompareDatasets_Panel = uipanel(app.UIFigure);
            app.SelectCompareDatasets_Panel.Visible = 'off';
            app.GridLayout6 = uigridlayout(app.SelectCompareDatasets_Panel);
            app.SelectCompareDatasets_SubPanel = uipanel(app.GridLayout6);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.PlotOptionsOKButton = uibutton(app.GridLayout7, 'push');
            app.PlotOptionsOKButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOptionsOKButtonPushed, true);
            app.PlotOptionsOKButton.Tag = 'OK';
            app.PlotOptionsOKButton.Text = 'OK';
            app.AnalysisConfigurationPanel = uipanel(app.UIFigure);
            app.AnalysisConfigurationPanel.Title = 'Analysis Configuration';
            app.AnalysisConfigurationPanel.Visible = 'off';
            app.SteeringLabel = uilabel(app.AnalysisConfigurationPanel);
            app.SteeringLabel.Text = 'Steering';
            app.OpositeLabel = uilabel(app.AnalysisConfigurationPanel);
            app.OpositeLabel.Text = 'Oposite';
            app.RollLabel = uilabel(app.AnalysisConfigurationPanel);
            app.RollLabel.Text = 'Roll';
            app.ParallelLabel = uilabel(app.AnalysisConfigurationPanel);
            app.ParallelLabel.Text = 'Parallel';
            app.LimitsLabel = uilabel(app.AnalysisConfigurationPanel);
            app.LimitsLabel.Text = 'Limits';
            app.StepLabel = uilabel(app.AnalysisConfigurationPanel);
            app.StepLabel.Text = 'Step';
            app.ParallelStep = uieditfield(app.AnalysisConfigurationPanel, 'numeric');
            app.ParallelStep.Limits = [0.001 10];
            app.ParallelStep.Value = 0.1;
            app.ParallelLowerLimit = uieditfield(app.AnalysisConfigurationPanel, 'numeric');
            app.ParallelLowerLimit.Limits = [-100 0];
            app.ParallelUpperLimit = uieditfield(app.AnalysisConfigurationPanel, 'numeric');
            app.ParallelUpperLimit.Limits = [0 100];
            app.OpositeStep = uieditfield(app.AnalysisConfigurationPanel, 'numeric');
            app.OpositeStep.Limits = [0.001 10];
            app.OpositeStep.Value = 0.001;
            app.OpositeLowerLimit = uieditfield(app.AnalysisConfigurationPanel, 'numeric');
            app.OpositeLowerLimit.Limits = [-100 0];
            app.OpositeUpperLimit = uieditfield(app.AnalysisConfigurationPanel, 'numeric');
            app.OpositeUpperLimit.Limits = [0 100];
            app.RollStep = uieditfield(app.AnalysisConfigurationPanel, 'numeric');
            app.RollStep.Limits = [0.001 1];
            app.RollStep.Value = 0.001;
            app.RollTol = uieditfield(app.AnalysisConfigurationPanel, 'numeric');
            app.RollTol.Limits = [0.001 1];
            app.RollTol.Tooltip = {'Precission of the roll analysis. Finer analysis takes longer'};
            app.RollTol.Value = 0.001;
            app.RollLimit = uieditfield(app.AnalysisConfigurationPanel, 'numeric');
            app.RollLimit.Limits = [0 10];
            app.SteeringStep = uieditfield(app.AnalysisConfigurationPanel, 'numeric');
            app.SteeringStep.Limits = [0.001 10];
            app.SteeringStep.Value = 0.001;
            app.SteeringLowerLimit = uieditfield(app.AnalysisConfigurationPanel, 'numeric');
            app.SteeringLowerLimit.Limits = [-100 0];
            app.SteeringUpperLimit = uieditfield(app.AnalysisConfigurationPanel, 'numeric');
            app.SteeringUpperLimit.Limits = [0 100];
            app.ToleranceLabel = uilabel(app.AnalysisConfigurationPanel);
            app.ToleranceLabel.Text = 'Tolerance';
            app.InterpolationPanel = uipanel(app.AnalysisConfigurationPanel);
            app.InterpolationPanel.Title = 'Interpolation';
            app.InterpolateStepsSpinner = uispinner(app.InterpolationPanel);
            app.InterpolateStepsSpinner.Limits = [1 100];
            app.InterpolateStepsSpinner.RoundFractionalValues = 'on';
            app.InterpolateStepsSpinner.Value = 5;
            app.InterpolateCheckBox = uicheckbox(app.InterpolationPanel);
            app.InterpolateCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterpolateCheckBoxValueChanged, true);
            app.InterpolateCheckBox.Text = '';
            app.InterpolateCheckBox.Value = true;
            app.InterpolateLabel = uilabel(app.InterpolationPanel);
            app.InterpolateLabel.Text = 'Interpolate';
            app.InterpStepsLabel = uilabel(app.InterpolationPanel);
            app.InterpStepsLabel.Text = 'Steps';
            app.AnalysisConfigCancelButton = uibutton(app.AnalysisConfigurationPanel, 'push');
            app.AnalysisConfigCancelButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisConfigCancelButtonPushed, true);
            app.AnalysisConfigCancelButton.Text = 'Cancel';
            app.AnalysisConfigOKButton = uibutton(app.AnalysisConfigurationPanel, 'push');
            app.AnalysisConfigOKButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisConfigOKButtonPushed, true);
            app.AnalysisConfigOKButton.Text = 'OK';
            app.SaveContextMenu = uicontextmenu(app.UIFigure);
            app.SaveasMenu = uimenu(app.SaveContextMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as...';
            app.SaveasMenu.Tag = 'saveAs_button';
            % Assign app.SaveContextMenu
            app.SaveButton.ContextMenu = app.SaveContextMenu;
            app.GraphsContextMenu = uicontextmenu(app.UIFigure);
            app.ResetViewMenu = uimenu(app.GraphsContextMenu);
            app.ResetViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetViewMenuSelected, true);
            app.ResetViewMenu.Text = 'Reset View';
            app.GraphsCompareWithMenu = uimenu(app.GraphsContextMenu);
            app.GraphsCompareWithMenu.Text = 'Compare with';
            app.CombinedanalysisMenu = uimenu(app.GraphsContextMenu);
            app.CombinedanalysisMenu.Text = 'Combined analysis';
            app.PlotsurfaceMenuCheck = uimenu(app.CombinedanalysisMenu);
            app.PlotsurfaceMenuCheck.MenuSelectedFcn = createCallbackFcn(app, @PlotsurfaceMenuCheckSelected, true);
            app.PlotsurfaceMenuCheck.Checked = 'on';
            app.PlotsurfaceMenuCheck.Text = 'Plot surface';
            app.ChangeprimaryaxisMenu = uimenu(app.CombinedanalysisMenu);
            app.ChangeprimaryaxisMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeprimaryaxisMenuSelected, true);
            app.ChangeprimaryaxisMenu.Text = 'Change primary axis';
            app.GraphsSaveAsMenu = uimenu(app.GraphsContextMenu);
            app.GraphsSaveAsMenu.Text = 'Save as...';
            app.ImageMenu = uimenu(app.GraphsSaveAsMenu);
            app.ImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageMenuSelected, true);
            app.ImageMenu.Text = 'Image';
            app.MatlabFigureMenu = uimenu(app.GraphsSaveAsMenu);
            app.MatlabFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @MatlabFigureMenuSelected, true);
            app.MatlabFigureMenu.Text = 'Matlab Figure';
            app.MatlabDatamatMenu = uimenu(app.GraphsSaveAsMenu);
            app.MatlabDatamatMenu.MenuSelectedFcn = createCallbackFcn(app, @MatlabDatamatMenuSelected, true);
            app.MatlabDatamatMenu.Text = 'Matlab Data (.mat)';
            app.ExporttoworkspaceMenu = uimenu(app.GraphsSaveAsMenu);
            app.ExporttoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoworkspaceMenuSelected, true);
            app.ExporttoworkspaceMenu.Text = 'Export to workspace';
            % Assign app.GraphsContextMenu
            app.GraphsTab.ContextMenu = app.GraphsContextMenu;
            app.Graphs_Axes.ContextMenu = app.GraphsContextMenu;
            app.View3DContextMenu = uicontextmenu(app.UIFigure);
            app.view3DcheckMenu = uimenu(app.View3DContextMenu);
            app.view3DcheckMenu.MenuSelectedFcn = createCallbackFcn(app, @view3DcheckMenuSelected, true);
            app.view3DcheckMenu.Text = '3D';
            app.Reset3DViewMenu = uimenu(app.View3DContextMenu);
            app.Reset3DViewMenu.MenuSelectedFcn = createCallbackFcn(app, @Reset3DViewMenuSelected, true);
            app.Reset3DViewMenu.Text = 'Reset View';
            app.view3DCompareWithMenu = uimenu(app.View3DContextMenu);
            app.view3DCompareWithMenu.Text = 'Compare with...';
            % Assign app.View3DContextMenu
            app.View3D_Axes.ContextMenu = app.View3DContextMenu;
            app.ExportContextMenu = uicontextmenu(app.UIFigure);
            app.ExportSavetoworkspaceMenu = uimenu(app.ExportContextMenu);
            app.ExportSavetoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSavetoworkspaceMenuSelected, true);
            app.ExportSavetoworkspaceMenu.Text = 'Save to workspace';
            app.SaveImages = uimenu(app.ExportContextMenu);
            app.SaveImages.MenuSelectedFcn = createCallbackFcn(app, @SaveImagesMenuSelected, true);
            app.SaveImages.Text = 'Save all as image';
            app.SaveFiguresMenu = uimenu(app.ExportContextMenu);
            app.SaveFiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveFiguresMenuSelected, true);
            app.SaveFiguresMenu.Text = 'Save all as figure';
            % Assign app.ExportContextMenu
            app.AnalysisExportButton.ContextMenu = app.ExportContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kinematic3DAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end